classdef Developers_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TipText                         matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        CodeHierarchyTab                matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        RootEaRL_AppmlappNode           matlab.ui.container.TreeNode
        registerNode                    matlab.ui.container.TreeNode
        aboutNode                       matlab.ui.container.TreeNode
        About_AppmlappNode              matlab.ui.container.TreeNode
        helpNode                        matlab.ui.container.TreeNode
        developersNode                  matlab.ui.container.TreeNode
        Developers_AppmlappNode         matlab.ui.container.TreeNode
        NewNode                         matlab.ui.container.TreeNode
        UnitMessage_AppmlappNode_2      matlab.ui.container.TreeNode
        Initialize_ProjectmNode         matlab.ui.container.TreeNode
        OpenNode                        matlab.ui.container.TreeNode
        SaveasNode                      matlab.ui.container.TreeNode
        ScopeNode                       matlab.ui.container.TreeNode
        Show_Project_SummarymNode       matlab.ui.container.TreeNode
        Get_Project_SummarymNode        matlab.ui.container.TreeNode
        Project_Summary_AppmlappNode    matlab.ui.container.TreeNode
        BuildingDataNode                matlab.ui.container.TreeNode
        Module_Building_AppmlappNode    matlab.ui.container.TreeNode
        Tip_AppmlappNode_4              matlab.ui.container.TreeNode
        ComponentDataNode               matlab.ui.container.TreeNode
        Module_Component_AppmlappNode   matlab.ui.container.TreeNode
        ManualDefinitionNode            matlab.ui.container.TreeNode
        Module_Component_Option1_AppmlappNode  matlab.ui.container.TreeNode
        Process_Imported_Component_DatamNode  matlab.ui.container.TreeNode
        Module_PredictorDatamlappNode   matlab.ui.container.TreeNode
        LossEDPSteelNode                matlab.ui.container.TreeNode
        Module_Component_Option2_AppmlappNode  matlab.ui.container.TreeNode
        Plot_LossvsEDP_Fragility_Option2mNode  matlab.ui.container.TreeNode
        LossEDPRCNode                   matlab.ui.container.TreeNode
        Module_Component_Option3_AppmlappNode  matlab.ui.container.TreeNode
        Plot_LossvsEDP_Fragility_Option3mNode  matlab.ui.container.TreeNode
        ResponseDataNode                matlab.ui.container.TreeNode
        Module_Response_AppmlappNode    matlab.ui.container.TreeNode
        IDADataNode                     matlab.ui.container.TreeNode
        Module_Response_Option1_AppmlappNode  matlab.ui.container.TreeNode
        Plot_IM_vs_EDPmNode             matlab.ui.container.TreeNode
        Check_EDP_DatamNode             matlab.ui.container.TreeNode
        Get_EDP_RangemNode              matlab.ui.container.TreeNode
        Tip_AppmlappNode_6              matlab.ui.container.TreeNode
        MultiStripeDataNode             matlab.ui.container.TreeNode
        Module_Response_Option4_AppmlappNode  matlab.ui.container.TreeNode
        Get_EDP_Data_MultiStripesOptionmNode  matlab.ui.container.TreeNode
        Plot_EDP_ProfilemNode_2         matlab.ui.container.TreeNode
        MultipleGMsNode                 matlab.ui.container.TreeNode
        Module_Response_Option2or3_AppmlappNode  matlab.ui.container.TreeNode
        Plot_EDP_ProfilemNode           matlab.ui.container.TreeNode
        ParametersNode                  matlab.ui.container.TreeNode
        Module_Response_Option2or3_AppmlappNode_2  matlab.ui.container.TreeNode
        Plot_EDP_ProfilemNode_3         matlab.ui.container.TreeNode
        MDoFShearModelNode              matlab.ui.container.TreeNode
        Module_Response_Option5_AppmlappNode  matlab.ui.container.TreeNode
        v2structmNode                   matlab.ui.container.TreeNode
        rmdirmNode                      matlab.ui.container.TreeNode
        Import_ShearModel_Data_from_ExcelmNode_2  matlab.ui.container.TreeNode
        Get_EDP_RangemNode_2            matlab.ui.container.TreeNode
        MDoF_CREATOR_EIGENmNode         matlab.ui.container.TreeNode
        MDoF_CREATOR_MAINmNode          matlab.ui.container.TreeNode
        MDoF_Run_OpenSEESmNode          matlab.ui.container.TreeNode
        v2structmNode_2                 matlab.ui.container.TreeNode
        MDoF_Get_IMmNode_2              matlab.ui.container.TreeNode
        MDoF_Read_DatamNode             matlab.ui.container.TreeNode
        MDoF_Save_Incr_DatamNode        matlab.ui.container.TreeNode
        MDoF_Save_IDA_DatamNode         matlab.ui.container.TreeNode
        Plot_EDP_Data_Option5mNode      matlab.ui.container.TreeNode
        Plot_IM_vs_EDP_Option5mNode     matlab.ui.container.TreeNode
        Write_EDP_Data_2Excel_Option5mNode  matlab.ui.container.TreeNode
        FEMAP58Node                     matlab.ui.container.TreeNode
        Module_Response_Option6_AppmlappNode  matlab.ui.container.TreeNode
        v2structmNode_3                 matlab.ui.container.TreeNode
        rmdirmNode_2                    matlab.ui.container.TreeNode
        Import_ShearModel_Data_from_ExcelmNode  matlab.ui.container.TreeNode
        MDoF_CREATOR_EIGENmNode_2       matlab.ui.container.TreeNode
        MDoF_CREATOR_ELASTICmNode_2     matlab.ui.container.TreeNode
        FEMAP58_Get_Fx_DistributionmNode  matlab.ui.container.TreeNode
        FEMAP58_EDP_CorrectionmNode     matlab.ui.container.TreeNode
        Plot_EDP_Profile_Option6mNode   matlab.ui.container.TreeNode
        HazardDataNode                  matlab.ui.container.TreeNode
        Module_Hazard_AppmlappNode      matlab.ui.container.TreeNode
        Get_HazardCurve_USGSMapsmNode   matlab.ui.container.TreeNode
        Fit_Hazard_4PolymNode           matlab.ui.container.TreeNode
        Plot_Hazard_CurvemNode          matlab.ui.container.TreeNode
        Tip_AppmlappNode                matlab.ui.container.TreeNode
        DamageFragilityNode             matlab.ui.container.TreeNode
        Module_Fragility_AppmlappNode   matlab.ui.container.TreeNode
        ExploreEditFragilityDatabaseNode  matlab.ui.container.TreeNode
        Module_Fragility_Explorer_AppmlappNode  matlab.ui.container.TreeNode
        Module_Fragility_Edit_AppmlappNode  matlab.ui.container.TreeNode
        AddUnivariateFragilityNode      matlab.ui.container.TreeNode
        Module_Fragility_Univariate_AppmlappNode  matlab.ui.container.TreeNode
        AddMultivariateFragilityNode    matlab.ui.container.TreeNode
        Module_Fragility_Univariate_AppmlappNode_2  matlab.ui.container.TreeNode
        PopulationModelNode             matlab.ui.container.TreeNode
        Module_Population_AppmlappNode  matlab.ui.container.TreeNode
        Plot_Population_ModelmNode      matlab.ui.container.TreeNode
        rotateXLabelsmNode_5            matlab.ui.container.TreeNode
        RepairTimeSchemeNode            matlab.ui.container.TreeNode
        Module_Time_AppmlappNode        matlab.ui.container.TreeNode
        DemolitionNode                  matlab.ui.container.TreeNode
        Module_Demolition_AppmlappNode  matlab.ui.container.TreeNode
        Plot_Univariate_LognCDFmNode    matlab.ui.container.TreeNode
        Plot_Correlated_Bivariate_LognCDFmNode  matlab.ui.container.TreeNode
        Tip_AppmlappNode_2              matlab.ui.container.TreeNode
        CollapseNode                    matlab.ui.container.TreeNode
        Module_Collapse_AppmlappNode    matlab.ui.container.TreeNode
        Get_Collapse_Fragility_ParametersmNode  matlab.ui.container.TreeNode
        Get_Collapse_Fragility_IDAmNode  matlab.ui.container.TreeNode
        Plot_Collapse_FragilitymNode    matlab.ui.container.TreeNode
        Get_Collapse_Fragility_IDAmNode_2  matlab.ui.container.TreeNode
        Tip_AppmlappNode_3              matlab.ui.container.TreeNode
        IMEDPBinsNode                   matlab.ui.container.TreeNode
        Module_IM_AppmlappNode          matlab.ui.container.TreeNode
        ComputeNode                     matlab.ui.container.TreeNode
        Module_Run_Options_AppmlappNode  matlab.ui.container.TreeNode
        Tip_AppmlappNode_5              matlab.ui.container.TreeNode
        Run_Loss_CodemNode              matlab.ui.container.TreeNode
        LossCode_FEMAP58_CO1mNode       matlab.ui.container.TreeNode
        Check_Frag_DatamNode            matlab.ui.container.TreeNode
        Get_REALIZATIONSmNode           matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_SImNode  matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_MSmNode  matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_RimNode   matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_RimNode  matlab.ui.container.TreeNode
        Get_Realization_EDP_SImNode     matlab.ui.container.TreeNode
        Get_Realization_EDP_MSmNode     matlab.ui.container.TreeNode
        Get_Active_DSmNode              matlab.ui.container.TreeNode
        Get_DS_CostmNode                matlab.ui.container.TreeNode
        Get_DS_TimemNode                matlab.ui.container.TreeNode
        Assemble_Deaggregated_DatamNode  matlab.ui.container.TreeNode
        Get_Casualty_and_InjurymNode    matlab.ui.container.TreeNode
        Get_Unsafe_PlacardmNode         matlab.ui.container.TreeNode
        LossCode_FEMAP58_CO2mNode       matlab.ui.container.TreeNode
        Get_REALIZATIONSmNode_3         matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_SImNode_2  matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_MSmNode_2  matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_RimNode_2  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_RimNode_2  matlab.ui.container.TreeNode
        Get_Realization_EDP_SImNode_2   matlab.ui.container.TreeNode
        Get_Realization_EDP_MSmNode_3   matlab.ui.container.TreeNode
        LossCode_FEMAP58_CO3mNode       matlab.ui.container.TreeNode
        Get_REALIZATIONSmNode_4         matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_SImNode_3  matlab.ui.container.TreeNode
        Get_REALIZATIONS_Matrix_MSmNode_3  matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_RimNode_3  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_RimNode_3  matlab.ui.container.TreeNode
        Get_Realization_EDP_SImNode_3   matlab.ui.container.TreeNode
        Get_Realization_EDP_MSmNode_4   matlab.ui.container.TreeNode
        LossCode_PEER_CO1mNode          matlab.ui.container.TreeNode
        Check_Frag_DatamNode_3          matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode_2  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode_2  matlab.ui.container.TreeNode
        Get_EDP_FragParammNode_2        matlab.ui.container.TreeNode
        LossCode_PEER_CO1_IDAmNode      matlab.ui.container.TreeNode
        Check_Frag_DatamNode_2          matlab.ui.container.TreeNode
        Get_EDP_Fargiltiy_Paramters_at_IMmNode  matlab.ui.container.TreeNode
        Count_Surviving_GMsmNode        matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode_5  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode_4  matlab.ui.container.TreeNode
        Get_EDP_FragParam_IMmNode       matlab.ui.container.TreeNode
        LossCode_PEER_CO2mNode          matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode   matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode  matlab.ui.container.TreeNode
        LossCode_PEER_CO2_IDAmNode      matlab.ui.container.TreeNode
        Get_EDP_Fargiltiy_Paramters_at_IMmNode_2  matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode_6  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode_5  matlab.ui.container.TreeNode
        LossCode_PEER_CO3mNode          matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode_3  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode_3  matlab.ui.container.TreeNode
        LossCode_PEER_CO3_IDAmNode      matlab.ui.container.TreeNode
        Get_EDP_Fargiltiy_Paramters_at_IMmNode_3  matlab.ui.container.TreeNode
        Get_Collapse_Loss_Per_IMmNode_7  matlab.ui.container.TreeNode
        Get_Demolition_Loss_Per_IMmNode_6  matlab.ui.container.TreeNode
        VisualizeNode                   matlab.ui.container.TreeNode
        Module_Visualize_AppmlappNode   matlab.ui.container.TreeNode
        Module_SummaryPlot_PEER_AppNode  matlab.ui.container.TreeNode
        Write2FilemNode_2               matlab.ui.container.TreeNode
        rotateXLabelsmNode_2            matlab.ui.container.TreeNode
        Module_SummaryPlot_FEMAP58_AppNode  matlab.ui.container.TreeNode
        Get_Repair_Cost_Per_RimNode     matlab.ui.container.TreeNode
        Get_Repair_Time_Per_RimNode     matlab.ui.container.TreeNode
        Get_Repair_Cost_Profile_by_ComponentmNode  matlab.ui.container.TreeNode
        Get_Repair_Cost_Breakdown_by_ComponentmNode  matlab.ui.container.TreeNode
        Get_Repair_Time_Per_ComponentmNode  matlab.ui.container.TreeNode
        Write2FilemNode                 matlab.ui.container.TreeNode
        Plot_VulnerabilityCurves_EventmNode  matlab.ui.container.TreeNode
        Plot_VulnerabilityCurves_ComponentmNode  matlab.ui.container.TreeNode
        Plot_RepairCost_vs_RealizationmNode  matlab.ui.container.TreeNode
        Plot_RepairTime_vs_RealizationmNode  matlab.ui.container.TreeNode
        Plot_LossBreakdown_by_EventmNode  matlab.ui.container.TreeNode
        rotateXLabelsmNode_4            matlab.ui.container.TreeNode
        Plot_RepairCostProfile_by_TypemNode  matlab.ui.container.TreeNode
        Plot_RepairTimeProfile_by_TypemNode  matlab.ui.container.TreeNode
        Plot_RepairCostProfile_by_ComponentmNode  matlab.ui.container.TreeNode
        rotateXLabelsmNode_6            matlab.ui.container.TreeNode
        Plot_RepairTimeProfile_by_ComponentmNode  matlab.ui.container.TreeNode
        rotateXLabelsmNode_7            matlab.ui.container.TreeNode
        Plot_RepairCostBreakdown_by_ComponentmNode  matlab.ui.container.TreeNode
        Plot_RepairTimeBreakdown_by_ComponentmNode  matlab.ui.container.TreeNode
        Plot_EALmNode                   matlab.ui.container.TreeNode
        rotateXLabelsmNode_3            matlab.ui.container.TreeNode
        Plot_LAMBDAmNode                matlab.ui.container.TreeNode
        rotateXLabelsmNode              matlab.ui.container.TreeNode
        ReportNode                      matlab.ui.container.TreeNode
        Module_Report_AppmlappNode      matlab.ui.container.TreeNode
        Get_Project_SummarymNode_2      matlab.ui.container.TreeNode
        Get_Summarized_DatamNode        matlab.ui.container.TreeNode
        Report_RepairCost_vs_RealizationmNode  matlab.ui.container.TreeNode
        Report_RepairTime_vs_RealizationmNode  matlab.ui.container.TreeNode
        Report_VulnerabilityCurvesmNode  matlab.ui.container.TreeNode
        Report_LossBreakdown_by_EventmNode  matlab.ui.container.TreeNode
        Report_RepairCostBreakdown_by_ComponentmNode  matlab.ui.container.TreeNode
        Report_RepairTimeBreakdown_by_ComponentmNode  matlab.ui.container.TreeNode
        Report_RepairCostProfile_by_ComponentmNode  matlab.ui.container.TreeNode
        Report_RepairTimeProfile_by_ComponentmNode  matlab.ui.container.TreeNode
        Report_RepairCostProfile_by_TypemNode  matlab.ui.container.TreeNode
        Report_RepairTimeProfile_by_TypemNode  matlab.ui.container.TreeNode
        Report_EALmNode                 matlab.ui.container.TreeNode
        Report_LAMBDAmNode              matlab.ui.container.TreeNode
        VariablesTab                    matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        SorttablebyLabel                matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ForDevelopers_OpeningFcn(app)
            DropDownValueChanged(app)
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            if selectedNodes.NodeData ~=0; app.TipText.Value=selectedNodes.NodeData; else app.TipText.Value=''; end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            sortType=str2double(app.DropDown.Value);
            [NUM,TXT,RAW] = xlsread('Variable_Database.xlsx','Sheet1','A2:D400');
            if sortType==1; TXT=sortrows(TXT,[1,1]);  else; TXT=sortrows(TXT,[3,1]); end
            app.TipText.Value=TXT{indices,4};
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            clc;
            sortType=str2double(app.DropDown.Value);
            [NUM,TXT,RAW] = xlsread('Variable_Database.xlsx','Sheet1','A2:C400');
            if sortType==1; TXT=sortrows(TXT,[1,1]);  else; TXT=sortrows(TXT,[3,1]); end
            app.UITable.Data=TXT;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'EaRL - For Developers';
            app.TipText = uitextarea(app.figure1);
            app.TipText.Value = {''; ''; ''; ''; ''; ''; ''};
            app.TabGroup = uitabgroup(app.figure1);
            app.CodeHierarchyTab = uitab(app.TabGroup);
            app.CodeHierarchyTab.Title = 'Code Hierarchy';
            app.Tree = uitree(app.CodeHierarchyTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.RootEaRL_AppmlappNode = uitreenode(app.Tree);
            app.RootEaRL_AppmlappNode.NodeData = '[GUI] EaRL''s main console';
            app.RootEaRL_AppmlappNode.Icon = 'icon_EaRL.png';
            app.RootEaRL_AppmlappNode.Text = '[Root] EaRL_App.mlapp';
            app.registerNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.registerNode.NodeData = 'Register for EaRL updates and fixes button';
            app.registerNode.Icon = 'icon_Register2.png';
            app.registerNode.Text = '[register]';
            app.aboutNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.aboutNode.NodeData = 'About button';
            app.aboutNode.Text = '   [about]';
            app.About_AppmlappNode = uitreenode(app.aboutNode);
            app.About_AppmlappNode.NodeData = '[GUI] Display the EaRL''s summary description';
            app.About_AppmlappNode.Text = 'About_App.mlapp';
            app.helpNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.helpNode.NodeData = 'Help button - opens the PDF manual';
            app.helpNode.Icon = 'icon_Help2.jpg';
            app.helpNode.Text = '[help]';
            app.developersNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.developersNode.NodeData = 'Developers button';
            app.developersNode.Icon = 'icon_Code.png';
            app.developersNode.Text = '[developers]';
            app.Developers_AppmlappNode = uitreenode(app.developersNode);
            app.Developers_AppmlappNode.NodeData = '[GUI] Display the software variablles descriptions and code hierarchy';
            app.Developers_AppmlappNode.Icon = 'icon_GUI.png';
            app.Developers_AppmlappNode.Text = 'Developers_App.mlapp';
            app.NewNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.NewNode.NodeData = 'New file button';
            app.NewNode.Icon = 'icon_New.png';
            app.NewNode.Text = '[New]';
            app.UnitMessage_AppmlappNode_2 = uitreenode(app.NewNode);
            app.UnitMessage_AppmlappNode_2.NodeData = '[GUI] Project units selection';
            app.UnitMessage_AppmlappNode_2.Icon = 'icon_GUI.png';
            app.UnitMessage_AppmlappNode_2.Text = 'UnitMessage_App.mlapp';
            app.Initialize_ProjectmNode = uitreenode(app.NewNode);
            app.Initialize_ProjectmNode.NodeData = '[function] Initializes the project variables';
            app.Initialize_ProjectmNode.Icon = 'icon_Function.png';
            app.Initialize_ProjectmNode.Text = 'Initialize_Project.m';
            app.OpenNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.OpenNode.NodeData = 'Open file button';
            app.OpenNode.Icon = 'icon_Open.png';
            app.OpenNode.Text = '[Open]';
            app.SaveasNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.SaveasNode.NodeData = 'Save as button';
            app.SaveasNode.Icon = 'icon_SaveAs.png';
            app.SaveasNode.Text = '[Save as]';
            app.ScopeNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.ScopeNode.NodeData = 'Scope button';
            app.ScopeNode.Icon = 'output-onlinepngtools2.png';
            app.ScopeNode.Text = '[Scope]';
            app.Show_Project_SummarymNode = uitreenode(app.ScopeNode);
            app.Show_Project_SummarymNode.NodeData = '[function] Show the project summary data';
            app.Show_Project_SummarymNode.Icon = 'icon_Function.png';
            app.Show_Project_SummarymNode.Text = 'Show_Project_Summary.m';
            app.Get_Project_SummarymNode = uitreenode(app.Show_Project_SummarymNode);
            app.Get_Project_SummarymNode.NodeData = '[function] Gets the summary data in text format --> Output variable: SummaryText';
            app.Get_Project_SummarymNode.Icon = 'icon_Function.png';
            app.Get_Project_SummarymNode.Text = 'Get_Project_Summary.m';
            app.Project_Summary_AppmlappNode = uitreenode(app.Get_Project_SummarymNode);
            app.Project_Summary_AppmlappNode.NodeData = '[GUI] Display the summary data text';
            app.Project_Summary_AppmlappNode.Icon = 'icon_GUI.png';
            app.Project_Summary_AppmlappNode.Text = 'Project_Summary_App.mlapp';
            app.BuildingDataNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.BuildingDataNode.NodeData = 'Building data button';
            app.BuildingDataNode.Icon = 'icon_Building.png';
            app.BuildingDataNode.Text = '[Building Data]';
            app.Module_Building_AppmlappNode = uitreenode(app.BuildingDataNode);
            app.Module_Building_AppmlappNode.NodeData = '[GUI] Building data module';
            app.Module_Building_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Building_AppmlappNode.Text = 'Module_Building_App.mlapp';
            app.Tip_AppmlappNode_4 = uitreenode(app.Module_Building_AppmlappNode);
            app.Tip_AppmlappNode_4.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_4.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_4.Text = 'Tip_App.mlapp';
            app.ComponentDataNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.ComponentDataNode.NodeData = 'Component data button';
            app.ComponentDataNode.Icon = 'icon_Components.png';
            app.ComponentDataNode.Text = '[Component Data]';
            app.Module_Component_AppmlappNode = uitreenode(app.ComponentDataNode);
            app.Module_Component_AppmlappNode.NodeData = 'Building data module (GUI)';
            app.Module_Component_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Component_AppmlappNode.Text = 'Module_Component_App.mlapp';
            app.ManualDefinitionNode = uitreenode(app.Module_Component_AppmlappNode);
            app.ManualDefinitionNode.NodeData = 'Option 1: Manual definition of building''s components/contents';
            app.ManualDefinitionNode.Icon = 'icon_CO1.png';
            app.ManualDefinitionNode.Text = '[Manual Definition]';
            app.Module_Component_Option1_AppmlappNode = uitreenode(app.ManualDefinitionNode);
            app.Module_Component_Option1_AppmlappNode.NodeData = '[GUI] Option 1 module';
            app.Module_Component_Option1_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Component_Option1_AppmlappNode.Text = 'Module_Component_Option1_App.mlapp';
            app.Process_Imported_Component_DatamNode = uitreenode(app.Module_Component_Option1_AppmlappNode);
            app.Process_Imported_Component_DatamNode.NodeData = '[function] process imported component data --> Output variables: C_Data';
            app.Process_Imported_Component_DatamNode.Icon = 'icon_Function.png';
            app.Process_Imported_Component_DatamNode.Text = 'Process_Imported_Component_Data.m';
            app.Module_PredictorDatamlappNode = uitreenode(app.Process_Imported_Component_DatamNode);
            app.Module_PredictorDatamlappNode.NodeData = '[GUI] Define the values of predictors of multivariate fragility functions';
            app.Module_PredictorDatamlappNode.Icon = 'icon_GUI.png';
            app.Module_PredictorDatamlappNode.Text = 'Module_PredictorData.mlapp';
            app.LossEDPSteelNode = uitreenode(app.Module_Component_AppmlappNode);
            app.LossEDPSteelNode.NodeData = 'Option 2: Loss-EDP functions for steel office buildings';
            app.LossEDPSteelNode.Icon = 'Component2.png';
            app.LossEDPSteelNode.Text = '[Loss-EDP Steel]';
            app.Module_Component_Option2_AppmlappNode = uitreenode(app.LossEDPSteelNode);
            app.Module_Component_Option2_AppmlappNode.NodeData = '[GUI] Option 2 definitions module';
            app.Module_Component_Option2_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Component_Option2_AppmlappNode.Text = 'Module_Component_Option2_App.mlapp';
            app.Plot_LossvsEDP_Fragility_Option2mNode = uitreenode(app.Module_Component_Option2_AppmlappNode);
            app.Plot_LossvsEDP_Fragility_Option2mNode.NodeData = '[function] Plot the Loss-EDP curves';
            app.Plot_LossvsEDP_Fragility_Option2mNode.Icon = 'icon_Function.png';
            app.Plot_LossvsEDP_Fragility_Option2mNode.Text = 'Plot_LossvsEDP_Fragility_Option2.m';
            app.LossEDPRCNode = uitreenode(app.Module_Component_AppmlappNode);
            app.LossEDPRCNode.NodeData = 'Option 3: Loss-EDP functions for RC office buildings';
            app.LossEDPRCNode.Icon = 'Component3.png';
            app.LossEDPRCNode.Text = '[Loss-EDP RC]';
            app.Module_Component_Option3_AppmlappNode = uitreenode(app.LossEDPRCNode);
            app.Module_Component_Option3_AppmlappNode.NodeData = '[GUI] Option 3 definitions module';
            app.Module_Component_Option3_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Component_Option3_AppmlappNode.Text = 'Module_Component_Option3_App.mlapp';
            app.Plot_LossvsEDP_Fragility_Option3mNode = uitreenode(app.Module_Component_Option3_AppmlappNode);
            app.Plot_LossvsEDP_Fragility_Option3mNode.NodeData = '[function] Plot the Loss-EDP curves';
            app.Plot_LossvsEDP_Fragility_Option3mNode.Icon = 'icon_Function.png';
            app.Plot_LossvsEDP_Fragility_Option3mNode.Text = 'Plot_LossvsEDP_Fragility_Option3.m';
            app.ResponseDataNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.ResponseDataNode.NodeData = 'Response data component';
            app.ResponseDataNode.Icon = 'icon_Response.png';
            app.ResponseDataNode.Text = '[Response Data]';
            app.Module_Response_AppmlappNode = uitreenode(app.ResponseDataNode);
            app.Module_Response_AppmlappNode.NodeData = '[GUI] Structural response definition options';
            app.Module_Response_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_AppmlappNode.Text = 'Module_Response_App.mlapp';
            app.IDADataNode = uitreenode(app.Module_Response_AppmlappNode);
            app.IDADataNode.NodeData = 'Option 1: IDA data';
            app.IDADataNode.Icon = 'NRHA1.png';
            app.IDADataNode.Text = '[IDA Data]';
            app.Module_Response_Option1_AppmlappNode = uitreenode(app.IDADataNode);
            app.Module_Response_Option1_AppmlappNode.NodeData = '[GUI] IDA data definition module';
            app.Module_Response_Option1_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_Option1_AppmlappNode.Text = 'Module_Response_Option1_App.mlapp';
            app.Plot_IM_vs_EDPmNode = uitreenode(app.Module_Response_Option1_AppmlappNode);
            app.Plot_IM_vs_EDPmNode.NodeData = '[function] Plot the IDA curves for the imported EDPs';
            app.Plot_IM_vs_EDPmNode.Icon = 'icon_Function.png';
            app.Plot_IM_vs_EDPmNode.Text = 'Plot_IM_vs_EDP.m';
            app.Check_EDP_DatamNode = uitreenode(app.Module_Response_Option1_AppmlappNode);
            app.Check_EDP_DatamNode.NodeData = '[function] Check the format and validity of the imported IDA data --> Output variables: errorflag';
            app.Check_EDP_DatamNode.Icon = 'icon_Function.png';
            app.Check_EDP_DatamNode.Text = 'Check_EDP_Data.m';
            app.Get_EDP_RangemNode = uitreenode(app.Module_Response_Option1_AppmlappNode);
            app.Get_EDP_RangemNode.NodeData = '[function] Gets the maximum value of the imported EDP data --> Output variables: MaxEDP';
            app.Get_EDP_RangemNode.Icon = 'icon_Function.png';
            app.Get_EDP_RangemNode.Text = 'Get_EDP_Range.m';
            app.Tip_AppmlappNode_6 = uitreenode(app.Module_Response_Option1_AppmlappNode);
            app.Tip_AppmlappNode_6.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_6.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_6.Text = 'Tip_App.mlapp';
            app.MultiStripeDataNode = uitreenode(app.Module_Response_AppmlappNode);
            app.MultiStripeDataNode.NodeData = 'Option 4: Multi-stripe data';
            app.MultiStripeDataNode.Icon = 'NRHA4.png';
            app.MultiStripeDataNode.Text = '[Multi-Stripe Data]';
            app.Module_Response_Option4_AppmlappNode = uitreenode(app.MultiStripeDataNode);
            app.Module_Response_Option4_AppmlappNode.NodeData = '[GUI] Multi-stripe data defintion module';
            app.Module_Response_Option4_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_Option4_AppmlappNode.Text = 'Module_Response_Option4_App.mlapp';
            app.Get_EDP_Data_MultiStripesOptionmNode = uitreenode(app.Module_Response_Option4_AppmlappNode);
            app.Get_EDP_Data_MultiStripesOptionmNode.NodeData = '[function] Extracts and processes the imported multi-stripe data --> Output variables: EDP_Data, MaxSDR, MaxPFA, MaxVRD';
            app.Get_EDP_Data_MultiStripesOptionmNode.Icon = 'icon_Function.png';
            app.Get_EDP_Data_MultiStripesOptionmNode.Text = 'Get_EDP_Data_MultiStripesOption.m';
            app.Plot_EDP_ProfilemNode_2 = uitreenode(app.Module_Response_Option4_AppmlappNode);
            app.Plot_EDP_ProfilemNode_2.NodeData = '[function] Plot the EDPs distribution along the building height';
            app.Plot_EDP_ProfilemNode_2.Icon = 'icon_Function.png';
            app.Plot_EDP_ProfilemNode_2.Text = 'Plot_EDP_Profile.m';
            app.MultipleGMsNode = uitreenode(app.Module_Response_AppmlappNode);
            app.MultipleGMsNode.NodeData = 'Option 2: Response data from multiple GM records scaled at a single intensity';
            app.MultipleGMsNode.Icon = 'NRHA2.png';
            app.MultipleGMsNode.Text = '[Multiple GMs]';
            app.Module_Response_Option2or3_AppmlappNode = uitreenode(app.MultipleGMsNode);
            app.Module_Response_Option2or3_AppmlappNode.NodeData = '[GUI] Options 2 data defintion module';
            app.Module_Response_Option2or3_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_Option2or3_AppmlappNode.Text = 'Module_Response_Option2or3_App.mlapp';
            app.Plot_EDP_ProfilemNode = uitreenode(app.Module_Response_Option2or3_AppmlappNode);
            app.Plot_EDP_ProfilemNode.NodeData = '[function] Plot the EDPs distribution along the building height';
            app.Plot_EDP_ProfilemNode.Icon = 'icon_Function.png';
            app.Plot_EDP_ProfilemNode.Text = 'Plot_EDP_Profile.m';
            app.ParametersNode = uitreenode(app.Module_Response_AppmlappNode);
            app.ParametersNode.NodeData = 'Option 3: Parametric response data at a single intensity';
            app.ParametersNode.Icon = 'NRHA3.png';
            app.ParametersNode.Text = '[Parameters]';
            app.Module_Response_Option2or3_AppmlappNode_2 = uitreenode(app.ParametersNode);
            app.Module_Response_Option2or3_AppmlappNode_2.NodeData = '{GUI] Options 3 data defintion module';
            app.Module_Response_Option2or3_AppmlappNode_2.Icon = 'icon_GUI.png';
            app.Module_Response_Option2or3_AppmlappNode_2.Text = 'Module_Response_Option2or3_App.mlapp';
            app.Plot_EDP_ProfilemNode_3 = uitreenode(app.Module_Response_Option2or3_AppmlappNode_2);
            app.Plot_EDP_ProfilemNode_3.NodeData = '[function] Plot the EDPs distribution along the building height';
            app.Plot_EDP_ProfilemNode_3.Icon = 'icon_Function.png';
            app.Plot_EDP_ProfilemNode_3.Text = 'Plot_EDP_Profile.m';
            app.MDoFShearModelNode = uitreenode(app.Module_Response_AppmlappNode);
            app.MDoFShearModelNode.NodeData = 'Option 5: Response data based on nonlinear analysis of an MDoF shear model';
            app.MDoFShearModelNode.Icon = 'NRHA5.png';
            app.MDoFShearModelNode.Text = '[MDoF Shear Model]';
            app.Module_Response_Option5_AppmlappNode = uitreenode(app.MDoFShearModelNode);
            app.Module_Response_Option5_AppmlappNode.NodeData = '[GUI] Options 5 data defintion module';
            app.Module_Response_Option5_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_Option5_AppmlappNode.Text = 'Module_Response_Option5_App.mlapp';
            app.v2structmNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.v2structmNode.NodeData = '[function] Packs and unpacks structure variables';
            app.v2structmNode.Icon = 'icon_Function.png';
            app.v2structmNode.Text = 'v2struct.m';
            app.rmdirmNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.rmdirmNode.NodeData = '[function] Removes results directory after analysis';
            app.rmdirmNode.Icon = 'icon_Function.png';
            app.rmdirmNode.Text = 'rmdir.m';
            app.Import_ShearModel_Data_from_ExcelmNode_2 = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.Import_ShearModel_Data_from_ExcelmNode_2.NodeData = '[function] Import the MDoF model properties --> Output variables: HStory, Load, Mass, Parameters';
            app.Import_ShearModel_Data_from_ExcelmNode_2.Icon = 'icon_Function.png';
            app.Import_ShearModel_Data_from_ExcelmNode_2.Text = 'Import_ShearModel_Data_from_Excel.m';
            app.Get_EDP_RangemNode_2 = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.Get_EDP_RangemNode_2.NodeData = '[function] Gets the maximum value of the imported EDP data --> Output variables: MaxEDP';
            app.Get_EDP_RangemNode_2.Icon = 'icon_Function.png';
            app.Get_EDP_RangemNode_2.Text = 'Get_EDP_Range.m';
            app.MDoF_CREATOR_EIGENmNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.MDoF_CREATOR_EIGENmNode.NodeData = '[function] Build the .tcl file for the MDoF eigen analysis';
            app.MDoF_CREATOR_EIGENmNode.Icon = 'icon_Function.png';
            app.MDoF_CREATOR_EIGENmNode.Text = 'MDoF_CREATOR_EIGEN.m';
            app.MDoF_CREATOR_MAINmNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.MDoF_CREATOR_MAINmNode.NodeData = '[function] Build the .tcl file for the MDoF main dynamic analysis procedure';
            app.MDoF_CREATOR_MAINmNode.Icon = 'icon_Function.png';
            app.MDoF_CREATOR_MAINmNode.Text = 'MDoF_CREATOR_MAIN.m';
            app.MDoF_Run_OpenSEESmNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.MDoF_Run_OpenSEESmNode.NodeData = '[function] Runs the OpenSEES analysis and extracts response data';
            app.MDoF_Run_OpenSEESmNode.Icon = 'icon_Function.png';
            app.MDoF_Run_OpenSEESmNode.Text = 'MDoF_Run_OpenSEES.m';
            app.v2structmNode_2 = uitreenode(app.MDoF_Run_OpenSEESmNode);
            app.v2structmNode_2.NodeData = '[function] Packs and unpacks structure variables';
            app.v2structmNode_2.Icon = 'icon_Function.png';
            app.v2structmNode_2.Text = 'v2struct.m';
            app.MDoF_Get_IMmNode_2 = uitreenode(app.MDoF_Run_OpenSEESmNode);
            app.MDoF_Get_IMmNode_2.NodeData = '[function] Get the spectral acceleration of a given ground motion for the MDoF first-mode period --> Output variables: GM.GMpsaT1';
            app.MDoF_Get_IMmNode_2.Icon = 'icon_Function.png';
            app.MDoF_Get_IMmNode_2.Text = 'MDoF_Get_IM.m';
            app.MDoF_Read_DatamNode = uitreenode(app.MDoF_Run_OpenSEESmNode);
            app.MDoF_Read_DatamNode.NodeData = '[function] Read OpenSEES EDP output (recorder) files --> Output variables: DATA';
            app.MDoF_Read_DatamNode.Icon = 'icon_Function.png';
            app.MDoF_Read_DatamNode.Text = 'MDoF_Read_Data.m';
            app.MDoF_Save_Incr_DatamNode = uitreenode(app.MDoF_Run_OpenSEESmNode);
            app.MDoF_Save_Incr_DatamNode.NodeData = '[function] Save the analysis incremental step data --> Output variables: IncrDATA';
            app.MDoF_Save_Incr_DatamNode.Icon = 'icon_Function.png';
            app.MDoF_Save_Incr_DatamNode.Text = 'MDoF_Save_Incr_Data.m';
            app.MDoF_Save_IDA_DatamNode = uitreenode(app.MDoF_Run_OpenSEESmNode);
            app.MDoF_Save_IDA_DatamNode.NodeData = '[function] Save IDA data in text files';
            app.MDoF_Save_IDA_DatamNode.Icon = 'icon_Function.png';
            app.MDoF_Save_IDA_DatamNode.Text = 'MDoF_Save_IDA_Data.m';
            app.Plot_EDP_Data_Option5mNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.Plot_EDP_Data_Option5mNode.NodeData = '[function] Plot the  generated EDP data';
            app.Plot_EDP_Data_Option5mNode.Icon = 'icon_Function.png';
            app.Plot_EDP_Data_Option5mNode.Text = 'Plot_EDP_Data_Option5.m';
            app.Plot_IM_vs_EDP_Option5mNode = uitreenode(app.Plot_EDP_Data_Option5mNode);
            app.Plot_IM_vs_EDP_Option5mNode.NodeData = '[function] Plot the EDPs IDA data';
            app.Plot_IM_vs_EDP_Option5mNode.Icon = 'icon_Function.png';
            app.Plot_IM_vs_EDP_Option5mNode.Text = 'Plot_IM_vs_EDP_Option5.m';
            app.Write_EDP_Data_2Excel_Option5mNode = uitreenode(app.Module_Response_Option5_AppmlappNode);
            app.Write_EDP_Data_2Excel_Option5mNode.NodeData = '[function] Writes generated EDP data to an Excel file';
            app.Write_EDP_Data_2Excel_Option5mNode.Icon = 'icon_Function.png';
            app.Write_EDP_Data_2Excel_Option5mNode.Text = 'Write_EDP_Data_2Excel_Option5.m';
            app.FEMAP58Node = uitreenode(app.Module_Response_AppmlappNode);
            app.FEMAP58Node.NodeData = 'Option 6: Response data based on FEMA P58 Simplified Analysis method';
            app.FEMAP58Node.Icon = 'NRHA6.png';
            app.FEMAP58Node.Text = '[FEMA P58]';
            app.Module_Response_Option6_AppmlappNode = uitreenode(app.FEMAP58Node);
            app.Module_Response_Option6_AppmlappNode.NodeData = '[GUI] Options 6 data defintion module';
            app.Module_Response_Option6_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Response_Option6_AppmlappNode.Text = 'Module_Response_Option6_App.mlapp';
            app.v2structmNode_3 = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.v2structmNode_3.NodeData = '[function] Packs and unpacks structure variables';
            app.v2structmNode_3.Icon = 'icon_Function.png';
            app.v2structmNode_3.Text = 'v2struct.m';
            app.rmdirmNode_2 = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.rmdirmNode_2.NodeData = '[function] Removes results directory after analysis';
            app.rmdirmNode_2.Icon = 'icon_Function.png';
            app.rmdirmNode_2.Text = 'rmdir.m';
            app.Import_ShearModel_Data_from_ExcelmNode = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.Import_ShearModel_Data_from_ExcelmNode.NodeData = '[function] Import the MDoF model properties --> Output variables: HStory, Load, Mass, Parameters';
            app.Import_ShearModel_Data_from_ExcelmNode.Icon = 'icon_Function.png';
            app.Import_ShearModel_Data_from_ExcelmNode.Text = 'Import_ShearModel_Data_from_Excel.m';
            app.MDoF_CREATOR_EIGENmNode_2 = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.MDoF_CREATOR_EIGENmNode_2.NodeData = '[function] Build the .tcl file for the MDoF eigen analysis';
            app.MDoF_CREATOR_EIGENmNode_2.Icon = 'icon_Function.png';
            app.MDoF_CREATOR_EIGENmNode_2.Text = 'MDoF_CREATOR_EIGEN.m';
            app.MDoF_CREATOR_ELASTICmNode_2 = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.MDoF_CREATOR_ELASTICmNode_2.NodeData = '[function] Build the .tcl file for the elastic MDoF main dynamic analysis procedure';
            app.MDoF_CREATOR_ELASTICmNode_2.Icon = 'icon_Function.png';
            app.MDoF_CREATOR_ELASTICmNode_2.Text = 'MDoF_CREATOR_ELASTIC.m';
            app.FEMAP58_Get_Fx_DistributionmNode = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.FEMAP58_Get_Fx_DistributionmNode.NodeData = '[function] Get the lateral force distribution along the building height --> Output variables: Fx, S, hx, W, W1';
            app.FEMAP58_Get_Fx_DistributionmNode.Icon = 'icon_Function.png';
            app.FEMAP58_Get_Fx_DistributionmNode.Text = 'FEMAP58_Get_Fx_Distribution.m';
            app.FEMAP58_EDP_CorrectionmNode = uitreenode(app.Module_Response_Option6_AppmlappNode);
            app.FEMAP58_EDP_CorrectionmNode.NodeData = '[function] Corrects the elastic EDP values to account for nonlinear response --> Output variables: EDP_Data';
            app.FEMAP58_EDP_CorrectionmNode.Icon = 'icon_Function.png';
            app.FEMAP58_EDP_CorrectionmNode.Text = 'FEMAP58_EDP_Correction.m';
            app.Plot_EDP_Profile_Option6mNode = uitreenode(app.FEMAP58Node);
            app.Plot_EDP_Profile_Option6mNode.NodeData = '[function] Plot the EDPs distribution along the building height';
            app.Plot_EDP_Profile_Option6mNode.Icon = 'icon_Function.png';
            app.Plot_EDP_Profile_Option6mNode.Text = 'Plot_EDP_Profile_Option6.m';
            app.HazardDataNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.HazardDataNode.NodeData = 'Hazard data button';
            app.HazardDataNode.Icon = 'Hazard.png';
            app.HazardDataNode.Text = '[Hazard Data]';
            app.Module_Hazard_AppmlappNode = uitreenode(app.HazardDataNode);
            app.Module_Hazard_AppmlappNode.NodeData = '[GUI] Seismic hazard data module';
            app.Module_Hazard_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Hazard_AppmlappNode.Text = 'Module_Hazard_App.mlapp';
            app.Get_HazardCurve_USGSMapsmNode = uitreenode(app.Module_Hazard_AppmlappNode);
            app.Get_HazardCurve_USGSMapsmNode.NodeData = '[function] Extract the seismic hazard data from the USGS seismic hazard maps';
            app.Get_HazardCurve_USGSMapsmNode.Icon = 'icon_Function.png';
            app.Get_HazardCurve_USGSMapsmNode.Text = 'Get_HazardCurve_USGSMaps.m';
            app.Fit_Hazard_4PolymNode = uitreenode(app.Module_Hazard_AppmlappNode);
            app.Fit_Hazard_4PolymNode.NodeData = '[function] Fit discrete seismic hazard data with a fourth order polynomial function --> Output variables: MAF, Hazard_Sa';
            app.Fit_Hazard_4PolymNode.Icon = 'icon_Function.png';
            app.Fit_Hazard_4PolymNode.Text = 'Fit_Hazard_4Poly.m';
            app.Plot_Hazard_CurvemNode = uitreenode(app.Module_Hazard_AppmlappNode);
            app.Plot_Hazard_CurvemNode.NodeData = '[function] Plot seismic hazard curve';
            app.Plot_Hazard_CurvemNode.Icon = 'icon_Function.png';
            app.Plot_Hazard_CurvemNode.Text = 'Plot_Hazard_Curve.m';
            app.Tip_AppmlappNode = uitreenode(app.Module_Hazard_AppmlappNode);
            app.Tip_AppmlappNode.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode.Text = 'Tip_App.mlapp';
            app.DamageFragilityNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.DamageFragilityNode.Icon = 'Fragility2.png';
            app.DamageFragilityNode.Text = '[Damage Fragility]';
            app.Module_Fragility_AppmlappNode = uitreenode(app.DamageFragilityNode);
            app.Module_Fragility_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Fragility_AppmlappNode.Text = 'Module_Fragility_App.mlapp';
            app.ExploreEditFragilityDatabaseNode = uitreenode(app.Module_Fragility_AppmlappNode);
            app.ExploreEditFragilityDatabaseNode.NodeData = 'Option 1: Explore/Edit existing fragility functions';
            app.ExploreEditFragilityDatabaseNode.Icon = 'Explorer.png';
            app.ExploreEditFragilityDatabaseNode.Text = '[Explore/Edit Fragility Database]';
            app.Module_Fragility_Explorer_AppmlappNode = uitreenode(app.ExploreEditFragilityDatabaseNode);
            app.Module_Fragility_Explorer_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Fragility_Explorer_AppmlappNode.Text = 'Module_Fragility_Explorer_App.mlapp';
            app.Module_Fragility_Edit_AppmlappNode = uitreenode(app.Module_Fragility_Explorer_AppmlappNode);
            app.Module_Fragility_Edit_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Fragility_Edit_AppmlappNode.Text = 'Module_Fragility_Edit_App.mlapp';
            app.AddUnivariateFragilityNode = uitreenode(app.Module_Fragility_AppmlappNode);
            app.AddUnivariateFragilityNode.NodeData = 'Option 2: Add univariate fragility function';
            app.AddUnivariateFragilityNode.Icon = 'Univariate.png';
            app.AddUnivariateFragilityNode.Text = '[Add Univariate Fragility]';
            app.Module_Fragility_Univariate_AppmlappNode = uitreenode(app.AddUnivariateFragilityNode);
            app.Module_Fragility_Univariate_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Fragility_Univariate_AppmlappNode.Text = 'Module_Fragility_Univariate_App.mlapp';
            app.AddMultivariateFragilityNode = uitreenode(app.Module_Fragility_AppmlappNode);
            app.AddMultivariateFragilityNode.NodeData = 'Option 3: Add multivariate fragility function';
            app.AddMultivariateFragilityNode.Icon = 'Bivariate.png';
            app.AddMultivariateFragilityNode.Text = '[Add Multivariate Fragility]';
            app.Module_Fragility_Univariate_AppmlappNode_2 = uitreenode(app.AddMultivariateFragilityNode);
            app.Module_Fragility_Univariate_AppmlappNode_2.Icon = 'icon_GUI.png';
            app.Module_Fragility_Univariate_AppmlappNode_2.Text = 'Module_Fragility_Univariate_App.mlapp';
            app.PopulationModelNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.PopulationModelNode.NodeData = 'Population model button';
            app.PopulationModelNode.Icon = 'icon_population.png';
            app.PopulationModelNode.Text = '[Population Model]';
            app.Module_Population_AppmlappNode = uitreenode(app.PopulationModelNode);
            app.Module_Population_AppmlappNode.NodeData = '[GUI] Population model options and definitions';
            app.Module_Population_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Population_AppmlappNode.Text = 'Module_Population_App.mlapp';
            app.Plot_Population_ModelmNode = uitreenode(app.Module_Population_AppmlappNode);
            app.Plot_Population_ModelmNode.NodeData = '[function[ Plot the population variation with time';
            app.Plot_Population_ModelmNode.Icon = 'icon_Function.png';
            app.Plot_Population_ModelmNode.Text = 'Plot_Population_Model.m';
            app.rotateXLabelsmNode_5 = uitreenode(app.Plot_Population_ModelmNode);
            app.rotateXLabelsmNode_5.NodeData = '[function] Rotate the x-axis of a figure';
            app.rotateXLabelsmNode_5.Icon = 'icon_Function.png';
            app.rotateXLabelsmNode_5.Text = 'rotateXLabels.m';
            app.RepairTimeSchemeNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.RepairTimeSchemeNode.NodeData = 'Repair time scheme button';
            app.RepairTimeSchemeNode.Icon = 'icon_time.png';
            app.RepairTimeSchemeNode.Text = '[Repair Time Scheme]';
            app.Module_Time_AppmlappNode = uitreenode(app.RepairTimeSchemeNode);
            app.Module_Time_AppmlappNode.NodeData = '[GUI] Repair time scheme options and definitions';
            app.Module_Time_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Time_AppmlappNode.Text = 'Module_Time_App.mlapp';
            app.DemolitionNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.DemolitionNode.NodeData = 'Demolition fragility button';
            app.DemolitionNode.Icon = 'icon_Demolition.png';
            app.DemolitionNode.Text = '[Demolition]';
            app.Module_Demolition_AppmlappNode = uitreenode(app.DemolitionNode);
            app.Module_Demolition_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Demolition_AppmlappNode.Text = 'Module_Demolition_App.mlapp';
            app.Plot_Univariate_LognCDFmNode = uitreenode(app.Module_Demolition_AppmlappNode);
            app.Plot_Univariate_LognCDFmNode.NodeData = '[function] Plot the demolition univariate fragility curve';
            app.Plot_Univariate_LognCDFmNode.Icon = 'icon_Function.png';
            app.Plot_Univariate_LognCDFmNode.Text = 'Plot_Univariate_LognCDF.m';
            app.Plot_Correlated_Bivariate_LognCDFmNode = uitreenode(app.Module_Demolition_AppmlappNode);
            app.Plot_Correlated_Bivariate_LognCDFmNode.NodeData = '[function] Plot the demolition bivariate fragility curve';
            app.Plot_Correlated_Bivariate_LognCDFmNode.Icon = 'icon_Function.png';
            app.Plot_Correlated_Bivariate_LognCDFmNode.Text = 'Plot_Correlated_Bivariate_LognCDF.m';
            app.Tip_AppmlappNode_2 = uitreenode(app.Module_Demolition_AppmlappNode);
            app.Tip_AppmlappNode_2.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_2.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_2.Text = 'Tip_App.mlapp';
            app.CollapseNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.CollapseNode.NodeData = 'Collapse fragility button';
            app.CollapseNode.Icon = 'icon_Demolition.png';
            app.CollapseNode.Text = '[Collapse]';
            app.Module_Collapse_AppmlappNode = uitreenode(app.CollapseNode);
            app.Module_Collapse_AppmlappNode.NodeData = '[GUI] Collapse fragility options and definitions';
            app.Module_Collapse_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Collapse_AppmlappNode.Text = 'Module_Collapse_App.mlapp';
            app.Get_Collapse_Fragility_ParametersmNode = uitreenode(app.Module_Collapse_AppmlappNode);
            app.Get_Collapse_Fragility_ParametersmNode.NodeData = '[function] Gets the distribution parameters of the collapse fragility function --> Output variables: CollapseSDR, CPS_Option, MedianCPS, SigmaCPS, Pcollapse, PcollapseSa';
            app.Get_Collapse_Fragility_ParametersmNode.Icon = 'icon_Function.png';
            app.Get_Collapse_Fragility_ParametersmNode.Text = 'Get_Collapse_Fragility_Parameters.m';
            app.Get_Collapse_Fragility_IDAmNode = uitreenode(app.Get_Collapse_Fragility_ParametersmNode);
            app.Get_Collapse_Fragility_IDAmNode.NodeData = '[function] Deduces the collapse fragility curve from the IDA data --> Output variables: EmpDist, MedianCPS, SigmaCPS';
            app.Get_Collapse_Fragility_IDAmNode.Icon = 'icon_Function.png';
            app.Get_Collapse_Fragility_IDAmNode.Text = 'Get_Collapse_Fragility_IDA.m';
            app.Plot_Collapse_FragilitymNode = uitreenode(app.Module_Collapse_AppmlappNode);
            app.Plot_Collapse_FragilitymNode.NodeData = '[function] Plot the collapse fragility curve';
            app.Plot_Collapse_FragilitymNode.Icon = 'icon_Function.png';
            app.Plot_Collapse_FragilitymNode.Text = 'Plot_Collapse_Fragility.m';
            app.Get_Collapse_Fragility_IDAmNode_2 = uitreenode(app.Plot_Collapse_FragilitymNode);
            app.Get_Collapse_Fragility_IDAmNode_2.NodeData = '[function] Deduces the collapse fragility curve from the IDA data --> Output variables: EmpDist, MedianCPS, SigmaCPS';
            app.Get_Collapse_Fragility_IDAmNode_2.Icon = 'icon_Function.png';
            app.Get_Collapse_Fragility_IDAmNode_2.Text = 'Get_Collapse_Fragility_IDA.m';
            app.Tip_AppmlappNode_3 = uitreenode(app.Module_Collapse_AppmlappNode);
            app.Tip_AppmlappNode_3.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_3.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_3.Text = 'Tip_App.mlapp';
            app.IMEDPBinsNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.IMEDPBinsNode.Icon = 'icon_IM.png';
            app.IMEDPBinsNode.Text = '[IM/EDP Bins]';
            app.Module_IM_AppmlappNode = uitreenode(app.IMEDPBinsNode);
            app.Module_IM_AppmlappNode.NodeData = '[GUI] IM and EDP range definition';
            app.Module_IM_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_IM_AppmlappNode.Text = 'Module_IM_App.mlapp';
            app.ComputeNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.ComputeNode.NodeData = 'Compute losses button';
            app.ComputeNode.Icon = 'icon_Compute_black.png';
            app.ComputeNode.Text = '[Compute]';
            app.Module_Run_Options_AppmlappNode = uitreenode(app.ComputeNode);
            app.Module_Run_Options_AppmlappNode.NodeData = '[GUI] Loss methodology options and parameters';
            app.Module_Run_Options_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Run_Options_AppmlappNode.Text = 'Module_Run_Options_App.mlapp';
            app.Tip_AppmlappNode_5 = uitreenode(app.Module_Run_Options_AppmlappNode);
            app.Tip_AppmlappNode_5.NodeData = '[GUI] Window for showing tips and loading messages';
            app.Tip_AppmlappNode_5.Icon = 'icon_GUI.png';
            app.Tip_AppmlappNode_5.Text = 'Tip_App.mlapp';
            app.Run_Loss_CodemNode = uitreenode(app.ComputeNode);
            app.Run_Loss_CodemNode.NodeData = '[function] Exectues the proper loss code based on the selected methodology and the project definitions';
            app.Run_Loss_CodemNode.Icon = 'icon_Function.png';
            app.Run_Loss_CodemNode.Text = 'Run_Loss_Code.m';
            app.LossCode_FEMAP58_CO1mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_FEMAP58_CO1mNode.NodeData = '[function] Loss code main procedures for FEMA P58 mehtodology and manual component definition option';
            app.LossCode_FEMAP58_CO1mNode.Icon = 'icon_Function.png';
            app.LossCode_FEMAP58_CO1mNode.Text = 'LossCode_FEMAP58_CO1.m';
            app.Check_Frag_DatamNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Check_Frag_DatamNode.NodeData = '[function] Check if the fragility data of the defined building components are correctly and fully specified';
            app.Check_Frag_DatamNode.Icon = 'icon_Function.png';
            app.Check_Frag_DatamNode.Text = 'Check_Frag_Data.m';
            app.Get_REALIZATIONSmNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_REALIZATIONSmNode.NodeData = '[function] Gets the realizations EDP data --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONSmNode.Icon = 'icon_Function.png';
            app.Get_REALIZATIONSmNode.Text = 'Get_REALIZATIONS.m';
            app.Get_REALIZATIONS_Matrix_SImNode = uitreenode(app.Get_REALIZATIONSmNode);
            app.Get_REALIZATIONS_Matrix_SImNode.NodeData = '[function] Gets the realizations EDP data for the single intensity response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_SImNode.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_SImNode.Text = 'Get_REALIZATIONS_Matrix_SI.m';
            app.Get_REALIZATIONS_Matrix_MSmNode = uitreenode(app.Get_REALIZATIONSmNode);
            app.Get_REALIZATIONS_Matrix_MSmNode.NodeData = '[function] Gets the realizations EDP data for the multi-stripe response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_MSmNode.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_MSmNode.Text = 'Get_REALIZATIONS_Matrix_MS.m';
            app.Get_Collapse_Loss_Per_RimNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Collapse_Loss_Per_RimNode.NodeData = '[function] Gets the collapse loss at each realization --> Output variables: COLLAPSE_LOSSES_Per_Ri';
            app.Get_Collapse_Loss_Per_RimNode.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_RimNode.Text = 'Get_Collapse_Loss_Per_Ri.m';
            app.Get_Demolition_Loss_Per_RimNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Demolition_Loss_Per_RimNode.NodeData = '[function] Gets the demolition loss at each realization --> Output variables: DEMOLITION_LOSSES_Per_Ri';
            app.Get_Demolition_Loss_Per_RimNode.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_RimNode.Text = 'Get_Demolition_Loss_Per_Ri.m';
            app.Get_Realization_EDP_SImNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Realization_EDP_SImNode.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a single intensity response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_SImNode.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_SImNode.Text = 'Get_Realization_EDP_SI.m';
            app.Get_Realization_EDP_MSmNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Realization_EDP_MSmNode.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a multi-strip response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_MSmNode.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_MSmNode.Text = 'Get_Realization_EDP_MS.m';
            app.Get_Active_DSmNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Active_DSmNode.NodeData = '[function] Query the activated damage state for a given component at a given realization --> Output variables: ActiveDS';
            app.Get_Active_DSmNode.Icon = 'icon_Function.png';
            app.Get_Active_DSmNode.Text = 'Get_Active_DS.m';
            app.Get_DS_CostmNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_DS_CostmNode.NodeData = '[function] Gets the repair cost associated with the activated damage state --> Output variables: DS_Cost';
            app.Get_DS_CostmNode.Icon = 'icon_Function.png';
            app.Get_DS_CostmNode.Text = 'Get_DS_Cost.m';
            app.Get_DS_TimemNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_DS_TimemNode.NodeData = '[function] Gets the repair time associated with the activated damage state --> Output variables: DS_Time';
            app.Get_DS_TimemNode.Icon = 'icon_Function.png';
            app.Get_DS_TimemNode.Text = 'Get_DS_Time.m';
            app.Assemble_Deaggregated_DatamNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Assemble_Deaggregated_DatamNode.NodeData = '[function] Assembles the deaggregated loss data matrix --> Output variables: DEAGG_DATA';
            app.Assemble_Deaggregated_DatamNode.Icon = 'icon_Function.png';
            app.Assemble_Deaggregated_DatamNode.Text = 'Assemble_Deaggregated_Data.m';
            app.Get_Casualty_and_InjurymNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Casualty_and_InjurymNode.NodeData = '[function] Compute the number of casualties and injuries associated with damage --> Output variables: DEAGG_DATA';
            app.Get_Casualty_and_InjurymNode.Icon = 'icon_Function.png';
            app.Get_Casualty_and_InjurymNode.Text = 'Get_Casualty_and_Injury.m';
            app.Get_Unsafe_PlacardmNode = uitreenode(app.LossCode_FEMAP58_CO1mNode);
            app.Get_Unsafe_PlacardmNode.NodeData = '[function] Compute the potential of issuing an unsafe placard due to damage --> Output variables: DEAGG_DATA';
            app.Get_Unsafe_PlacardmNode.Icon = 'icon_Function.png';
            app.Get_Unsafe_PlacardmNode.Text = 'Get_Unsafe_Placard.m';
            app.LossCode_FEMAP58_CO2mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_FEMAP58_CO2mNode.NodeData = '[function] Loss code main procedures for FEMA P58 mehtodology and Loss-EDP fragility functions for steel buildings option';
            app.LossCode_FEMAP58_CO2mNode.Icon = 'icon_Function.png';
            app.LossCode_FEMAP58_CO2mNode.Text = 'LossCode_FEMAP58_CO2.m';
            app.Get_REALIZATIONSmNode_3 = uitreenode(app.LossCode_FEMAP58_CO2mNode);
            app.Get_REALIZATIONSmNode_3.NodeData = '[function] Gets the realizations EDP data --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONSmNode_3.Icon = 'icon_Function.png';
            app.Get_REALIZATIONSmNode_3.Text = 'Get_REALIZATIONS.m';
            app.Get_REALIZATIONS_Matrix_SImNode_2 = uitreenode(app.Get_REALIZATIONSmNode_3);
            app.Get_REALIZATIONS_Matrix_SImNode_2.NodeData = '[function] Gets the realizations EDP data for the single intensity response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_SImNode_2.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_SImNode_2.Text = 'Get_REALIZATIONS_Matrix_SI.m';
            app.Get_REALIZATIONS_Matrix_MSmNode_2 = uitreenode(app.Get_REALIZATIONSmNode_3);
            app.Get_REALIZATIONS_Matrix_MSmNode_2.NodeData = '[function] Gets the realizations EDP data for the multi-stripe response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_MSmNode_2.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_MSmNode_2.Text = 'Get_REALIZATIONS_Matrix_MS.m';
            app.Get_Collapse_Loss_Per_RimNode_2 = uitreenode(app.LossCode_FEMAP58_CO2mNode);
            app.Get_Collapse_Loss_Per_RimNode_2.NodeData = '[function] Gets the collapse loss at each realization --> Output variables: COLLAPSE_LOSSES_Per_Ri';
            app.Get_Collapse_Loss_Per_RimNode_2.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_RimNode_2.Text = 'Get_Collapse_Loss_Per_Ri.m';
            app.Get_Demolition_Loss_Per_RimNode_2 = uitreenode(app.LossCode_FEMAP58_CO2mNode);
            app.Get_Demolition_Loss_Per_RimNode_2.NodeData = '[function] Gets the demolition loss at each realization --> Output variables: DEMOLITION_LOSSES_Per_Ri';
            app.Get_Demolition_Loss_Per_RimNode_2.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_RimNode_2.Text = 'Get_Demolition_Loss_Per_Ri.m';
            app.Get_Realization_EDP_SImNode_2 = uitreenode(app.LossCode_FEMAP58_CO2mNode);
            app.Get_Realization_EDP_SImNode_2.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a single intensity response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_SImNode_2.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_SImNode_2.Text = 'Get_Realization_EDP_SI.m';
            app.Get_Realization_EDP_MSmNode_3 = uitreenode(app.LossCode_FEMAP58_CO2mNode);
            app.Get_Realization_EDP_MSmNode_3.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a multi-strip response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_MSmNode_3.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_MSmNode_3.Text = 'Get_Realization_EDP_MS.m';
            app.LossCode_FEMAP58_CO3mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_FEMAP58_CO3mNode.NodeData = '[function] Loss code main procedures for FEMA P58 mehtodology and Loss-EDP fragility functions for RC buildings option';
            app.LossCode_FEMAP58_CO3mNode.Icon = 'icon_Function.png';
            app.LossCode_FEMAP58_CO3mNode.Text = 'LossCode_FEMAP58_CO3.m';
            app.Get_REALIZATIONSmNode_4 = uitreenode(app.LossCode_FEMAP58_CO3mNode);
            app.Get_REALIZATIONSmNode_4.NodeData = '[function] Gets the realizations EDP data --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONSmNode_4.Icon = 'icon_Function.png';
            app.Get_REALIZATIONSmNode_4.Text = 'Get_REALIZATIONS.m';
            app.Get_REALIZATIONS_Matrix_SImNode_3 = uitreenode(app.Get_REALIZATIONSmNode_4);
            app.Get_REALIZATIONS_Matrix_SImNode_3.NodeData = '[function] Gets the realizations EDP data for the single intensity response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_SImNode_3.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_SImNode_3.Text = 'Get_REALIZATIONS_Matrix_SI.m';
            app.Get_REALIZATIONS_Matrix_MSmNode_3 = uitreenode(app.Get_REALIZATIONSmNode_4);
            app.Get_REALIZATIONS_Matrix_MSmNode_3.NodeData = '[function] Gets the realizations EDP data for the multi-stripe response data option --> Output variables: REALIZATIONS';
            app.Get_REALIZATIONS_Matrix_MSmNode_3.Icon = 'icon_Function.png';
            app.Get_REALIZATIONS_Matrix_MSmNode_3.Text = 'Get_REALIZATIONS_Matrix_MS.m';
            app.Get_Collapse_Loss_Per_RimNode_3 = uitreenode(app.LossCode_FEMAP58_CO3mNode);
            app.Get_Collapse_Loss_Per_RimNode_3.NodeData = '[function] Gets the collapse loss at each realization --> Output variables: COLLAPSE_LOSSES_Per_Ri';
            app.Get_Collapse_Loss_Per_RimNode_3.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_RimNode_3.Text = 'Get_Collapse_Loss_Per_Ri.m';
            app.Get_Demolition_Loss_Per_RimNode_3 = uitreenode(app.LossCode_FEMAP58_CO3mNode);
            app.Get_Demolition_Loss_Per_RimNode_3.NodeData = '[function] Gets the demolition loss at each realization --> Output variables: DEMOLITION_LOSSES_Per_Ri';
            app.Get_Demolition_Loss_Per_RimNode_3.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_RimNode_3.Text = 'Get_Demolition_Loss_Per_Ri.m';
            app.Get_Realization_EDP_SImNode_3 = uitreenode(app.LossCode_FEMAP58_CO3mNode);
            app.Get_Realization_EDP_SImNode_3.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a single intensity response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_SImNode_3.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_SImNode_3.Text = 'Get_Realization_EDP_SI.m';
            app.Get_Realization_EDP_MSmNode_4 = uitreenode(app.LossCode_FEMAP58_CO3mNode);
            app.Get_Realization_EDP_MSmNode_4.NodeData = '[function] Extract a given EDP value at a given realization from the realizations EDP data of a multi-strip response option --> Output variables: Ri_EDP ';
            app.Get_Realization_EDP_MSmNode_4.Icon = 'icon_Function.png';
            app.Get_Realization_EDP_MSmNode_4.Text = 'Get_Realization_EDP_MS.m';
            app.LossCode_PEER_CO1mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO1mNode.NodeData = '[function] Loss code main procedures for PEER mehtodology and manual component definition option';
            app.LossCode_PEER_CO1mNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO1mNode.Text = 'LossCode_PEER_CO1.m';
            app.Check_Frag_DatamNode_3 = uitreenode(app.LossCode_PEER_CO1mNode);
            app.Check_Frag_DatamNode_3.NodeData = '[function] Check if the fragility data of the defined building components are correctly and fully specified --> Output variables: errorflag';
            app.Check_Frag_DatamNode_3.Icon = 'icon_Function.png';
            app.Check_Frag_DatamNode_3.Text = 'Check_Frag_Data.m';
            app.Get_Collapse_Loss_Per_IMmNode_2 = uitreenode(app.LossCode_PEER_CO1mNode);
            app.Get_Collapse_Loss_Per_IMmNode_2.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode_2.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode_2.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode_2 = uitreenode(app.LossCode_PEER_CO1mNode);
            app.Get_Demolition_Loss_Per_IMmNode_2.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode_2.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode_2.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.Get_EDP_FragParammNode_2 = uitreenode(app.LossCode_PEER_CO1mNode);
            app.Get_EDP_FragParammNode_2.NodeData = '[function] Get the PDF of the EDP data --> Output variables: MIDPTS, DPEDP';
            app.Get_EDP_FragParammNode_2.Icon = 'icon_Function.png';
            app.Get_EDP_FragParammNode_2.Text = 'Get_EDP_FragParam.m';
            app.LossCode_PEER_CO1_IDAmNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO1_IDAmNode.NodeData = '[function] Loss code main procedures for PEER mehtodology, manual component definition and IDA data option';
            app.LossCode_PEER_CO1_IDAmNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO1_IDAmNode.Text = 'LossCode_PEER_CO1_IDA.m';
            app.Check_Frag_DatamNode_2 = uitreenode(app.LossCode_PEER_CO1_IDAmNode);
            app.Check_Frag_DatamNode_2.NodeData = '[function] Check if the fragility data of the defined building components are correctly and fully specified --> Output variables: errorflag';
            app.Check_Frag_DatamNode_2.Icon = 'icon_Function.png';
            app.Check_Frag_DatamNode_2.Text = 'Check_Frag_Data.m';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode = uitreenode(app.LossCode_PEER_CO1_IDAmNode);
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode.NodeData = '[function] Deduces the median and beta values of the IDA EDP data --> Output variables: MEDIAN_IM, SIGMA_IM';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode.Icon = 'icon_Function.png';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode.Text = 'Get_EDP_Fargiltiy_Paramters_at_IM.m';
            app.Count_Surviving_GMsmNode = uitreenode(app.Get_EDP_Fargiltiy_Paramters_at_IMmNode);
            app.Count_Surviving_GMsmNode.NodeData = '[function] Count the survining ground motion records (where the building did not collapse) at a given IM --> Output variables: countSurvive, EDP_survive, INDX_LAST_SA_ALL_SURVIVE, INDX_FIRST_SA_NO_SURVIVE';
            app.Count_Surviving_GMsmNode.Icon = 'icon_Function.png';
            app.Count_Surviving_GMsmNode.Text = 'Count_Surviving_GMs.m';
            app.Get_Collapse_Loss_Per_IMmNode_5 = uitreenode(app.LossCode_PEER_CO1_IDAmNode);
            app.Get_Collapse_Loss_Per_IMmNode_5.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode_5.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode_5.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode_4 = uitreenode(app.LossCode_PEER_CO1_IDAmNode);
            app.Get_Demolition_Loss_Per_IMmNode_4.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode_4.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode_4.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.Get_EDP_FragParam_IMmNode = uitreenode(app.LossCode_PEER_CO1_IDAmNode);
            app.Get_EDP_FragParam_IMmNode.NodeData = '[function] Get the PDF of the EDP data at each IM --> Output variables: MIDPTS, DPEDP';
            app.Get_EDP_FragParam_IMmNode.Icon = 'icon_Function.png';
            app.Get_EDP_FragParam_IMmNode.Text = 'Get_EDP_FragParam_IM.m';
            app.LossCode_PEER_CO2mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO2mNode.NodeData = '[function] Loss code main procedures for PEER mehtodology and Loss-EDP fragility functions for steel buildings option';
            app.LossCode_PEER_CO2mNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO2mNode.Text = 'LossCode_PEER_CO2.m';
            app.Get_Collapse_Loss_Per_IMmNode = uitreenode(app.LossCode_PEER_CO2mNode);
            app.Get_Collapse_Loss_Per_IMmNode.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode = uitreenode(app.LossCode_PEER_CO2mNode);
            app.Get_Demolition_Loss_Per_IMmNode.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.LossCode_PEER_CO2_IDAmNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO2_IDAmNode.NodeData = '[function] Loss code main procedures for PEER mehtodology, Loss-EDP fragility functions for steel buildings and IDA data option';
            app.LossCode_PEER_CO2_IDAmNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO2_IDAmNode.Text = 'LossCode_PEER_CO2_IDA.m';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_2 = uitreenode(app.LossCode_PEER_CO2_IDAmNode);
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_2.NodeData = '[function] Deduces the median and beta values of the IDA EDP data --> Output variables: MEDIAN_IM, SIGMA_IM';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_2.Icon = 'icon_Function.png';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_2.Text = 'Get_EDP_Fargiltiy_Paramters_at_IM.m';
            app.Get_Collapse_Loss_Per_IMmNode_6 = uitreenode(app.LossCode_PEER_CO2_IDAmNode);
            app.Get_Collapse_Loss_Per_IMmNode_6.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode_6.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode_6.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode_5 = uitreenode(app.LossCode_PEER_CO2_IDAmNode);
            app.Get_Demolition_Loss_Per_IMmNode_5.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode_5.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode_5.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.LossCode_PEER_CO3mNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO3mNode.NodeData = '[function] Loss code main procedures for PEER mehtodology and Loss-EDP fragility functions for RC buildings option';
            app.LossCode_PEER_CO3mNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO3mNode.Text = 'LossCode_PEER_CO3.m';
            app.Get_Collapse_Loss_Per_IMmNode_3 = uitreenode(app.LossCode_PEER_CO3mNode);
            app.Get_Collapse_Loss_Per_IMmNode_3.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode_3.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode_3.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode_3 = uitreenode(app.LossCode_PEER_CO3mNode);
            app.Get_Demolition_Loss_Per_IMmNode_3.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode_3.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode_3.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.LossCode_PEER_CO3_IDAmNode = uitreenode(app.Run_Loss_CodemNode);
            app.LossCode_PEER_CO3_IDAmNode.NodeData = '[function] Loss code main procedures for PEER mehtodology, Loss-EDP fragility functions for RC buildings and IDA data option';
            app.LossCode_PEER_CO3_IDAmNode.Icon = 'icon_Function.png';
            app.LossCode_PEER_CO3_IDAmNode.Text = 'LossCode_PEER_CO3_IDA.m';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_3 = uitreenode(app.LossCode_PEER_CO3_IDAmNode);
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_3.NodeData = '[function] Deduces the median and beta values of the IDA EDP data --> Output variables: MEDIAN_IM, SIGMA_IM';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_3.Icon = 'icon_Function.png';
            app.Get_EDP_Fargiltiy_Paramters_at_IMmNode_3.Text = 'Get_EDP_Fargiltiy_Paramters_at_IM.m';
            app.Get_Collapse_Loss_Per_IMmNode_7 = uitreenode(app.LossCode_PEER_CO3_IDAmNode);
            app.Get_Collapse_Loss_Per_IMmNode_7.NodeData = '[function] Gets the collapse loss at each IM --> Output variables: COLLAPSE_LOSSES_Per_IM, Pr_Collapse_per_IM';
            app.Get_Collapse_Loss_Per_IMmNode_7.Icon = 'icon_Function.png';
            app.Get_Collapse_Loss_Per_IMmNode_7.Text = 'Get_Collapse_Loss_Per_IM.m';
            app.Get_Demolition_Loss_Per_IMmNode_6 = uitreenode(app.LossCode_PEER_CO3_IDAmNode);
            app.Get_Demolition_Loss_Per_IMmNode_6.NodeData = '[function] Gets the demolition loss at each IM --> Output variables: DEMOLITION_LOSSES_Per_IM, Pr_Demolition_per_IM';
            app.Get_Demolition_Loss_Per_IMmNode_6.Icon = 'icon_Function.png';
            app.Get_Demolition_Loss_Per_IMmNode_6.Text = 'Get_Demolition_Loss_Per_IM.m';
            app.VisualizeNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.VisualizeNode.NodeData = 'Visulaize data button';
            app.VisualizeNode.Icon = 'icon_Visulaize_black.png';
            app.VisualizeNode.Text = '[Visualize]';
            app.Module_Visualize_AppmlappNode = uitreenode(app.VisualizeNode);
            app.Module_Visualize_AppmlappNode.NodeData = '[GUI] Visulaize loss data options';
            app.Module_Visualize_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Visualize_AppmlappNode.Text = 'Module_Visualize_App.mlapp';
            app.Module_SummaryPlot_PEER_AppNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Module_SummaryPlot_PEER_AppNode.NodeData = '[GUI] Interactive summarized loss data plots for PEER computations';
            app.Module_SummaryPlot_PEER_AppNode.Icon = 'icon_GUI.png';
            app.Module_SummaryPlot_PEER_AppNode.Text = 'Module_SummaryPlot_PEER_App';
            app.Write2FilemNode_2 = uitreenode(app.Module_SummaryPlot_PEER_AppNode);
            app.Write2FilemNode_2.NodeData = '[function] Write X-Y plot data to text files';
            app.Write2FilemNode_2.Icon = 'icon_Function.png';
            app.Write2FilemNode_2.Text = 'Write2File.m';
            app.rotateXLabelsmNode_2 = uitreenode(app.Module_SummaryPlot_PEER_AppNode);
            app.rotateXLabelsmNode_2.NodeData = '[function] Rotates the x-axis of a figure';
            app.rotateXLabelsmNode_2.Icon = 'icon_Function.png';
            app.rotateXLabelsmNode_2.Text = 'rotateXLabels.m';
            app.Module_SummaryPlot_FEMAP58_AppNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Module_SummaryPlot_FEMAP58_AppNode.NodeData = '[GUI] Interactive summarized loss data plots for FEMA P58 computations';
            app.Module_SummaryPlot_FEMAP58_AppNode.Icon = 'icon_GUI.png';
            app.Module_SummaryPlot_FEMAP58_AppNode.Text = 'Module_SummaryPlot_FEMAP58_App';
            app.Get_Repair_Cost_Per_RimNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Get_Repair_Cost_Per_RimNode.NodeData = '[function] Process the DEAGG_DATA matrix and deduces the repair cost at each realization --> Output variables: Realization_Filtered, REPAIR_COST_Per_Ri, REPAIR_COST_Per_Ri_Filtered';
            app.Get_Repair_Cost_Per_RimNode.Icon = 'icon_Function.png';
            app.Get_Repair_Cost_Per_RimNode.Text = 'Get_Repair_Cost_Per_Ri.m';
            app.Get_Repair_Time_Per_RimNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Get_Repair_Time_Per_RimNode.NodeData = '[function] Process the DEAGG_DATA matrix and deduces the repair time at each realization --> Output variables: Realization_Filtered, REPAIR_TIME_Per_Ri, REPAIR_TIME_Per_Ri_Filtered';
            app.Get_Repair_Time_Per_RimNode.Icon = 'icon_Function.png';
            app.Get_Repair_Time_Per_RimNode.Text = 'Get_Repair_Time_Per_Ri.m';
            app.Get_Repair_Cost_Profile_by_ComponentmNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Get_Repair_Cost_Profile_by_ComponentmNode.NodeData = '[function] Process the DEAGG_DATA matrix and deduces the component''s repair cost distribution at each floor at each realization --> Output variables: Cost_Loss_Per_Component_Per_Story, Clabel';
            app.Get_Repair_Cost_Profile_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Get_Repair_Cost_Profile_by_ComponentmNode.Text = 'Get_Repair_Cost_Profile_by_Component.m';
            app.Get_Repair_Cost_Breakdown_by_ComponentmNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Get_Repair_Cost_Breakdown_by_ComponentmNode.NodeData = '[function] Process the DEAGG_DATA matrix and deduces the component''s repair cost distribution at each realization --> Output variables: Cost_Loss_Per_Component, Clabel';
            app.Get_Repair_Cost_Breakdown_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Get_Repair_Cost_Breakdown_by_ComponentmNode.Text = 'Get_Repair_Cost_Breakdown_by_Component.m';
            app.Get_Repair_Time_Per_ComponentmNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Get_Repair_Time_Per_ComponentmNode.NodeData = '[function] Process the DEAGG_DATA matrix and deduces the component''s repair time distribution at each floor at each realization --> Output variables: Time_Loss_Per_Component, Time_Loss_Per_Component_Per_Story';
            app.Get_Repair_Time_Per_ComponentmNode.Icon = 'icon_Function.png';
            app.Get_Repair_Time_Per_ComponentmNode.Text = 'Get_Repair_Time_Per_Component.m';
            app.Write2FilemNode = uitreenode(app.Module_SummaryPlot_FEMAP58_AppNode);
            app.Write2FilemNode.NodeData = '[function] Write X-Y plot data to text files';
            app.Write2FilemNode.Icon = 'icon_Function.png';
            app.Write2FilemNode.Text = 'Write2File.m';
            app.Plot_VulnerabilityCurves_EventmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_VulnerabilityCurves_EventmNode.NodeData = '[function] Plot the cost vulnerability curves among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Plot_VulnerabilityCurves_EventmNode.Icon = 'icon_Function.png';
            app.Plot_VulnerabilityCurves_EventmNode.Text = 'Plot_VulnerabilityCurves_Event.m';
            app.Plot_VulnerabilityCurves_ComponentmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_VulnerabilityCurves_ComponentmNode.NodeData = '[function] Plot the cost vulnerability curves among the different building components';
            app.Plot_VulnerabilityCurves_ComponentmNode.Icon = 'icon_Function.png';
            app.Plot_VulnerabilityCurves_ComponentmNode.Text = 'Plot_VulnerabilityCurves_Component.m';
            app.Plot_RepairCost_vs_RealizationmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairCost_vs_RealizationmNode.NodeData = '[function] Plot the scatter of repair cost versus realizations';
            app.Plot_RepairCost_vs_RealizationmNode.Icon = 'icon_Function.png';
            app.Plot_RepairCost_vs_RealizationmNode.Text = 'Plot_RepairCost_vs_Realization.m';
            app.Plot_RepairTime_vs_RealizationmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairTime_vs_RealizationmNode.NodeData = '[function] Plot the scatter of repair time versus realizations';
            app.Plot_RepairTime_vs_RealizationmNode.Icon = 'icon_Function.png';
            app.Plot_RepairTime_vs_RealizationmNode.Text = 'Plot_RepairTime_vs_Realization.m';
            app.Plot_LossBreakdown_by_EventmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_LossBreakdown_by_EventmNode.NodeData = '[function] Plot the a bar chart breakdown of the repair cost among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Plot_LossBreakdown_by_EventmNode.Icon = 'icon_Function.png';
            app.Plot_LossBreakdown_by_EventmNode.Text = 'Plot_LossBreakdown_by_Event.m';
            app.rotateXLabelsmNode_4 = uitreenode(app.Plot_LossBreakdown_by_EventmNode);
            app.rotateXLabelsmNode_4.Text = 'rotateXLabels.m';
            app.Plot_RepairCostProfile_by_TypemNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairCostProfile_by_TypemNode.NodeData = '[function] Plot the repair cost distribution along the height for different building component types (SC, NSC-SDR and NSC-ACC)';
            app.Plot_RepairCostProfile_by_TypemNode.Icon = 'icon_Function.png';
            app.Plot_RepairCostProfile_by_TypemNode.Text = 'Plot_RepairCostProfile_by_Type.m';
            app.Plot_RepairTimeProfile_by_TypemNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairTimeProfile_by_TypemNode.NodeData = '[function] Plot the repair time distribution along the height for different building component types (SC, NSC-SDR and NSC-ACC)';
            app.Plot_RepairTimeProfile_by_TypemNode.Icon = 'icon_Function.png';
            app.Plot_RepairTimeProfile_by_TypemNode.Text = 'Plot_RepairTimeProfile_by_Type.m';
            app.Plot_RepairCostProfile_by_ComponentmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairCostProfile_by_ComponentmNode.NodeData = '[function] Plot the repair cost distribution along the height for different building components';
            app.Plot_RepairCostProfile_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Plot_RepairCostProfile_by_ComponentmNode.Text = 'Plot_RepairCostProfile_by_Component.m';
            app.rotateXLabelsmNode_6 = uitreenode(app.Plot_RepairCostProfile_by_ComponentmNode);
            app.rotateXLabelsmNode_6.NodeData = '[function] Rotates the x-axis of a figure';
            app.rotateXLabelsmNode_6.Icon = 'icon_Function.png';
            app.rotateXLabelsmNode_6.Text = 'rotateXLabels.m';
            app.Plot_RepairTimeProfile_by_ComponentmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairTimeProfile_by_ComponentmNode.NodeData = '[function] Plot the repair time distribution along the height for different building components';
            app.Plot_RepairTimeProfile_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Plot_RepairTimeProfile_by_ComponentmNode.Text = 'Plot_RepairTimeProfile_by_Component.m';
            app.rotateXLabelsmNode_7 = uitreenode(app.Plot_RepairTimeProfile_by_ComponentmNode);
            app.rotateXLabelsmNode_7.Text = 'rotateXLabels.m';
            app.Plot_RepairCostBreakdown_by_ComponentmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairCostBreakdown_by_ComponentmNode.NodeData = '[function] Plot the a bar chart breakdown of the repair cost among building components';
            app.Plot_RepairCostBreakdown_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Plot_RepairCostBreakdown_by_ComponentmNode.Text = 'Plot_RepairCostBreakdown_by_Component.m';
            app.Plot_RepairTimeBreakdown_by_ComponentmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_RepairTimeBreakdown_by_ComponentmNode.NodeData = '[function] Plot the a bar chart breakdown of the repair time among building components';
            app.Plot_RepairTimeBreakdown_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Plot_RepairTimeBreakdown_by_ComponentmNode.Text = 'Plot_RepairTimeBreakdown_by_Component.m';
            app.Plot_EALmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_EALmNode.NodeData = '[function] Plot a bar chart breakdown of expected annual losses (EAL) among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Plot_EALmNode.Icon = 'icon_Function.png';
            app.Plot_EALmNode.Text = 'Plot_EAL.m';
            app.rotateXLabelsmNode_3 = uitreenode(app.Plot_EALmNode);
            app.rotateXLabelsmNode_3.NodeData = '[function] Rotates the x-axis of a figure';
            app.rotateXLabelsmNode_3.Icon = 'icon_Function.png';
            app.rotateXLabelsmNode_3.Text = 'rotateXLabels.m';
            app.Plot_LAMBDAmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.Plot_LAMBDAmNode.NodeData = '[function] Plot the rate of excceding a given repair cost';
            app.Plot_LAMBDAmNode.Icon = 'icon_Function.png';
            app.Plot_LAMBDAmNode.Text = 'Plot_LAMBDA.m';
            app.rotateXLabelsmNode = uitreenode(app.Module_Visualize_AppmlappNode);
            app.rotateXLabelsmNode.NodeData = '[function] Rotates the x-axis of a figure';
            app.rotateXLabelsmNode.Icon = 'icon_Function.png';
            app.rotateXLabelsmNode.Text = 'rotateXLabels.m';
            app.ReportNode = uitreenode(app.RootEaRL_AppmlappNode);
            app.ReportNode.NodeData = 'Report data button';
            app.ReportNode.Icon = 'icon_Report_black.png';
            app.ReportNode.Text = '[Report]';
            app.Module_Report_AppmlappNode = uitreenode(app.ReportNode);
            app.Module_Report_AppmlappNode.NodeData = '[GUI] Report loss data options';
            app.Module_Report_AppmlappNode.Icon = 'icon_GUI.png';
            app.Module_Report_AppmlappNode.Text = 'Module_Report_App.mlapp';
            app.Get_Project_SummarymNode_2 = uitreenode(app.Module_Report_AppmlappNode);
            app.Get_Project_SummarymNode_2.NodeData = '[function] Gets the summary data in text format --> Output variable: SummaryText';
            app.Get_Project_SummarymNode_2.Icon = 'icon_Function.png';
            app.Get_Project_SummarymNode_2.Text = 'Get_Project_Summary.m';
            app.Get_Summarized_DatamNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Get_Summarized_DatamNode.NodeData = '[function] Gets the summary data of the loss results and the realizations EDPs --> DEAGGREGATED_LOSS_DATA, REALIZATIONS_EDP_DATA, DEAGGREGATED_LOSS_DATA_Titles';
            app.Get_Summarized_DatamNode.Icon = 'icon_Function.png';
            app.Get_Summarized_DatamNode.Text = 'Get_Summarized_Data.m';
            app.Report_RepairCost_vs_RealizationmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairCost_vs_RealizationmNode.NodeData = '[function] Report the repair cost versus realizations number data';
            app.Report_RepairCost_vs_RealizationmNode.Icon = 'icon_Function.png';
            app.Report_RepairCost_vs_RealizationmNode.Text = 'Report_RepairCost_vs_Realization.m';
            app.Report_RepairTime_vs_RealizationmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairTime_vs_RealizationmNode.NodeData = '[function] Report the repair time versus realizations number data';
            app.Report_RepairTime_vs_RealizationmNode.Icon = 'icon_Function.png';
            app.Report_RepairTime_vs_RealizationmNode.Text = 'Report_RepairTime_vs_Realization.m';
            app.Report_VulnerabilityCurvesmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_VulnerabilityCurvesmNode.NodeData = '[function] Report the cost vulnerability curves among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Report_VulnerabilityCurvesmNode.Icon = 'icon_Function.png';
            app.Report_VulnerabilityCurvesmNode.Text = 'Report_VulnerabilityCurves.m';
            app.Report_LossBreakdown_by_EventmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_LossBreakdown_by_EventmNode.NodeData = '[function] Report the a bar chart breakdown of the repair cost among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Report_LossBreakdown_by_EventmNode.Icon = 'icon_Function.png';
            app.Report_LossBreakdown_by_EventmNode.Text = 'Report_LossBreakdown_by_Event.m';
            app.Report_RepairCostBreakdown_by_ComponentmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairCostBreakdown_by_ComponentmNode.NodeData = '[function] Report the breakdown of the repair cost among building components';
            app.Report_RepairCostBreakdown_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Report_RepairCostBreakdown_by_ComponentmNode.Text = 'Report_RepairCostBreakdown_by_Component.m';
            app.Report_RepairTimeBreakdown_by_ComponentmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairTimeBreakdown_by_ComponentmNode.NodeData = '[function] Report the breakdown of the repair time among building components';
            app.Report_RepairTimeBreakdown_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Report_RepairTimeBreakdown_by_ComponentmNode.Text = 'Report_RepairTimeBreakdown_by_Component.m';
            app.Report_RepairCostProfile_by_ComponentmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairCostProfile_by_ComponentmNode.NodeData = '[function] Repair the repair cost distribution along the height for different building components';
            app.Report_RepairCostProfile_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Report_RepairCostProfile_by_ComponentmNode.Text = 'Report_RepairCostProfile_by_Component.m';
            app.Report_RepairTimeProfile_by_ComponentmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairTimeProfile_by_ComponentmNode.NodeData = '[function] Report the repair time distribution along the height for different building components';
            app.Report_RepairTimeProfile_by_ComponentmNode.Icon = 'icon_Function.png';
            app.Report_RepairTimeProfile_by_ComponentmNode.Text = 'Report_RepairTimeProfile_by_Component.m';
            app.Report_RepairCostProfile_by_TypemNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairCostProfile_by_TypemNode.NodeData = '[function] Report the repair cost distribution along the height for different building components';
            app.Report_RepairCostProfile_by_TypemNode.Icon = 'icon_Function.png';
            app.Report_RepairCostProfile_by_TypemNode.Text = 'Report_RepairCostProfile_by_Type.m';
            app.Report_RepairTimeProfile_by_TypemNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_RepairTimeProfile_by_TypemNode.NodeData = '[function] Report the repair time distribution along the height for different building components';
            app.Report_RepairTimeProfile_by_TypemNode.Icon = 'icon_Function.png';
            app.Report_RepairTimeProfile_by_TypemNode.Text = 'Report_RepairTimeProfile_by_Type.m';
            app.Report_EALmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_EALmNode.NodeData = '[function] Report the breakdown of expected annual losses (EAL) among the different events (Collapse, Demolition, SC, NSC-SDR, , NSC-ACC)';
            app.Report_EALmNode.Icon = 'icon_Function.png';
            app.Report_EALmNode.Text = 'Report_EAL.m';
            app.Report_LAMBDAmNode = uitreenode(app.Module_Report_AppmlappNode);
            app.Report_LAMBDAmNode.NodeData = '[function] Report the rate of excceding a given repair cost';
            app.Report_LAMBDAmNode.Icon = 'icon_Function.png';
            app.Report_LAMBDAmNode.Text = 'Report_LAMBDA.m';
            app.VariablesTab = uitab(app.TabGroup);
            app.VariablesTab.Title = 'Variables';
            app.UITable = uitable(app.VariablesTab);
            app.UITable.ColumnName = {'Name'; 'Type'; 'Parent Modules/Functions'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SorttablebyLabel = uilabel(app.VariablesTab);
            app.SorttablebyLabel.Text = 'Sort table by';
            app.DropDown = uidropdown(app.VariablesTab);
            app.DropDown.Items = {'Variable name', 'Parent Module/Function'};
            app.DropDown.ItemsData = {'1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Developers_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @ForDevelopers_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end