classdef EaRL_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        UnlockButton      matlab.ui.control.Button
        RegisterButton    matlab.ui.control.Button
        CodeButton        matlab.ui.control.Button
        ProgressBar       matlab.ui.control.EditField
        Image5            matlab.ui.control.Image
        ResetButton       matlab.ui.control.Button
        text27            matlab.ui.control.Label
        AboutButton       matlab.ui.control.Button
        HelpButton        matlab.ui.control.Button
        Image_5           matlab.ui.control.Image
        uipanel7          matlab.ui.container.Panel
        IMButton          matlab.ui.control.Button
        Image_6           matlab.ui.control.Image
        Image_10          matlab.ui.control.Image
        TimeButton        matlab.ui.control.Button
        Image_9           matlab.ui.control.Image
        Image_8           matlab.ui.control.Image
        PopulationButton  matlab.ui.control.Button
        Image_7           matlab.ui.control.Image
        status30          matlab.ui.control.Label
        status20          matlab.ui.control.Label
        status10          matlab.ui.control.Label
        CollapseButton    matlab.ui.control.Button
        DemolitionButton  matlab.ui.control.Button
        FragilityButton   matlab.ui.control.Button
        text26            matlab.ui.control.Label
        ComputePanel      matlab.ui.container.Panel
        ReportButton      matlab.ui.control.Button
        VisualizeButton   matlab.ui.control.Button
        ComputeButton     matlab.ui.control.Button
        ProgressText      matlab.ui.control.EditField
        DefinePanel       matlab.ui.container.Panel
        Image_3           matlab.ui.control.Image
        Image_2           matlab.ui.control.Image
        Image             matlab.ui.control.Image
        uipanel6          matlab.ui.container.Panel
        HazardButton      matlab.ui.control.Button
        Image_4           matlab.ui.control.Image
        status3           matlab.ui.control.Label
        status3b          matlab.ui.control.Label
        uipanel5          matlab.ui.container.Panel
        ResponseButton    matlab.ui.control.Button
        status4           matlab.ui.control.Label
        status4b          matlab.ui.control.Label
        uipanel4          matlab.ui.container.Panel
        ComponentButton   matlab.ui.control.Button
        status2           matlab.ui.control.Label
        status2b          matlab.ui.control.Label
        uipanel3          matlab.ui.container.Panel
        BuildingButton    matlab.ui.control.Button
        status1           matlab.ui.control.Label
        status1b          matlab.ui.control.Label
        uipanel9          matlab.ui.container.Panel
        ScopeButton       matlab.ui.control.Button
        SaveAsButton      matlab.ui.control.Button
        OpenButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.ProgressText.FontColor=[0 1 0];
            app.ProgressText.Value=YourString;
            drawnow();
            
            for i=1:nTimes
                app.ProgressText.Value='';
                drawnow();
                pause(pauseTime);
                app.ProgressText.Value=YourString;
                drawnow();
                pause(pauseTime);
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EaRL_OpeningFcn(app)
            global MainDirectory resource_root
            
            clc; fclose all;
            
            %resource_root="resources\images\";
            resource_root="";

            if   resource_root==''; [y, Fs] = audioread(strjoin(resource_root+"sound-start.mp3"));    player = audioplayer(y, Fs); play(player); pause(1); 
            else                    [y, Fs] = audioread(strjoin("resources\sounds\sound-start.mp3")); player = audioplayer(y, Fs); play(player); pause(1); end            

            app.figure1.Icon        = strjoin(resource_root+"ICON.png");
            app.Image5.ImageSource  = strjoin(resource_root+"Transparent.png");
            app.Image.ImageSource   = strjoin(resource_root+"Components.png"); 
            app.Image_2.ImageSource = strjoin(resource_root+"Building.png"); 
            app.Image_3.ImageSource = strjoin(resource_root+"Response.png"); 
            app.Image_4.ImageSource = strjoin(resource_root+"Hazard.png"); 
            app.Image_5.ImageSource = strjoin(resource_root+"Banner.png"); 
            app.Image_6.ImageSource = strjoin(resource_root+"Options.png"); 
            app.Image_7.ImageSource = strjoin(resource_root+"Fragility2.png"); 
            app.Image_8.ImageSource = strjoin(resource_root+"Population.png"); 
            app.Image_9.ImageSource = strjoin(resource_root+"RepairTime.png"); 
            app.ScopeButton.Icon    = strjoin(resource_root+"output-onlinepngtools2.png"); 
            app.ComputeButton.Icon  = strjoin(resource_root+"icon_Compute.png"); 
            app.VisualizeButton.Icon= strjoin(resource_root+"icon_Visualize.png"); 
            app.ReportButton.Icon   = strjoin(resource_root+"icon_Report.png"); 
            app.RegisterButton.Icon = strjoin(resource_root+"icon_Register2.png"); 
            app.CodeButton.Icon     = strjoin(resource_root+"icon_Code2.png"); 
            app.HelpButton.Icon     = strjoin(resource_root+"icon_Help.png"); 
            app.UnlockButton.Icon   = strjoin(resource_root+"lock.png"); 

            app.Image5.Visible          = 'off';
            app.StartButton.Enable      = 'on';
            app.OpenButton.Enable       = 'on';
            app.SaveAsButton.Enable     = 'off';
            app.BuildingButton.Enable   = 'off';
            app.ComponentButton.Enable  = 'off';
            app.ResponseButton.Enable   = 'off';
            app.HazardButton.Enable     = 'off';
            app.FragilityButton.Enable  = 'off';
            app.PopulationButton.Enable = 'off';
            app.IMButton.Enable         = 'off';
            app.DemolitionButton.Enable = 'off';
            app.CollapseButton.Enable   = 'off';
            app.ComputeButton.Enable    = 'off';
            app.VisualizeButton.Enable  = 'off';
            app.ReportButton.Enable     = 'off';
            app.ScopeButton.Enable      = 'off';
            app.TimeButton.Enable       = 'off';
            
            app.status1.BackgroundColor  = [0.7 0.7 0.7];
            app.status2.BackgroundColor  = [0.7 0.7 0.7];
            app.status3.BackgroundColor  = [0.7 0.7 0.7];
            app.status4.BackgroundColor  = [0.7 0.7 0.7];
            app.status1b.BackgroundColor = [0.7 0.7 0.7];
            app.status2b.BackgroundColor = [0.7 0.7 0.7];
            app.status3b.BackgroundColor = [0.7 0.7 0.7];
            app.status4b.BackgroundColor = [0.7 0.7 0.7];
            
            app.status1.FontColor='w';
            app.status1.Text='status: not defined';
            app.status2.FontColor='w';
            app.status2.Text='status: not defined';
            app.status3.FontColor='w';
            app.status3.Text='status: not defined';
            app.status4.FontColor='w';
            app.status4.Text='status: not defined';
            
            app.status10.BackgroundColor = [0.6 0.6 0.6];
            app.status20.BackgroundColor = [0.6 0.6 0.6];
            app.status30.BackgroundColor = [0.6 0.6 0.6];
            
            app.ProgressText.FontColor='g';
            app.ProgressBar.Position=[9 5 613 6];
            app.ProgressBar.BackgroundColor=[0.6 0.6 0.6];
            
            BLINK_TEXT (app,'© A. ELKADY and D. LIGNOS (2020)', 0.2, 2)
            
        end

        % Button pushed function: AboutButton
        function AboutButton_Callback(app, event)
            About_App
        end

        % Button pushed function: BuildingButton
        function BuildingButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_Building_App(app)
        end

        % Button pushed function: CollapseButton
        function CollapseButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_Collapse_App(app)
        end

        % Button pushed function: ComponentButton
        function ComponentButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_Component_App(app)
        end

        % Button pushed function: ComputeButton
        function ComputeButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName RunMethodology resource_root
            clc;
            
            app.Image5.Visible='on';
            waitfor(Module_Run_Options_App(app))
            
            try
                
                if RunMethodology~=0
                    tic
                    
                    Run_Loss_Code(app)
                    
                    ResultsStatus=1;
                    save(strcat(ProjectPath,ProjectName),'ResultsStatus','-append')
                    
                    if   resource_root==''; [y, Fs] = audioread(strjoin(resource_root+"sound-done.mp3"));    player = audioplayer(y, Fs); play(player); pause(3); 
                    else                    [y, Fs] = audioread(strjoin("resources\sounds\sound-done.mp3")); player = audioplayer(y, Fs); play(player); pause(3); end
            
                    if toc>60; ETime=round(toc*10/60)/10; str=' min'; else;  ETime=round(toc); str=' sec'; end
                    app.ProgressText.Value=['DONE - Run Time: ', num2str(ETime), str];
                end
                
                app.Image5.Visible='off';
                
            catch ME
                
                app.Image5.Visible='off';
                app.ProgressText.Value='Oops| A problem has occured! - An error file has been created';
                app.ProgressText.FontColor='r';
                app.ProgressBar.BackgroundColor='r';
                app.ProgressBar.Position=[9 5 613 6];
                cd (MainDirectory)
                write_Error(ME);
                
            end
            
        end

        % Button pushed function: DemolitionButton
        function DemolitionButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_Demolition_App(app)
        end

        % Button pushed function: FragilityButton
        function FragilityButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_Fragility_App(app)
        end

        % Button pushed function: HazardButton
        function HazardButton_Callback(app, event)
            global  ProjectName ProjectPath
            clc;
            load(strcat(ProjectPath,ProjectName),'ResponseDataStatus')
            if ResponseDataStatus == 0
               errordlg('Please define the Response Data first!');
               return
            else
               Module_Hazard_App(app)
            end
        end

        % Button pushed function: HelpButton
        function HelpButton_Callback(app, event)
            global MainDirectory
            cd (MainDirectory)
            filex='EaRL Manual.pdf';
            
            if ispc;  winopen(filex);           end
            if ismac; system(['open ' filex ]); end
        end

        % Button pushed function: IMButton
        function IMButton_Callback(app, event)
            Module_IM_App(app)
        end

        % Button pushed function: OpenButton
        function OpenButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName Units
            clc;
            % Get current directory and user-specified project name
            MainDirectory=pwd;
            [ProjectName,ProjectPath] = uigetfile('*.mat','Select the Project .mat file');
            cd(MainDirectory)
            
            if  isempty(ProjectName) || ischar(ProjectName)==0
                return
            end
            
            if exist(ProjectPath) ==0
                d=errordlg('Project does not exist in current directory!','Error','modal');
                uiwait(d);
            else
                save(strcat(ProjectPath,ProjectName),'MainDirectory','ProjectPath','ProjectName','-append');
                load(strcat(ProjectPath,ProjectName),'BuildingDataStatus','ComponentDataStatus','ResponseDataStatus','HazardDataStatus','IMstatus','DemolitionStatus','CollapseStatus','LossDataStatus','Component_Option','N_Story','Response_Option','Units');
                
                if BuildingDataStatus==1
                    app.status1.BackgroundColor='g';
                    app.status1.FontColor='k';
                    app.status1.Text=[num2str(N_Story),'-story building'];
                    app.status1b.BackgroundColor='g';
                    app.BuildingButton.Enable='on';
                    app.ComponentButton.Enable='on';
                    app.FragilityButton.Enable='on';
                    app.PopulationButton.Enable='on';
                end
                if ComponentDataStatus==1
                    if Component_Option==1
                        app.status2.BackgroundColor='g';
                        app.status2.FontColor='k';
                        app.status2.Text='Manually defined';
                        app.TimeButton.Enable='on';
                    else
                        app.status2.BackgroundColor='g';
                        app.status2.FontColor='k';
                        app.status2.Text='Loss-EDP Function';
                        app.TimeButton.Enable='off';
                    end
                    app.status2b.BackgroundColor='g';
                    app.ComponentButton.Enable='on';
                    app.FragilityButton.Enable='on';
                    app.ResponseButton.Enable='on';
                end
                if ResponseDataStatus==1
                    if Response_Option==1
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='IDA Data';
                    elseif Response_Option==2
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='Single Intensity - GMs';
                    elseif Response_Option==3
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='Single Intensity - DPs';
                    elseif Response_Option==4
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='Multiple Stripes';
                    elseif Response_Option==5
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='MDoF Model';
                    elseif Response_Option==6
                        app.status4.BackgroundColor='g';
                        app.status4.FontColor='k';
                        app.status4.Text='FEMA P-58 SAM';
                    end
                    app.status4b.BackgroundColor='g';
                    app.ResponseButton.Enable='on';
                    app.ComputeButton.Enable='on';
                    app.IMButton.Enable='on';
                    app.DemolitionButton.Enable='on';
                    app.CollapseButton.Enable='on';
                    app.HazardButton.Enable='on';
                else
                    app.DemolitionButton.Enable='off';
                    app.CollapseButton.Enable='off';
                end
                if HazardDataStatus==1
                    app.status3.BackgroundColor='g';
                    app.status3.FontColor='k';
                    app.status3.Text='Hazard is defined';
                    app.status3b.BackgroundColor='g';
                    app.HazardButton.Enable='on';
                end
                if IMstatus==1
                    app.status10.BackgroundColor='g';
                    app.IMButton.Enable='on';
                end
                if DemolitionStatus==1
                    app.status20.BackgroundColor='g';
                    app.DemolitionButton.Enable='on';
                else
                    app.status20.BackgroundColor=[0.65 0.65 0.65];
                end
                if CollapseStatus==1
                    app.status30.BackgroundColor='g';
                    app.CollapseButton.Enable='on';
                else
                    app.status30.BackgroundColor=[0.65 0.65 0.65];
                end
                if LossDataStatus==1
                    app.ComputeButton.Enable='on';
                    app.VisualizeButton.Enable='on';
                    app.ReportButton.Enable='on';
                end
                
                app.StartButton.Enable='off';
                app.OpenButton.Enable='off';
                app.SaveAsButton.Enable='on';
                app.ScopeButton.Enable='on';
                
                xx=ProjectName;
                xx=xx(1:end-4);
                app.text26.Text=xx;
                
                app.ProgressText.FontColor='g';
                
            end
        end

        % Button pushed function: ReportButton
        function ReportButton_Callback(app, event)
            Module_Report_App
        end

        % Button pushed function: ResponseButton
        function ResponseButton_Callback(app, event)
            global  ProjectName ProjectPath
            clc;
            load(strcat(ProjectPath,ProjectName),'ComponentDataStatus')

            if ComponentDataStatus == 0
                errordlg('Please define the Component Data first!');
                return
            else
                app.Image5.Visible='on';
                Module_Response_App(app)
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName MainDirectory2 ProjectPath2 ProjectName2
            clc;
            
            try

                load (strcat(ProjectPath,ProjectName))
                
                clear MainDirectory2 ProjectPath2 ProjectName2
                
                MainDirectory2= pwd;
                
                % Get the directory and name of the new user-specified project
                [ProjectName2, ProjectPath2] = uiputfile('*.mat','Save current project as');
                
                save(strcat(ProjectPath2,ProjectName2), '-regexp', '^(?!(app|event)$).')
                
                MainDirectory=MainDirectory2;
                ProjectName=ProjectName2;
                ProjectPath=ProjectPath2;
                
                save(strcat(ProjectPath,ProjectName),'MainDirectory','ProjectName','ProjectPath','-append')
                
                xx=ProjectName;
                xx=xx(1:end-4);
                app.text26.Text=xx;
            
            catch ME
                
            end
        end

        % Button pushed function: ScopeButton
        function ScopeButton_Callback(app, event)
            Show_Project_Summary
        end

        % Button pushed function: StartButton
        function StartButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath Units
            clc;
            
            % Get current directory and user-specified project name
            MainDirectory=pwd;
            [ProjectName, ProjectPath] = uiputfile('*.mat','Select the workspace direcotry and specify project name');
            
            if  isempty(ProjectName) || ischar(ProjectName)==0; return; end
            
            Units=0;
            waitfor(UnitMessage_App(app));
            
            if Units==0; return; end
            
            Initialize_Project(MainDirectory, ProjectPath, ProjectName, Units);

            xx=ProjectName;
            xx=xx(1:end-4);
            app.text26.Text=xx;
            
            app.StartButton.Enable='off';
            app.OpenButton.Enable='off';
            app.SaveAsButton.Enable='on';
            app.BuildingButton.Enable='on';
            app.status1.BackgroundColor='r';
            app.status1b.BackgroundColor='r';
            app.BuildingButton.Enable='on';
            app.ComponentButton.Enable='off';
            app.ResponseButton.Enable='off';
            app.HazardButton.Enable='off';
            app.FragilityButton.Enable='off';
            app.ComputeButton.Enable='off';
            app.VisualizeButton.Enable='off';
            app.ReportButton.Enable='off';
            app.ScopeButton.Enable='on';
            
        end

        % Button pushed function: VisualizeButton
        function VisualizeButton_Callback(app, event)
            Module_Visualize_App
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            close all
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            clc; fclose all;
            app.text26.Text='Project: --';
            EaRL_OpeningFcn(app)     
            
%             app.StartButton.Enable='on';
%             app.OpenButton.Enable='on';
%             BLINK_TEXT (app,'COPYRIGHT: A. ELKADY and D. LIGNOS (2020)', 0.2, 2)
%             app.status10.BackgroundColor='g';
        end

        % Button pushed function: PopulationButton
        function PopulationButtonPushed(app, event)
            app.Image5.Visible='on';
            Module_Population_App(app)
        end

        % Button pushed function: TimeButton
        function TimeButtonPushed(app, event)
            global ProjectPath ProjectName

            load (strcat(ProjectPath,ProjectName), 'Component_Option')
            
            if Component_Option==1            
                app.Image5.Visible='on';
                Module_Choose_Downtime_Scheme_App(app);
            else
                warndlg('This option is only available when the Manual Definition option of the component data is selected')
            end
        end

        % Button pushed function: CodeButton
        function CodeButtonPushed(app, event)
            Developers_App
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            web('https://forms.gle/vsC7czRhyRVudjwP8','-new')
        end

        % Button pushed function: UnlockButton
        function UnlockButtonPushed(app, event)
            app.Image5.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'EaRL';
            app.figure1.Icon = fullfile(pathToMLAPP, 'ICON.png');
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel9 = uipanel(app.figure1);
            app.StartButton = uibutton(app.uipanel9, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Callback, true);
            app.StartButton.Text = 'New';
            app.OpenButton = uibutton(app.uipanel9, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_Callback, true);
            app.OpenButton.Text = 'Open';
            app.SaveAsButton = uibutton(app.uipanel9, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButton_Callback, true);
            app.SaveAsButton.Text = 'Save as';
            app.ScopeButton = uibutton(app.uipanel9, 'push');
            app.ScopeButton.ButtonPushedFcn = createCallbackFcn(app, @ScopeButton_Callback, true);
            app.ScopeButton.Icon = 'output-onlinepngtools2.png';
            app.ScopeButton.Tooltip = 'Show summary of the defined project paramters';
            app.ScopeButton.Text = 'SCOPE';
            app.DefinePanel = uipanel(app.figure1);
            app.uipanel3 = uipanel(app.DefinePanel);
            app.status1b = uilabel(app.uipanel3);
            app.status1b.Text = '';
            app.status1 = uilabel(app.uipanel3);
            app.status1.Text = 'status: not defined';
            app.BuildingButton = uibutton(app.uipanel3, 'push');
            app.BuildingButton.ButtonPushedFcn = createCallbackFcn(app, @BuildingButton_Callback, true);
            app.BuildingButton.Text = 'Building Data';
            app.uipanel4 = uipanel(app.DefinePanel);
            app.status2b = uilabel(app.uipanel4);
            app.status2b.Text = '';
            app.status2 = uilabel(app.uipanel4);
            app.status2.Text = 'status: not defined';
            app.ComponentButton = uibutton(app.uipanel4, 'push');
            app.ComponentButton.ButtonPushedFcn = createCallbackFcn(app, @ComponentButton_Callback, true);
            app.ComponentButton.Text = 'Component Data';
            app.uipanel5 = uipanel(app.DefinePanel);
            app.status4b = uilabel(app.uipanel5);
            app.status4b.Text = '';
            app.status4 = uilabel(app.uipanel5);
            app.status4.Text = 'status: not defined';
            app.ResponseButton = uibutton(app.uipanel5, 'push');
            app.ResponseButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseButton_Callback, true);
            app.ResponseButton.Text = 'Response Data';
            app.uipanel6 = uipanel(app.DefinePanel);
            app.status3b = uilabel(app.uipanel6);
            app.status3b.Text = '';
            app.status3 = uilabel(app.uipanel6);
            app.status3.Text = 'status: not defined';
            app.Image_4 = uiimage(app.uipanel6);
            app.Image_4.ImageSource = 'Hazard.png';
            app.HazardButton = uibutton(app.uipanel6, 'push');
            app.HazardButton.ButtonPushedFcn = createCallbackFcn(app, @HazardButton_Callback, true);
            app.HazardButton.Text = 'Hazard Data';
            app.Image = uiimage(app.DefinePanel);
            app.Image.ImageSource = 'Components.png';
            app.Image_2 = uiimage(app.DefinePanel);
            app.Image_2.ImageSource = 'Building.png';
            app.Image_3 = uiimage(app.DefinePanel);
            app.Image_3.ImageSource = 'Response.png';
            app.ProgressText = uieditfield(app.figure1, 'text');
            app.ProgressText.Value = '© A. ELKADY and D. LIGNOS - 2020';
            app.ComputePanel = uipanel(app.figure1);
            app.ComputeButton = uibutton(app.ComputePanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButton_Callback, true);
            app.ComputeButton.Icon = 'icon_Compute.png';
            app.ComputeButton.Text = 'Compute';
            app.VisualizeButton = uibutton(app.ComputePanel, 'push');
            app.VisualizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeButton_Callback, true);
            app.VisualizeButton.Icon = 'icon_Visualize.png';
            app.VisualizeButton.Text = 'Visualize';
            app.ReportButton = uibutton(app.ComputePanel, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButton_Callback, true);
            app.ReportButton.Icon = 'icon_Report.png';
            app.ReportButton.Text = 'Report';
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Project: --';
            app.uipanel7 = uipanel(app.figure1);
            app.FragilityButton = uibutton(app.uipanel7, 'push');
            app.FragilityButton.ButtonPushedFcn = createCallbackFcn(app, @FragilityButton_Callback, true);
            app.FragilityButton.Text = {'Damage'; 'Fragility'};
            app.DemolitionButton = uibutton(app.uipanel7, 'push');
            app.DemolitionButton.ButtonPushedFcn = createCallbackFcn(app, @DemolitionButton_Callback, true);
            app.DemolitionButton.Text = 'Demolition';
            app.CollapseButton = uibutton(app.uipanel7, 'push');
            app.CollapseButton.ButtonPushedFcn = createCallbackFcn(app, @CollapseButton_Callback, true);
            app.CollapseButton.Text = 'Collapse';
            app.status10 = uilabel(app.uipanel7);
            app.status10.Text = '';
            app.status20 = uilabel(app.uipanel7);
            app.status20.Text = '';
            app.status30 = uilabel(app.uipanel7);
            app.status30.Text = '';
            app.Image_7 = uiimage(app.uipanel7);
            app.Image_7.ImageSource = 'Fragility2.png';
            app.PopulationButton = uibutton(app.uipanel7, 'push');
            app.PopulationButton.ButtonPushedFcn = createCallbackFcn(app, @PopulationButtonPushed, true);
            app.PopulationButton.Text = {'Population'; 'Model'};
            app.Image_8 = uiimage(app.uipanel7);
            app.Image_8.ImageSource = 'Population.png';
            app.Image_9 = uiimage(app.uipanel7);
            app.Image_9.ImageSource = 'RepairTime.png';
            app.TimeButton = uibutton(app.uipanel7, 'push');
            app.TimeButton.ButtonPushedFcn = createCallbackFcn(app, @TimeButtonPushed, true);
            app.TimeButton.Text = {'Repair Time'; ' Scheme'};
            app.Image_10 = uiimage(app.uipanel7);
            app.Image_10.ImageSource = 'Demolition.png';
            app.Image_6 = uiimage(app.uipanel7);
            app.Image_6.ImageSource = 'Options.png';
            app.IMButton = uibutton(app.uipanel7, 'push');
            app.IMButton.ButtonPushedFcn = createCallbackFcn(app, @IMButton_Callback, true);
            app.IMButton.Text = {'IM/EDP'; 'Bins'};
            app.Image_5 = uiimage(app.figure1);
            app.Image_5.ImageSource = 'Banner.png';
            app.HelpButton = uibutton(app.figure1, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Callback, true);
            app.HelpButton.Icon = 'icon_Help.png';
            app.HelpButton.Text = 'help';
            app.AboutButton = uibutton(app.figure1, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButton_Callback, true);
            app.AboutButton.Text = 'about';
            app.text27 = uilabel(app.figure1);
            app.text27.Text = 'v2.2605';
            app.ResetButton = uibutton(app.figure1, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Click to reset'};
            app.ResetButton.Text = 'R';
            app.Image5 = uiimage(app.figure1);
            app.Image5.ImageSource = 'Transparent.png';
            app.ProgressBar = uieditfield(app.figure1, 'text');
            app.CodeButton = uibutton(app.figure1, 'push');
            app.CodeButton.ButtonPushedFcn = createCallbackFcn(app, @CodeButtonPushed, true);
            app.CodeButton.Icon = 'icon_Code2.png';
            app.CodeButton.Text = 'developers';
            app.RegisterButton = uibutton(app.figure1, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Icon = 'icon_Register2.png';
            app.RegisterButton.Text = 'register';
            app.UnlockButton = uibutton(app.figure1, 'push');
            app.UnlockButton.ButtonPushedFcn = createCallbackFcn(app, @UnlockButtonPushed, true);
            app.UnlockButton.Icon = fullfile(pathToMLAPP, 'lock.png');
            app.UnlockButton.Tooltip = {'Unfreeze the console in case a job/run is interrupted '};
            app.UnlockButton.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EaRL_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @EaRL_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end