classdef Module_Building_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        SubmitButton    matlab.ui.control.Button
        uipanel4        matlab.ui.container.Panel
        text6           matlab.ui.control.Label
        text7           matlab.ui.control.Label
        TipIcon1        matlab.ui.control.Button
        TipIcon2        matlab.ui.control.Button
        checkbox1       matlab.ui.control.CheckBox
        checkbox2       matlab.ui.control.CheckBox
        checkbox3       matlab.ui.control.CheckBox
        checkbox4       matlab.ui.control.CheckBox
        listbox1        matlab.ui.control.ListBox
        uipanel3        matlab.ui.container.Panel
        text25          matlab.ui.control.Label
        text2           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text11          matlab.ui.control.Label
        edit2           matlab.ui.control.NumericEditField
        edit3           matlab.ui.control.NumericEditField
        edit4           matlab.ui.control.NumericEditField
        uipanel2        matlab.ui.container.Panel
        text5           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text13          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        edit5           matlab.ui.control.NumericEditField
        edit6           matlab.ui.control.NumericEditField
        uipanel3_2      matlab.ui.container.Panel
        text29          matlab.ui.control.Label
        textbox         matlab.ui.control.TextArea
        text29_2        matlab.ui.control.Label
        Occupancypopup  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp
        TipWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Building_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';            
        
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'BuildingDataStatus','N_Story','Area_FootPrint','Area_Total','Replacement_Cost','Demolition_Cost','BuildingDescription','Units','Material','Occupancy')
            cd (MainDirectory)
            
            % Loading Data If It is Already Defined
            if BuildingDataStatus==1
                app.textbox.Value=BuildingDescription;
                app.edit2.Value=N_Story;
                app.edit3.Value=Area_FootPrint;
                app.edit4.Value=Area_Total;
                app.edit5.Value=Replacement_Cost/1000000;
                app.edit6.Value=Demolition_Cost/1000000;
                app.Occupancypopup.Value=char(string(Occupancy));

                if strcmp(Material{1,1},'Steel');    app.checkbox1.Value=1; end
                if strcmp(Material{1,2},'Concrete'); app.checkbox2.Value=1; end
                if strcmp(Material{1,3},'Wood');     app.checkbox3.Value=1; end
                if strcmp(Material{1,4},'Masonry');  app.checkbox4.Value=1; end
            
            end
            
            if Units==1
                app.text11.Text='sq. feet';
                app.text25.Text='sq. feet';
            else
                app.text11.Text='sq. meter';
                app.text25.Text='sq. meter';
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'BuildingDataStatus','N_Story','Area_FootPrint','Area_Total','ResponseDataStatus','ComponentDataStatus')
            cd (MainDirectory)
            
            if BuildingDataStatus==1
                N_Storymod=app.edit2.Value;
                Area_FootPrintmod=app.edit3.Value;
                Area_Totalmod=app.edit4.Value;
                if N_Storymod~= N_Story || Area_FootPrintmod~= Area_FootPrint || Area_Totalmod~= Area_Total
                    ComponentDataStatus=0;
                    ResponseDataStatus=0;
                    app.MainApp.status2.BackgroundColor=[0.7 0.7 0.7];
                    app.MainApp.status2b.BackgroundColor=[0.7 0.7 0.7];
                    app.MainApp.status2.Text='status: not defined';
                    app.MainApp.status4.BackgroundColor=[0.7 0.7 0.7];
                    app.MainApp.status4b.BackgroundColor=[0.7 0.7 0.7];
                    app.MainApp.status4.Text='status: not defined';
                end
            end
            
            BuildingDescription=app.textbox.Value;
            if strcmp(BuildingDescription,'[Optional]')==1; BuildingDescription=' '; end            
            if isempty(BuildingDescription)==1; BuildingDescription=' '; end
            Occupancy=str2double(app.Occupancypopup.Value);
            OccupancyName=app.Occupancypopup.Items{1,Occupancy};
            N_Story=app.edit2.Value;
            Area_FootPrint=app.edit3.Value;
            Area_Total=app.edit4.Value;
            Replacement_Cost=1000000*app.edit5.Value;
            Demolition_Cost=1000000*app.edit6.Value;
            
            
            % Get Population model parameters
            load('POPMODELORIGINAL.mat');
            PopModel.PeakPop=PopModel.PeakPop(1,Occupancy);
            PopModel.PeakPopArea=PopModel.PeakPopArea;
            PopModel.PopVarPerHourDay=PopModel.PopVarPerHourDay(:,Occupancy*2:Occupancy*2+1);
            PopModel.PopVarPerMonth=PopModel.PopVarPerMonth(:,Occupancy*2:Occupancy*2+1);
            
            % Get Material(s) Type(s)
            Steel=app.checkbox1.Value;
            Concrete=app.checkbox2.Value;
            Wood=app.checkbox3.Value;
            Masonry=app.checkbox4.Value;
            Material{1,1}='';Material{1,2}='';Material{1,3}='';Material{1,4}='';
            if Steel==1;    Material{1,1} ='Steel';   end
            if Concrete==1; Material{1,2} ='Concrete';end
            if Wood==1;     Material{1,3} ='Wood';    end
            if Masonry==1;  Material{1,4} ='Masonry'; end
            
            % Get System(s) Type(s)
            System_Index=app.listbox1.Value;
            System_Type{1,12}='0';
            if any(strcmp(System_Index,'1'));    System_Type{1,1}  ='MF';  end
            if any(strcmp(System_Index,'2'));    System_Type{1,2}  ='MF';  end
            if any(strcmp(System_Index,'3'));    System_Type{1,3}  ='MF';  end
            if any(strcmp(System_Index,'4'));    System_Type{1,4}  ='CMF'; end
            if any(strcmp(System_Index,'5'));    System_Type{1,5}  ='CBF'; end
            if any(strcmp(System_Index,'6'));    System_Type{1,6}  ='CBF'; end
            if any(strcmp(System_Index,'7'));    System_Type{1,7}  ='CBF';  end
            if any(strcmp(System_Index,'8'));    System_Type{1,8}  ='EBF';  end
            if any(strcmp(System_Index,'9'));    System_Type{1,9}  ='SW';   end
            if any(strcmp(System_Index,'10'));   System_Type{1,10} ='MW';   end
            if any(strcmp(System_Index,'11'));   System_Type{1,11} ='WW';   end
            if any(strcmp(System_Index,'12'));   System_Type{1,12} ='CFSW'; end
            
            BuildingDataStatus=1;
            
            cd (ProjectPath)
            pause(1)
            save(ProjectName,'Material','Occupancy','OccupancyName','PopModel','System_Type','N_Story','Area_FootPrint','Area_Total','Replacement_Cost','Demolition_Cost','-append')
            save(ProjectName,'BuildingDataStatus','BuildingDescription','ComponentDataStatus','ResponseDataStatus','-append')
            pause(1)
            cd (MainDirectory)
            
            app.MainApp.status1.BackgroundColor='g';
            app.MainApp.status1.FontColor='k';
            app.MainApp.status1.Text=[num2str(N_Story),'-story building'];
            app.MainApp.status1b.BackgroundColor='g';
            app.MainApp.ComponentButton.Enable='on';
            app.MainApp.FragilityButton.Enable='on';
            app.MainApp.PopulationButton.Enable='on';
                        
            figure1CloseRequest(app, event)
        end

        % Button pushed function: TipIcon1
        function TipIcon1_Callback(app, event)
            str{1}='Select all the contruction materials of the building structural components';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon2
        function TipIcon2_Callback(app, event)
            str{1}='Select all the lateral force resisting systems in the building (multiple selection allowed)';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Callback function
        function edit2_Callback(app, event)
            x=app.edit2.Value;
            if x<0 || isnan(x)
               app.edit2.FontColor='r';
            else
               app.edit2.FontColor='k';
            end
            
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'BuildingDataStatus','N_Story')
            cd (MainDirectory)
            
            if BuildingDataStatus==1
                N_Storymod=app.edit2.Value;
                if N_Storymod~= N_Story
                    ed = warndlg('If you change the building geometric attributes, you will need to re-define the component and response data!!','NOTE', 'modal');
                    waitfor(ed);
                end
            end
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            x=app.edit3.Value;
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'BuildingDataStatus','Area_FootPrint')
            cd (MainDirectory)
            
            if BuildingDataStatus==1
                Area_FootPrintmod=app.edit2.Value;
                if Area_FootPrint~= Area_FootPrintmod
                    ed = warndlg('If you change the building geometric attributes, you will need to re-define the component and response data!!','NOTE', 'modal');
                    uiwait(ed);
                end
            end
            
            if     x <0 || isnan(x)
               app.edit3.FontColor='r';
            else
               app.edit3.FontColor='k';
            end
            N_Story=app.edit2.Value;
            Area_Total=N_Story*x;
            app.edit4.Value=Area_Total;
            edit4_Callback(app)
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            x=app.edit4.Value;
            if  x<0 || x < app.edit3.Value
               app.edit4.FontColor='r';
            else
               app.edit4.FontColor='k';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Building Data';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Structural Characteristics';
            app.text6 = uilabel(app.uipanel4);
            app.text6.Text = 'Construction Material';
            app.text7 = uilabel(app.uipanel4);
            app.text7.Text = 'Structure System';
            app.TipIcon1 = uibutton(app.uipanel4, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1_Callback, true);
            app.TipIcon1.Text = '?';
            app.TipIcon2 = uibutton(app.uipanel4, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2_Callback, true);
            app.TipIcon2.Text = '?';
            app.checkbox1 = uicheckbox(app.uipanel4);
            app.checkbox1.Text = 'Steel';
            app.checkbox2 = uicheckbox(app.uipanel4);
            app.checkbox2.Text = 'Concrete';
            app.checkbox3 = uicheckbox(app.uipanel4);
            app.checkbox3.Text = 'Wood';
            app.checkbox4 = uicheckbox(app.uipanel4);
            app.checkbox4.Text = 'Masonry';
            app.listbox1 = uilistbox(app.uipanel4);
            app.listbox1.Items = {'Special Moment Frame', 'Intermediate Moment Frame', 'Ordinary Moment Frame', 'Non-Conforming Moment Frame', 'Special Concentrically Braced Frame', 'Ordinary Concentrically Braced Frame', 'Non-Conforming Braced Frame', 'Eccentrically Braced Frame', 'Shear Wall', 'Masonry Wall', 'Wood Wall', 'Cold Formed Steel Wall'};
            app.listbox1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.listbox1.Multiselect = 'on';
            app.listbox1.Value = {'1'};
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Geometric Attributes';
            app.text25 = uilabel(app.uipanel3);
            app.text25.Text = 'ft';
            app.text2 = uilabel(app.uipanel3);
            app.text2.Text = 'Number of Stories';
            app.text3 = uilabel(app.uipanel3);
            app.text3.Tooltip = 'Specify single floor area';
            app.text3.Text = 'Foot Print Area';
            app.text4 = uilabel(app.uipanel3);
            app.text4.Tooltip = 'Specify total floor area';
            app.text4.Text = 'Total Floor Area';
            app.text11 = uilabel(app.uipanel3);
            app.text11.Text = 'ft';
            app.edit2 = uieditfield(app.uipanel3, 'numeric');
            app.edit2.Limits = [1 Inf];
            app.edit2.ValueDisplayFormat = '%.0f';
            app.edit2.Value = 1;
            app.edit3 = uieditfield(app.uipanel3, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%5.1f';
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.uipanel3, 'numeric');
            app.edit4.Limits = [1 Inf];
            app.edit4.ValueDisplayFormat = '%5.1f';
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Value = 1;
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Construction Attributes';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tooltip = 'Specify cost for replacing the building (building cost)';
            app.text5.Text = 'Replacement Cost';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tooltip = 'Million Dollars';
            app.text8.Text = 'M$';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tooltip = 'Specify cost for demolishing the building';
            app.text13.Text = 'Demolition  Cost';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tooltip = 'Million Dollars';
            app.text14.Text = 'M$';
            app.edit5 = uieditfield(app.uipanel2, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.Value = 1;
            app.edit6 = uieditfield(app.uipanel2, 'numeric');
            app.edit6.Limits = [0 Inf];
            app.edit6.Value = 1;
            app.uipanel3_2 = uipanel(app.figure1);
            app.uipanel3_2.Title = 'Building Information';
            app.text29 = uilabel(app.uipanel3_2);
            app.text29.Tooltip = '[Optional] Add a text description of the project';
            app.text29.Text = 'Building Description';
            app.textbox = uitextarea(app.uipanel3_2);
            app.textbox.Value = {'[Optional]'};
            app.text29_2 = uilabel(app.uipanel3_2);
            app.text29_2.Tooltip = '[Optional] Add a text description of the project';
            app.text29_2.Text = 'Occupancy';
            app.Occupancypopup = uidropdown(app.uipanel3_2);
            app.Occupancypopup.Items = {'Commercial Office', 'Education - Elementary Schools', 'Education - Middle Schools', 'Education - High Schools', 'Healthcare', 'Hospitality', 'Multi-Unit Residential', 'Research Laboratories', 'Retail', 'Warehouse'};
            app.Occupancypopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Occupancypopup.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Building_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Building_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end