classdef Module_Collapse_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        SelectButtonGroup  matlab.ui.container.ButtonGroup
        radio1             matlab.ui.control.RadioButton
        radio2             matlab.ui.control.RadioButton
        radio3             matlab.ui.control.RadioButton
        text17             matlab.ui.control.Label
        TipButton3         matlab.ui.control.Button
        text39             matlab.ui.control.Label
        text40             matlab.ui.control.Label
        radio4             matlab.ui.control.RadioButton
        text28             matlab.ui.control.Label
        text0              matlab.ui.control.Label
        TipButton4         matlab.ui.control.Button
        edit1              matlab.ui.control.NumericEditField
        edit2              matlab.ui.control.NumericEditField
        edit3              matlab.ui.control.NumericEditField
        edit4              matlab.ui.control.NumericEditField
        edit5              matlab.ui.control.NumericEditField
        edit6              matlab.ui.control.NumericEditField
        text18             matlab.ui.control.Label
        text19             matlab.ui.control.Label
        SubmitButton       matlab.ui.control.Button
        ShowCPSFragButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
        TipWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Collapse_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load (ProjectName,'CollapseStatus','CPS_Option','ResponseDataStatus','Response_Option','Option5_Type','MedianCPS','SigmaCPS','Pcollapse','PcollapseSa')
            cd (MainDirectory)
            
            if ResponseDataStatus==1
               if Response_Option==2 || Response_Option==3 || Response_Option==4
                   app.radio2.Enable='off';
                   app.edit1.Enable='off';
                   app.text17.Enable='off';
                   app.text39.Enable='off';
               end
               if Response_Option ==5 && Option5_Type~=1
                   app.radio2.Enable='off';
                   app.edit1.Enable='off';
                   app.text17.Enable='off';
                   app.text39.Enable='off';
                end
               
            end
            
            if CollapseStatus==1
                if CPS_Option==0
                    app.radio1.Value=1;
                elseif CPS_Option==1
                    app.radio2.Value=1;
                elseif CPS_Option==2
                    app.radio3.Value=1;
                    app.edit2.Value=MedianCPS;
                    app.edit3.Value=SigmaCPS;
                elseif CPS_Option==3
                    app.radio4.Value=1;
                    app.edit4.Value=Pcollapse*100;
                    app.edit5.Value=PcollapseSa;
                    app.edit6.Value=SigmaCPS;
                end
            else
                app.radio1.Value=1;
            end
            SelectButtonGroupSelectionChanged(app)
            
        end

        % Button pushed function: ShowCPSFragButton
        function ShowCPSFragButton_Callback(app, event)
            Plot_Collapse_Fragility(app)    
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            
            [CollapseSDR,CPS_Option,MedianCPS,SigmaCPS,Pcollapse,PcollapseSa]=Get_Collapse_Fragility_Parameters(app);
            
            CollapseStatus=1;
            
            cd (ProjectPath)
            save(ProjectName,'CollapseStatus','CollapseSDR','CPS_Option','MedianCPS','SigmaCPS','Pcollapse','PcollapseSa','-append')
            pause(0.5)
            cd (MainDirectory)
            
            if CPS_Option~=0
                app.MainApp.status30.BackgroundColor='g';
            else
                app.MainApp.status30.BackgroundColor=[0.65 0.65 0.65];                
            end            
            figure1CloseRequest(app)
        end

        % Button pushed function: TipButton3
        function TipButton3_Callback(app, event)
            str{1}='Specify an SDR limit at which collaspe will occur. This limit is used to deduce collaspe intensities from the IDA data.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Button pushed function: TipButton4
        function TipButton4Pushed(app, event)
            str{1}='Specify on point on the fragility curve [IM, P(collapse)] and sigma.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Selection changed function: SelectButtonGroup
        function SelectButtonGroupSelectionChanged(app, event)
            if app.radio1.Value==1
                app.radio1.FontColor='b';
                app.radio2.FontColor='k';
                app.radio3.FontColor='k';
                app.radio4.FontColor='k';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
            elseif app.radio2.Value==1
                app.radio1.FontColor='k';
                app.radio2.FontColor='b';
                app.radio3.FontColor='k';
                app.radio4.FontColor='k';
                app.edit1.Enable='on';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
            elseif app.radio3.Value==1
                app.radio1.FontColor='k';
                app.radio2.FontColor='k';
                app.radio3.FontColor='b';
                app.radio4.FontColor='k';
                app.edit1.Enable='off';
                app.edit2.Enable='on';
                app.edit3.Enable='on';
                app.edit4.Enable='of';
                app.edit5.Enable='of';
                app.edit6.Enable='of';
            elseif app.radio4.Value==1
                app.radio1.FontColor='k';
                app.radio2.FontColor='k';
                app.radio3.FontColor='k';
                app.radio4.FontColor='b';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.edit4.Enable='on';
                app.edit5.Enable='on';
                app.edit6.Enable='on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Collapse Fragility';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.SelectButtonGroup = uibuttongroup(app.figure1);
            app.SelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectButtonGroupSelectionChanged, true);
            app.SelectButtonGroup.Title = 'Collapse Fragility';
            app.radio1 = uiradiobutton(app.SelectButtonGroup);
            app.radio1.Text = 'No Collapse';
            app.radio1.Value = true;
            app.radio2 = uiradiobutton(app.SelectButtonGroup);
            app.radio2.Text = 'Deduce Fragility Parameters from IDA data';
            app.radio3 = uiradiobutton(app.SelectButtonGroup);
            app.radio3.Text = 'Specify Fragility Parameters Directly';
            app.text17 = uilabel(app.SelectButtonGroup);
            app.text17.Text = 'Collapse SDR Limit=';
            app.TipButton3 = uibutton(app.SelectButtonGroup, 'push');
            app.TipButton3.ButtonPushedFcn = createCallbackFcn(app, @TipButton3_Callback, true);
            app.TipButton3.Text = '?';
            app.text39 = uilabel(app.SelectButtonGroup);
            app.text39.Text = '[rad]';
            app.text40 = uilabel(app.SelectButtonGroup);
            app.text40.Text = '[g]';
            app.radio4 = uiradiobutton(app.SelectButtonGroup);
            app.radio4.Text = 'Specify one Point and Beta value';
            app.text28 = uilabel(app.SelectButtonGroup);
            app.text28.Tooltip = 'Standard deviation of the ln(Sa) collapse dataset';
            app.text28.Text = 'β';
            app.text0 = uilabel(app.SelectButtonGroup);
            app.text0.Text = 'P(Collapse)=     % at IM=        [g]';
            app.TipButton4 = uibutton(app.SelectButtonGroup, 'push');
            app.TipButton4.ButtonPushedFcn = createCallbackFcn(app, @TipButton4Pushed, true);
            app.TipButton4.Text = '?';
            app.edit1 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit1.Limits = [0.01 0.3];
            app.edit1.Value = 0.15;
            app.edit2 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit2.Limits = [0.001 Inf];
            app.edit2.Value = 1;
            app.edit3 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit3.Limits = [0.0001 Inf];
            app.edit3.Value = 0.3;
            app.edit4 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit4.Limits = [0.1 99];
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Value = 4;
            app.edit5 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit5.Limits = [0.001 Inf];
            app.edit5.Value = 0.45;
            app.edit6 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit6.Limits = [0.0001 Inf];
            app.edit6.Value = 0.3;
            app.text18 = uilabel(app.SelectButtonGroup);
            app.text18.Tooltip = {'Median value of the collapse IM'};
            app.text18.Text = 'μ';
            app.text19 = uilabel(app.SelectButtonGroup);
            app.text19.Tooltip = {'Standard deviation of the ln(IM_Collapse) dataset'};
            app.text19.Text = 'β';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.ShowCPSFragButton = uibutton(app.figure1, 'push');
            app.ShowCPSFragButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCPSFragButton_Callback, true);
            app.ShowCPSFragButton.Text = 'Plot Fragility Curve';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Collapse_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Collapse_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end