classdef Module_Component_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1x        matlab.ui.Figure
        SelectanOptiontoDefineComponentDataButtonGroup  matlab.ui.container.ButtonGroup
        radiobutton1    matlab.ui.control.RadioButton
        radiobutton2    matlab.ui.control.RadioButton
        radiobutton3    matlab.ui.control.RadioButton
        radiobutton4    matlab.ui.control.RadioButton
        text3           matlab.ui.control.Label
        text16          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text17          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        text19          matlab.ui.control.Label
        linkbutton1     matlab.ui.control.Button
        linkbutton2     matlab.ui.control.Button
        Image           matlab.ui.control.Image
        Image_2         matlab.ui.control.Image
        Image_3         matlab.ui.control.Image
        Image_4         matlab.ui.control.Image
        ContinueButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Component_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'ComponentDataStatus','Component_Option')
            cd (MainDirectory)
            
            if ComponentDataStatus==1
                if Component_Option==1
                    app.radiobutton1.Value=1;
                elseif Component_Option==2
                    app.radiobutton2.Value=1;
                elseif Component_Option==3
                    app.radiobutton3.Value=1;
                end
            else
                app.radiobutton1.Value=1;
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButton_Callback(app, event)
            clc;
            if app.radiobutton1.Value ==1
                Module_Component_Option1_App(app.MainApp)
            elseif app.radiobutton2.Value ==1
                Module_Component_Option2_App(app.MainApp)
            elseif app.radiobutton3.Value ==1
                Module_Component_Option3_App(app.MainApp)
            end
            closereq
        end

        % Button pushed function: linkbutton1
        function linkbutton1_Callback(app, event)
            web('http://earthquakespectra.org/doi/pdf/10.1193/102417EQS222M','-new')
        end

        % Button pushed function: linkbutton2
        function linkbutton2_Callback(app, event)
            web('https://stacks.stanford.edu/file/druid:zy329mj7285/TR171_Ramirez.pdf','-new')
        end

        % Close request function: figure1x
        function figure1xCloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Selection changed function: 
        % SelectanOptiontoDefineComponentDataButtonGroup
        function SelectanOptiontoDefineComponentDataButtonGroupSelectionChanged(app, event)
                if app.radiobutton1.Value==1
                    app.radiobutton1.FontColor='b';
                    app.radiobutton2.FontColor='k';
                    app.radiobutton3.FontColor='k';
                    app.radiobutton4.FontColor='k';
                elseif app.radiobutton2.Value==2
                    app.radiobutton1.FontColor='k';
                    app.radiobutton2.FontColor='b';
                    app.radiobutton3.FontColor='k';
                    app.radiobutton4.FontColor='k';                                        
                elseif app.radiobutton3.Value==3
                    app.radiobutton1.FontColor='k';
                    app.radiobutton2.FontColor='k';
                    app.radiobutton3.FontColor='b';
                    app.radiobutton4.FontColor='k';
                elseif app.radiobutton4.Value==4
                    app.radiobutton1.FontColor='k';
                    app.radiobutton2.FontColor='k';
                    app.radiobutton3.FontColor='k';
                    app.radiobutton4.FontColor='b';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1x = uifigure('Visible', 'off');
            app.figure1x.Name = 'Building Components Definition Options';
            app.figure1x.CloseRequestFcn = createCallbackFcn(app, @figure1xCloseRequest, true);
            app.SelectanOptiontoDefineComponentDataButtonGroup = uibuttongroup(app.figure1x);
            app.SelectanOptiontoDefineComponentDataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectanOptiontoDefineComponentDataButtonGroupSelectionChanged, true);
            app.SelectanOptiontoDefineComponentDataButtonGroup.Title = 'Select an Option to Define Component Data';
            app.radiobutton1 = uiradiobutton(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.radiobutton1.Text = 'Manual Definition';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.radiobutton2.Text = 'Loss-EDP Functions - Steel Buildings';
            app.radiobutton3 = uiradiobutton(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.radiobutton3.Text = 'Loss-EDP Functions - RC Buildings';
            app.radiobutton4 = uiradiobutton(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.radiobutton4.Enable = 'off';
            app.radiobutton4.Text = 'User Defined Loss-EDP Functions';
            app.text3 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text3.Text = 'Specify manually the type and number of building';
            app.text16 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text16.Text = 'components at each story';
            app.text14 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text14.Text = 'Utilize the story-based loss fragility functions';
            app.text15 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text15.Text = 'developed by Papadopoulos et al (2018)';
            app.text17 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text17.Text = 'Utilize the story-based loss fragility functions';
            app.text18 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text18.Text = 'developed by Ramirez and Miranda (2009)';
            app.text19 = uilabel(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.text19.Enable = 'off';
            app.text19.Text = 'Define new story-based loss fragility functions';
            app.linkbutton1 = uibutton(app.SelectanOptiontoDefineComponentDataButtonGroup, 'push');
            app.linkbutton1.ButtonPushedFcn = createCallbackFcn(app, @linkbutton1_Callback, true);
            app.linkbutton1.Tooltip = {'link to publication'};
            app.linkbutton1.Text = 'link ↗';
            app.linkbutton2 = uibutton(app.SelectanOptiontoDefineComponentDataButtonGroup, 'push');
            app.linkbutton2.ButtonPushedFcn = createCallbackFcn(app, @linkbutton2_Callback, true);
            app.linkbutton2.Tooltip = {'link to publication'};
            app.linkbutton2.Text = 'link ↗';
            app.Image = uiimage(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.Image.ImageSource = 'Component1.png';
            app.Image_2 = uiimage(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.Image_2.ImageSource = 'Component2.png';
            app.Image_3 = uiimage(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.Image_3.ImageSource = 'Component3.png';
            app.Image_4 = uiimage(app.SelectanOptiontoDefineComponentDataButtonGroup);
            app.Image_4.ImageSource = 'UnderDevelopment.png';
            app.ContinueButton = uibutton(app.figure1x, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_Callback, true);
            app.ContinueButton.Text = 'Next';
            % Show the figure after all components are created
            app.figure1x.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Component_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1x)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Component_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1x)
        end
    end
end