classdef Module_Component_Option1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel2         matlab.ui.container.Panel
        text34           matlab.ui.control.Label
        text31           matlab.ui.control.Label
        text30           matlab.ui.control.Label
        text33           matlab.ui.control.Label
        ComponentPopup   matlab.ui.control.DropDown
        AddButton        matlab.ui.control.Button
        ComponentTable1  matlab.ui.control.Table
        LocationPopup    matlab.ui.control.DropDown
        SubmitButton     matlab.ui.control.Button
        DeleteEntry      matlab.ui.control.Button
        TipIcon2         matlab.ui.control.Button
        TipIcon1         matlab.ui.control.Button
        ImportButton     matlab.ui.control.Button
        CategoryPopup    matlab.ui.control.DropDown
        TipText          matlab.ui.control.TextArea
        ClearButton      matlab.ui.control.Button
        popupmenu10      matlab.ui.control.DropDown
        ComponentEdit1   matlab.ui.control.NumericEditField
        ComponentEdit2   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.TipText.FontColor=[0 1 0];
            app.TipText.Value=YourString;
            drawnow();
            
            for i=1:nTimes
                app.ComponentEdit1.Value=0;
                drawnow();
                pause(pauseTime);
                app.TipText.Value=YourString;
                drawnow();
                pause(pauseTime);
            end
        end
        
        function TipIcon3_Callback(app)
            str{1}='This button prompts a module for defining a new structural/non-structural component and its damage states';
            app.TipText.Value=str;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Component_Option1_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'ComponentDataStatus','Component_Data','Component_Option')
            cd (MainDirectory)
            
            % Loading Component Data if it was already defined
            if ComponentDataStatus==1 && Component_Option==1
                app.ComponentTable1.Data=Component_Data;
            end
            
            % Set the Category popup selection to 1 and run its function
            app.CategoryPopup.Value='1';
            CategoryPopup_Callback(app)
            
            app.LocationPopup.Value='2';
            
        end

        % Button pushed function: AddButton
        function AddButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            % try
            
            cd (ProjectPath)
            load(ProjectName,'FRAGDATA','COMPDATA','FilteredIDList','FilteredNameList','Category','Component_Data')
            cd (MainDirectory)
            
            % Get the ID number of the selected component
            i =app.ComponentPopup.Value;
            Component_ID=FilteredIDList(i,1);
            
            idxx=find(FRAGDATA.C_ID==Component_ID);
            if FRAGDATA.DS_EDPmedian(idxx(1,1),1)==999
                cd (ProjectPath)
                save(ProjectName,'Component_ID','-append')
                cd (MainDirectory)
                waitfor(Module_PredictorData_App);
            end
            
            % Get the story/floor location of the selected component
            Level = str2double(app.LocationPopup.Value);
            CompStory =(app.popupmenu10.Value);
            if CompStory == 1
                errordlg('Select component location first');
            elseif CompStory == 2
                Component_Location  = 'Typ.';
            else
                Component_Location=CompStory-2;
            end
            
            UnitType=FRAGDATA.DS_UnitType(idxx(1,1),1);
            UnitVal=FRAGDATA.DS_UnitVal(idxx(1,1),1);
            
            % Get the number of units of the selected component
            N_Units =app.ComponentEdit1.Value;
            N_CostUnits=round(N_Units/UnitVal);
            
            % Get the number of damage states and EDP for the selected component
            indxCOMPDATA=find(Component_ID==COMPDATA.C_ID);
            N_DS   = COMPDATA.C_nDS(indxCOMPDATA);
            DS_EDP = char(COMPDATA.C_EDP(indxCOMPDATA));
            
            % Add the selected componant data to the Global Component Data Matrix
            if isnan(N_CostUnits)==1 %|| rem(N_Units,1)>0.0
                errordlg('Number of Units must be a Real Number');
            elseif N_Units==0.0
                errordlg('The Units value should not be zero')
            else
                if strcmp (Component_Data{1,1},'-')
                    Component_Data {1,1}=Category;
                    Component_Data {1,2}=char(COMPDATA.C_shortername(indxCOMPDATA,1));
                    Component_Data {1,3}=Component_ID;
                    Component_Data {1,4}=Component_Location;
                    Component_Data {1,5}=N_Units;
                    Component_Data {1,6}=N_DS;
                    Component_Data {1,7}=DS_EDP;
                    if Level==1; Component_Data {1,8}='Story'; else; Component_Data {1,8}='Floor'; end
                    app.ComponentTable1.Data=Component_Data;
                    cd (ProjectPath)
                    save(ProjectName,'Component_Data','-append')%,'Column_Data'
                    cd (MainDirectory)
                else
                    Component_Data = app.ComponentTable1.Data;
                    Size=size(Component_Data,1);
                    Component_Data {Size+1,1}=Category;
                    Component_Data {Size+1,2}=char(COMPDATA.C_shortername(indxCOMPDATA,1));
                    Component_Data {Size+1,3}=Component_ID;
                    Component_Data {Size+1,4}=Component_Location;
                    Component_Data {Size+1,5}=N_Units;
                    Component_Data {Size+1,6}=N_DS;
                    Component_Data {Size+1,7}=DS_EDP;
                    if Level==1; Component_Data {Size+1,8}='Story'; else; Component_Data {Size+1,8}='Floor'; end
                    app.ComponentTable1.Data=Component_Data;
                    cd (ProjectPath)
                    save(ProjectName,'Component_Data','-append')%,'Column_Data'
                    cd (MainDirectory)
                end
            end
        end

        % Value changed function: CategoryPopup
        function CategoryPopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FilteredIDList','FilteredNameList')
            cd (MainDirectory)
            
            clear FilteredIDList FilteredNameList
            
            i =str2double(app.CategoryPopup.Value);
            switch i
                case 1
                    Category  = 'Superstructure';
                case 2
                    Category  = 'Roofing';
                case 3
                    Category  = 'Exterior Enclosure';
                case 4
                    Category  = 'Interiors';
                case 5
                    Category  = 'HAVC';
                case 6
                    Category  = 'Fire Protection';
                case 7
                    Category  = 'Electrical';
                case 8
                    Category  = 'Conveying';
                case 9
                    Category  = 'Plumbing';
                case 10
                    Category  = 'Furniture';
            end
            
            % Get the filtered Component (ID and Name) list based on the User's Category selection
            count=1;
            for i=1:size(COMPDATA.C_ID)
               if COMPDATA.C_Category2(i)==Category
                   FilteredIDList  (count,1) = COMPDATA.C_ID(i);
                   FilteredNameList(count,1) = COMPDATA.C_name(i);
                   count=count+1;
               end
            end
            % Populate the Component popup menu with the component filtered list
            app.ComponentPopup.Items=FilteredNameList;
            app.ComponentPopup.ItemsData=1:size(FilteredNameList,1);
            
            cd (ProjectPath)
            save(ProjectName,'Category','FilteredIDList','FilteredNameList','-append')
            cd (MainDirectory)
            
            ComponentPopup_Callback(app)
        end

        % Button pushed function: ClearButton
        function ClearButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath

            Component_Data{1,1}='-';
            ComponentDataStatus=0;
            app.ComponentTable1.Data=Component_Data;
            
            cd (ProjectPath)
            save(ProjectName,'Component_Data','ComponentDataStatus','-append');
            cd (MainDirectory)
        end

        % Value changed function: ComponentPopup
        function ComponentPopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            try
            
            cd (ProjectPath)
            load (ProjectName,'FRAGDATA','Category','FilteredIDList','FilteredNameList','Units','Area_FootPrint')
            cd (MainDirectory)
            
            % Display info regarding the component units
            
            % Get the ID number of the selected component
            i = app.ComponentPopup.Value;
            Component_ID=FilteredIDList(i,1);
            
            idxx = find(FRAGDATA.C_ID==Component_ID);
            UnitType = FRAGDATA.DS_UnitType(idxx(1,1),1);
            UnitVal  = FRAGDATA.DS_UnitVal(idxx(1,1),1);
            
            str{1}='';
            
            if UnitType=='EA' || UnitType=='EU' || UnitType=='BU'
                str{1}=['The repair cost for this component is associated with  ', num2str(UnitVal),' unit'];
                str{2}='';
                str{3}='--> You need to specify the total number of units per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='# Units';
            end
            
            if UnitType=='SF'
                if Units==1
                    unit1='ft2';
                    str{1}=['The repair cost for this component is based on a pre-defined unit floor area of ', num2str(UnitVal),' ft2.'];
                else
                    unit1='m2';
                    str{1}=['The repair cost for this component is based on a pre-defined unit floor area of ', num2str(UnitVal),' m2.'];
                end
                str{2}='Therefore, the number of units will be calculated as the building floor area/cost-associated unit floor area.';
                str{3}='';
                str{4}='--> You do not need to specify the number of units for this component.';
                str{5}='';
                str{6}='--> You have the option, however, to override the assigned floor area for this component.';
                app.ComponentEdit1.Value=999;
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text=['Area [',unit1,']'];
                app.ComponentEdit1.Value=Area_FootPrint;
            end
            
            if UnitType=='EP'
                str{1}=['The repair cost for this component is associated with  ', num2str(UnitVal),' pane'];
                str{2}='';
                str{3}='--> You need to specify the total number of units (i.e., panes) per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='# Panes';
            end
            
            if UnitType=='LF'
                if Units==1
                    str{1}=['The repair cost for this component is associated with a unit length of ', num2str(UnitVal),' ft.'];
                else
                    str{1}=['The repair cost for this component is associated with a unit length of ', num2str(UnitVal),' m.'];
                end
                str{2}='';
                str{3}='--> You need to specify the total length of the component per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='Length';
            end
            
            if UnitType=='TN'
                str{1}=['Costing is per unit and is based upon ', num2str(UnitVal), ' ton'];
                str{2}='';
                str{3}='--> You need to specify the total number of units per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='Length';
            end
            
            if UnitType=='AP'
                str{1}=['Costing is per unit and is based upon ', num2str(UnitVal), ' ampere'];
                str{2}='';
                str{3}='--> You need to specify the total number of units per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='Length';
            end
            
            if UnitType=='kVa'
                str{1}=['Costing is per unit and is based upon ', num2str(UnitVal), '  kilo-volt-ampere'];
                str{2}='';
                str{3}='--> You need to specify the total number of units per story/floor.';
                BLINK_TEXT (app,'Add', 0.2, 3)
                app.ComponentEdit1.FontColor='k';
                app.ComponentEdit1.Enable='on';
                app.text30.Text='Length';
            end
                        
            app.TipText.Value=str;
            
            catch ME
                app.MainApp.ProgressText.Value='Oops| Something went wrong! - An error file has been created';
                app.MainApp.ProgressText.FontColor='r';
                cd (ProjectPath)
                fid = fopen('Error.txt', 'wt');
                fprintf(fid, '%s\n', ME.identifier);
                fprintf(fid, '%s\n', ME.message);
                fprintf(fid, '%s\n', ME.stack.name);
                fprintf(fid, '%d\n', ME.stack.line);
                cd (MainDirectory)
                fclose(fid);
            end
        end

        % Button pushed function: DeleteEntry
        function DeleteEntry_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            try
            
            cd (ProjectPath)
            load(ProjectName,'Component_Data')
            cd (MainDirectory)
            EntryDeleteIndex =app.ComponentEdit2.Value;
            Component_ID=Component_Data (EntryDeleteIndex,2);
            
            Component_Data = app.ComponentTable1.Data;
            Component_Data(EntryDeleteIndex,:)=[];
            if size(Component_Data,1)==0
                Component_Data{1,1}='-';
                ComponentDataStatus=0;
            else
                ComponentDataStatus=1;
            end
            
            app.ComponentTable1.Data=Component_Data;
            Component_Data = app.ComponentTable1.Data;
            cd (ProjectPath)
            save(ProjectName,'Component_Data','ComponentDataStatus','-append'); %,'Column_Data'
            cd (MainDirectory)
            
            catch ME
                app.MainApp.ProgressText.Value='Oops| Something went wrong! - An error file has been created';
                app.MainApp.ProgressText.FontColor='r';
                cd (ProjectPath)
                fid = fopen('Error.txt', 'wt');
                %X=convertCharsToStrings(ME.stack.name);
                fprintf(fid, '%s\n', ME.stack.name);
                fprintf(fid, '%d\n', ME.stack.line);
                %fprintf(fid, '%s\n', X);
                fprintf(fid, '%s\n', ME.identifier);
                cd (MainDirectory)
                fclose(fid);
            end
        end

        % Button pushed function: ImportButton
        function ImportButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            % try
            [ComponentDataFileName ComponentDataPath]= uigetfile({'*.*'},'Select the Excel file containing component data');
            
            cd (ProjectPath)
            load(ProjectName,'COMPDATA')
            cd (MainDirectory)
            
            z=[ComponentDataPath,ComponentDataFileName];
            
            try
                [Data,txt,all] = xlsread(z,'Component_Data','A2:D500');
            catch ME
                errordlg('Something is wrong with the EXcel file; Check the format!');
                return
            end
            
            for i = 1:size(Data,1)
                if isnan(Data(i,3))
                    Data(i,2) = 0;
                end
            end
            
            for i=1:size(Data,1)
                ID=Data(i,1);
                indxCOMPDATA=find(ID==COMPDATA.C_ID);
                ComponentDataImport(i,1) = (COMPDATA.C_Category2(indxCOMPDATA,1));
                ComponentDataImport(i,2) = (COMPDATA.C_shortername(indxCOMPDATA,1));
                ComponentDataImport(i,3) = indxCOMPDATA;
                if Data(i,2)==0
                    ComponentDataImport(i,4) = 'Typ.';
                else
                    ComponentDataImport(i,4) = Data(i,3);
                end
                ComponentDataImport(i,5) = Data(i,4);
                ComponentDataImport(i,6) = COMPDATA.C_nDS(indxCOMPDATA,1);
                ComponentDataImport(i,7) = COMPDATA.C_EDP(indxCOMPDATA,1);
                ComponentDataImport(i,8) = txt(i,1);
            end
            ComponentDataImport=cellstr(ComponentDataImport);
            app.ComponentTable1.Data=ComponentDataImport;
            Component_Data = app.ComponentTable1.Data;
            
            cd (ProjectPath)
            save(ProjectName,'ComponentDataFileName','ComponentDataPath','Component_Data','-append')%,'Column_Data'
            pause(0.5)
            cd (MainDirectory)
        end

        % Value changed function: LocationPopup
        function LocationPopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'N_Story')
            cd (MainDirectory)
            X=str2double(app.LocationPopup.Value);
            
            if X==1
                % Populate the Story Location Popup
                S{1}='';
                S{2}='Typ.';
                for i=1:N_Story
                    if i==1; S{i+2}=[num2str(i),'st']; end
                    if i==2; S{i+2}=[num2str(i),'nd']; end
                    if i==3; S{i+2}=[num2str(i),'rd']; end
                    if i> 3; S{i+2}=[num2str(i),'th']; end
                end
                app.popupmenu10.Items=S;
                app.popupmenu10.ItemsData=1:N_Story+2;
                app.popupmenu10.Value=1;
            else
                % Populate the Floor Location Popup
                S{1}='';
                S{2}='Typ.';
                for i=1:N_Story
                    if i==1; S{i+2}=[num2str(i),'st']; end
                    if i==2; S{i+2}=[num2str(i),'nd']; end
                    if i==3; S{i+2}=[num2str(i),'rd']; end
                    if i> 3; S{i+2}=[num2str(i),'th']; end
                end
                S{end+1}='Roof';
                app.popupmenu10.Items=S;
                app.popupmenu10.ItemsData=1:N_Story+3;
                app.popupmenu10.Value=1;
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'C_Data','Component_Data','COMPDATA','FRAGDATA','Area_FootPrint')
            cd (MainDirectory)
            
            clear C_Data Component_Data
            
            % try
            Component_Data = app.ComponentTable1.Data;
            
            if Component_Data {1,1}=='-'
                closereq
            else
                [C_Data]=Process_Imported_Component_Data(Component_Data,COMPDATA, FRAGDATA);
            
                % Modify DS repair cost based on number of units per
                % story/floor (Code added on 25 Dec 2019) -- ideally this
                % should be based on the number of damaged units per
                % building
                
                ComponentDataStatus=1;
                Component_Option=1;
            
                CIDs = unique(C_Data(:,1));
                nComponents=length(CIDs);  
                CIDs=sort(CIDs);
                xx=zeros(nComponents,nComponents);
                xx(:,1)=CIDs;
                TimeModel.TimeMatrix =xx;
                TimeModel.Scheme=1;
                TimeModel.SchemeSameComp=1;
                TimeModel.SchemeFloor=1;
                Timestatus=1;
                
                cd (ProjectPath)
                save(ProjectName,'Component_Data','C_Data','ComponentDataStatus','Component_Option','TimeModel','Timestatus','-append')
                pause(0.5)
                cd (MainDirectory)
                        
                app.MainApp.status2.BackgroundColor='g';
                app.MainApp.status2.FontColor='k';
                app.MainApp.status2.Text='Manually defined';
                app.MainApp.status2b.BackgroundColor='g';
                app.MainApp.ResponseButton.Enable='on';
                app.MainApp.FragilityButton.Enable='on';
                app.MainApp.TimeButton.Enable='on';

                app.MainApp.Image5.Visible='off';
        
                closereq
            end
        end

        % Button pushed function: TipIcon1
        function TipIcon1_Callback(app, event)
            str{1}='1- Select a structural/nonstructural component.';
            str{2}='2- Select the story/floor at which this component exists';
            str{3}='3- Select the number of component units per story/floor';
            str{4}='4- Click Add Entry to add component';
            app.TipText.Value=str;
        end

        % Button pushed function: TipIcon2
        function TipIcon2_Callback(app, event)
            str{1}='To delete an Entry,';
            str{2}='1- Specify the Entry number (i.e., row number)';
            str{3}='2- Click Delete Entry';
            app.TipText.Value=str;
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Component Data';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel2 = uipanel(app.figure1);
            app.text34 = uilabel(app.uipanel2);
            app.text34.Text = 'Category';
            app.text31 = uilabel(app.uipanel2);
            app.text31.Text = 'Component';
            app.text30 = uilabel(app.uipanel2);
            app.text30.Text = '# Units';
            app.text33 = uilabel(app.uipanel2);
            app.text33.Text = 'Location';
            app.ComponentPopup = uidropdown(app.uipanel2);
            app.ComponentPopup.Items = {'Column Base Plate', 'Column', 'Beam'};
            app.ComponentPopup.ItemsData = {'1', '2', '3', '4', '5'};
            app.ComponentPopup.ValueChangedFcn = createCallbackFcn(app, @ComponentPopup_Callback, true);
            app.ComponentPopup.Value = '1';
            app.AddButton = uibutton(app.uipanel2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButton_Callback, true);
            app.AddButton.Text = 'Add Entry';
            app.ComponentTable1 = uitable(app.uipanel2);
            app.ComponentTable1.ColumnName = {'Category'; 'Component Name'; 'Index'; 'Location'; '# Units'; '# DS'; 'EDP'; 'Level'};
            app.ComponentTable1.ColumnEditable = [true true true true true true true true];
            app.LocationPopup = uidropdown(app.uipanel2);
            app.LocationPopup.Items = {'Story', 'Floor'};
            app.LocationPopup.ItemsData = {'1', '2'};
            app.LocationPopup.ValueChangedFcn = createCallbackFcn(app, @LocationPopup_Callback, true);
            app.LocationPopup.Value = '1';
            app.SubmitButton = uibutton(app.uipanel2, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.DeleteEntry = uibutton(app.uipanel2, 'push');
            app.DeleteEntry.ButtonPushedFcn = createCallbackFcn(app, @DeleteEntry_Callback, true);
            app.DeleteEntry.Text = 'Delete Entry';
            app.TipIcon2 = uibutton(app.uipanel2, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2_Callback, true);
            app.TipIcon2.Text = '?';
            app.TipIcon1 = uibutton(app.uipanel2, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1_Callback, true);
            app.TipIcon1.Text = '?';
            app.ImportButton = uibutton(app.uipanel2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButton_Callback, true);
            app.ImportButton.Text = 'Import from EXCEL';
            app.CategoryPopup = uidropdown(app.uipanel2);
            app.CategoryPopup.Items = {'Superstructure', 'Roofing', 'Exterior Enclosure', 'Interiors', 'HAVC', 'Fire Protection', 'Electrical', 'Conveying', 'Plumbing', 'Furniture'};
            app.CategoryPopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.CategoryPopup.ValueChangedFcn = createCallbackFcn(app, @CategoryPopup_Callback, true);
            app.CategoryPopup.Value = '1';
            app.TipText = uitextarea(app.uipanel2);
            app.TipText.Value = {''; ''; ''; ''; ''; ''; ''};
            app.ClearButton = uibutton(app.uipanel2, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_Callback, true);
            app.ClearButton.Text = 'Clear Table';
            app.popupmenu10 = uidropdown(app.uipanel2);
            app.popupmenu10.Items = {'Typ.'};
            app.popupmenu10.ItemsData = {'1'};
            app.popupmenu10.Value = '1';
            app.ComponentEdit1 = uieditfield(app.uipanel2, 'numeric');
            app.ComponentEdit1.Limits = [0 Inf];
            app.ComponentEdit2 = uieditfield(app.uipanel2, 'numeric');
            app.ComponentEdit2.Limits = [0 Inf];
            app.ComponentEdit2.ValueDisplayFormat = '%.0f';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Component_Option1_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Component_Option1_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end