classdef Module_Component_Option2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text2         matlab.ui.control.Label
        text5         matlab.ui.control.Label
        Systempopup   matlab.ui.control.DropDown
        text3         matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
        text8         matlab.ui.control.Label
        Luxurypopup   matlab.ui.control.DropDown
        PlotButton    matlab.ui.control.Button
        edit1         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        function TipIcon1_Callback(app)
            app.TipText.Text='';
        end
        
        function [StructuralSystem,StructuralSystemName] = Get_System(app)
            X =str2double(app.Systempopup.Value);
            switch X
                case 1
                    StructuralSystem  = 1;
                    StructuralSystemName ='Steel Moment Frame';
                case 2
                    StructuralSystem  = 2;
                    StructuralSystemName ='Steel Braced Frame - X Bracing';
                case 3
                    StructuralSystem  = 3;
                    StructuralSystemName ='Steel Braced Frame - Chevron';
            end
        end
        
        function [Luxury_Level,Luxury_Level_Name] = Get_Luxury(app)
            X =str2double(app.Luxurypopup.Value);
            switch X
                case 1
                    Luxury_Level  = 1;
                    Luxury_Level_Name  = 'Low';
                case 2
                    Luxury_Level  = 2;
                    Luxury_Level_Name  = 'Medium';
                case 3
                    Luxury_Level  = 3;
                    Luxury_Level_Name  = 'High';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Component_Option2_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'Area_FootPrint','Area_Total','Units')
            cd (MainDirectory)
            
            app.edit1.Value=Area_FootPrint;
            app.edit2.Value=Area_Total;
            
            if Units==1
                app.text2.Text='Floor Area [ft2]';
                app.text3.Text='Total Building Floor Area [ft2]';
            else
                app.text2.Text='Floor Area [m2]';
                app.text3.Text='Total Building Floor Area [m2]';
            end
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'Units');
            cd (MainDirectory)
            
            [StructuralSystem,StructuralSystemName]=Get_System(app);
            [Luxury_Level,Luxury_Level_Name]=Get_Luxury(app);
            Area_FootPrint =app.edit1.Value;
            Area_Total=app.edit2.Value;
            
            Plot_LossvsEDP_Fragility_Option2 (Units,StructuralSystem,Luxury_Level,Area_FootPrint,Area_Total);

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'Units');
            cd (MainDirectory)
            
            [StructuralSystem,StructuralSystemName]=Get_System(app);
            [Luxury_Level,Luxury_Level_Name]=Get_Luxury(app);
            
            ComponentDataStatus=1;
            Component_Option=2;
            
            cd (ProjectPath)
            save(ProjectName,'Luxury_Level','StructuralSystem','ComponentDataStatus','Component_Option','Luxury_Level_Name','StructuralSystemName','-append');
            cd (MainDirectory)
            
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='Loss-EDP Functions';
            app.MainApp.status2b.BackgroundColor='g';
            app.MainApp.ResponseButton.Enable='on';
            app.MainApp.FragilityButton.Enable='on';
            app.MainApp.TimeButton.Enable='off';  

            pause(0.5);
            
            figure1CloseRequest(app, event)

        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Loss-EDP Fragility Functions';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Floor Area [ft2]';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Structural System';
            app.Systempopup = uidropdown(app.uipanel1);
            app.Systempopup.Items = {'Steel Moment Frame', 'Steel Braced Frame - X Bracing', 'Steel Braced Frame - Chevron'};
            app.Systempopup.ItemsData = {'1', '2', '3'};
            app.Systempopup.Value = '1';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Total Building Floor Area [ft2]';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = 'Luxury Level (Contents)';
            app.Luxurypopup = uidropdown(app.uipanel1);
            app.Luxurypopup.Items = {'Low Luxury', 'Medium luxury', 'High Luxury'};
            app.Luxurypopup.ItemsData = {'1', '2', '3'};
            app.Luxurypopup.Value = '1';
            app.PlotButton = uibutton(app.uipanel1, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Text = 'Show Loss-EDP Curves';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0.001 Inf];
            app.edit1.ValueDisplayFormat = '%.2f';
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [0.001 Inf];
            app.edit2.ValueDisplayFormat = '%.2f';
            app.edit2.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Component_Option2_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Component_Option2_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end