classdef Module_Component_Option3_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        text5           matlab.ui.control.Label
        Framepopup      matlab.ui.control.DropDown
        SubmitButton    matlab.ui.control.Button
        text8           matlab.ui.control.Label
        Ductilitypopup  matlab.ui.control.DropDown
        PlotButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        
        function [DuctilityType] = Get_Ductility(app)
            X = str2double(app.Framepopup.Value);
            switch X
                case 1
                    DuctilityType  = 1;
                case 2
                    DuctilityType  = 2;
            end
        end
        
        function [FrameType] = Get_FrameType(app)
            X = str2double(app.Framepopup.Value);
            switch X
                case 1
                    FrameType  = 1;
                case 2
                    FrameType  = 2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Component_Option3_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        end

        % Value changed function: Ductilitypopup
        function Ductilitypopup_Callback(app, event)
            value = app.Ductilitypopup.Value;
            
        end

        % Value changed function: Framepopup
        function Framepopup_Callback(app, event)
            value = app.Framepopup.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'Units','N_Story','Replacement_Cost');
            cd (MainDirectory)
            
            [FrameType] = Get_FrameType(app);
            [DuctilityType]=Get_Ductility(app);
            
            Plot_LossvsEDP_Fragility_Option3 (Units,N_Story,Replacement_Cost,FrameType,DuctilityType)
            
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            
            [FrameType] = Get_FrameType(app);
            [DuctilityType]=Get_Ductility(app);
            
            ComponentDataStatus=1;
            Component_Option=3;
            
            cd (ProjectPath)
            save(ProjectName,'FrameType','DuctilityType','ComponentDataStatus','Component_Option','-append');
            pause(0.5)
            cd (MainDirectory)
            
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='Loss-EDP Functions';
            app.MainApp.status2b.BackgroundColor='g';
            app.MainApp.ResponseButton.Enable='on';
            app.MainApp.FragilityButton.Enable='on';
            app.MainApp.TimeButton.Enable='off';  
            app.MainApp.Image5.Visible='off';
            
            figure1CloseRequest(app, event)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Loss-EDP Fragility Functions';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'Frame Type';
            app.Framepopup = uidropdown(app.uipanel1);
            app.Framepopup.Items = {'Perimeter Frame', 'Space Frame'};
            app.Framepopup.ItemsData = {'1', '2'};
            app.Framepopup.ValueChangedFcn = createCallbackFcn(app, @Framepopup_Callback, true);
            app.Framepopup.Value = '1';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Text = 'Frame Ductility';
            app.Ductilitypopup = uidropdown(app.uipanel1);
            app.Ductilitypopup.Items = {'Ductile', 'Non-Ductile'};
            app.Ductilitypopup.ItemsData = {'1', '2'};
            app.Ductilitypopup.ValueChangedFcn = createCallbackFcn(app, @Ductilitypopup_Callback, true);
            app.Ductilitypopup.Value = '1';
            app.PlotButton = uibutton(app.uipanel1, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Text = 'Show Loss-EDP Curves';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Component_Option3_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Component_Option3_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end