classdef Module_Demolition_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        DemolitionFragilityButtonGroup  matlab.ui.container.ButtonGroup
        radiobutton1                    matlab.ui.control.RadioButton
        radiobutton2                    matlab.ui.control.RadioButton
        radiobutton3                    matlab.ui.control.RadioButton
        text18                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        text39                          matlab.ui.control.Label
        text34                          matlab.ui.control.Label
        text38                          matlab.ui.control.Label
        edit3                           matlab.ui.control.NumericEditField
        edit4                           matlab.ui.control.NumericEditField
        edit5                           matlab.ui.control.NumericEditField
        edit6                           matlab.ui.control.NumericEditField
        edit7                           matlab.ui.control.NumericEditField
        edit1                           matlab.ui.control.NumericEditField
        edit2                           matlab.ui.control.NumericEditField
        text18_2                        matlab.ui.control.Label
        text18_3                        matlab.ui.control.Label
        text19_2                        matlab.ui.control.Label
        text39_2                        matlab.ui.control.Label
        text18_4                        matlab.ui.control.Label
        text18_5                        matlab.ui.control.Label
        text19_3                        matlab.ui.control.Label
        text18_6                        matlab.ui.control.Label
        TipButton                       matlab.ui.control.Button
        linkbutton1                     matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        ShowDemFragButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
        TipWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Demolition_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';            

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'DemolitionStatus','Demolition_Option','DemolishMedianRDR','DemolishSigmaRDR','DemolishMedianVRD','DemolishSigmaVRD','DemolishCorr','Units','Data_Status')
            cd (MainDirectory)
            
            if Demolition_Option==1
                app.radiobutton1.Value=1;
            elseif Demolition_Option==2
                app.radiobutton2.Value=1;
                app.edit1.Value=DemolishMedianRDR;
                app.edit2.Value=DemolishSigmaRDR;
            elseif Demolition_Option==3
                app.radiobutton3.Value=1;
                app.edit3.Value=DemolishMedianRDR;
                app.edit4.Value=DemolishSigmaRDR;
                app.edit5.Value=DemolishMedianVRD;
                app.edit6.Value=DemolishSigmaVRD;
                app.edit7.Value=DemolishCorr;
            end
            
            if Units==1
                app.text38.Text='in';
            else
                app.text38.Text='mm';
            end
            
            if Data_Status.RDR==0
                app.radiobutton2.Enable='off';
            end
            if Data_Status.VRD==0
                app.radiobutton3.Enable='off';
            end
            
            ButtonGroupSelectionChanged(app)
            
        end

        % Button pushed function: ShowDemFragButton
        function ShowDemFragButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc
            cd (ProjectPath)
            load(ProjectName,'Units')
            cd (MainDirectory)
            
            if app.radiobutton2.Value==1
                DemolishMedianRDR = app.edit1.Value;
                DemolishSigmaRDR  = app.edit2.Value;

                Plot_Univariate_LognCDF (DemolishMedianRDR, DemolishSigmaRDR)

            end
            if app.radiobutton3.Value==1
                DemolishMedianRDR  = app.edit3.Value;
                DemolishSigmaRDR   = app.edit4.Value;
                DemolishMedianVRD  = app.edit5.Value;
                DemolishSigmaVRD   = app.edit6.Value;
                DemolishCorr       = app.edit7.Value;

                if DemolishCorr==1;  DemolishCorr= 0.99; end
                if DemolishCorr==-1; DemolishCorr=-0.99; end
            
                RangeX1=0.001:0.001:DemolishMedianRDR*3;
                RangeX2=0.001:DemolishMedianVRD*3/100:DemolishMedianVRD*3;
            
                Plot_Correlated_Bivariate_LognCDF (RangeX1, RangeX2, DemolishMedianRDR, DemolishSigmaRDR, DemolishMedianVRD, DemolishSigmaVRD, DemolishCorr,Units,1)
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'Data_Status')
            cd (MainDirectory)
            
            if app.radiobutton1.Value==1
                Demolition_Option=1; % No Demolition is Considered
                % Assign a very large value for the mean of demolition RDR and a very
                % small value for the sigma
                DemolishMedianRDR = 10.0;
                DemolishSigmaRDR  = 0.001;
                DemolishMedianVRD = 1000.0;
                DemolishSigmaVRD  = 0.001;
                DemolishCorr      = 0.0;
            end
            
            if app.radiobutton2.Value==1
                if Data_Status.RDR~=1
                    errordlg(['The RDR data need to be defined first in the Response Data module!']);
                    return
                end
                Demolition_Option=2; % Univariate Demolition Fragility is Considered
                DemolishMedianRDR  = app.edit1.Value;
                DemolishSigmaRDR   = app.edit2.Value;
                DemolishMedianVRD  = 1000.0;
                DemolishSigmaVRD   = 0.01;
                DemolishCorr       = 0.0;
            end
            
            if app.radiobutton3.Value==1
                if Data_Status.RDR~=1
                    errordlg(['The RDR data need to be defined first in the Response Data module!']);
                    return
                end
                if Data_Status.VRD~=1
                    errordlg(['The VRD data need to be defined first in the Response Data module!']);
                    return
                end
                Demolition_Option=3; % Bivariate Demolition Fragility is Considered
                DemolishMedianRDR  = app.edit3.Value;
                DemolishSigmaRDR   = app.edit4.Value;
                DemolishMedianVRD  = app.edit5.Value;
                DemolishSigmaVRD   = app.edit6.Value;
                DemolishCorr       = app.edit7.Value;
            
            end
            
            if DemolishCorr==1;  DemolishCorr= 0.99; end
            if DemolishCorr==-1; DemolishCorr=-0.99; end
            
            DemolitionStatus=1;
            
            cd (ProjectPath)
            pause(0.5)
            save(ProjectName,'DemolitionStatus','Demolition_Option','DemolishMedianRDR','DemolishSigmaRDR','DemolishMedianVRD','DemolishSigmaVRD','DemolishCorr','-append')
            cd (MainDirectory)
            
            if Demolition_Option~=1
                app.MainApp.status20.BackgroundColor='g';
            else                
                app.MainApp.status20.BackgroundColor=[0.65 0.65 0.65];
            end
            
            figure1_CloseRequestFcn(app, event)
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            app.MainApp.Image5.Visible='off';            
            delete(app);
        end

        % Button pushed function: TipButton
        function TipButtonPushed(app, event)
            str{1}='For further information on demolition fragility and recommended parameters per geographical region, check the reference below:';
            str{2}=' ';
            str{3}='Elkady et al (2020). "Proposed methodology for building-specific earthquake loss assessment including column residual axial shortening." Earthquake Engineering and Structural Dynamics, DOI: 10.1002/eqe.3242.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Selection changed function: DemolitionFragilityButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.radiobutton1.Value==1
                app.radiobutton1.FontColor='b';
                app.radiobutton2.FontColor='k';
                app.radiobutton3.FontColor='k';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.edit7.Enable='off';
            elseif app.radiobutton2.Value==1
                app.radiobutton1.FontColor='k';
                app.radiobutton2.FontColor='b';
                app.radiobutton3.FontColor='k';
                app.edit1.Enable='on';
                app.edit2.Enable='on';
                app.edit3.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.edit7.Enable='off';
            elseif app.radiobutton3.Value==1
                app.radiobutton1.FontColor='k';
                app.radiobutton2.FontColor='k';
                app.radiobutton3.FontColor='b';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='on';
                app.edit4.Enable='on';
                app.edit5.Enable='on';
                app.edit6.Enable='on';
                app.edit7.Enable='on';
            end
        end

        % Button pushed function: linkbutton1
        function linkbutton1ButtonPushed(app, event)
            web('https://onlinelibrary.wiley.com/doi/10.1002/eqe.3242','-new')            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Demolition Fragility';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.DemolitionFragilityButtonGroup = uibuttongroup(app.figure1);
            app.DemolitionFragilityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DemolitionFragilityButtonGroup.Title = 'Demolition Fragility';
            app.radiobutton1 = uiradiobutton(app.DemolitionFragilityButtonGroup);
            app.radiobutton1.Text = 'No demolition';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.DemolitionFragilityButtonGroup);
            app.radiobutton2.Text = 'Univariate demolition fragility';
            app.radiobutton3 = uiradiobutton(app.DemolitionFragilityButtonGroup);
            app.radiobutton3.Text = 'Bivariate demolition fragility';
            app.text18 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18.Tooltip = 'Median value of the residual drift ratio  (i.e., central tendency of the RDR dataset)';
            app.text18.Text = 'μ';
            app.text19 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text19.Tooltip = 'Standard deviation of the ln(RDR) dataset';
            app.text19.Text = 'β';
            app.text39 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text39.Text = 'rad';
            app.text34 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text34.Tooltip = {'Correlation coefficient between lnRDR and lnVRD'};
            app.text34.Text = 'ρ';
            app.text38 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text38.Text = 'mm';
            app.edit3 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit3.Limits = [0.0001 Inf];
            app.edit3.Value = 0.01;
            app.edit4 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit4.Limits = [0.0001 Inf];
            app.edit4.Value = 0.3;
            app.edit5 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit5.Limits = [0.0001 Inf];
            app.edit5.Value = 15;
            app.edit6 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit6.Limits = [0.0001 Inf];
            app.edit6.Value = 0.3;
            app.edit7 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit7.Limits = [-1 1];
            app.edit7.Tooltip = {'The correlation coefficient between the ln(RDR) and ln(VRD) data sets'};
            app.edit1 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit1.Limits = [0.0001 Inf];
            app.edit1.Value = 0.01;
            app.edit2 = uieditfield(app.DemolitionFragilityButtonGroup, 'numeric');
            app.edit2.Limits = [0.0001 Inf];
            app.edit2.Value = 0.3;
            app.text18_2 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18_2.Tooltip = 'Median value of the residual drift ratio  (i.e., central tendency of the RDR dataset)';
            app.text18_2.Text = 'RDR :';
            app.text18_3 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18_3.Tooltip = 'Median value of the residual drift ratio  (i.e., central tendency of the RDR dataset)';
            app.text18_3.Text = 'μ';
            app.text19_2 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text19_2.Tooltip = 'Standard deviation of the ln(RDR) dataset';
            app.text19_2.Text = 'β';
            app.text39_2 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text39_2.Text = 'rad';
            app.text18_4 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18_4.Tooltip = 'Median value of the residual drift ratio  (i.e., central tendency of the RDR dataset)';
            app.text18_4.Text = 'RDR :';
            app.text18_5 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18_5.Tooltip = {'Median value of the residual veritical residual deformation (i.e.'; ' central tendency of the VRD dataset)'};
            app.text18_5.Text = 'μ';
            app.text19_3 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text19_3.Tooltip = {'Standard deviation of the ln(VRD) dataset'};
            app.text19_3.Text = 'β';
            app.text18_6 = uilabel(app.DemolitionFragilityButtonGroup);
            app.text18_6.Tooltip = {'Median value of the vertical residual deformation (i.e.'; ' central tendency of the VRD dataset)'};
            app.text18_6.Text = 'VRD :';
            app.TipButton = uibutton(app.DemolitionFragilityButtonGroup, 'push');
            app.TipButton.ButtonPushedFcn = createCallbackFcn(app, @TipButtonPushed, true);
            app.TipButton.Text = '?';
            app.linkbutton1 = uibutton(app.DemolitionFragilityButtonGroup, 'push');
            app.linkbutton1.ButtonPushedFcn = createCallbackFcn(app, @linkbutton1ButtonPushed, true);
            app.linkbutton1.Tooltip = {'link to publication'};
            app.linkbutton1.Text = 'link ↗';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.ShowDemFragButton = uibutton(app.figure1, 'push');
            app.ShowDemFragButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDemFragButton_Callback, true);
            app.ShowDemFragButton.Text = 'Plot Fragility Curve';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Demolition_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Demolition_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end