classdef Module_Fragility_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        ButtonGroup           matlab.ui.container.ButtonGroup
        radio1                matlab.ui.control.RadioButton
        radio2                matlab.ui.control.RadioButton
        radio3                matlab.ui.control.RadioButton
        Image_2               matlab.ui.control.Image
        Image_3               matlab.ui.control.Image
        Image                 matlab.ui.control.Image
        text16                matlab.ui.control.Label
        text17                matlab.ui.control.Label
        text19                matlab.ui.control.Label
        text20                matlab.ui.control.Label
        text22                matlab.ui.control.Label
        text23                matlab.ui.control.Label
        NextButton            matlab.ui.control.Button
        ImportDatabaseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Fragility_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
            
            app.radio1.Value=1;
            ButtonGroupSelectionChanged(app)
        end

        % Button pushed function: NextButton
        function NextButton_Callback(app, event)
            if     app.radio1.Value==1; Module_Fragility_Explorer_App(app.MainApp)
            elseif app.radio2.Value==1; Module_Fragility_Univariate_App(app.MainApp)
            elseif app.radio3.Value==1; Module_Fragility_Multivariate_App(app.MainApp)
            end
            
            closereq
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.radio1.FontColor='k';
            app.radio2.FontColor='k';
            app.radio3.FontColor='k';
            
            if     app.radio1.Value==1; app.radio1.FontColor='b';
            elseif app.radio2.Value==1; app.radio2.FontColor='b';
            elseif app.radio3.Value==1; app.radio3.FontColor='b';
            end            
        end

        % Button pushed function: ImportDatabaseButton
        function ImportDatabaseButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            MatFilesPath = uigetdir(MainDirectory,'Select the folder containing the COMPDATA & FRADATA .mat files');
            if isempty(MatFilesPath) || ischar(MatFilesPath)==0
                return
            end
            
            cd(MatFilesPath)
            if exist ('COMPDATA.mat')==0 || exist ('FRAGDATA.mat')==0; errordlg('Either COMPDATA.mat or FRAGDATA.mat can not be found in the specfied folder!'); return; end
            load ('COMPDATA.mat');
            load ('FRAGDATA.mat');
            cd(ProjectPath)
            save(ProjectName,'COMPDATA','FRAGDATA','-append')
            cd(MainDirectory)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Fragility Data Module';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.radio1 = uiradiobutton(app.ButtonGroup);
            app.radio1.Text = 'Explore/Edit Fragility Database';
            app.radio1.Value = true;
            app.radio2 = uiradiobutton(app.ButtonGroup);
            app.radio2.Text = 'Add Univariate Fragility';
            app.radio3 = uiradiobutton(app.ButtonGroup);
            app.radio3.Enable = 'off';
            app.radio3.Text = 'Add Multivariate Fragility';
            app.Image_2 = uiimage(app.ButtonGroup);
            app.Image_2.ImageSource = 'Univariate.png';
            app.Image_3 = uiimage(app.ButtonGroup);
            app.Image_3.Enable = 'off';
            app.Image_3.ImageSource = 'Bivariate.png';
            app.Image = uiimage(app.ButtonGroup);
            app.Image.ImageSource = 'Explorer.png';
            app.text16 = uilabel(app.ButtonGroup);
            app.text16.Enable = 'off';
            app.text16.Text = 'Define and add a new mutivariate';
            app.text17 = uilabel(app.ButtonGroup);
            app.text17.Enable = 'off';
            app.text17.Text = 'fragility function';
            app.text19 = uilabel(app.ButtonGroup);
            app.text19.Text = 'Define and add a new univariate';
            app.text20 = uilabel(app.ButtonGroup);
            app.text20.Text = 'lognormal fragility function';
            app.text22 = uilabel(app.ButtonGroup);
            app.text22.Text = 'Explore and edit the existing';
            app.text23 = uilabel(app.ButtonGroup);
            app.text23.Text = 'FEMA P58 damage fragility databse';
            app.NextButton = uibutton(app.figure1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButton_Callback, true);
            app.NextButton.Text = 'Next';
            app.ImportDatabaseButton = uibutton(app.figure1, 'push');
            app.ImportDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatabaseButtonPushed, true);
            app.ImportDatabaseButton.Text = 'Import Database';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Fragility_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Fragility_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end