classdef Module_Fragility_Edit_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel3         matlab.ui.container.Panel
        text19           matlab.ui.control.Label
        text17           matlab.ui.control.Label
        EDPpopup         matlab.ui.control.DropDown
        CategoryPopup    matlab.ui.control.DropDown
        text12           matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text9            matlab.ui.control.Label
        text8            matlab.ui.control.Label
        text7            matlab.ui.control.Label
        text6            matlab.ui.control.Label
        ComponentPopup   matlab.ui.control.DropDown
        DSpopup          matlab.ui.control.DropDown
        edit2            matlab.ui.control.EditField
        UnitTypepopup    matlab.ui.control.DropDown
        edit3            matlab.ui.control.NumericEditField
        edit4            matlab.ui.control.NumericEditField
        edit5            matlab.ui.control.NumericEditField
        edit8            matlab.ui.control.NumericEditField
        text9_2          matlab.ui.control.Label
        Ppopup           matlab.ui.control.DropDown
        edit10           matlab.ui.control.NumericEditField
        text9_3          matlab.ui.control.Label
        text10_2         matlab.ui.control.Label
        edit6            matlab.ui.control.NumericEditField
        text12_2         matlab.ui.control.Label
        text9_4          matlab.ui.control.Label
        text10_3         matlab.ui.control.Label
        edit11           matlab.ui.control.NumericEditField
        edit12           matlab.ui.control.NumericEditField
        CostQPpopup      matlab.ui.control.DropDown
        TimeQPpopup      matlab.ui.control.DropDown
        text20           matlab.ui.control.Label
        Costfitfunpopup  matlab.ui.control.DropDown
        Timefitfunpopup  matlab.ui.control.DropDown
        text12_3         matlab.ui.control.Label
        text9_5          matlab.ui.control.Label
        text10_4         matlab.ui.control.Label
        edit13           matlab.ui.control.NumericEditField
        edit14           matlab.ui.control.NumericEditField
        Casualtypopup    matlab.ui.control.DropDown
        text12_4         matlab.ui.control.Label
        text9_6          matlab.ui.control.Label
        text10_5         matlab.ui.control.Label
        edit15           matlab.ui.control.NumericEditField
        edit16           matlab.ui.control.NumericEditField
        text12_5         matlab.ui.control.Label
        text10_6         matlab.ui.control.Label
        edit17           matlab.ui.control.NumericEditField
        text12_6         matlab.ui.control.Label
        Placardpopup     matlab.ui.control.DropDown
        text9_7          matlab.ui.control.Label
        text10_7         matlab.ui.control.Label
        edit18           matlab.ui.control.NumericEditField
        edit19           matlab.ui.control.NumericEditField
        ApplyButton      matlab.ui.control.Button
        SubmitButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        function Update_Fields(app, DS_EDP, FRAGDATA, idxxx)
        
            app.text9.Text=['EDP: ',DS_EDP];
            if     DS_EDP=='SDR';       app.EDPpopup.Value='1';
            elseif DS_EDP=='PFA';       app.EDPpopup.Value='2';
            elseif DS_EDP=='PFV';       app.EDPpopup.Value='3';
            elseif DS_EDP=='LINK ROT';  app.EDPpopup.Value='4';
            elseif DS_EDP=='BEAM ROT';  app.EDPpopup.Value='5';
            elseif DS_EDP=='COL ROT';   app.EDPpopup.Value='6';
            elseif DS_EDP=='RDR';       app.EDPpopup.Value='7';
            elseif DS_EDP=='DWD';       app.EDPpopup.Value='8';
            elseif DS_EDP=='VRD';       app.EDPpopup.Value='9';
            elseif DS_EDP=='RD';        app.EDPpopup.Value='10';
            elseif DS_EDP=='ED';        app.EDPpopup.Value='11';
            elseif DS_EDP=='GENS1';     app.EDPpopup.Value='12';
            elseif DS_EDP=='GENS2';     app.EDPpopup.Value='13';
            elseif DS_EDP=='GENS3';     app.EDPpopup.Value='14';
            elseif DS_EDP=='GENF1';     app.EDPpopup.Value='15';
            elseif DS_EDP=='GENF2';     app.EDPpopup.Value='16';
            elseif DS_EDP=='GENF3';     app.EDPpopup.Value='17';
            end
            
            DS_EDPmedian = FRAGDATA.DS_EDPmedian(idxxx,1); if isnan(DS_EDPmedian); DS_EDPmedian=999; end
            DS_EDPsigma  = FRAGDATA.DS_EDPsigma (idxxx,1); if isnan(DS_EDPsigma);  DS_EDPsigma=999;  end; if DS_EDPsigma==0.0; DS_EDPsigma=0.001; end
            DS_CostP50   = FRAGDATA.DS_CostP50  (idxxx,1);
            DS_Costsigma = FRAGDATA.DS_Costsigma(idxxx,1);
            DS_TimeP50   = FRAGDATA.DS_TimeP50  (idxxx,1);
            DS_Timesigma = FRAGDATA.DS_Timesigma(idxxx,1);
            UnitVal      = FRAGDATA.DS_UnitVal  (idxxx,1);
            UnitType     = FRAGDATA.DS_UnitType (idxxx,1);
            DS_P         = FRAGDATA.DS_P        (idxxx,1);
            Hierarchy    = FRAGDATA.DS_Hierarchy{idxxx,1};
            Costfitfun   = FRAGDATA.DS_CostFitFun{idxxx,1};
            Timefitfun   = FRAGDATA.DS_TimeFitFun{idxxx,1};
            Casualty     = FRAGDATA.DS_Casualty(idxxx,1);
            Casualtymu   = FRAGDATA.DS_CasualtyRatemedian(idxxx,1);
            Casualtysigma= FRAGDATA.DS_CasualtyRatesigma(idxxx,1);
            Injurymu     = FRAGDATA.DS_InjuryRatemedian(idxxx,1);
            Injurysigma  = FRAGDATA.DS_InjuryRatesigma(idxxx,1);
            AffectArea   = FRAGDATA.DS_AffectArea(idxxx,1);
            PlacardFlag  = FRAGDATA.DS_UnsafePlacardFlag(idxxx,1);
            Placardmu    = FRAGDATA.DS_UnsafePlacardmedian(idxxx,1);
            Placardsigma = FRAGDATA.DS_UnsafePlacardsigma(idxxx,1);
            
            if strcmp(Hierarchy,'Sequential')==1;         app.Ppopup.Value='1'; end
            if strcmp(Hierarchy,'Mutually Exclusive')==1; app.Ppopup.Value='2'; end
            if strcmp(Hierarchy,'Simultaneous')==1;       app.Ppopup.Value='3'; end
            
            if strcmp(Costfitfun,'Normal')==1;       app.Costfitfunpopup.Value='1'; else; app.Costfitfunpopup.Value='2'; end
            if strcmp(Timefitfun,'LogNormal')==1;    app.Timefitfunpopup.Value='1'; else; app.Timefitfunpopup.Value='2'; end

            app.edit10.Value=DS_P;

            app.edit3.Value=DS_EDPmedian;
            app.edit4.Value=DS_EDPsigma;
            
            app.CostQPpopup.Value='2';
            app.edit5.Value=DS_CostP50;
            app.edit6.Value=DS_Costsigma;
            
            app.TimeQPpopup.Value='2';
            app.edit11.Value=DS_TimeP50;
            app.edit12.Value=DS_Timesigma;
            
            app.edit8.Value=UnitVal;
            
            if     UnitType=='EA'; app.UnitTypepopup.Value=1;
            elseif UnitType=='EU'; app.UnitTypepopup.Value=1;
            elseif UnitType=='EP'; app.UnitTypepopup.Value=1;
            elseif UnitType=='LF'; app.UnitTypepopup.Value=2;
            elseif UnitType=='SF'; app.UnitTypepopup.Value=3;
            end
            app.UnitTypepopup.Enable='off';

            if  Casualty=='YES'; app.Casualtypopup.Value='2'; else; app.Casualtypopup.Value='1'; end 
            app.edit13.Value=Casualtymu;
            app.edit14.Value=Casualtysigma;
            app.edit15.Value=Injurymu;
            app.edit16.Value=Injurysigma;
            app.edit17.Value=AffectArea;
            app.edit18.Value=Placardmu;
            app.edit19.Value=Placardsigma;
            if  PlacardFlag=='YES'; app.Placardpopup.Value='2'; else; app.Placardpopup.Value='1'; end 
            
        end
        
        function [DS_EDP] = Get_DS_EDP(app)
            X=str2double(app.EDPpopup.Value);
            switch X
                case 1; DS_EDP='SDR';
                case 2; DS_EDP='PFA';
                case 3; DS_EDP='PFV';
                case 4; DS_EDP='LINK ROT';
                case 5; DS_EDP='BEAM ROT';
                case 6; DS_EDP='COL ROT';
                case 7; DS_EDP='RDR';
                case 8; DS_EDP='DWD';
                case 9; DS_EDP='VRD';
                case 10;DS_EDP='RD';
                case 11;DS_EDP='ED';
                case 12;DS_EDP='GENS1';
                case 13;DS_EDP='GENS2';
                case 14;DS_EDP='GENS3';
                case 15;DS_EDP='GENF1';
                case 16;DS_EDP='GENF2';
                case 17;DS_EDP='GENF3';
            end
        end
        
        function Get_Data_and_Save(app)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'FRAGDATA','COMPDATA','FilteredIDList','Component_Data','C_Data','ComponentDataStatus','Component_Option')
            pause(0.5)
            cd (MainDirectory)
            
            if isa(app.ComponentPopup.Value,'double')==1; i=app.ComponentPopup.Value; else; i =str2double(app.ComponentPopup.Value); end
            if isa(app.DSpopup.Value,'double')==1; j=app.DSpopup.Value; else; j =str2double(app.DSpopup.Value); end
            Component_ID=FilteredIDList(i,1);
            idx=find(COMPDATA.C_ID==Component_ID);
            idxx=find(FRAGDATA.C_ID==Component_ID);
            idxxx=idxx(j,1);
            
            nDS        = app.DSpopup.Value;
            DSname     = app.edit2.Value;
            [DS_EDP]   = Get_DS_EDP(app);
            EDPmedian  = app.edit3.Value;
            EDPsigma   = app.edit4.Value;
            Costmu     = app.edit5.Value;
            Costsigma  = app.edit6.Value;
            Timemu     = app.edit11.Value;
            Timesigma  = app.edit12.Value;
            UnitVal    = app.edit8.Value;
            P          = app.edit10.Value;
            CostPQ     = str2double(app.CostQPpopup.Value);
            TimePQ     = str2double(app.TimeQPpopup.Value);
            Hierarchy  = str2double(app.Ppopup.Value);
            Costfitfun = str2double(app.Costfitfunpopup.Value);
            Timefitfun = str2double(app.Timefitfunpopup.Value);
            if  app.Casualtypopup.Value=='1'; Casualty="NO"; else; Casualty="YES"; end 
            Casualtymu=app.edit13.Value;
            Casualtysigma=app.edit14.Value;
            Injurymu=app.edit15.Value;
            Injurysigma=app.edit16.Value;
            AffectArea=app.edit17.Value;
            Placardmu=app.edit18.Value;
            Placardsigma=app.edit19.Value;
            if  app.Placardpopup.Value=='1'; Placard='NO'; else; Placard='YES'; end 
            
            
            % Edit the component DS data in the COMPDATA matrix
            COMPDATA.C_EDP(idx,1)=DS_EDP;            % Component EDP

            % Edit the component DS data in the FRAGDATA matrix
            FRAGDATA.DS_name{idxxx,1}=DSname;           % DS Description
            FRAGDATA.DS_EDP(idxx(1,1):idxxx,1)=DS_EDP;  % DS EDP % Modified on 31Oct2019, so that a change in DS_EDP will affect all DS not just the one selected
            FRAGDATA.DS_EDPmedian(idxxx,1)=EDPmedian;   % Median EDP
            FRAGDATA.DS_EDPsigma(idxxx,1)=EDPsigma;     % Sigma  EDP
            FRAGDATA.DS_CostP50(idxxx,1)=Costmu;
            if CostPQ==1; FRAGDATA.DS_Costmean(idxxx,1)=Costmu;end       % Repair Cost mu
            if CostPQ==2; FRAGDATA.DS_CostP50(idxxx,1)=Costmu; end       % Repair Cost mu
            if CostPQ==3; FRAGDATA.DS_CostP90(idxxx,1)=Costmu; end       % Repair Cost mu
            if CostPQ==4; FRAGDATA.DS_CostP10(idxxx,1)=Costmu; end       % Repair Cost mu
            FRAGDATA.DS_Costsigma(idxxx,1)=Costsigma;   % Repair Cost sigma
            if Costfitfun==1; FRAGDATA.DS_CostFitFun{idxxx,1}='Normal';    end       % Repair Cost fit function
            if Costfitfun==2; FRAGDATA.DS_CostFitFun{idxxx,1}='LogNormal'; end       % Repair Cost fit function
            FRAGDATA.DS_TimeP50(idxxx,1)=Timemu;
            if TimePQ==1; FRAGDATA.DS_Timemean(idxxx,1)=Timemu;end       % Repair Time mu
            if TimePQ==2; FRAGDATA.DS_TimeP50(idxxx,1)=Timemu; end       % Repair Time mu
            if TimePQ==3; FRAGDATA.DS_TimeP90(idxxx,1)=Timemu; end       % Repair Time mu
            if TimePQ==4; FRAGDATA.DS_TimeP10(idxxx,1)=Timemu; end       % Repair Time mu
            FRAGDATA.DS_Timesigma(idxxx,1)=Timesigma;   % Repair Time sigma
            if Timefitfun==1; FRAGDATA.DS_TimeFitFun{idxxx,1}='Normal';    end       % Time fit function
            if Timefitfun==2; FRAGDATA.DS_TimeFitFun{idxxx,1}='LogNormal'; end       % Time fit function
            FRAGDATA.DS_UnitVal(idxxx,1)=UnitVal;       % UnitVal
            FRAGDATA.DS_P(idxxx,1)=P;                   % Complementary Probability
            if Hierarchy==1; FRAGDATA.DS_Hierarchy{idxxx,1}='Sequential';          end  % Hierarchy
            if Hierarchy==2; FRAGDATA.DS_Hierarchy{idxxx,1}='Mutually Exclusive';  end  % Hierarchy
            if Hierarchy==3; FRAGDATA.DS_Hierarchy{idxxx,1}='Simultaneous';        end  % Hierarchy
            if Hierarchy==1; COMPDATA.C_DS_Hierarchy{idx,1}='Seq';    end  % Hierarchy
            if Hierarchy==2; COMPDATA.C_DS_Hierarchy{idx,1}='MutEx';  end  % Hierarchy
            if Hierarchy==3; COMPDATA.C_DS_Hierarchy{idx,1}='Simul';  end  % Hierarchy
            FRAGDATA.DS_Casualty           (idxxx,1)=Casualty;     % Type of Fragility
            FRAGDATA.DS_UnsafePlacardFlag  (idxxx,1)=Placard;      % Type of Fragility
            FRAGDATA.DS_InjuryRatemedian   (idxxx,1)=Injurymu;     % Type of Fragility
            FRAGDATA.DS_InjuryRatesigma    (idxxx,1)=Injurysigma;  % Type of Fragility
            FRAGDATA.DS_CasualtyRatemedian (idxxx,1)=Casualtymu;   % Type of Fragility
            FRAGDATA.DS_CasualtyRatesigma  (idxxx,1)=Casualtysigma;% Type of Fragility
            FRAGDATA.DS_UnsafePlacardmedian(idxxx,1)=Placardmu;    % Type of Fragility
            FRAGDATA.DS_UnsafePlacardsigma (idxxx,1)=Placardsigma; % Type of Fragility
            FRAGDATA.DS_AffectArea         (idxxx,1)=AffectArea;   % Type of Fragility

            % Update the C_Data matrix if it contains the edited component
            if ComponentDataStatus==1 &&  Component_Option==1
                for i=1:size(Component_Data,1)
                    if C_Data (i,1)==Component_ID
                        if isnumeric(Component_Data{i,3})==1
                            C_Data (i,3)=(Component_Data{i,5})/UnitVal; % Number of Cost Units
                            C_Data (i,4)=(Component_Data{i,6});         % Number of Damage States
                        else
                            C_Data (i,3)=str2double(Component_Data{i,5})/UnitVal;   % Number of Cost Units
                            C_Data (i,4)=str2double(Component_Data{i,6});           % Number of Damage States
                        end
                    end
                end
            
                cd (ProjectPath)
                save(ProjectName,'C_Data','-append')
                pause(0.8)
                cd (MainDirectory)
            end
            
            cd (ProjectPath)
            pause(0.5)
            save(ProjectName,'FRAGDATA','COMPDATA','-append')
            pause(1)
            cd (MainDirectory)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Fragility_Edit_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';

            global tempVar1 tempVar2 Units
            clc
            
            if Units==1
                xx{1}='unit'; xx{2}='ft'; xx{3}='sq. ft';
            else
                xx{1}='unit'; xx{2}='m'; xx{3}='sq. meter';                
            end
            
            app.UnitTypepopup.Items=xx;
            app.UnitTypepopup.ItemsData=1:3;
            
            app.CategoryPopup.Value=num2str(tempVar2);
            CategoryPopup_Callback(app)
            PlacardpopupValueChanged(app)
            CasualtypopupValueChanged(app)
        end

        % Value changed function: CategoryPopup
        function CategoryPopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath tempVar1
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FilteredIDList','FilteredNameList')
            cd (MainDirectory)
            
            clear FilteredIDList FilteredNameList
            
            i =str2double(app.CategoryPopup.Value);
            switch i
                case 1; Category  = 'Superstructure';
                case 2; Category  = 'Roofing';
                case 3; Category  = 'Exterior Enclosure';
                case 4; Category  = 'Interiors';
                case 5; Category  = 'HAVC';
                case 6; Category  = 'Fire Protection';
                case 7; Category  = 'Electrical';
                case 8; Category  = 'Conveying';
                case 9; Category  = 'Plumbing';
                case 10;Category  = 'Furniture';
            end
            
            
            % Get the filtered Component (ID and Name) list based on the User's Category selection
            count=1;
            for i=1:size(COMPDATA.C_ID)
               if COMPDATA.C_Category2(i)==Category
                   FilteredIDList  (count,1) = COMPDATA.C_ID(i);
                   FilteredNameList(count,1) = COMPDATA.C_name(i);
                   count=count+1;
               end
            end
            
            cd (ProjectPath)
            save(ProjectName,'Category','FilteredIDList','FilteredNameList','-append')
            pause(0.5)
            cd (MainDirectory)
            
            app.CategoryPopup.Enable='off';
            
            % Populate the Component popup menu with the component filtered list
            app.ComponentPopup.Items=FilteredNameList;
            app.ComponentPopup.ItemsData=1:size(FilteredNameList,1);
            for i=1:size(FilteredIDList,1)
                if FilteredIDList(i,1)==tempVar1
                    app.ComponentPopup.Value=i;
                    app.ComponentPopup.Enable='off';
                    break;
                end
            end
            ComponentPopup_Callback(app)
        end

        % Value changed function: ComponentPopup
        function ComponentPopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'COMPDATA','FRAGDATA','Category','FilteredIDList','FilteredNameList')
            pause(0.5)
            cd (MainDirectory)
            
            % Get the ID number of the selected component
            i =app.ComponentPopup.Value;
            Component_ID=FilteredIDList(i,1);
            indxCOMPDATA=find(Component_ID==COMPDATA.C_ID);
            N_DS   = COMPDATA.C_nDS(indxCOMPDATA);
            idxx=find(FRAGDATA.C_ID==Component_ID);
            
            % Get the number of damage states and populate the DS popup
            for i=1:size(idxx,1)
                s{i} = ['DS ', num2str(i)];
            end
            % Populate the DS popup menu with the DS number list
            app.DSpopup.Items=s;
            app.DSpopup.ItemsData=1:size(idxx,1);
            app.DSpopup.Value=1;
            DSpopup_Callback(app)
        end

        % Value changed function: DSpopup
        function DSpopup_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'FRAGDATA','FilteredIDList')
            pause(0.5)
            cd (MainDirectory)
            
            i =app.ComponentPopup.Value;
            j =app.DSpopup.Value;
            Component_ID=FilteredIDList(i,1);
            idxx=find(FRAGDATA.C_ID==Component_ID);
            
            idxxx=idxx(j,1);
            DS_discription=FRAGDATA.DS_name(idxxx,1);
            app.edit2.Value=char(DS_discription{1,1});
            DS_EDP=FRAGDATA.DS_EDP(idxxx,1);
            Update_Fields (app, DS_EDP, FRAGDATA, idxxx);
            EDPpopup_Callback(app)
        end

        % Value changed function: EDPpopup
        function EDPpopup_Callback(app, event)
            [DS_EDP] = Get_DS_EDP(app);
            app.text9.Text=['EDP: ',DS_EDP];
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
           Get_Data_and_Save(app)
           figure1CloseRequest(app, event)
        end

        % Value changed function: Ppopup
        function Ppopup_Callback(app, event)
            if app.Ppopup.Value=='1'
               app.edit10.Enable='off';
               app.edit10.Value=1.0;
            else
               app.edit10.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Value changed function: CostQPpopup
        function CostQPpopupValueChanged(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'FRAGDATA','COMPDATA','FilteredIDList','Component_Data','C_Data','ComponentDataStatus','Component_Option')
            pause(0.5)
            cd (MainDirectory)
            
            if isa(app.ComponentPopup.Value,'double')==1; i=app.ComponentPopup.Value; else; i =str2double(app.ComponentPopup.Value); end
            if isa(app.DSpopup.Value,'double')==1; j=app.DSpopup.Value; else; j =str2double(app.DSpopup.Value); end
            Component_ID=FilteredIDList(i,1);
            idx=find(COMPDATA.C_ID==Component_ID);
            idxx=find(FRAGDATA.C_ID==Component_ID);
            idxxx=idxx(j,1);

            X=str2double(app.CostQPpopup.Value);
            switch X
                case 1; app.edit5.Value=FRAGDATA.DS_Costmean(idxxx,1);
                case 2; app.edit5.Value=FRAGDATA.DS_CostP50(idxxx,1);
                case 3; app.edit5.Value=FRAGDATA.DS_CostP90(idxxx,1);
                case 4; app.edit5.Value=FRAGDATA.DS_CostP10(idxxx,1);
            end            
        end

        % Value changed function: TimeQPpopup
        function TimeQPpopupValueChanged(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'FRAGDATA','COMPDATA','FilteredIDList','Component_Data','C_Data','ComponentDataStatus','Component_Option')
            pause(0.5)
            cd (MainDirectory)
            
            if isa(app.ComponentPopup.Value,'double')==1; i=app.ComponentPopup.Value; else; i =str2double(app.ComponentPopup.Value); end
            if isa(app.DSpopup.Value,'double')==1; j=app.DSpopup.Value; else; j =str2double(app.DSpopup.Value); end
            Component_ID=FilteredIDList(i,1);
            idx=find(COMPDATA.C_ID==Component_ID);
            idxx=find(FRAGDATA.C_ID==Component_ID);
            idxxx=idxx(j,1);

            X=str2double(app.TimeQPpopup.Value);
            switch X
                case 1; app.edit5.Value=FRAGDATA.DS_Timemean(idxxx,1);
                case 2; app.edit5.Value=FRAGDATA.DS_TimeP50(idxxx,1);
                case 3; app.edit5.Value=FRAGDATA.DS_TimeP90(idxxx,1);
                case 4; app.edit5.Value=FRAGDATA.DS_TimeP10(idxxx,1);
            end             
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            Get_Data_and_Save(app)
        end

        % Value changed function: Casualtypopup
        function CasualtypopupValueChanged(app, event)
            if app.Casualtypopup.Value=='1'
                app.edit13.Enable='off';
                app.edit14.Enable='off';
                app.edit15.Enable='off';
                app.edit16.Enable='off';
                app.edit17.Enable='off';
                app.edit13.Value=0;
                app.edit14.Value=0;                
                app.edit15.Value=0;                
                app.edit16.Value=0;                
                app.edit17.Value=0;                
            else
                app.edit13.Enable='on';
                app.edit14.Enable='on';
                app.edit15.Enable='on';
                app.edit16.Enable='on';
                app.edit17.Enable='on';
            end  
        end

        % Value changed function: Placardpopup
        function PlacardpopupValueChanged(app, event)
            if app.Placardpopup.Value=='1'
                app.edit18.Enable='off';
                app.edit19.Enable='off';
                app.edit18.Value=0;
                app.edit19.Value=0;                
            else
                app.edit18.Enable='on';
                app.edit19.Enable='on';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Edit a FEMA-P58 Univariate Fragility';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Edit Univariate Fragility Data';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Text = 'Damage State Description';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Text = 'Select Category';
            app.EDPpopup = uidropdown(app.uipanel3);
            app.EDPpopup.Items = {'SDR', 'PFA', 'PGV', 'LINK ROT', 'BEAM ROT', 'COL ROT', 'RDR', 'DWD', 'VRD', 'RD', 'ED', 'GENS1', 'GENS2', 'GENS3', 'GENF1', 'GENF2', 'GENF3'};
            app.EDPpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17'};
            app.EDPpopup.ValueChangedFcn = createCallbackFcn(app, @EDPpopup_Callback, true);
            app.EDPpopup.Value = '1';
            app.CategoryPopup = uidropdown(app.uipanel3);
            app.CategoryPopup.Items = {'Superstructure', 'Roofing', 'Exterior Enclosure', 'Interiors', 'HAVC', 'Fire Protection', 'Electrical', 'Conveying', 'Plumbing'};
            app.CategoryPopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.CategoryPopup.ValueChangedFcn = createCallbackFcn(app, @CategoryPopup_Callback, true);
            app.CategoryPopup.Value = '1';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Text = 'Cost:';
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10.Text = 'β';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tooltip = 'Central tendency of the EDP dataset';
            app.text9.Text = 'EDP:';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Text = 'Damage State EDP';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = 'Select Damage State';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Text = 'Select Component';
            app.ComponentPopup = uidropdown(app.uipanel3);
            app.ComponentPopup.Items = {'Column Base Plate', 'Column', 'Beam'};
            app.ComponentPopup.ItemsData = {'1', '2', '3'};
            app.ComponentPopup.ValueChangedFcn = createCallbackFcn(app, @ComponentPopup_Callback, true);
            app.ComponentPopup.Value = '1';
            app.DSpopup = uidropdown(app.uipanel3);
            app.DSpopup.Items = {'1'};
            app.DSpopup.ItemsData = {'1'};
            app.DSpopup.ValueChangedFcn = createCallbackFcn(app, @DSpopup_Callback, true);
            app.DSpopup.Value = '1';
            app.edit2 = uieditfield(app.uipanel3, 'text');
            app.UnitTypepopup = uidropdown(app.uipanel3);
            app.UnitTypepopup.Items = {'unit', 'ft', 'ft2'};
            app.UnitTypepopup.ItemsData = {'1', '2', '3'};
            app.UnitTypepopup.Value = '1';
            app.edit3 = uieditfield(app.uipanel3, 'numeric');
            app.edit3.Limits = [0.0001 Inf];
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.uipanel3, 'numeric');
            app.edit4.Limits = [0.0001 Inf];
            app.edit4.ValueDisplayFormat = '%.2f';
            app.edit4.Value = 0.3;
            app.edit5 = uieditfield(app.uipanel3, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.Tooltip = {'value in $'};
            app.edit5.Value = 1;
            app.edit8 = uieditfield(app.uipanel3, 'numeric');
            app.edit8.Limits = [0.0001 Inf];
            app.edit8.Value = 1;
            app.text9_2 = uilabel(app.uipanel3);
            app.text9_2.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_2.Text = 'μ';
            app.Ppopup = uidropdown(app.uipanel3);
            app.Ppopup.Items = {'Sequential', 'Mutually Exclusive', 'Simultaneous'};
            app.Ppopup.ItemsData = {'1', '2', '3'};
            app.Ppopup.ValueChangedFcn = createCallbackFcn(app, @Ppopup_Callback, true);
            app.Ppopup.Value = '1';
            app.edit10 = uieditfield(app.uipanel3, 'numeric');
            app.edit10.Limits = [0 1];
            app.edit10.Value = 1;
            app.text9_3 = uilabel(app.uipanel3);
            app.text9_3.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_3.Text = 'μ';
            app.text10_2 = uilabel(app.uipanel3);
            app.text10_2.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_2.Text = 'β';
            app.edit6 = uieditfield(app.uipanel3, 'numeric');
            app.edit6.Limits = [0.0001 Inf];
            app.edit6.ValueDisplayFormat = '%.2f';
            app.edit6.Value = 0.3;
            app.text12_2 = uilabel(app.uipanel3);
            app.text12_2.Text = 'Time:';
            app.text9_4 = uilabel(app.uipanel3);
            app.text9_4.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_4.Text = 'μ';
            app.text10_3 = uilabel(app.uipanel3);
            app.text10_3.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_3.Text = 'β';
            app.edit11 = uieditfield(app.uipanel3, 'numeric');
            app.edit11.Limits = [0 Inf];
            app.edit11.Tooltip = {'value in hours'};
            app.edit11.Value = 1;
            app.edit12 = uieditfield(app.uipanel3, 'numeric');
            app.edit12.Limits = [0.0001 Inf];
            app.edit12.ValueDisplayFormat = '%.2f';
            app.edit12.Value = 0.3;
            app.CostQPpopup = uidropdown(app.uipanel3);
            app.CostQPpopup.Items = {'Mean', 'Median', 'P90', 'P10'};
            app.CostQPpopup.ItemsData = {'1', '2', '3', '4'};
            app.CostQPpopup.ValueChangedFcn = createCallbackFcn(app, @CostQPpopupValueChanged, true);
            app.CostQPpopup.Tooltip = {'Cost μ parameter to modify'};
            app.CostQPpopup.Value = '1';
            app.TimeQPpopup = uidropdown(app.uipanel3);
            app.TimeQPpopup.Items = {'Mean', 'Median', 'P90', 'P10'};
            app.TimeQPpopup.ItemsData = {'1', '2', '3', '4'};
            app.TimeQPpopup.ValueChangedFcn = createCallbackFcn(app, @TimeQPpopupValueChanged, true);
            app.TimeQPpopup.Tooltip = {'Time μ parameter to modify'};
            app.TimeQPpopup.Value = '1';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Text = 'Cost/Time is per';
            app.Costfitfunpopup = uidropdown(app.uipanel3);
            app.Costfitfunpopup.Items = {'Norm', 'Logn'};
            app.Costfitfunpopup.ItemsData = {'1', '2'};
            app.Costfitfunpopup.Tooltip = {'Cost μ parameter to modify'};
            app.Costfitfunpopup.Value = '1';
            app.Timefitfunpopup = uidropdown(app.uipanel3);
            app.Timefitfunpopup.Items = {'Norm', 'Logn'};
            app.Timefitfunpopup.ItemsData = {'1', '2'};
            app.Timefitfunpopup.Tooltip = {'Cost μ parameter to modify'};
            app.Timefitfunpopup.Value = '1';
            app.text12_3 = uilabel(app.uipanel3);
            app.text12_3.Text = 'Casualty:';
            app.text9_5 = uilabel(app.uipanel3);
            app.text9_5.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_5.Text = 'μ';
            app.text10_4 = uilabel(app.uipanel3);
            app.text10_4.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_4.Text = 'β';
            app.edit13 = uieditfield(app.uipanel3, 'numeric');
            app.edit13.Limits = [0 1];
            app.edit13.Tooltip = {'casualty rate'};
            app.edit14 = uieditfield(app.uipanel3, 'numeric');
            app.edit14.Limits = [0 Inf];
            app.edit14.ValueDisplayFormat = '%.2f';
            app.edit14.Value = 0.5;
            app.Casualtypopup = uidropdown(app.uipanel3);
            app.Casualtypopup.Items = {'No', 'Yes'};
            app.Casualtypopup.ItemsData = {'1', '2'};
            app.Casualtypopup.ValueChangedFcn = createCallbackFcn(app, @CasualtypopupValueChanged, true);
            app.Casualtypopup.Tooltip = {'Potential casualty'};
            app.Casualtypopup.Value = '1';
            app.text12_4 = uilabel(app.uipanel3);
            app.text12_4.Text = 'Injury:';
            app.text9_6 = uilabel(app.uipanel3);
            app.text9_6.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_6.Text = 'μ';
            app.text10_5 = uilabel(app.uipanel3);
            app.text10_5.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_5.Text = 'β';
            app.edit15 = uieditfield(app.uipanel3, 'numeric');
            app.edit15.Limits = [0 1];
            app.edit15.Tooltip = {'injury rate'};
            app.edit16 = uieditfield(app.uipanel3, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit16.ValueDisplayFormat = '%.2f';
            app.edit16.Value = 0.5;
            app.text12_5 = uilabel(app.uipanel3);
            app.text12_5.Text = 'Affected Area:';
            app.text10_6 = uilabel(app.uipanel3);
            app.text10_6.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_6.Text = 'sq. feet';
            app.edit17 = uieditfield(app.uipanel3, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.Tooltip = {'area affected by a damaged component'};
            app.text12_6 = uilabel(app.uipanel3);
            app.text12_6.Text = 'Unsafe Placard:';
            app.Placardpopup = uidropdown(app.uipanel3);
            app.Placardpopup.Items = {'No', 'Yes'};
            app.Placardpopup.ItemsData = {'1', '2'};
            app.Placardpopup.ValueChangedFcn = createCallbackFcn(app, @PlacardpopupValueChanged, true);
            app.Placardpopup.Tooltip = {'Potential unsafe placard'};
            app.Placardpopup.Value = '1';
            app.text9_7 = uilabel(app.uipanel3);
            app.text9_7.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_7.Text = 'μ';
            app.text10_7 = uilabel(app.uipanel3);
            app.text10_7.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_7.Text = 'β';
            app.edit18 = uieditfield(app.uipanel3, 'numeric');
            app.edit18.Limits = [0 1];
            app.edit18.Tooltip = {'unsafe placard rate'};
            app.edit19 = uieditfield(app.uipanel3, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit19.ValueDisplayFormat = '%.2f';
            app.edit19.Value = 0.5;
            app.ApplyButton = uibutton(app.figure1, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit & Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Fragility_Edit_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Fragility_Edit_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end