classdef Module_Fragility_Explorer_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel2         matlab.ui.container.Panel
        FragilityPopup1  matlab.ui.control.DropDown
        CB1              matlab.ui.control.CheckBox
        text15           matlab.ui.control.Label
        edit1            matlab.ui.control.NumericEditField
        TabGroup         matlab.ui.container.TabGroup
        DamageTab        matlab.ui.container.Tab
        DSfigure         matlab.ui.control.UIAxes
        CostTab          matlab.ui.container.Tab
        Costfigure       matlab.ui.control.UIAxes
        TimeTab          matlab.ui.container.Tab
        Timefigure       matlab.ui.control.UIAxes
        CO2Tab           matlab.ui.container.Tab
        CO2figure        matlab.ui.control.UIAxes
        EnergyTab        matlab.ui.container.Tab
        Energyfigure     matlab.ui.control.UIAxes
        DSPhotosTab      matlab.ui.container.Tab
        Image            matlab.ui.control.Image
        Spinner          matlab.ui.control.Spinner
        text16           matlab.ui.control.Label
        uitable1         matlab.ui.control.Table
        EditButton       matlab.ui.control.Button
        DeleteButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        
        function Plot_Cost_Distribution(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
               
            app.Costfigure.cla;
            app.Costfigure.XGrid='on';
            app.Costfigure.YGrid='on';
            app.Costfigure.Box='on';
            hold(app.Costfigure,'on');
            app.Costfigure.FontName='Times';
            app.Costfigure.FontSize=18;
            
            app.Costfigure.YLabel.String= 'Cost [$]';
            app.Costfigure.XLabel.String= 'Quantity';
            
            for i=1:nds
                INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
                if isnan(FRAGDATA.DS_LQcutoff(INDX))
                    if     i==1; plot(app.Costfigure,[0 100],[FRAGDATA.DS_CostP50(INDX) FRAGDATA.DS_CostP50(INDX) ],'-k','linewidth',2);
                    elseif i==2; plot(app.Costfigure,[0 100],[FRAGDATA.DS_CostP50(INDX) FRAGDATA.DS_CostP50(INDX) ],'--b','linewidth',2);
                    elseif i==3; plot(app.Costfigure,[0 100],[FRAGDATA.DS_CostP50(INDX) FRAGDATA.DS_CostP50(INDX) ],'-.r','linewidth',2);
                    elseif i==4; plot(app.Costfigure,[0 100],[FRAGDATA.DS_CostP50(INDX) FRAGDATA.DS_CostP50(INDX) ],':g','linewidth',2);
                    elseif i>4;  plot(app.Costfigure,[0 100],[FRAGDATA.DS_CostP50(INDX) FRAGDATA.DS_CostP50(INDX) ],'linewidth',2);
                    end      
                else
                    if     i==1; plot(app.Costfigure,[0 FRAGDATA.DS_LQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX)*1.2],[FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX)],'-k','linewidth',2);
                    elseif i==2; plot(app.Costfigure,[0 FRAGDATA.DS_LQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX)*1.2],[FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX)],'--b','linewidth',2);
                    elseif i==3; plot(app.Costfigure,[0 FRAGDATA.DS_LQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX)*1.2],[FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX)],'-.r','linewidth',2);
                    elseif i==4; plot(app.Costfigure,[0 FRAGDATA.DS_LQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX)*1.2],[FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX)],':g','linewidth',2);
                    elseif i>4;  plot(app.Costfigure,[0 FRAGDATA.DS_LQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX) FRAGDATA.DS_UQcutoff(INDX)*1.2],[FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_LQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX) FRAGDATA.DS_UQCcutoff(INDX)],'linewidth',2);
                    end
                end
            end
            
            xx=FRAGDATA.DS_UnitType(INDX);
            if     xx=='EA'; UnitType1=''; UnitType2='unit';
            elseif xx=='EU'; UnitType1=''; UnitType2='unit';
            elseif xx=='EP'; UnitType1=''; UnitType2='panel';
            elseif xx=='BU'; UnitType1=''; UnitType2='by user';
            elseif xx=='LF'; if Units==1; UnitType1='ft'; else; UnitType1='m'; end; UnitType2='length';
            elseif xx=='SF'; if Units==1; UnitType1='ft2'; else; UnitType1='m2'; end; UnitType2='area';
            end
            
            if INDX>size(FRAGDATA.DS_LQcutoff,1)

            else
                if FRAGDATA.DS_UnitVal(INDX)==0
                    text(app.Costfigure,0.0,0.1*FRAGDATA.DS_LQCcutoff(INDX),[' *Cost is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                else
                    text(app.Costfigure,0.0,0.1*FRAGDATA.DS_LQCcutoff(INDX),[' *Cost is per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                end
                
                if isnan(FRAGDATA.DS_LQcutoff(INDX)) 
                    if isnan(FRAGDATA.DS_CostP50(INDX)) 
                        s{1}=' Fragility parameters are missing for this';
                        s{2}=' component and shall be defined by user';
                        text(app.Costfigure,0.01,0.5,s, 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                        app.Costfigure.XLim=[0.0 1];
                        app.Costfigure.YLim=[0.0 1];
                    else
                        if FRAGDATA.DS_UnitVal(INDX)==0
                            text(app.Costfigure,0.0,0.1*FRAGDATA.DS_CostP50(INDX),[' *Cost is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                        else
                            text(app.Costfigure,0.0,0.1*FRAGDATA.DS_CostP50(INDX),[' *Cost is per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                        end
                        app.Costfigure.XLim=[0.0 100];
                        app.Costfigure.YLim=[0.0 max(FRAGDATA.DS_CostP50(INDX-nds+1:INDX))*1.2];
                    end

                else
                    app.Costfigure.XLim=[0.0 max(FRAGDATA.DS_UQcutoff(INDX-nds+1:INDX))*1.2];
                    app.Costfigure.YLim=[0.0 max(FRAGDATA.DS_LQCcutoff(INDX-nds+1:INDX))*1.2];
                end
            end
            legend(app.Costfigure,'DS_1','DS_2','DS_3','DS_4','DS_5');
            app.Costfigure.Legend.Location='northoutside';
            app.Costfigure.Legend.Orientation='horizontal';
            app.Costfigure.Legend.FontSize=14;
            
            app.Costfigure.FontName='Times';
            app.Costfigure.FontSize=18;           
            
        end
        
        function Plot_Frag_Distribution(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            if strcmp(COMPDATA.C_code(i),'User_MV')==1
                if app.CB1.Value==0
                    id = COMPDATA.C_ID(i);
                    edp=COMPDATA.C_EDP(i);
                    nds=COMPDATA.C_nDS(i);
                    app.edit1.Value=id;
                else
                    CIDs = unique(C_Data(:,1));
                    id = CIDs(i,1);
                    indxCOMPDATA=find(id==COMPDATA.C_ID);
                    edp=COMPDATA.C_EDP(indxCOMPDATA);
                    nds=COMPDATA.C_nDS(indxCOMPDATA);
                    app.edit1.Value=id;
                end
                
                app.DSfigure.cla;
                app.DSfigure.XGrid='on';
                app.DSfigure.YGrid='on';
                app.DSfigure.Box='on';
                hold(app.DSfigure,'on');
                app.DSfigure.FontName='Times';
                app.DSfigure.FontSize=18;
                
                s{1}=' Multivariate fragilities';
                s{2}=' can not be visualized in this module!';
                text(app.DSfigure,0.0,0.5,s, 'fontname', 'Times', 'fontsize',11,'fontweight','bold')
                INDX=find(id==FRAGDATA.C_ID & 1==FRAGDATA.DS_n);
                ds_name=FRAGDATA.DS_name(INDX:INDX+nds-1);
                app.uitable1.Data=ds_name;
                return
            end
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
            
            % if id ==322
            %     errordlg('Column Multivariate Not Ready Yet!');
            % end
            
            app.DSfigure.cla;
            app.DSfigure.XGrid='on';
            app.DSfigure.YGrid='on';
            app.DSfigure.Box='on';
            hold(app.DSfigure,'on');
            app.DSfigure.FontName='Times';
            app.DSfigure.FontSize=18;
            
            app.DSfigure.YLabel.String= 'P[ds \geq DS_i | EDP]';
            
            if strcmp(edp,'SDR')==1
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = SDR [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='RDR'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = RDR [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='COL ROT'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = COL ROT [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='BEAM ROT'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = BEAM ROT [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='PFA'
                RangeEDP=0:0.01:3;
                app.DSfigure.XLabel.String='EDP = PFA [g]';
                app.DSfigure.XLim=[0.0 3];
            elseif edp=='PGA'
                RangeEDP=0:0.01:3;
                app.DSfigure.XLabel.String='EDP = PGA [g]';
                app.DSfigure.XLim=[0.0 3];
            elseif edp=='PGV'
                RangeEDP=0:0.01:10;
                if Units==1; app.DSfigure.XLabel.String='EDP = PGV [in/sec]'; else; app.DSfigure.XLabel.String='EDP = PGV [m/sec]'; end
                app.DSfigure.XLim=[0.0 10];
            elseif edp=='LINK ROT'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = LR [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='DWD'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = DWD [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif edp=='RD'
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = RD [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif strcmp(edp,'ED')==1
                RangeEDP=0:0.001:0.1;
                app.DSfigure.XLabel.String='EDP = ED [rads]';
                app.DSfigure.XLim=[0.0 0.1];
            elseif strcmp(edp,'GENS1')==1
                RangeEDP=RANGE.GENS1;
                app.DSfigure.XLabel.String='EDP = GENS1';
                app.DSfigure.XLim=[min(RANGE.GENS1) max(RANGE.GENS1)];
            elseif strcmp(edp,'GENS2')==1
                RangeEDP=RANGE.GENS2;
                app.DSfigure.XLabel.String='EDP = GENS2';
                app.DSfigure.XLim=[min(RANGE.GENS1) max(RANGE.GENS1)];
            elseif strcmp(edp,'GENS3')==1
                RangeEDP=RANGE.GENS3;
                app.DSfigure.XLabel.String='EDP = GENS3';
                app.DSfigure.XLim=[min(RANGE.GENS1) max(RANGE.GENS1)];
            elseif strcmp(edp,'GENF1')==1
                RangeEDP=RANGE.GENF1;
                app.DSfigure.XLabel.String='EDP = GENF1';
                app.DSfigure.XLim=[min(RANGE.GENF1) max(RANGE.GENF1)];
            elseif strcmp(edp,'GENF2')==1
                RangeEDP=RANGE.GENF2;
                app.DSfigure.XLabel.String='EDP = GENF2';
                app.DSfigure.XLim=[min(RANGE.GENF2) max(RANGE.GENF2)];
            elseif strcmp(edp,'GENF3')==1
                RangeEDP=RANGE.GENF3;
                app.DSfigure.XLabel.String='EDP = GENF3';
                app.DSfigure.XLim=[min(RANGE.GENF3) max(RANGE.GENF3)];
            end
            app.DSfigure.XLabel.FontName= 'Times';
            app.DSfigure.YLabel.FontName= 'Times';

            x=0.95;
            for i=1:nds
                INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
                if FRAGDATA.DS_EDPsigma(INDX)==0.0; FRAGDATA.DS_EDPsigma(INDX)=0.001; end
                y=logncdf(RangeEDP,log(FRAGDATA.DS_EDPmedian(INDX)),FRAGDATA.DS_EDPsigma(INDX));
                if i==1
                    plot(app.DSfigure,RangeEDP,y,'-k','linewidth',2);
                    text(app.DSfigure,0.0,x,[' DS_',num2str(i),'=',num2str(round(FRAGDATA.DS_CostP50(INDX))),'$'], 'fontname', 'Courier', 'fontsize',11,'Color','k','fontweight','bold')
                elseif i==2
                    plot(app.DSfigure,RangeEDP,y,'--b','linewidth',2);
                    text(app.DSfigure,0.0,x,[' DS_',num2str(i),'=',num2str(round(FRAGDATA.DS_CostP50(INDX))),'$'], 'fontname', 'Courier', 'fontsize',11,'Color','b','fontweight','bold')
                elseif i==3
                    plot(app.DSfigure,RangeEDP,y,'-.r','linewidth',2);
                    text(app.DSfigure,0.0,x,[' DS_',num2str(i),'=',num2str(round(FRAGDATA.DS_CostP50(INDX))),'$'], 'fontname', 'Courier', 'fontsize',11,'Color','r','fontweight','bold')
                elseif i==4
                    plot(app.DSfigure,RangeEDP,y,':g','linewidth',2);
                    text(app.DSfigure,0.0,x,[' DS_',num2str(i),'=',num2str(round(FRAGDATA.DS_CostP50(INDX))),'$'], 'fontname', 'Courier', 'fontsize',11,'Color','g','fontweight','bold')
                elseif i>4
                    plot(app.DSfigure,RangeEDP,y,'linewidth',2);
                    text(app.DSfigure,0.0,x,[' DS_',num2str(i),'=',num2str(round(FRAGDATA.DS_CostP50(INDX))),'$'], 'fontname', 'Courier', 'fontsize',11,'fontweight','bold')
                end
                x=x-0.07;
            end
            xx=FRAGDATA.DS_UnitType(INDX);
            if     xx=='EA'; UnitType1=''; UnitType2='unit';
            elseif xx=='EU'; UnitType1=''; UnitType2='unit';
            elseif xx=='EP'; UnitType1=''; UnitType2='panel';
            elseif xx=='BU'; UnitType1=''; UnitType2='by user';
            elseif xx=='TN'; UnitType1=''; UnitType2=['unit of ',num2str(FRAGDATA.DS_UnitVal(INDX)),'Ton'];
            elseif xx=='CF'; UnitType1=''; UnitType2=['unit of ',num2str(FRAGDATA.DS_UnitVal(INDX)),'CFM'];
            elseif xx=='KV'; UnitType1=''; UnitType2=['unit of ',num2str(FRAGDATA.DS_UnitVal(INDX)),'kVa'];
            elseif xx=='AP'; UnitType1=''; UnitType2=['unit of ',num2str(FRAGDATA.DS_UnitVal(INDX)),'Amp'];
            elseif xx=='LF'; if Units==1; UnitType1='ft'; else; UnitType1='m'; end; UnitType2='length';
            elseif xx=='SF'; if Units==1; UnitType1='ft2'; else; UnitType1='m2'; end; UnitType2='area';
            end
            
            if FRAGDATA.DS_UnitVal(INDX)==0
                text(app.DSfigure,0.0,x,[' *Cost is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            else
                text(app.DSfigure,0.0,x,[' *Median cost per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            end
                text(app.DSfigure,0.0,x-0.07,['  DS Hierarchy: ', COMPDATA.C_DS_Hierarchy(id)], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            
            if isnan(FRAGDATA.DS_EDPsigma(INDX))
                s{1}=' Fragility parameters are missing for this';
                s{2}=' component and shall be defined by user';
                text(app.DSfigure,0.0,0.2,s, 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            end
            
            legend(app.DSfigure,'DS_1','DS_2','DS_3','DS_4','DS_5');
            app.DSfigure.Legend.Location='northoutside';
            app.DSfigure.Legend.Orientation='horizontal';
            app.DSfigure.Legend.FontSize=10;
            
        end
        
        function Plot_Time_Distribution(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
               
            app.Timefigure.cla;
            app.Timefigure.XGrid='on';
            app.Timefigure.YGrid='on';
            app.Timefigure.Box='on';
            hold(app.Timefigure,'on');
            
            Maxrange=1;
            for i=1:nds
                INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
                [range, DS_Time]=Get_DS_Time(app,FRAGDATA,INDX);
                if     i==1; plot(app.Timefigure,range,DS_Time,'-k','linewidth',2);
                elseif i==2; plot(app.Timefigure,range,DS_Time,'--b','linewidth',2);
                elseif i==3; plot(app.Timefigure,range,DS_Time,'-.r','linewidth',2);
                elseif i==4; plot(app.Timefigure,range,DS_Time,':g','linewidth',2);
                elseif i>4;  plot(app.Timefigure,range,DS_Time,'linewidth',2);
                end
                Maxrange=max(max(range),Maxrange);
            end
            
            xx=FRAGDATA.DS_UnitType(INDX);
            if     xx=='EA'; UnitType1=''; UnitType2='unit';
            elseif xx=='EU'; UnitType1=''; UnitType2='unit';
            elseif xx=='EP'; UnitType1=''; UnitType2='panel';
            elseif xx=='BU'; UnitType1=''; UnitType2='by user';
            elseif xx=='LF'; if Units==1; UnitType1='ft'; else; UnitType1='m'; end; UnitType2='length';
            elseif xx=='SF'; if Units==1; UnitType1='ft2'; else; UnitType1='m2'; end; UnitType2='area';
            end
            
            if FRAGDATA.DS_UnitVal(INDX)==0
                text(app.Timefigure,0.0,0.1,[' *Time is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            else
                text(app.Timefigure,0.0,0.1,[' *Time is per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            end
            
            if isnan(FRAGDATA.DS_Timesigma(INDX))
                s{1}=' Fragility parameters are missing for this';
                s{2}=' component and shall be defined by user';
                text(app.Timefigure,0.01,0.5,s, 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                app.Timefigure.XLim=[0.0 1];
                app.Timefigure.YLim=[0.0 1];
            end
            app.Timefigure.XLim=[0.0 max(Maxrange)];
            app.Timefigure.YLim=[0.0 1];
                
            legend(app.Timefigure,'DS_1','DS_2','DS_3','DS_4','DS_5');
            app.Timefigure.Legend.Location='northoutside';
            app.Timefigure.Legend.Orientation='horizontal';
            app.Timefigure.Legend.FontSize=14;
            
            app.Timefigure.XLabel.String= 'Time [days]';
            app.Timefigure.YLabel.String= 'P(time\leqTime)';
            app.Timefigure.FontName='Times';
            app.Timefigure.FontSize=18;
        end
        
        function [range, DS_Time]=Get_DS_Time(app,FRAGDATA,IDX)
            sigma_Time =     FRAGDATA.DS_Timesigma  (IDX);  if sigma_Time==0.0; sigma_Time=0.001; end    % scaler value 
            fitfun_Time =    FRAGDATA.DS_TimeFitFun (IDX);                                               % string 
            if strcmp(fitfun_Time,'Normal')
                mu_Time    = 	 FRAGDATA.DS_Timemean(IDX); if mu_Time==0.0; mu_Time=0.001; end     % scaler value
                range=0:mu_Time/50:mu_Time*3;
                DS_Time = normcdf(range,mu_Time, sigma_Time*mu_Time);         % Random DSi cost  (scaler value)
            else
                mu_Time    = 	 FRAGDATA.DS_TimeP50(IDX);  if mu_Time==0.0; mu_Time=0.001; end     % scaler value
                range=0:mu_Time/50:mu_Time*max(3,7*sigma_Time);
                DS_Time = logncdf(range,log(mu_Time), sigma_Time);            % Random DSi cost  (scaler value)                                
            end
        end
        
        function Plot_CO2_Distribution(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
               
            app.CO2figure.cla;
            app.CO2figure.XGrid='on';
            app.CO2figure.YGrid='on';
            app.CO2figure.Box='on';
            hold(app.CO2figure,'on');
            
            Maxrange=1;
            for i=1:nds
                INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
                [range, DS_CO2]=Get_DS_CO2(app,FRAGDATA,INDX);
                if     i==1; plot(app.CO2figure,range,DS_CO2,'-k','linewidth',2);
                elseif i==2; plot(app.CO2figure,range,DS_CO2,'--b','linewidth',2);
                elseif i==3; plot(app.CO2figure,range,DS_CO2,'-.r','linewidth',2);
                elseif i==4; plot(app.CO2figure,range,DS_CO2,':g','linewidth',2);
                elseif i>4;  plot(app.CO2figure,range,DS_CO2,'linewidth',2);
                end
                Maxrange=max(max(range),Maxrange);
            end
            
            xx=FRAGDATA.DS_UnitType(INDX);
            if     xx=='EA'; UnitType1=''; UnitType2='unit';
            elseif xx=='EU'; UnitType1=''; UnitType2='unit';
            elseif xx=='EP'; UnitType1=''; UnitType2='panel';
            elseif xx=='BU'; UnitType1=''; UnitType2='by user';
            elseif xx=='LF'; if Units==1; UnitType1='ft'; else; UnitType1='m'; end; UnitType2='length';
            elseif xx=='SF'; if Units==1; UnitType1='ft2'; else; UnitType1='m2'; end; UnitType2='area';
            end
            
            if FRAGDATA.DS_UnitVal(INDX)==0
                text(app.CO2figure,0.0,0.1,[' *CO_2 is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            else
                text(app.CO2figure,0.0,0.1,[' *CO_2 is per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            end
            
            if isnan(FRAGDATA.DS_CO2median(INDX)) || isnan(FRAGDATA.DS_CO2sigma(INDX))
                s{1}=' Fragility parameters are missing for this';
                s{2}=' component and shall be defined by user';
                text(app.CO2figure,0.01,0.5,s, 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                app.CO2figure.XLim=[0.0 1];
                app.CO2figure.YLim=[0.0 1];
            else
                app.CO2figure.XLim=[0.0 max(Maxrange)];
                app.CO2figure.YLim=[0.0 1];
            end

            legend(app.CO2figure,'DS_1','DS_2','DS_3','DS_4','DS_5');
            app.CO2figure.Legend.Location='northoutside';
            app.CO2figure.Legend.Orientation='horizontal';
            app.CO2figure.Legend.FontSize=14;
            
            app.CO2figure.XLabel.String= 'Embodied CO_2 [kg]';
            app.CO2figure.YLabel.String= 'P(co_2\leqCO_2)';
            app.CO2figure.FontName='Times';
            app.CO2figure.FontSize=18;            
        end
        
        function [range, DS_CO2]=Get_DS_CO2(app,FRAGDATA,IDX)
            sigma_CO2 =     FRAGDATA.DS_CO2sigma  (IDX);  if sigma_CO2==0.0; sigma_CO2=0.001; end    % scaler value 
            fitfun_CO2 =    FRAGDATA.DS_CO2FitFun (IDX);                                               % string 
            if strcmp(fitfun_CO2,'Normal')
                mu_CO2    = 	 FRAGDATA.DS_CO2median(IDX); if mu_CO2==0.0; mu_CO2=0.001; end     % scaler value
                range=0:mu_CO2/50:mu_CO2*3;
                DS_CO2 = normcdf(range,mu_CO2, sigma_CO2*mu_CO2);         % Random DSi cost  (scaler value)
            else
                mu_CO2    = 	 FRAGDATA.DS_CO2median(IDX);  if mu_CO2==0.0; mu_CO2=0.001; end     % scaler value
                range=0:mu_CO2/50:mu_CO2*max(3,7*sigma_CO2);
                DS_CO2 = logncdf(range,log(mu_CO2), sigma_CO2);            % Random DSi cost  (scaler value)                                
            end
        end
        
        function Plot_Energy_Distribution(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
               
            app.Energyfigure.cla;
            app.Energyfigure.XGrid='on';
            app.Energyfigure.YGrid='on';
            app.Energyfigure.Box='on';
            hold(app.Energyfigure,'on');
            
            Maxrange=1;
            for i=1:nds
                INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
                [range, DS_Energy]=Get_DS_Energy(app,FRAGDATA,INDX);
                if     i==1; plot(app.Energyfigure,range,DS_Energy,'-k','linewidth',2);
                elseif i==2; plot(app.Energyfigure,range,DS_Energy,'--b','linewidth',2);
                elseif i==3; plot(app.Energyfigure,range,DS_Energy,'-.r','linewidth',2);
                elseif i==4; plot(app.Energyfigure,range,DS_Energy,':g','linewidth',2);
                elseif i>4;  plot(app.Energyfigure,range,DS_Energy,'linewidth',2);
                end
                Maxrange=max(max(range),Maxrange);
            end
            
            xx=FRAGDATA.DS_UnitType(INDX);
            if     xx=='EA'; UnitType1=''; UnitType2='unit';
            elseif xx=='EU'; UnitType1=''; UnitType2='unit';
            elseif xx=='EP'; UnitType1=''; UnitType2='panel';
            elseif xx=='BU'; UnitType1=''; UnitType2='by user';
            elseif xx=='LF'; if Units==1; UnitType1='ft'; else; UnitType1='m'; end; UnitType2='length';
            elseif xx=='SF'; if Units==1; UnitType1='ft2'; else; UnitType1='m2'; end; UnitType2='area';
            end
            
            if FRAGDATA.DS_UnitVal(INDX)==0
                text(app.Energyfigure,0.0,0.1,[' *Energy is per ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            else
                text(app.Energyfigure,0.0,0.1,[' *Energy is per ',num2str(FRAGDATA.DS_UnitVal(INDX)),UnitType1, ' ',UnitType2], 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
            end
            
            if isnan(FRAGDATA.DS_ENERGYmedian(INDX)) || isnan(FRAGDATA.DS_ENERGYsigma(INDX))
                s{1}=' Fragility parameters are missing for this';
                s{2}=' component and shall be defined by user';
                text(app.Energyfigure,0.01,0.5,s, 'fontname', 'Courier', 'fontsize',10,'fontweight','bold')
                app.Energyfigure.XLim=[0.0 1];
                app.Energyfigure.YLim=[0.0 1];
            else
                app.Energyfigure.XLim=[0.0 max(Maxrange)];
                app.Energyfigure.YLim=[0.0 1];
            end

            legend(app.Energyfigure,'DS_1','DS_2','DS_3','DS_4','DS_5');
            app.Energyfigure.Legend.Location='northoutside';
            app.Energyfigure.Legend.Orientation='horizontal';
            app.Energyfigure.Legend.FontSize=14;
            
            app.Energyfigure.XLabel.String= 'Embodied Energy [MJ]';
            app.Energyfigure.YLabel.String= 'P(energy\leqEnergy)';
            app.Energyfigure.FontName='Times';
            app.Energyfigure.FontSize=18; 
        end


        function [range, DS_Energy]=Get_DS_Energy(app,FRAGDATA,IDX)
            sigma_Energy =     FRAGDATA.DS_ENERGYsigma  (IDX);  if sigma_Energy==0.0; sigma_Energy=0.001; end    % scaler value 
            fitfun_Energy =    FRAGDATA.DS_ENERGYFitFun (IDX);                                               % string 
            if strcmp(fitfun_Energy,'Normal')
                mu_Energy    = 	 FRAGDATA.DS_ENERGYmedian(IDX); if mu_Energy==0.0; mu_Energy=0.001; end     % scaler value
                range=0:mu_Energy/50:mu_Energy*3;
                DS_Energy = normcdf(range,mu_Energy, sigma_Energy*mu_Energy);         % Random DSi cost  (scaler value)
            else
                mu_Energy    = 	 FRAGDATA.DS_ENERGYmedian(IDX);  if mu_Energy==0.0; mu_Energy=0.001; end     % scaler value
                range=0:mu_Energy/50:mu_Energy*max(3,7*sigma_Energy);
                DS_Energy = logncdf(range,log(mu_Energy), sigma_Energy);            % Random DSi cost  (scaler value)                                
            end
        end
        
        function Show_DS_Photo(app)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
               
            if nds>1; app.Spinner.Limits=[1 nds]; end
            if nds==1; app.Spinner.Limits=[0 1]; end
            
            i=app.Spinner.Value;
            INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
            xx=MainDirectory;
            if strcmp(FRAGDATA.DS_Photo{INDX,1},'none')~=1
                str=[xx,'\FEMA_P58_DS_PHOTOS\',FRAGDATA.DS_Photo{INDX,1}];
                app.Image.ImageSource= str;
            else
                str=[xx,'\FEMA_P58_DS_PHOTOS\NA.jpg'];
                app.Image.ImageSource= str;
            end
            
            cd (MainDirectory)

%             for i=1:nds
%                 INDX=find(id==FRAGDATA.C_ID & i==FRAGDATA.DS_n);
%                 [range, DS_CO2]=Get_DS_CO2(app,FRAGDATA,INDX);
%                 if     i==1; plot(app.CO2figure,range,DS_CO2,'-k','linewidth',2);
%                 elseif i==2; plot(app.CO2figure,range,DS_CO2,'--b','linewidth',2);
%                 elseif i==3; plot(app.CO2figure,range,DS_CO2,'-.r','linewidth',2);
%                 elseif i==4; plot(app.CO2figure,range,DS_CO2,':g','linewidth',2);
%                 elseif i>4;  plot(app.CO2figure,range,DS_CO2,'linewidth',2);
%                 end
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Fragility_Explorer_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','ComponentDataStatus','Component_Option')
            cd (MainDirectory)
            
            if ComponentDataStatus==1 && Component_Option==1
                app.CB1.Value=1;
                app.CB1.Enable='on';
            else
                app.CB1.Value=0;
                app.CB1.Enable='off';
            end
            CB1_Callback(app)

        end

        % Value changed function: CB1
        function CB1_Callback(app, event)
            global ProjectName MainDirectory ProjectPath
            clc;
            
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','C_Data')
            cd (MainDirectory)
            
            if get(app.CB1,'Value')==0
                S=COMPDATA.C_name;
                app.FragilityPopup1.Items=S;
                app.FragilityPopup1.ItemsData=1:size(S,1);
                app.FragilityPopup1.Value=1;
                FragilityPopup1_Callback(app)
            else
            
                % Get the unique component IDs
                CIDs = unique(C_Data(:,1));
                % Get the component names
                for i=1:size(CIDs,1)
                    indxCOMPDATA=find(CIDs(i,1)==COMPDATA.C_ID);
                    CNames(i,1)=COMPDATA.C_name(indxCOMPDATA);
                end
                app.FragilityPopup1.Items=CNames;
                app.FragilityPopup1.ItemsData=1:size(CNames,1);
                app.FragilityPopup1.Value=1;
                FragilityPopup1_Callback(app)
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','FilteredIDList','ComponentDataStatus','Component_Option','C_Data')
            pause(0.8)
            cd (MainDirectory)
            
            Component_ID =app.edit1.Value;
            
            % Check that the deleted component is not part of the defined project components
            if ComponentDataStatus==1 && Component_Option==1
                if ismember(Component_ID,C_Data(:,1))
                    errordlg('You cannot delete a component that is already part of your defined project components!');
                    return
                end
            end
            
            idxCOMPDATA=find(COMPDATA.C_ID==Component_ID);
            idxFRAGDATA=find(FRAGDATA.C_ID==Component_ID);
            
            % Delete Component Entry from COMPDATA
            COMPDATA.C_ID(idxCOMPDATA,:)=[];
            COMPDATA.C_name(idxCOMPDATA,:)=[];
            COMPDATA.C_nDS(idxCOMPDATA,:)=[];
            COMPDATA.C_EDP(idxCOMPDATA,:)=[];
            COMPDATA.C_Unit(idxCOMPDATA,:)=[];
            COMPDATA.C_code(idxCOMPDATA,:)=[];
            COMPDATA.C_Category(idxCOMPDATA,:)=[];
            COMPDATA.C_Category2(idxCOMPDATA,:)=[];
            COMPDATA.C_shortname(idxCOMPDATA,:)=[];
            COMPDATA.C_shortername(idxCOMPDATA,:)=[];
            COMPDATA.C_DS_Hierarchy(idxCOMPDATA,:)=[];
           
            % Delete Component Entry from FRAGDATA
            FRAGDATA.C_ID(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.C_name(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_name(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_n(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_EDP(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_EDPmedian(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_EDPsigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CostP50(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CostP90(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CostP10(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Costmean(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Costsigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_TimeP50(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_TimeP90(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_TimeP10(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Timemean(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Timesigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Unit(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_P(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.C_code(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_repairmeasure(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UnitType(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UnitVal(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_FragType(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Hierarchy(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            
            FRAGDATA.DS_UQcutoff(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UQCcutoff(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_LQcutoff(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_LQCcutoff(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CO2median(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CO2sigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CO2FitFun(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_ENERGYmedian(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_ENERGYsigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_ENERGYFitFun(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Casualty(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UnsafePlacardFlag(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_InjuryRatemedian(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_InjuryRatesigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CasualtyRatemedian(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_CasualtyRatesigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UnsafePlacardmedian(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_UnsafePlacardsigma(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_AffectArea(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            FRAGDATA.DS_Photo(idxFRAGDATA(1,1):idxFRAGDATA(end,1),:)=[];
            
            cd (ProjectPath)
            save(ProjectName,'COMPDATA','FRAGDATA','-append')
            pause(1.0)
            cd (MainDirectory)
            
            app.FragilityPopup1.Value=(app.edit1.Value-1);
            FragilityPopup1_Callback(app)
        end

        % Button pushed function: EditButton
        function EditButton_Callback(app, event)
            global ProjectName MainDirectory ProjectPath tempVar1 tempVar2
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA')
            cd (MainDirectory)
            
            tempVar1=app.edit1.Value;
            indxCOMPDATA=find(tempVar1==COMPDATA.C_ID);
            if     COMPDATA.C_Category2(indxCOMPDATA)== 'Superstructure';     tempVar2=1;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Roofing';            tempVar2=2;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Exterior Enclosure'; tempVar2=3;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Interiors';          tempVar2=4;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'HAVC';               tempVar2=5;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Fire Protection';    tempVar2=6;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Electrical';         tempVar2=7;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Conveying';          tempVar2=8;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Plumbing';           tempVar2=9;
            elseif COMPDATA.C_Category2(indxCOMPDATA)== 'Furniture';          tempVar2=10;
            end
            
            i = app.FragilityPopup1.Value;
            if strcmp(COMPDATA.C_code(i),'User_MV')==1
                Module_Fragility_EditMV_App
            else
                waitfor(Module_Fragility_Edit_App(app.MainApp));
                FragilityPopup1_Callback(app, event)
            end
        end

        % Value changed function: FragilityPopup1
        function FragilityPopup1_Callback(app, event)
            app.DSfigure.cla
            app.Costfigure.cla
            app.Timefigure.cla
            app.CO2figure.cla
            app.Energyfigure.cla
            
            Plot_Frag_Distribution(app)
            Plot_Cost_Distribution(app)
            Plot_Time_Distribution(app)
            Plot_CO2_Distribution(app)
            Plot_Energy_Distribution(app)
            Show_DS_Photo(app)
            TabGroupSelectionChanged(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Value changed function: edit1
        function edit1ValueChanged(app, event)
            global ProjectName MainDirectory ProjectPath
            clc;
            
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','C_Data')
            cd (MainDirectory)
            
            id =app.edit1.Value;
            
            
            if app.CB1.Value==0
                indx=find(id==COMPDATA.C_ID);
                if isempty(indx)
                    errordlg('The specified component ID does not exist in the database!');
                    return
                end
                app.FragilityPopup1.Value=indx;
                FragilityPopup1_Callback(app)
            else
                CIDs = unique(C_Data(:,1));
                indx=find(id==CIDs);
                if isempty(indx)
                    errordlg('The specified component ID does not belong to any of the defined project components!');
                    return
                end
                app.FragilityPopup1.Value=indx;
                FragilityPopup1_Callback(app)
            end            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            global ProjectName MainDirectory ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA','C_Data','Units','RANGE')
            cd (MainDirectory)
            
            i =app.FragilityPopup1.Value;
            
            if app.CB1.Value==0
                id = COMPDATA.C_ID(i);
                edp=COMPDATA.C_EDP(i);
                nds=COMPDATA.C_nDS(i);
                app.edit1.Value=id;
            else
                CIDs = unique(C_Data(:,1));
                id = CIDs(i,1);
                indxCOMPDATA=find(id==COMPDATA.C_ID);
                edp=COMPDATA.C_EDP(indxCOMPDATA);
                nds=COMPDATA.C_nDS(indxCOMPDATA);
                app.edit1.Value=id;
            end
            
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title,'Damage')==1 || strcmp(selectedTab.Title,'DS Photos')==1 
                INDX=find(id==FRAGDATA.C_ID & 1==FRAGDATA.DS_n);
                counter=1;
                for i=INDX:INDX+nds-1
                 ds_name{counter,1}=char(FRAGDATA.DS_name{i});
                 counter=counter+1;
                end
                %ds_name=FRAGDATA.DS_name{INDX:INDX+nds-1,1};
                app.uitable1.Data=ds_name;    
            else
                INDX=find(id==FRAGDATA.C_ID & 1==FRAGDATA.DS_n);
                counter=1;
                for i=INDX:INDX+nds-1
                 ds_name{counter,1}=char(FRAGDATA.DS_repairmeasure(i));
                 counter=counter+1;
                end
                app.uitable1.Data=ds_name;                  
            end
            
            if strcmp(selectedTab.Title,'DS Photos')~=1 
                app.Spinner.Visible='off';
                app.text16.Visible='off';
            else
                app.Spinner.Visible='on';
                app.text16.Visible='on';                
            end
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            Show_DS_Photo(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Explore/Edit Fragility Database';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Select Component';
            app.FragilityPopup1 = uidropdown(app.uipanel2);
            app.FragilityPopup1.Items = {'Column Base Plate', 'Column', 'Beam'};
            app.FragilityPopup1.ItemsData = {'1', '2', '3'};
            app.FragilityPopup1.ValueChangedFcn = createCallbackFcn(app, @FragilityPopup1_Callback, true);
            app.FragilityPopup1.Value = '1';
            app.CB1 = uicheckbox(app.uipanel2);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1_Callback, true);
            app.CB1.Text = 'Filter list to show only project components ';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Text = 'ID:';
            app.edit1 = uieditfield(app.uipanel2, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit1.Value = 1;
            app.TabGroup = uitabgroup(app.uipanel2);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DamageTab = uitab(app.TabGroup);
            app.DamageTab.Title = 'Damage';
            app.DSfigure = uiaxes(app.DamageTab);
            app.CostTab = uitab(app.TabGroup);
            app.CostTab.Title = 'Cost';
            app.Costfigure = uiaxes(app.CostTab);
            app.TimeTab = uitab(app.TabGroup);
            app.TimeTab.Title = 'Time';
            app.Timefigure = uiaxes(app.TimeTab);
            app.CO2Tab = uitab(app.TabGroup);
            app.CO2Tab.Title = 'CO2';
            app.CO2figure = uiaxes(app.CO2Tab);
            app.EnergyTab = uitab(app.TabGroup);
            app.EnergyTab.Title = 'Energy';
            app.Energyfigure = uiaxes(app.EnergyTab);
            app.DSPhotosTab = uitab(app.TabGroup);
            app.DSPhotosTab.Title = 'DS Photos';
            app.Image = uiimage(app.DSPhotosTab);
            app.Spinner = uispinner(app.DSPhotosTab);
            app.Spinner.Limits = [1 5];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.text16 = uilabel(app.DSPhotosTab);
            app.text16.Text = 'DS:';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = '';
            app.uitable1.RowName = {'DS1'; 'DS2'; 'DS3'; 'DS4'; 'DS5'};
            app.uitable1.ColumnEditable = [false false];
            app.EditButton = uibutton(app.figure1, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButton_Callback, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.figure1, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_Callback, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Fragility_Explorer_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Fragility_Explorer_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end