classdef Module_Fragility_Univariate_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uipanel3           matlab.ui.container.Panel
        text19             matlab.ui.control.Label
        text7              matlab.ui.control.Label
        text6              matlab.ui.control.Label
        text17             matlab.ui.control.Label
        text8              matlab.ui.control.Label
        text18             matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        NewRadio           matlab.ui.control.RadioButton
        ExistingRadio      matlab.ui.control.RadioButton
        edit9              matlab.ui.control.NumericEditField
        edit1              matlab.ui.control.EditField
        edit2              matlab.ui.control.EditField
        Categorypopup      matlab.ui.control.DropDown
        edit7              matlab.ui.control.NumericEditField
        text10             matlab.ui.control.Label
        text9              matlab.ui.control.Label
        edit3              matlab.ui.control.NumericEditField
        edit4              matlab.ui.control.NumericEditField
        text9_2            matlab.ui.control.Label
        text12             matlab.ui.control.Label
        edit5              matlab.ui.control.NumericEditField
        text9_3            matlab.ui.control.Label
        text10_2           matlab.ui.control.Label
        edit6              matlab.ui.control.NumericEditField
        text12_2           matlab.ui.control.Label
        text9_4            matlab.ui.control.Label
        text10_3           matlab.ui.control.Label
        edit11             matlab.ui.control.NumericEditField
        edit12             matlab.ui.control.NumericEditField
        CostQPpopup        matlab.ui.control.DropDown
        TimeQPpopup        matlab.ui.control.DropDown
        Ppopup             matlab.ui.control.DropDown
        edit10             matlab.ui.control.NumericEditField
        UnitTypepopup      matlab.ui.control.DropDown
        text20             matlab.ui.control.Label
        EDPpopup           matlab.ui.control.DropDown
        text15             matlab.ui.control.Label
        Costfitfunpopup    matlab.ui.control.DropDown
        Timefitfunpopup    matlab.ui.control.DropDown
        text12_3           matlab.ui.control.Label
        text9_5            matlab.ui.control.Label
        text10_4           matlab.ui.control.Label
        edit13             matlab.ui.control.NumericEditField
        edit14             matlab.ui.control.NumericEditField
        Casualtypopup      matlab.ui.control.DropDown
        text12_4           matlab.ui.control.Label
        text9_6            matlab.ui.control.Label
        text10_5           matlab.ui.control.Label
        edit15             matlab.ui.control.NumericEditField
        edit16             matlab.ui.control.NumericEditField
        text12_5           matlab.ui.control.Label
        text10_6           matlab.ui.control.Label
        edit17             matlab.ui.control.NumericEditField
        text12_6           matlab.ui.control.Label
        Placardpopup       matlab.ui.control.DropDown
        text9_7            matlab.ui.control.Label
        text10_7           matlab.ui.control.Label
        edit18             matlab.ui.control.NumericEditField
        edit19             matlab.ui.control.NumericEditField
        edit8              matlab.ui.control.NumericEditField
        ApplyButton        matlab.ui.control.Button
        SubmitCloseButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        
        function  [NewComponentCategory, NewComponentType]= GetNewCompCatnType(app)
            i = str2double(app.Categorypopup.Value);
            switch i
                case 1; NewComponentCategory  = 'NaN';
                case 2; NewComponentCategory  = 'Superstructure';
                case 3; NewComponentCategory  = 'Roofing';
                case 4; NewComponentCategory  = 'Exterior Enclosure';
                case 5; NewComponentCategory  = 'Interiors';
                case 6; NewComponentCategory  = 'HAVC';
                case 7; NewComponentCategory  = 'Fire Protection';
                case 8; NewComponentCategory  = 'Electrical';
                case 9; NewComponentCategory  = 'Conveying';
                case 10; NewComponentCategory  = 'Plumbing';
                case 11;NewComponentCategory  = 'Furniture';
            end
            
            if     i==1; NewComponentType='NaN';
            elseif i==2; NewComponentType='SC';
            else         NewComponentType='NSC';
            end
        end
        
        function [DS_EDP] = GetDS_EDP(app)
            X=str2double(app.EDPpopup.Value);
            switch X
                case 1; errordlg('Please select the damage state EDP!'); return;
                case 2; DS_EDP='SDR';
                case 3; DS_EDP='PFA';
                case 4; DS_EDP='PFV';
                case 5; DS_EDP='LINK ROT';
                case 6; DS_EDP='BEAM ROT';
                case 7; DS_EDP='COL ROT';
                case 8; DS_EDP='RDR';
                case 9; DS_EDP='DWD';
                case 10; DS_EDP='VRD';
                case 11;DS_EDP='RD';
                case 12;DS_EDP='ED';
                case 13;DS_EDP='GENS1';
                case 14;DS_EDP='GENS2';
                case 15;DS_EDP='GENS3';
                case 16;DS_EDP='GENF1';
                case 17;DS_EDP='GENF2';
                case 18;DS_EDP='GENF3';
            end
        end
        
        function [Cost_Unit_Type] = Get_UnitType(app)
            x=app.UnitTypepopup.Value;
            switch x
                case 1; Cost_Unit_Type='EA';
                case 2; Cost_Unit_Type='LF';
                case 3; Cost_Unit_Type='SF';
            end
        end
        
        function Apply(app)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'COMPDATA','FRAGDATA')
            pause(0.5)
            cd (MainDirectory)
            
            LastComponentID=COMPDATA.C_ID(end,1);       % Get the size of the current component database
            
            nDS           = app.edit7.Value;
            EDPmu         = app.edit3.Value;
            EDPsigma      = app.edit4.Value;
            Costmu        = app.edit5.Value;
            Costsigma     = app.edit6.Value;
            Timemu        = app.edit11.Value;
            Timesigma     = app.edit12.Value;
            P             = app.edit10.Value;
            CostPQ        = str2double(app.CostQPpopup.Value);
            TimePQ        = str2double(app.TimeQPpopup.Value);
            Hierarchy     = str2double(app.Ppopup.Value);
            Costfitfun    = str2double(app.Costfitfunpopup.Value);
            Timefitfun    = str2double(app.Timefitfunpopup.Value);
            if  app.Casualtypopup.Value=='1'; Casualty="NO"; else; Casualty="YES"; end 
            Casualtymu=app.edit13.Value;
            Casualtysigma=app.edit14.Value;
            Injurymu=app.edit15.Value;
            Injurysigma=app.edit16.Value;
            AffectArea=app.edit17.Value;
            Placardmu=app.edit18.Value;
            Placardsigma=app.edit19.Value;
            if  app.Placardpopup.Value=='1'; Placard='NO'; else; Placard='YES'; end 
            
            %% New Component
            if app.NewRadio.Value==1
            
                NewComponentID   = app.edit9.Value;
                NewComponentName = app.edit1.Value;
                NewDSDescription = app.edit2.Value;
            
                [NewComponentCategory, NewComponentType]=GetNewCompCatnType(app);
                [DS_EDP] = GetDS_EDP(app);
                
                Cost_Unit_Val = app.edit8.Value;
                [Cost_Unit_Type] = Get_UnitType(app);
            
               % Data Checks
                if isempty(NewDSDescription)
                    app.edit2.Value='Damage State Description was not Defined by the User';
                    NewDSDescription = app.edit2.Value;
                end
                if isempty(NewComponentName)
                    ed=errordlg('Please Specify the Component Name');
                    waitfor(ed)
                    return
                end
                if strcmp(NewComponentType,'NaN')==1 || strcmp(DS_EDP,'NaN')==1
                    ed=errordlg('Please Specify Component Type and EDP');
                    waitfor(ed)
                    return
                end
            
                % Add new component info to Component_List
                COMPDATA.C_ID(end+1,1)=NewComponentID;       % Component ID
                COMPDATA.C_name(end+1,1)=NewComponentName;   % Component Description
                COMPDATA.C_nDS(end+1,1)=1;                   % number of DS
                COMPDATA.C_EDP(end+1,1)=DS_EDP;              % DS EDp
                COMPDATA.C_Unit(end+1,1)=Cost_Unit_Type;
                COMPDATA.C_code(end+1,1)='User_UV';
                COMPDATA.C_Category(end+1,1)=NewComponentCategory;
                COMPDATA.C_Category2(end+1,1)=NewComponentCategory;
                COMPDATA.C_shortname(end+1,1)=NewComponentName;
                COMPDATA.C_shortername(end+1,1)=NewComponentName;
                if Hierarchy==1; COMPDATA.C_DS_Hierarchy(end+1,1)='Seq';    end  % Hierarchy
                if Hierarchy==2; COMPDATA.C_DS_Hierarchy(end+1,1)='MutEx';  end  % Hierarchy
                if Hierarchy==3; COMPDATA.C_DS_Hierarchy(end+1,1)='Simul';  end  % Hierarchy
            
                % Add new component info to Fragility_List
                FragiltiyCount=size(FRAGDATA.C_ID,1);
                FRAGDATA.C_ID(FragiltiyCount+1,1)=NewComponentID;       % Component ID
                FRAGDATA.C_name(FragiltiyCount+1,1)=NewComponentName;   % Component Name
                FRAGDATA.DS_n(FragiltiyCount+1,1)=nDS;                  % DS Number
                FRAGDATA.DS_name{FragiltiyCount+1,1}=NewDSDescription;  % DS Description
                FRAGDATA.DS_EDP(FragiltiyCount+1,1)=DS_EDP;             % DS EDP
                FRAGDATA.DS_EDPmedian(FragiltiyCount+1,1)=EDPmu;    % Median EDP
                FRAGDATA.DS_EDPsigma(FragiltiyCount+1,1)=EDPsigma;      % Sigma  EDP
                FRAGDATA.DS_Costmean(FragiltiyCount+1,1)=Costmu;       % Repair Cost mu
                FRAGDATA.DS_CostP50(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_CostP90(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_CostP10(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_Costsigma(FragiltiyCount+1,1)=Costsigma;   % Repair Cost sigma
                if Costfitfun==1; FRAGDATA.DS_CostFitFun{FragiltiyCount+1,1}='Normal';    end       % Repair Cost fit function
                if Costfitfun==2; FRAGDATA.DS_CostFitFun{FragiltiyCount+1,1}='LogNormal'; end       % Repair Cost fit function
                FRAGDATA.DS_Timemean(FragiltiyCount+1,1)=Timemu;       % Repair Time mu
                FRAGDATA.DS_TimeP50(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_TimeP90(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_TimeP10(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_Timesigma(FragiltiyCount+1,1)=Timesigma;   % Repair Time sigma
                if Timefitfun==1; FRAGDATA.DS_TimeFitFun{FragiltiyCount+1,1}='Normal';    end       % Time fit function
                if Timefitfun==2; FRAGDATA.DS_TimeFitFun{FragiltiyCount+1,1}='LogNormal'; end       % Time fit function
                FRAGDATA.DS_UnitType(FragiltiyCount+1,1)=Cost_Unit_Type;% Cost Unit Type
                FRAGDATA.DS_UnitVal(FragiltiyCount+1,1)=Cost_Unit_Val;  % Cost Unit Value
                FRAGDATA.DS_Unit(FragiltiyCount+1,1)=Cost_Unit_Type;    % Cost Unit
                FRAGDATA.DS_P(FragiltiyCount+1,1)=P;                    % Mutually Exclusive/Sequential Probability
                if Hierarchy==1; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Sequential';          end  % Hierarchy
                if Hierarchy==2; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Mutually Exclusive';  end  % Hierarchy
                if Hierarchy==3; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Simultaneous';        end  % Hierarchy
                FRAGDATA.C_code(FragiltiyCount+1,1)='User_UV';          % DS Code
                FRAGDATA.DS_repairmeasure(FragiltiyCount+1,1)='NR';     % DS Repair Measure
                FRAGDATA.DS_FragType(FragiltiyCount+1,1)=111;           % Type of Fragility
                
                FRAGDATA.DS_UQcutoff           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_UQCcutoff          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_LQcutoff           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_LQCcutoff          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2median          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2sigma           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2FitFun          (FragiltiyCount+1,1)='NaN';     % Type of Fragility
                FRAGDATA.DS_ENERGYmedian       (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_ENERGYsigma        (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_ENERGYFitFun       (FragiltiyCount+1,1)='NaN';     % Type of Fragility
                FRAGDATA.DS_Casualty           (FragiltiyCount+1,1)=Casualty;       % Type of Fragility
                FRAGDATA.DS_UnsafePlacardFlag  (FragiltiyCount+1,1)=Placard;        % Type of Fragility
                FRAGDATA.DS_InjuryRatemedian   (FragiltiyCount+1,1)=Injurymu;       % Type of Fragility
                FRAGDATA.DS_InjuryRatesigma    (FragiltiyCount+1,1)=Injurysigma;    % Type of Fragility
                FRAGDATA.DS_CasualtyRatemedian (FragiltiyCount+1,1)=Casualtymu;     % Type of Fragility
                FRAGDATA.DS_CasualtyRatesigma  (FragiltiyCount+1,1)=Casualtysigma;  % Type of Fragility
                FRAGDATA.DS_UnsafePlacardmedian(FragiltiyCount+1,1)=Placardmu;      % Type of Fragility
                FRAGDATA.DS_UnsafePlacardsigma (FragiltiyCount+1,1)=Placardsigma;   % Type of Fragility
                FRAGDATA.DS_AffectArea         (FragiltiyCount+1,1)=AffectArea;     % Type of Fragility
                FRAGDATA.DS_Photo              (FragiltiyCount+1,1)='none';         % Type of Fragility
            end
            
            
            %% Existing Component
            if app.ExistingRadio.Value==1
                ID=app.edit9.Value;
                indxCOMPDATA=find(COMPDATA.C_ID==ID);
                indxFRAGDATA=find(FRAGDATA.C_ID==ID);
                idxxx=indxFRAGDATA(end,1);
                ComponentName = app.edit1.Value;
                DSDescription = app.edit2.Value;
    
                % Data Checks
                if isempty(DSDescription)
                    app.edit2.Value='Damage State Description was not Defined by the User';
                    DSDescription = app.edit2.Value;
                end
                if strcmp(ID,'NaN')==1
                    errordlg('Please Specify Component Type and EDP');
                    waitfor(ed)
                    return
                end
            
                % Update existing component info to Component_List
                COMPDATA.C_nDS(indxCOMPDATA,1)=nDS;                   % number of DS
            
                % Add new damage state data to the FRAGDATA matrix
                FragiltiyCount=size(FRAGDATA.C_ID,1);
                FRAGDATA.DS_n(FragiltiyCount+1,1)=nDS;                  % DS Number
                FRAGDATA.DS_name{FragiltiyCount+1,1}=DSDescription;     % DS Description
                FRAGDATA.DS_EDPmedian(FragiltiyCount+1,1)=EDPmu;        % Median EDP
                FRAGDATA.DS_EDPsigma(FragiltiyCount+1,1)=EDPsigma;      % Sigma  EDP
                FRAGDATA.DS_Costmean(FragiltiyCount+1,1)=Costmu;       % Repair Cost mu
                FRAGDATA.DS_CostP50(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_CostP90(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_CostP10(FragiltiyCount+1,1)=Costmu;        % Repair Cost mu
                FRAGDATA.DS_Costsigma(FragiltiyCount+1,1)=Costsigma;   % Repair Cost sigma
                if Costfitfun==1; FRAGDATA.DS_CostFitFun{FragiltiyCount+1,1}='Normal';    end       % Repair Cost fit function
                if Costfitfun==2; FRAGDATA.DS_CostFitFun{FragiltiyCount+1,1}='LogNormal'; end       % Repair Cost fit function
                FRAGDATA.DS_Timemean(FragiltiyCount+1,1)=Timemu;       % Repair Time mu
                FRAGDATA.DS_TimeP50(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_TimeP90(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_TimeP10(FragiltiyCount+1,1)=Timemu;        % Repair Time mu
                FRAGDATA.DS_Timesigma(FragiltiyCount+1,1)=Timesigma;   % Repair Time sigma
                if Timefitfun==1; FRAGDATA.DS_TimeFitFun{FragiltiyCount+1,1}='Normal';    end       % Time fit function
                if Timefitfun==2; FRAGDATA.DS_TimeFitFun{FragiltiyCount+1,1}='LogNormal'; end       % Time fit function
                FRAGDATA.DS_P(FragiltiyCount+1,1)=P;                                    % Mutually Exclusive/Sequential Probability
                if Hierarchy==1; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Sequential';          end  % Hierarchy
                if Hierarchy==2; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Mutually Exclusive';  end  % Hierarchy
                if Hierarchy==3; FRAGDATA.DS_Hierarchy{FragiltiyCount+1,1}='Simultaneous';        end  % Hierarchy
                FRAGDATA.DS_repairmeasure(FragiltiyCount+1,1)='NR';                     % DS Repair Measure
                FRAGDATA.C_ID(FragiltiyCount+1,1)=FRAGDATA.C_ID(idxxx,1);               % Component ID
                FRAGDATA.C_name(FragiltiyCount+1,1)=FRAGDATA.C_name(idxxx,1);           % Component Name
                FRAGDATA.DS_Unit(FragiltiyCount+1,1)=FRAGDATA.DS_Unit(idxxx,1);         % Unit
                FRAGDATA.DS_UnitType(FragiltiyCount+1,1)=FRAGDATA.DS_UnitType(idxxx,1); % Unit Type
                FRAGDATA.DS_UnitVal(FragiltiyCount+1,1)=FRAGDATA.DS_UnitVal(idxxx,1);   % Unit Val
                FRAGDATA.DS_EDP(FragiltiyCount+1,1)=FRAGDATA.DS_EDP(idxxx,1);           % DS EDP
                FRAGDATA.C_code(FragiltiyCount+1,1)=FRAGDATA.C_code(idxxx,1);           % DS Code
                FRAGDATA.DS_FragType(FragiltiyCount+1,1)=FRAGDATA.DS_FragType(idxxx,1); % Type of Fragility
                
                FRAGDATA.DS_UQcutoff           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_UQCcutoff          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_LQcutoff           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_LQCcutoff          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2median          (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2sigma           (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_CO2FitFun          (FragiltiyCount+1,1)='NaN';     % Type of Fragility
                FRAGDATA.DS_ENERGYmedian       (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_ENERGYsigma        (FragiltiyCount+1,1)="NaN";     % Type of Fragility
                FRAGDATA.DS_ENERGYFitFun       (FragiltiyCount+1,1)='NaN';     % Type of Fragility
                FRAGDATA.DS_Casualty           (FragiltiyCount+1,1)=Casualty;       % Type of Fragility
                FRAGDATA.DS_UnsafePlacardFlag  (FragiltiyCount+1,1)=Placard;        % Type of Fragility
                FRAGDATA.DS_InjuryRatemedian   (FragiltiyCount+1,1)=Injurymu;       % Type of Fragility
                FRAGDATA.DS_InjuryRatesigma    (FragiltiyCount+1,1)=Injurysigma;    % Type of Fragility
                FRAGDATA.DS_CasualtyRatemedian (FragiltiyCount+1,1)=Casualtymu;     % Type of Fragility
                FRAGDATA.DS_CasualtyRatesigma  (FragiltiyCount+1,1)=Casualtysigma;  % Type of Fragility
                FRAGDATA.DS_UnsafePlacardmedian(FragiltiyCount+1,1)=Placardmu;      % Type of Fragility
                FRAGDATA.DS_UnsafePlacardsigma (FragiltiyCount+1,1)=Placardsigma;   % Type of Fragility
                FRAGDATA.DS_AffectArea         (FragiltiyCount+1,1)=AffectArea;     % Type of Fragility
                FRAGDATA.DS_Photo              (FragiltiyCount+1,1)='none';         % Type of Fragility
            end
            
            cd (ProjectPath)
            pause(0.5)
            save(ProjectName,'COMPDATA','FRAGDATA','-append')
            pause(1)
            cd (MainDirectory)            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Fragility_Univariate_OpeningFcn(app, mainapp)
            global  Units
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        
            app.Categorypopup.Enable='off';
            app.EDPpopup.Enable     ='off';
            app.Ppopup.Enable   ='off';
            app.edit1.Enable        ='off';
            app.edit2.Enable        ='off';
            app.edit3.Enable        ='off';
            app.edit4.Enable        ='off';
            app.edit10.Enable       ='off';
            app.UnitTypepopup.Enable='off';
            if Units==1; xx{1}='unit'; xx{2}='ft'; xx{3}='sq. ft';    end
            if Units==2; xx{1}='unit'; xx{2}='m';  xx{3}='sq. meter'; end
            app.UnitTypepopup.Items=xx;
            app.UnitTypepopup.ItemsData=1:3;

            ButtonGroupSelectionChanged(app);
            
        end

        % Button pushed function: ApplyButton
        function ApplyButton_Callback(app, event)
            Apply(app)
            ButtonGroupSelectionChanged(app)
        end

        % Value changed function: Ppopup
        function Ppopup_Callback(app, event)
            if app.Ppopup.Value=='1'
               app.edit10.Enable='off';
               app.edit10.Value=1.0;
            else
               app.edit10.Enable='on';
            end
            PlacardpopupValueChanged(app)
            CasualtypopupValueChanged(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.NewRadio.Value==1
                global MainDirectory ProjectName ProjectPath
                cd (ProjectPath) 
                load(ProjectName,'COMPDATA')
                pause(0.5)
                cd (MainDirectory)
                
                app.text18.Text='Auto-Generated ID';
                app.edit9.Enable='off';
                app.edit9.FontColor='r';
                NewComponent_ID=COMPDATA.C_ID(end,1)+1;     % Get the new ID of the component to be defined
                app.edit9.Value=NewComponent_ID;
                app.edit7.Value=1;
                app.Categorypopup.Enable='on';
                app.EDPpopup.Enable='on';
                app.Ppopup.Enable='on';
                app.edit1.Enable='on';
                app.edit2.Enable='on';
                app.edit3.Enable='on';
                app.edit4.Enable='on';
                app.edit10.Enable='on';
                app.UnitTypepopup.Enable='on';
                app.edit1.Value='';
                app.edit2.Value='';
                
                Ppopup_Callback(app)
            else
                app.text18.Text='Specify Component ID';
                app.edit9.Enable='on';  
                app.edit9.Value=1;  
                app.edit9.FontColor='k';
                app.Categorypopup.Enable='off';
                app.EDPpopup.Enable='off';
                app.Ppopup.Enable='off';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.edit4.Enable='off';
                app.edit10.Enable='off';
                app.UnitTypepopup.Enable='off';
                
                edit9ValueChanged(app)
            end            
        end

        % Value changed function: edit9
        function edit9ValueChanged(app, event)
            global MainDirectory ProjectName ProjectPath
            id=app.edit9.Value;
            if isnan(id)~=1
                cd (ProjectPath)
                load (ProjectName,'COMPDATA','FRAGDATA')
                pause(0.5)
                cd (MainDirectory)
            
                indx=find(id==COMPDATA.C_ID);
                if isempty(indx)
                    errordlg('The specified component ID does not exist in the database!');
                    return
                end
            
                app.Categorypopup.Enable='off';
                app.EDPpopup.Enable='off';
                app.Ppopup.Enable='on';
                app.edit1.Enable='off';
                app.edit2.Enable='on';
                app.edit3.Enable='on';
                app.edit4.Enable='on';
                app.edit10.Enable='on';
            
                edp     = COMPDATA.C_EDP(id);
                nds     = COMPDATA.C_nDS(id);
                name    = COMPDATA.C_shortername(id);
                category= COMPDATA.C_Category2(id);
                idxx=find(FRAGDATA.C_ID==id);
                idxxx=idxx(end,1);
            
                app.edit1.Value=name;
                app.edit7.Value=nds+1;
                app.edit3.Value=FRAGDATA.DS_EDPmedian(idxxx);
                app.edit4.Value=FRAGDATA.DS_EDPsigma(idxxx);
                app.CostQPpopup.Value='2';
                app.edit5.Value=FRAGDATA.DS_CostP50(idxxx);
                app.edit6.Value=FRAGDATA.DS_Costsigma(idxxx);
                app.TimeQPpopup.Value='2';
                app.edit11.Value=FRAGDATA.DS_TimeP50(idxxx);
                app.edit12.Value=FRAGDATA.DS_Timesigma(idxxx);
                app.edit8.Value=FRAGDATA.DS_UnitVal(idxxx);
                Casualty=FRAGDATA.DS_Casualty(idxxx);
                if  Casualty=='YES'; app.Casualtypopup.Value='2'; else; app.Casualtypopup.Value='1'; end 
                app.edit13.Value= FRAGDATA.DS_CasualtyRatemedian(idxxx,1);
                app.edit14.Value= FRAGDATA.DS_CasualtyRatesigma(idxxx,1);
                app.edit15.Value= FRAGDATA.DS_InjuryRatemedian(idxxx,1);
                app.edit16.Value= FRAGDATA.DS_InjuryRatesigma(idxxx,1);
                app.edit17.Value= FRAGDATA.DS_AffectArea(idxxx,1);
                PlacardFlag=FRAGDATA.DS_UnsafePlacardFlag(idxxx);
                if  PlacardFlag=='YES'; app.Placardpopup.Value='2'; else; app.Placardpopup.Value='1'; end 
                app.edit18.Value= FRAGDATA.DS_UnsafePlacardmedian(idxxx,1);
                app.edit19.Value= FRAGDATA.DS_UnsafePlacardsigma(idxxx,1);
            
                if     edp=='SDR';      app.EDPpopup.Value='2';
                elseif edp=='PFA';      app.EDPpopup.Value='3';
                elseif edp=='PFV';      app.EDPpopup.Value='4';
                elseif edp=='LINK ROT'; app.EDPpopup.Value='5';
                elseif edp=='BEAM ROT'; app.EDPpopup.Value='6';
                elseif edp=='COL ROT';  app.EDPpopup.Value='7';
                elseif edp=='RDR';      app.EDPpopup.Value='8';
                elseif edp=='DWD';      app.EDPpopup.Value='9';
                elseif edp=='VRD';      app.EDPpopup.Value='10';
                elseif edp=='RD';       app.EDPpopup.Value='11';
                elseif edp=='ED';       app.EDPpopup.Value='12';
                elseif edp=='GENS1';    app.EDPpopup.Value='13';
                elseif edp=='GENS2';    app.EDPpopup.Value='14';
                elseif edp=='GENF3';    app.EDPpopup.Value='15';
                elseif edp=='GENF1';    app.EDPpopup.Value='16';
                elseif edp=='GENF2';    app.EDPpopup.Value='17';
                elseif edp=='GENF3';    app.EDPpopup.Value='18';
                end
            
                if     category=='Superstructure';      app.Categorypopup.Value='2';
                elseif category=='Roofing';             app.Categorypopup.Value='3';
                elseif category=='Exterior Enclosure';  app.Categorypopup.Value='4';
                elseif category=='Interiors';           app.Categorypopup.Value='5';
                elseif category=='HAVC';                app.Categorypopup.Value='6';
                elseif category=='Fire Protection';     app.Categorypopup.Value='7';
                elseif category=='Electrical';          app.Categorypopup.Value='8';
                elseif category=='Conveying';           app.Categorypopup.Value='9';
                elseif category=='Plumbing';            app.Categorypopup.Value='10';
                elseif category=='Furniture';           app.Categorypopup.Value='11';
                end
            
                CostUnitType=FRAGDATA.DS_UnitType(idxxx);
                if     CostUnitType=='EA'; app.UnitTypepopup.Value=1;
                elseif CostUnitType=='EU'; app.UnitTypepopup.Value=1;
                elseif CostUnitType=='LF'; app.UnitTypepopup.Value=2;
                elseif CostUnitType=='SF'; app.UnitTypepopup.Value=3;
                end
            
                if strcmp(FRAGDATA.DS_P(idxxx),'Simultaneous')==1
                    app.Ppopup.Value='3';
                    app.Ppopup.Enable='off';
                    app.edit10.Value=1;                        
                    app.edit10.Limits=[0.001 1.0];
                else
                P=FRAGDATA.DS_P(idxxx);
                if P==1
                    app.Ppopup.Value='1';
                    app.edit10.Value=1;
                else
                    % loop to calculate the sum of Ps of
                    % the previous mutually exclusive DSs
                    Ps=0.0;
                    for i=idxxx:-1:idxxx-nds+1
                        if FRAGDATA.DS_P(i)<1
                            if i-1>=idxxx-nds+1
                                if FRAGDATA.DS_P(i-1)==1
                                    Ps=Ps+FRAGDATA.DS_P(i);
                                    break
                                end
                            end
                            Ps=Ps+FRAGDATA.DS_P(i);
                        end
                    end
                    
                    if Ps==1
                        app.Ppopup.Value='1';
                        app.Ppopup.Enable='on';
                        app.edit10.Value=1;                        
                        app.edit10.Limits=[0.001 1.0];
                    else
                        app.Ppopup.Value='2';
                        app.Ppopup.Enable='off';
                        app.edit10.Value=1-Ps;
                        app.edit10.Limits=[0.001 1-Ps];
                    end
                end
                end
            
            end  
            EDPpopupValueChanged(app)
            PlacardpopupValueChanged(app)
            CasualtypopupValueChanged(app)

        end

        % Callback function
        function EDPpopupValueChanged(app, event)
            [DS_EDP] = GetDS_EDP(app);
            app.text9.Text=['EDP: ',DS_EDP];
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Value changed function: Placardpopup
        function PlacardpopupValueChanged(app, event)
            if app.Placardpopup.Value=='1'
                app.edit18.Enable='off';
                app.edit19.Enable='off';
                app.edit18.Value=0;
                app.edit19.Value=0;                
            else
                app.edit18.Enable='on';
                app.edit19.Enable='on';
            end
        end

        % Value changed function: Casualtypopup
        function CasualtypopupValueChanged(app, event)
            if app.Casualtypopup.Value=='1'
                app.edit13.Enable='off';
                app.edit14.Enable='off';
                app.edit15.Enable='off';
                app.edit16.Enable='off';
                app.edit17.Enable='off';
                app.edit13.Value=0;
                app.edit14.Value=0;                
                app.edit15.Value=0;                
                app.edit16.Value=0;                
                app.edit17.Value=0;                
            else
                app.edit13.Enable='on';
                app.edit14.Enable='on';
                app.edit15.Enable='on';
                app.edit16.Enable='on';
                app.edit17.Enable='on';
            end            
        end

        % Button pushed function: SubmitCloseButton
        function SubmitCloseButtonPushed(app, event)
            Apply(app)
            figure1CloseRequest(app, event)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'New Univariate Fragility Curve';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Add Univariate Fragility';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Text = 'Added Component:';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Text = 'Damage State Description';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Text = 'Component Name';
            app.text17 = uilabel(app.uipanel3);
            app.text17.Text = 'Category';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Text = 'Damage State EDP';
            app.text18 = uilabel(app.uipanel3);
            app.text18.Text = 'Auto-Generated ID';
            app.ButtonGroup = uibuttongroup(app.uipanel3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.NewRadio = uiradiobutton(app.ButtonGroup);
            app.NewRadio.Text = 'New';
            app.NewRadio.Value = true;
            app.ExistingRadio = uiradiobutton(app.ButtonGroup);
            app.ExistingRadio.Text = 'Existing';
            app.edit9 = uieditfield(app.uipanel3, 'numeric');
            app.edit9.Limits = [1 Inf];
            app.edit9.ValueDisplayFormat = '%.0f';
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9ValueChanged, true);
            app.edit9.Value = 1;
            app.edit1 = uieditfield(app.uipanel3, 'text');
            app.edit2 = uieditfield(app.uipanel3, 'text');
            app.Categorypopup = uidropdown(app.uipanel3);
            app.Categorypopup.Items = {'', 'Superstructure', 'Roofing', 'Exterior Enclosure', 'Interiors', 'HAVC', 'Fire Protection', 'Electrical', 'Conveying', 'Plumbing'};
            app.Categorypopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Categorypopup.Value = '1';
            app.edit7 = uieditfield(app.uipanel3, 'numeric');
            app.edit7.Limits = [1 Inf];
            app.edit7.ValueDisplayFormat = '%.0f';
            app.edit7.Enable = 'off';
            app.edit7.Value = 1;
            app.text10 = uilabel(app.uipanel3);
            app.text10.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10.Text = 'β';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tooltip = 'Central tendency of the EDP dataset';
            app.text9.Text = 'EDP:';
            app.edit3 = uieditfield(app.uipanel3, 'numeric');
            app.edit3.Limits = [0.0001 Inf];
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.uipanel3, 'numeric');
            app.edit4.Limits = [0.0001 Inf];
            app.edit4.ValueDisplayFormat = '%.2f';
            app.edit4.Value = 0.3;
            app.text9_2 = uilabel(app.uipanel3);
            app.text9_2.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_2.Text = 'μ';
            app.text12 = uilabel(app.uipanel3);
            app.text12.Text = 'Cost:';
            app.edit5 = uieditfield(app.uipanel3, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.Tooltip = {'value in $'};
            app.edit5.Value = 1;
            app.text9_3 = uilabel(app.uipanel3);
            app.text9_3.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_3.Text = 'μ';
            app.text10_2 = uilabel(app.uipanel3);
            app.text10_2.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_2.Text = 'β';
            app.edit6 = uieditfield(app.uipanel3, 'numeric');
            app.edit6.Limits = [0.0001 Inf];
            app.edit6.ValueDisplayFormat = '%.2f';
            app.edit6.Value = 0.3;
            app.text12_2 = uilabel(app.uipanel3);
            app.text12_2.Text = 'Time:';
            app.text9_4 = uilabel(app.uipanel3);
            app.text9_4.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_4.Text = 'μ';
            app.text10_3 = uilabel(app.uipanel3);
            app.text10_3.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_3.Text = 'β';
            app.edit11 = uieditfield(app.uipanel3, 'numeric');
            app.edit11.Limits = [0 Inf];
            app.edit11.Tooltip = {'value in hours'};
            app.edit11.Value = 1;
            app.edit12 = uieditfield(app.uipanel3, 'numeric');
            app.edit12.Limits = [0.0001 Inf];
            app.edit12.ValueDisplayFormat = '%.2f';
            app.edit12.Value = 0.3;
            app.CostQPpopup = uidropdown(app.uipanel3);
            app.CostQPpopup.Items = {'Mean', 'Median', 'P90', 'P10'};
            app.CostQPpopup.ItemsData = {'1', '2', '3', '4'};
            app.CostQPpopup.Tooltip = {'Cost μ parameter to modify'};
            app.CostQPpopup.Value = '1';
            app.TimeQPpopup = uidropdown(app.uipanel3);
            app.TimeQPpopup.Items = {'Mean', 'Median', 'P90', 'P10'};
            app.TimeQPpopup.ItemsData = {'1', '2', '3', '4'};
            app.TimeQPpopup.Tooltip = {'Time μ parameter to modify'};
            app.TimeQPpopup.Value = '1';
            app.Ppopup = uidropdown(app.uipanel3);
            app.Ppopup.Items = {'Sequential', 'Mutually Exclusive', 'Simultaneous'};
            app.Ppopup.ItemsData = {'1', '2', '3'};
            app.Ppopup.ValueChangedFcn = createCallbackFcn(app, @Ppopup_Callback, true);
            app.Ppopup.Value = '1';
            app.edit10 = uieditfield(app.uipanel3, 'numeric');
            app.edit10.Limits = [0 1];
            app.edit10.Value = 1;
            app.UnitTypepopup = uidropdown(app.uipanel3);
            app.UnitTypepopup.Items = {'unit', 'ft', 'ft2'};
            app.UnitTypepopup.ItemsData = {'1', '2', '3'};
            app.UnitTypepopup.Value = '1';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Text = 'Cost/Time is per';
            app.EDPpopup = uidropdown(app.uipanel3);
            app.EDPpopup.Items = {'---', 'SDR', 'PFA', 'PGV', 'LINK ROT', 'BEAM ROT', 'COL ROT', 'RDR', 'DWD', 'VRD', 'RD', 'ED', 'GENS1', 'GENS2', 'GENS3', 'GENF1', 'GENF2', 'GENF3'};
            app.EDPpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18'};
            app.EDPpopup.Value = '1';
            app.text15 = uilabel(app.uipanel3);
            app.text15.Text = 'Damage State Number';
            app.Costfitfunpopup = uidropdown(app.uipanel3);
            app.Costfitfunpopup.Items = {'Norm', 'Logn'};
            app.Costfitfunpopup.ItemsData = {'1', '2'};
            app.Costfitfunpopup.Tooltip = {'Cost μ parameter to modify'};
            app.Costfitfunpopup.Value = '1';
            app.Timefitfunpopup = uidropdown(app.uipanel3);
            app.Timefitfunpopup.Items = {'Norm', 'Logn'};
            app.Timefitfunpopup.ItemsData = {'1', '2'};
            app.Timefitfunpopup.Tooltip = {'Cost μ parameter to modify'};
            app.Timefitfunpopup.Value = '1';
            app.text12_3 = uilabel(app.uipanel3);
            app.text12_3.Text = 'Casualty:';
            app.text9_5 = uilabel(app.uipanel3);
            app.text9_5.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_5.Text = 'μ';
            app.text10_4 = uilabel(app.uipanel3);
            app.text10_4.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_4.Text = 'β';
            app.edit13 = uieditfield(app.uipanel3, 'numeric');
            app.edit13.Limits = [0 1];
            app.edit13.Tooltip = {'casualty rate'};
            app.edit14 = uieditfield(app.uipanel3, 'numeric');
            app.edit14.Limits = [0 Inf];
            app.edit14.ValueDisplayFormat = '%.2f';
            app.edit14.Value = 0.5;
            app.Casualtypopup = uidropdown(app.uipanel3);
            app.Casualtypopup.Items = {'No', 'Yes'};
            app.Casualtypopup.ItemsData = {'1', '2'};
            app.Casualtypopup.ValueChangedFcn = createCallbackFcn(app, @CasualtypopupValueChanged, true);
            app.Casualtypopup.Tooltip = {'Potential casualties'};
            app.Casualtypopup.Value = '1';
            app.text12_4 = uilabel(app.uipanel3);
            app.text12_4.Text = 'Injury:';
            app.text9_6 = uilabel(app.uipanel3);
            app.text9_6.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_6.Text = 'μ';
            app.text10_5 = uilabel(app.uipanel3);
            app.text10_5.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_5.Text = 'β';
            app.edit15 = uieditfield(app.uipanel3, 'numeric');
            app.edit15.Limits = [0 1];
            app.edit15.Tooltip = {'injury rate'};
            app.edit16 = uieditfield(app.uipanel3, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit16.ValueDisplayFormat = '%.2f';
            app.edit16.Value = 0.5;
            app.text12_5 = uilabel(app.uipanel3);
            app.text12_5.Text = 'Affected Area:';
            app.text10_6 = uilabel(app.uipanel3);
            app.text10_6.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_6.Text = 'sq. feet';
            app.edit17 = uieditfield(app.uipanel3, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.Tooltip = {'area affected by a damaged component'};
            app.text12_6 = uilabel(app.uipanel3);
            app.text12_6.Text = 'Unsafe Placard:';
            app.Placardpopup = uidropdown(app.uipanel3);
            app.Placardpopup.Items = {'No', 'Yes'};
            app.Placardpopup.ItemsData = {'1', '2'};
            app.Placardpopup.ValueChangedFcn = createCallbackFcn(app, @PlacardpopupValueChanged, true);
            app.Placardpopup.Tooltip = {'Potential unsafe placard'};
            app.Placardpopup.Value = '1';
            app.text9_7 = uilabel(app.uipanel3);
            app.text9_7.Tooltip = 'Central tendency of the EDP dataset';
            app.text9_7.Text = 'μ';
            app.text10_7 = uilabel(app.uipanel3);
            app.text10_7.Tooltip = 'Standard deviation of the ln(EDP) dataset';
            app.text10_7.Text = 'β';
            app.edit18 = uieditfield(app.uipanel3, 'numeric');
            app.edit18.Limits = [0 1];
            app.edit18.Tooltip = {'placard rate'};
            app.edit19 = uieditfield(app.uipanel3, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit19.ValueDisplayFormat = '%.2f';
            app.edit19.Value = 0.5;
            app.edit8 = uieditfield(app.uipanel3, 'numeric');
            app.edit8.Limits = [0.0001 Inf];
            app.edit8.Value = 1;
            app.ApplyButton = uibutton(app.figure1, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_Callback, true);
            app.ApplyButton.Text = 'Apply';
            app.SubmitCloseButton = uibutton(app.figure1, 'push');
            app.SubmitCloseButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitCloseButtonPushed, true);
            app.SubmitCloseButton.Text = 'Submit & Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Fragility_Univariate_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Fragility_Univariate_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end