classdef Module_Hazard_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        ButtonGroup            matlab.ui.container.ButtonGroup
        HazardRadio1           matlab.ui.control.RadioButton
        HazardRadio2           matlab.ui.control.RadioButton
        BrowseButton           matlab.ui.control.Button
        text2                  matlab.ui.control.Label
        text3                  matlab.ui.control.Label
        text4                  matlab.ui.control.Label
        text5                  matlab.ui.control.Label
        TipIcon2               matlab.ui.control.Button
        text7                  matlab.ui.control.Label
        BrowseMapsbutton       matlab.ui.control.Button
        TipIcon1               matlab.ui.control.Button
        edit1                  matlab.ui.control.NumericEditField
        edit2                  matlab.ui.control.NumericEditField
        edit3                  matlab.ui.control.NumericEditField
        HazardPopup1           matlab.ui.control.DropDown
        SubmitButton           matlab.ui.control.Button
        PlothazardcurveButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        TipWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Hazard_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'HazardDataStatus','HazardOption','Latitude','Longitude','T1','Vs30_CaseNo')
            cd (MainDirectory)
            
            if HazardDataStatus==1 && HazardOption ==1
                app.HazardRadio1.Value=1;
                app.edit1.Value=Latitude;
                app.edit2.Value=Longitude;
                app.edit3.Value=T1;
                app.HazardPopup1.Value=Vs30_CaseNo;
            elseif HazardDataStatus==1 && HazardOption ==2
                app.HazardRadio2.Value=1;
            else
                app.HazardRadio1.Value=1;
            end

            ButtonGroupSelectionChanged(app)
        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            if app.HazardRadio2.Value == 1
                [HazardFilename HazardFilePath]= uigetfile({'*.txt'},'File Selector');
                cd (ProjectPath)
                save(ProjectName,'HazardFilename','HazardFilePath','-append')
                pause(0.5)
                cd (MainDirectory)
            else
                ed = errordlg('User-defined Input is not Selected','Error', 'modal');
                waitfor(ed);
                return
            end
        end

        % Button pushed function: BrowseMapsbutton
        function BrowseMapsbutton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            if app.HazardRadio1.Value == 1
                HazardFolderPath= uigetdir;
                cd (ProjectPath)
                save (ProjectName,'HazardFolderPath','-append')
                pause(0.5)
                cd (MainDirectory)
            else
                ed = errordlg('Option 1 is not Selected','Error', 'modal');
                waitfor(ed);
                return
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'HazardFolderPath','HazardFilePath','HazardFilename','IMpoints','nIMpoints');
            cd (MainDirectory)
            
            Vs30_CaseNo =str2double(app.HazardPopup1.Value);
            switch Vs30_CaseNo
                case 1; Vs30  = 180;
                case 2; Vs30  = 259;
                case 3; Vs30  = 360;
                case 4; Vs30  = 537;
                case 5; Vs30  = 760;
            end
            
            if app.HazardRadio1.Value==1
                Latitude    = app.edit1.Value;
                Longitude   = app.edit2.Value;
                T1          = app.edit3.Value;
            
                %%
                str{1}='Extracting Hazard Data...';
                str{2}='Please Wait';
                app.TipWindow=Tip_App;
                app.TipWindow.textbox.Value=str;%text1 is the value tag for the Static Text in GUI1
                drawnow
            
                [HazardCurveData]= Get_HazardCurve_USGSMaps (MainDirectory,HazardFolderPath,Latitude,Longitude,T1,Vs30,IMpoints);
            
                HazardDataStatus = 1;
                HazardOption     = 1;

                cd (ProjectPath)
                save(ProjectName,'Latitude','Longitude','T1','HazardCurveData','Vs30','HazardDataStatus','HazardOption','Vs30_CaseNo','-append')
                cd (MainDirectory)
            end
            
            if app.HazardRadio2.Value == 1
                if ischar(HazardFilename)
                    HazardCurveData=importdata(strcat(HazardFilePath,HazardFilename));
                    HazardDataStatus=1;
                    HazardOption=2;
                    Vs30_CaseNo =1;
                    for i=1:size(HazardCurveData,1)
                        for j=1:size(HazardCurveData,2)
                            if HazardCurveData(i,j)==0
                                ed = errordlg('**The Sa and Annual Fequency of Exceedance values MUST NOT BE EQUAL TO ZERO**','Error', 'modal');
                                waitfor(ed);
                                return
                            end
                        end
                    end
                    cd (ProjectPath)
                    save(ProjectName,'HazardCurveData','HazardDataStatus','HazardOption','Vs30_CaseNo','-append');
                    cd (MainDirectory)
                end
            end
            
            [MAF,Hazard_Sa]=Fit_Hazard_4Poly(HazardCurveData,IMpoints,nIMpoints);
            
            if app.HazardRadio1.Value == 1
                app.TipWindow.textbox.Value='Done...Hazard Curve was Extracted and Fitted';
                drawnow
                delete(app.TipWindow)
            end
            
            cd (ProjectPath)
            save(ProjectName,'MAF','Hazard_Sa','-append');
            cd (MainDirectory)
            
            app.MainApp.Image5.Visible='off';
            app.MainApp.status3.BackgroundColor='g';
            app.MainApp.status3.FontColor='k';
            app.MainApp.status3.Text='Hazard is defined';
            app.MainApp.status3b.BackgroundColor='g';
            closereq;
        end

        % Button pushed function: TipIcon1
        function TipIcon1_Callback(app, event)
            str{1}='This option extracts the hazard curve from the USGS Western US hazard maps.';
            str{2}='Linear interpolation is conducted based the specified building corrdinates, period and soil condition.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon2
        function TipIcon2_Callback(app, event)
            str{1}='Import hazard curve data from a file.';
            str{2}='The file should have .txt extension with two columns of data: Sa in [g] units (1st column) and Annual Fequency of Exceedance (2nd column).';
            str{3}='**The Sa and Annual Fequency of Exceedance values MUST NOT BE EQUAL TO ZERO**';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.HazardRadio1.Value==1
                app.HazardRadio1.FontColor='b';
                app.HazardRadio2.FontColor='k';
            else
                app.HazardRadio1.FontColor='k';
                app.HazardRadio2.FontColor='b';
            end
        end

        % Button pushed function: PlothazardcurveButton
        function PlothazardcurveButtonPushed(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'HazardDataStatus','HazardFolderPath','HazardFilePath','HazardFilename','IMpoints','nIMpoints') 
            cd (MainDirectory)
            
            if app.HazardRadio1.Value==1
                Latitude=app.edit1.Value;
                Longitude=app.edit2.Value;
                T1=app.edit3.Value;
                Vs30_CaseNo =str2double(app.HazardPopup1.Value);
                switch Vs30_CaseNo
                    case 1; Vs30  = 180;
                    case 2; Vs30  = 259;
                    case 3; Vs30  = 360;
                    case 4; Vs30  = 537;
                    case 5; Vs30  = 760;
                end
            
                clc
                str{1}='Processing...Please Wait...';
                app.TipWindow=Tip_App;
                app.TipWindow.textbox.Value=str;
                drawnow
                [HazardCurveData]= Get_HazardCurve_USGSMaps (MainDirectory,HazardFolderPath,Latitude,Longitude,T1,Vs30,IMpoints);
                app.TipWindow.textbox.Value='Done...Hazard Curve was Extracted';  
            
                HazardOption=1;
            end
            
            if app.HazardRadio2.Value == 1
                if ischar(HazardFilename)
                    HazardCurveData=importdata(strcat(HazardFilePath,HazardFilename));
                    HazardOption=2;
                    for i=1:size(HazardCurveData,1)
                        for j=1:size(HazardCurveData,2)
                            if HazardCurveData(i,j)==0
                                ed = errordlg('**The IM and Annual Fequency of Exceedance values MUST NOT BE EQUAL TO ZERO**','Error', 'modal');
                                waitfor(ed); 
                                return
                            end
                        end
                    end
                end
            end
            
            Plot_Hazard_Curve(HazardCurveData,HazardOption);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Seismic Hazard';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HazardRadio1 = uiradiobutton(app.ButtonGroup);
            app.HazardRadio1.Text = 'USGS Hazard Curve';
            app.HazardRadio1.Value = true;
            app.HazardRadio2 = uiradiobutton(app.ButtonGroup);
            app.HazardRadio2.Text = 'User-defined Hazard Curve';
            app.BrowseButton = uibutton(app.ButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tooltip = {''};
            app.BrowseButton.Text = 'Browse';
            app.text2 = uilabel(app.ButtonGroup);
            app.text2.Text = 'Latitude';
            app.text3 = uilabel(app.ButtonGroup);
            app.text3.Text = 'Longitude';
            app.text4 = uilabel(app.ButtonGroup);
            app.text4.Text = 'T [sec]';
            app.text5 = uilabel(app.ButtonGroup);
            app.text5.Tooltip = 'Shear wave velocity in the upper 30m soil layer (i.e., site condition)';
            app.text5.Text = 'Vs30 [m/sec]';
            app.TipIcon2 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2_Callback, true);
            app.TipIcon2.Text = '?';
            app.text7 = uilabel(app.ButtonGroup);
            app.text7.Tooltip = 'Browse for "USGS Hazard MAT Data" folder (Downloadable with IIDAP)';
            app.text7.Text = 'Hazard Maps Data Folder';
            app.BrowseMapsbutton = uibutton(app.ButtonGroup, 'push');
            app.BrowseMapsbutton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMapsbutton_Callback, true);
            app.BrowseMapsbutton.Tooltip = {''};
            app.BrowseMapsbutton.Text = 'Browse';
            app.TipIcon1 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1_Callback, true);
            app.TipIcon1.Text = '?';
            app.edit1 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit1.Limits = [24.6 50];
            app.edit1.ValueDisplayFormat = '%.3f';
            app.edit1.Value = 33.996;
            app.edit2 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit2.Limits = [-125 -100];
            app.edit2.ValueDisplayFormat = '%.3f';
            app.edit2.Value = -118.162;
            app.edit3 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit3.Limits = [0 10];
            app.edit3.ValueDisplayFormat = '%.3f';
            app.edit3.Value = 1;
            app.HazardPopup1 = uidropdown(app.ButtonGroup);
            app.HazardPopup1.Items = {'180', '259', '360', '537', '760'};
            app.HazardPopup1.ItemsData = {'1', '2', '3', '4', '5'};
            app.HazardPopup1.Tooltip = 'Shear wave velocity in the upper 30m soil layer (i.e., site condition)';
            app.HazardPopup1.Value = '1';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.PlothazardcurveButton = uibutton(app.figure1, 'push');
            app.PlothazardcurveButton.ButtonPushedFcn = createCallbackFcn(app, @PlothazardcurveButtonPushed, true);
            app.PlothazardcurveButton.Text = 'Plot hazard curve';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Hazard_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Hazard_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end