classdef Module_IM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        SubmitButton        matlab.ui.control.Button
        TabGroup            matlab.ui.container.TabGroup
        IMBinsTab           matlab.ui.container.Tab
        uipanel5            matlab.ui.container.Panel
        text24              matlab.ui.control.Label
        text29              matlab.ui.control.Label
        edit1               matlab.ui.control.NumericEditField
        edit2               matlab.ui.control.NumericEditField
        edit3               matlab.ui.control.NumericEditField
        ButtonGroup         matlab.ui.container.ButtonGroup
        radio1              matlab.ui.control.RadioButton
        radio2              matlab.ui.control.RadioButton
        radio3              matlab.ui.control.RadioButton
        edit6               matlab.ui.control.NumericEditField
        text42              matlab.ui.control.Label
        text43              matlab.ui.control.Label
        text44              matlab.ui.control.Label
        text45              matlab.ui.control.Label
        edit4               matlab.ui.control.NumericEditField
        edit5               matlab.ui.control.NumericEditField
        EDPBinsTab          matlab.ui.container.Tab
        text24_2            matlab.ui.control.Label
        editA1              matlab.ui.control.NumericEditField
        editB1              matlab.ui.control.NumericEditField
        editC1              matlab.ui.control.NumericEditField
        text24_3            matlab.ui.control.Label
        editA2              matlab.ui.control.NumericEditField
        editB2              matlab.ui.control.NumericEditField
        editC2              matlab.ui.control.NumericEditField
        text24_4            matlab.ui.control.Label
        editA3              matlab.ui.control.NumericEditField
        editB3              matlab.ui.control.NumericEditField
        editC3              matlab.ui.control.NumericEditField
        text24_5            matlab.ui.control.Label
        editA4              matlab.ui.control.NumericEditField
        editB4              matlab.ui.control.NumericEditField
        editC4              matlab.ui.control.NumericEditField
        text24_6            matlab.ui.control.Label
        editA5              matlab.ui.control.NumericEditField
        editB5              matlab.ui.control.NumericEditField
        editC5              matlab.ui.control.NumericEditField
        text24_7            matlab.ui.control.Label
        editA6              matlab.ui.control.NumericEditField
        editB6              matlab.ui.control.NumericEditField
        editC6              matlab.ui.control.NumericEditField
        text24_8            matlab.ui.control.Label
        editA7              matlab.ui.control.NumericEditField
        editB7              matlab.ui.control.NumericEditField
        editC7              matlab.ui.control.NumericEditField
        text24_9            matlab.ui.control.Label
        editA8              matlab.ui.control.NumericEditField
        editB8              matlab.ui.control.NumericEditField
        editC8              matlab.ui.control.NumericEditField
        text24_10           matlab.ui.control.Label
        editA9              matlab.ui.control.NumericEditField
        editB9              matlab.ui.control.NumericEditField
        editC9              matlab.ui.control.NumericEditField
        text24_11           matlab.ui.control.Label
        usedefaultCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        function ProcessHazard(app,IMpoints, nIMpoints)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'HazardCurveData');
            cd (MainDirectory)
            
            [MAF,Hazard_Sa]=Fit_Hazard_4Poly(HazardCurveData,IMpoints,nIMpoints);

            
            cd (ProjectPath)
            pause(0.5)
            save(ProjectName,'MAF','Hazard_Sa','-append');
            cd (MainDirectory)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_IM_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';            

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'Response_Option','ResponseSA','IMstart','IMincr','IMend','RANGE','TargetIM','Units','Data_Status')
            cd (MainDirectory)
            
            if Response_Option~=1
                app.radio1.Enable='off';
                app.radio2.Enable='off';
                app.radio3.Value=1;
                app.edit6.Value=TargetIM(1,1);
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.text42.Enable='off';
                app.text43.Enable='off';
                app.text44.Enable='off';
                app.text45.Enable='off';
            else
                app.radio1.Value=1;
                app.edit1.Value=IMstart;
                app.edit2.Value=IMincr;
                app.edit3.Value=IMend;
            end
            
            if Units==1
                app.text24_11.Text='PFV [in/sec]';      
            end

            app.editA1.Value=RANGE.SDR(1);   app.editB1.Value=RANGE.SDR(2)  -RANGE.SDR(1);   app.editC1.Value=RANGE.SDR(end);
            app.editA2.Value=RANGE.PFA(1);   app.editB2.Value=RANGE.PFA(2)  -RANGE.PFA(1);   app.editC2.Value=RANGE.PFA(end);            
            if Data_Status.GENS1==1; app.editA3.Value=RANGE.GENS1(1); app.editB6.Value=RANGE.GENS1(2)-RANGE.GENS1(1); app.editC3.Value=RANGE.GENS1(end); end
            if Data_Status.GENS2==1; app.editA4.Value=RANGE.GENS2(1); app.editB4.Value=RANGE.GENS2(2)-RANGE.GENS2(1); app.editC4.Value=RANGE.GENS2(end); end
            if Data_Status.GENS3==1; app.editA5.Value=RANGE.GENS3(1); app.editB5.Value=RANGE.GENS3(2)-RANGE.GENS3(1); app.editC5.Value=RANGE.GENS3(end); end
            if Data_Status.GENF1==1; app.editA6.Value=RANGE.GENF1(1); app.editB6.Value=RANGE.GENF1(2)-RANGE.GENF1(1); app.editC6.Value=RANGE.GENF1(end); end
            if Data_Status.GENF2==1; app.editA7.Value=RANGE.GENF2(1); app.editB7.Value=RANGE.GENF2(2)-RANGE.GENF2(1); app.editC7.Value=RANGE.GENF2(end); end
            if Data_Status.GENF3==1; app.editA8.Value=RANGE.GENF3(1); app.editB8.Value=RANGE.GENF3(2)-RANGE.GENF3(1); app.editC8.Value=RANGE.GENF3(end); end
            if Data_Status.PFV==1;   app.editA9.Value=RANGE.PFV(1);   app.editB9.Value=RANGE.PFV(2)  -RANGE.PFV(1);   app.editC9.Value=RANGE.PFV(end);   end 

            
%             ButtonGroupSelectionChanged(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'Response_Option','HazardDataStatus','RANGE','Units');
            cd (MainDirectory)
            
            if Response_Option==1
                
                IMstart   = app.edit1.Value;
                IMincr    = app.edit2.Value;
                IMend     = app.edit3.Value;
                IMpoints  = IMstart:IMincr:IMend;
                nIMpoints = size(IMpoints,2);
                
                if app.radio1.Value==1
                    TargetIMstart   = IMstart;
                    TargetIMend     = IMend;
                    TargetIM        = IMpoints;
                    TargetIM_Option = 1;
                end
                
                if app.radio2.Value==1
                    TargetIMstart   = app.edit4.Value;
                    TargetIMend     = app.edit5.Value;
                    TargetIM        = TargetIMstart:IMincr:TargetIMend;
                    TargetIM_Option = 2;
                end
                
                if app.radio3.Value==1
                    TargetIM        = app.edit6.Value;
                    TargetIMstart   = TargetIM;
                    TargetIMend     = TargetIM;
                    TargetIM_Option = 3;
                end
                
                if TargetIM_Option==3
                    if ismember(TargetIM,IMpoints)==0
                        for i=2:nIMpoints
                            if IMpoints(1,i)<TargetIM && IMpoints(1,i+1)>TargetIM
                                if abs(IMpoints(1,i)-TargetIM) < abs(IMpoints(1,i+1)-TargetIM)
                                    TargetIM=IMpoints(1,i);
                                else
                                    TargetIM=IMpoints(1,i+1);
                                end
                                break
                            end
                        end
                        app.edit6.Value=TargetIM;
                        ed=warndlg('The target intensity value is approximated to nearest intensity bin!','Heads Up');
                        waitfor(ed);
                        return
                    end
                end
                
                if HazardDataStatus ==1
                    ProcessHazard (app,IMpoints,nIMpoints);
                end

            cd (ProjectPath)
            pause(0.5)
            save(ProjectName,'IMstart','IMincr','IMend','IMpoints','nIMpoints','TargetIM_Option','TargetIM','TargetIMstart','TargetIMend','-append')
            cd (MainDirectory)
                
            end
            
            RANGE.SDR   = [app.editA1.Value:app.editB1.Value:app.editC1.Value];% app.editC1.Value+app.editB1.Value:0.001:max([0.2 app.editC1.Value]) 0.201:0.005:0.3];
            RANGE.PFA   = [app.editA2.Value:app.editB2.Value:app.editC2.Value];
            RANGE.RDR   = RANGE.SDR;
            RANGE.GENS1 = [app.editA3.Value:app.editB3.Value:app.editC3.Value];
            RANGE.GENS2 = [app.editA4.Value:app.editB4.Value:app.editC4.Value];
            RANGE.GENS3 = [app.editA5.Value:app.editB5.Value:app.editC5.Value];
            RANGE.GENF1 = [app.editA6.Value:app.editB6.Value:app.editC6.Value];
            RANGE.GENF2 = [app.editA7.Value:app.editB7.Value:app.editC7.Value];
            RANGE.GENF3 = [app.editA8.Value:app.editB8.Value:app.editC8.Value];
            RANGE.PFV   = [app.editA9.Value:app.editB9.Value:app.editC9.Value];

            if Units==1
                RANGE.VRD=[0:2:1000 1001:10:2000 2010:300:10000 10100:700:40000];    
            else
                RANGE.VRD=[0:0.1:50 51:0.5:100 105:1:200];
            end
                     
            
            if app.usedefaultCheckBox.Value==1
                RANGE.SDR   = [0:0.0001:0.05 0.0501:0.001:0.2 0.201:0.005:0.3];
                RANGE.PFA   = [0.0:0.01:6.0];
                RANGE.RDR   = RANGE.SDR;
                RANGE.DWD   = RANGE.SDR;
                RANGE.RD    = RANGE.SDR;
                RANGE.ED    = RANGE.SDR;
                RANGE.COLROT    = RANGE.SDR;
                RANGE.BEAMROT   = RANGE.SDR;
                RANGE.LINKROT   = RANGE.SDR;
                RANGE.GENS1 = RANGE.SDR;
                RANGE.GENS2 = RANGE.SDR;
                RANGE.GENS3 = RANGE.SDR;
                RANGE.GENF1 = RANGE.PFA;
                RANGE.GENF2 = RANGE.PFA;
                RANGE.GENF3 = RANGE.PFA;
                if Units==1
                    RANGE.VRD=[0:2:1000 1001:10:2000 2010:300:10000 10100:700:40000];    
                    RANGE.PFV   = [0.0:0.1:180.0];
                else
                    RANGE.VRD=[0:0.1:50 51:0.5:100 105:1:200];
                    RANGE.PFV=[0.0:0.01:6.0];
                end
            end
            
            
            IMstatus=1;
            
            cd (ProjectPath)
            save(ProjectName,'RANGE','IMstatus','-append')
            cd (MainDirectory)

            app.MainApp.status10.BackgroundColor='g';
            app.MainApp.Image5.Visible='off';            
            
            figure1CloseRequest(app, event)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            x=app.edit1.Value;
            app.edit3.Limits=[x 10];
            y=app.edit3.Value;
            app.edit2.Limits=[0.001 y];
        end

        % Callback function
        function edit6_Callback(app, event)
            x=app.edit1.Value;
            y=app.edit3.Value;
            app.edit6.Limits=[x y];
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.radio1.Value==1
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.text42.Enable='off';
                app.text43.Enable='off';
                app.text44.Enable='off';
                app.text45.Enable='off';
            elseif app.radio2.Value==1
                app.edit4.Enable='on';
                app.edit5.Enable='on';
                app.edit6.Enable='off'; 
                app.text42.Enable='off';
                app.text43.Enable='on';
                app.text44.Enable='on';
                app.text45.Enable='on';
            elseif app.radio3.Value==1
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='on';
                app.text42.Enable='on';
                app.text43.Enable='off';
                app.text44.Enable='off';
                app.text45.Enable='off';
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
%             if strcmp(app.TabGroup.SelectedTab.Title, 'IM Bins')==1
%                 app.ImageX.Visible='on';
%             else
%                 app.ImageX.Visible='off';
%             end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IM/EDP Bins';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IMBinsTab = uitab(app.TabGroup);
            app.IMBinsTab.Title = 'IM Bins';
            app.uipanel5 = uipanel(app.IMBinsTab);
            app.uipanel5.Title = 'IM Bins for Integeration Accuracy';
            app.text24 = uilabel(app.uipanel5);
            app.text24.Text = 'Start  :  Incr. :  End';
            app.text29 = uilabel(app.uipanel5);
            app.text29.Text = '[g]';
            app.edit1 = uieditfield(app.uipanel5, 'numeric');
            app.edit1.Limits = [0.001 10];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.uipanel5, 'numeric');
            app.edit2.Limits = [0.001 10];
            app.edit2.Value = 0.005;
            app.edit3 = uieditfield(app.uipanel5, 'numeric');
            app.edit3.Limits = [0.001 10];
            app.edit3.Value = 5;
            app.ButtonGroup = uibuttongroup(app.IMBinsTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Target Intensities for Loss Computation';
            app.radio1 = uiradiobutton(app.ButtonGroup);
            app.radio1.Text = 'Compute losses at all intensities';
            app.radio1.Value = true;
            app.radio2 = uiradiobutton(app.ButtonGroup);
            app.radio2.Text = 'Compute losses within intensity range';
            app.radio3 = uiradiobutton(app.ButtonGroup);
            app.radio3.Text = 'Compute losses at a single intensity';
            app.edit6 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit6.Limits = [0.001 Inf];
            app.edit6.Value = 1;
            app.text42 = uilabel(app.ButtonGroup);
            app.text42.Text = '[g]';
            app.text43 = uilabel(app.ButtonGroup);
            app.text43.Text = ':';
            app.text44 = uilabel(app.ButtonGroup);
            app.text44.Text = '[g]';
            app.text45 = uilabel(app.ButtonGroup);
            app.text45.Text = 'Range =';
            app.edit4 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit4.Limits = [0.001 Inf];
            app.edit4.Value = 0.3;
            app.edit5 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit5.Limits = [0.001 Inf];
            app.edit5.Value = 1;
            app.EDPBinsTab = uitab(app.TabGroup);
            app.EDPBinsTab.Title = 'EDP Bins';
            app.text24_2 = uilabel(app.EDPBinsTab);
            app.text24_2.Text = 'Start : Incr  : End';
            app.editA1 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA1.Limits = [0 10];
            app.editB1 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB1.Limits = [0 10];
            app.editB1.Value = 0.0001;
            app.editC1 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC1.Limits = [0 10];
            app.editC1.Value = 0.05;
            app.text24_3 = uilabel(app.EDPBinsTab);
            app.text24_3.Tooltip = {'SDR/RDR/BEAM ROT/COL ROT/LINK ROT/DWD/RD'};
            app.text24_3.Text = 'Drift/Rot EDPs [rad]';
            app.editA2 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA2.Limits = [0 10];
            app.editB2 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB2.Limits = [0 10];
            app.editB2.Value = 0.1;
            app.editC2 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC2.Limits = [0 10];
            app.editC2.Value = 6;
            app.text24_4 = uilabel(app.EDPBinsTab);
            app.text24_4.Tooltip = {'PFA/PGA'};
            app.text24_4.Text = 'Acceleration EDPs [g]';
            app.editA3 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA3.Limits = [0 10];
            app.editB3 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB3.Limits = [0 10];
            app.editB3.Value = 0.0001;
            app.editC3 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC3.Limits = [0 10];
            app.editC3.Value = 0.05;
            app.text24_5 = uilabel(app.EDPBinsTab);
            app.text24_5.Text = 'GENS1';
            app.editA4 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA4.Limits = [0 10];
            app.editB4 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB4.Limits = [0 10];
            app.editB4.Value = 0.0001;
            app.editC4 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC4.Limits = [0 10];
            app.editC4.Value = 0.05;
            app.text24_6 = uilabel(app.EDPBinsTab);
            app.text24_6.Text = 'GENS2';
            app.editA5 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA5.Limits = [0 10];
            app.editB5 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB5.Limits = [0 10];
            app.editB5.Value = 0.0001;
            app.editC5 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC5.Limits = [0 10];
            app.editC5.Value = 0.05;
            app.text24_7 = uilabel(app.EDPBinsTab);
            app.text24_7.Text = 'GENS3';
            app.editA6 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA6.Limits = [0 10];
            app.editB6 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB6.Limits = [0 10];
            app.editB6.Value = 0.1;
            app.editC6 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC6.Limits = [0 10];
            app.editC6.Value = 6;
            app.text24_8 = uilabel(app.EDPBinsTab);
            app.text24_8.Text = 'GENF1';
            app.editA7 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA7.Limits = [0 10];
            app.editB7 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB7.Limits = [0 10];
            app.editB7.Value = 0.1;
            app.editC7 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC7.Limits = [0 10];
            app.editC7.Value = 6;
            app.text24_9 = uilabel(app.EDPBinsTab);
            app.text24_9.Text = 'GENF2';
            app.editA8 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA8.Limits = [0 10];
            app.editB8 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB8.Limits = [0 10];
            app.editB8.Value = 0.1;
            app.editC8 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC8.Limits = [0 10];
            app.editC8.Value = 6;
            app.text24_10 = uilabel(app.EDPBinsTab);
            app.text24_10.Text = 'GENF3';
            app.editA9 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editA9.Limits = [0 10];
            app.editB9 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editB9.Limits = [0 10];
            app.editB9.Value = 0.01;
            app.editC9 = uieditfield(app.EDPBinsTab, 'numeric');
            app.editC9.Limits = [0 10];
            app.editC9.Value = 6;
            app.text24_11 = uilabel(app.EDPBinsTab);
            app.text24_11.Text = 'PGV [m/sec]';
            app.usedefaultCheckBox = uicheckbox(app.EDPBinsTab);
            app.usedefaultCheckBox.Tooltip = {'Default "recommneded" ranges will be used for key EDPs: SDR and PFA'};
            app.usedefaultCheckBox.Text = 'use default';
            app.usedefaultCheckBox.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_IM_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_IM_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end