classdef Module_Option6_RMF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text20        matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
        edit3         matlab.ui.control.NumericEditField
        text20_2      matlab.ui.control.Label
        edit1         matlab.ui.control.NumericEditField
        text20_3      matlab.ui.control.Label
        edit2         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Option6_RMF_OpeningFcn(app)


        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)

            global MainDirectory ProjectName ProjectPath
            clc;
            cd(ProjectPath)
            load(ProjectName,'Option6Data')
            cd(MainDirectory)
            v2struct(Option6Data); % unpack Option6Data 

            
            Omega = app.edit1.Value;
            R     = app.edit2.Value;
            I     = app.edit3.Value;

            Option6Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,ModelType,Omega,R,I); % re-pack with new data

            cd (ProjectPath)
            save (ProjectName,'Option6Data', '-append');
            cd (MainDirectory)

            closereq
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Option6 RMF';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tooltip = {'The importance factor'};
            app.text20.Text = 'I =';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.edit3 = uieditfield(app.uipanel1, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%.1f';
            app.edit3.Tooltip = {'The importance factor'};
            app.edit3.Value = 1;
            app.text20_2 = uilabel(app.uipanel1);
            app.text20_2.Tooltip = {'The overstrength factor'};
            app.text20_2.Text = 'Ωₒ =';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.1f';
            app.edit1.Tooltip = {'The overstrength factor'};
            app.edit1.Value = 1;
            app.text20_3 = uilabel(app.uipanel1);
            app.text20_3.Tooltip = {'The strength modification factor'};
            app.text20_3.Text = 'R =';
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [1 Inf];
            app.edit2.ValueDisplayFormat = '%.1f';
            app.edit2.Tooltip = {'The strength modification factor'};
            app.edit2.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Option6_RMF_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Option6_RMF_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end