classdef Module_Population_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        VisulaizeButton                 matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        text15                          matlab.ui.control.Label
        edit1                           matlab.ui.control.NumericEditField
        text15_2                        matlab.ui.control.Label
        uitable1                        matlab.ui.control.Table
        uitable2                        matlab.ui.control.Table
        byhouranddayLabel               matlab.ui.control.Label
        bymonthLabel                    matlab.ui.control.Label
        VariationinPeakPopulationLabel  matlab.ui.control.Label
        text8                           matlab.ui.control.Label
        Schemepopup                     matlab.ui.control.DropDown
        Monthpopup                      matlab.ui.control.DropDown
        Daypopup                        matlab.ui.control.DropDown
        Hourpopup                       matlab.ui.control.DropDown
        edit2                           matlab.ui.control.NumericEditField
        text5                           matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Population_Model_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'Occupancy','PopModel','Units')
            cd (MainDirectory)
                        
            if Units == 2; app.text5.Text='sq meter'; end
            
            app.edit1.Value    = PopModel.PeakPop;
            app.edit2.Value    = PopModel.PeakPopArea;
            app.uitable1.Data  = PopModel.PopVarPerHourDay;
            app.uitable2.Data  = PopModel.PopVarPerMonth;
            
            app.Schemepopup.Value='1';
            SchemepopupValueChanged(app);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            PopModel.PeakPop            = app.edit1.Value;
            PopModel.PeakPopArea        = app.edit2.Value;
            PopModel.PopVarPerHourDay   = app.uitable1.Data;
            PopModel.PopVarPerMonth     = app.uitable2.Data;
            
            EvalTimeOption = str2double(app.Schemepopup.Value);
            
            if EvalTimeOption==2
                PopModel.PopMonth = str2double(app.Monthpopup.Value);
                PopModel.PopDay   = str2double(app.Daypopup.Value);
                PopModel.PopHour  = str2double(app.Hourpopup.Value);
            end
            
            % Check data
            PopModelstatus=1;
            
            cd (ProjectPath)
            save(ProjectName,'EvalTimeOption','PopModel','PopModelstatus','-append')
            cd (MainDirectory)
            figure1CloseRequest(app);
        end

        % Button pushed function: VisulaizeButton
        function VisulaizeButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'OccupancyName','Units')
            cd (MainDirectory)
            
            PopModel.PeakPopP           = app.edit1.Value;
            PopModel.PeakPopArea        = app.edit2.Value;
            PopModel.PopVarPerHourDay   = app.uitable1.Data;
            PopModel.PopVarPerMonth     = app.uitable2.Data;      
            
            Plot_Population_Model(OccupancyName,PopModel,Units)
            
        end

        % Value changed function: Schemepopup
        function SchemepopupValueChanged(app, event)
            x = str2double(app.Schemepopup.Value);
            if x==1
                app.Monthpopup.Enable='off';
                app.Daypopup.Enable='off';
                app.Hourpopup.Enable='off';
            else
                app.Monthpopup.Enable='on';
                app.Daypopup.Enable='on';
                app.Hourpopup.Enable='on';                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Population Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.VisulaizeButton = uibutton(app.figure1, 'push');
            app.VisulaizeButton.ButtonPushedFcn = createCallbackFcn(app, @VisulaizeButtonPushed, true);
            app.VisulaizeButton.Text = 'Visulaize';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'Peak Occupancy:';
            app.edit1 = uieditfield(app.figure1, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Value = 10;
            app.text15_2 = uilabel(app.figure1);
            app.text15_2.Text = 'people per';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'Weekday'; 'Weekend'};
            app.uitable1.RowName = {'Midnight'; '1:00 am'; '2:00 am'; '3:00 am'; '4:00 am'; '5:00 am'; '6:00 am'; '7:00 am'; '8:00 am'; '9:00 am'; '10:00 am'; '11:00 am'; 'Noon'; '1:00 pm'; '2:00 pm'; '3:00 pm'; '4:00 pm'; '5:00 pm'; '6:00 pm'; '7:00 pm'; '8:00 pm'; '9:00 pm'; '10:00 pm'; '11:00 pm'};
            app.uitable1.ColumnEditable = true;
            app.uitable2 = uitable(app.figure1);
            app.uitable2.ColumnName = {'Weekday'; 'Weekend'};
            app.uitable2.RowName = {'Jan'; 'Feb'; 'Mar'; 'Apr'; 'May'; 'June'; 'July'; 'Aug'; 'Sep'; 'Oct'; 'Nov'; 'Dec'};
            app.uitable2.ColumnEditable = true;
            app.byhouranddayLabel = uilabel(app.figure1);
            app.byhouranddayLabel.Text = 'by hour and day';
            app.bymonthLabel = uilabel(app.figure1);
            app.bymonthLabel.Text = 'by month';
            app.VariationinPeakPopulationLabel = uilabel(app.figure1);
            app.VariationinPeakPopulationLabel.Text = 'Variation in Peak Population';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Evaluation Time:';
            app.Schemepopup = uidropdown(app.figure1);
            app.Schemepopup.Items = {'Random', 'Defined'};
            app.Schemepopup.ItemsData = {'1', '2'};
            app.Schemepopup.ValueChangedFcn = createCallbackFcn(app, @SchemepopupValueChanged, true);
            app.Schemepopup.Value = '1';
            app.Monthpopup = uidropdown(app.figure1);
            app.Monthpopup.Items = {'Jan', 'Feb', 'Mar', 'Apr', 'May', 'June', 'July', 'Aug', 'Sep', 'Oct', 'Nov', 'Dec'};
            app.Monthpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.Monthpopup.Value = '1';
            app.Daypopup = uidropdown(app.figure1);
            app.Daypopup.Items = {'Weekday', 'Weekend'};
            app.Daypopup.ItemsData = {'1', '2'};
            app.Daypopup.Value = '1';
            app.Hourpopup = uidropdown(app.figure1);
            app.Hourpopup.Items = {'Midnight', '1:00am', '2:00am', '3:00am', '4:00am', '5:00am', '6:00am', '7:00am', '8:00am', '9:00am', '10:00am', '11:00am', 'Noon', '1:00pm', '2:00pm', '3:00pm', '4:00pm', '5:00pm', '6:00pm', '7:00pm', '8:00pm', '9:00pm', '10:00pm', '11:00pm'};
            app.Hourpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24'};
            app.Hourpopup.Value = '1';
            app.edit2 = uieditfield(app.figure1, 'numeric');
            app.edit2.Limits = [1 Inf];
            app.edit2.ValueDisplayFormat = '%.2f';
            app.edit2.Value = 1000;
            app.text5 = uilabel(app.figure1);
            app.text5.Text = 'sq feet';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Population_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Population_Model_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end