classdef Module_PredictorData_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        text20          matlab.ui.control.Label
        SubmitButton    matlab.ui.control.Button
        ParameterTable  matlab.ui.control.Table
        ParameterEdit2  matlab.ui.control.EditField
        ParameterEdit1  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_PredictorData_OpeningFcn(app)
            load('temp.mat','C_id','C_type','C_edp','C_name');
            
            app.ParameterEdit1.Value=C_id;
            app.ParameterEdit2.Value=C_name;

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'N_Story')
            cd (MainDirectory)
            load('temp.mat','C_id','C_type','C_edp','C_name');
            
            Component_ID = app.ParameterEdit1.Value;
            Predictor_Datax = app.ParameterTable.Data;
            
            for i=1:N_Story
                for j=1:3
                   if isempty(Predictor_Datax{i,j})
                       evalc(['Predictor_Data',num2str(Component_ID),'(i,j)=0']);
                   else
                       evalc(['Predictor_Data',num2str(Component_ID),'(i,j)=str2num(cell2mat(Predictor_Datax(i,j)))']);
                   end
                end
            end
            
            evalc(['Predictor_DataSize1=size(Predictor_Data',num2str(Component_ID),',1)']);
            evalc(['Predictor_DataSize2=size(Predictor_Data',num2str(Component_ID),',2)']);
            
            if strcmp(C_edp,'SDR')==1 || strcmp(C_edp,'COL ROT')==1
                if Predictor_DataSize1 ~= N_Story
                    errordlg('Entered data does not match the number of stories. Please amend data');
                    OK=0;
                    return
                end
            end
            
            if strcmp(C_edp,'PFA')==1 || strcmp(C_edp,'BEAM ROT')==1  || strcmp(C_edp,'LINK ROT')==1
                if Predictor_DataSize1 ~= N_Story+1
                    errordlg('Entered data does not match the number of floors. Please amend data');
                    OK=0;
                    return
                end
            end
            
            if strcmp(C_edp,'PGA')==1
                if Predictor_DataSize1 ~= 1
                    errordlg('Only a single row of data is needed for this component at Floor 1. Please amend data');
                    OK=0;
                    return
                end
            end
            
            if C_type==222
                errordlg('Only a single column of data (Predictor 1) is needed for components with BV fragility. Please amend data');
                OK=0;
                return
            elseif C_type==999
            
            end
            
            cd (MainDirectory)
            cd (ProjectPath)
            evalc(strcat('save(ProjectName,','''','Predictor_Data',num2str(Component_ID),'''',',','''','-append','''',')'));
            pause(0.2)
            cd (MainDirectory)
            OK=1;
            save('temp.mat','OK');
            closereq
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Predictor Data Module';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'Enter Predictor(s) Data for Component:';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.ParameterTable = uitable(app.uipanel1);
            app.ParameterTable.ColumnName = {'Predictor 1'; 'Predictor 2'; 'Predictor 3'};
            app.ParameterTable.RowName = {'Story/Floor 1'; 'Story/Floor 2'; 'Story/Floor 3'; 'Story/Floor 4'; 'Story/Floor 5'; 'Story/Floor 6'; 'Story/Floor 7'; 'Story/Floor 8'; 'Story/Floor 9'; 'Story/Floor 10'; 'Story/Floor 11'; 'Story/Floor 12'; 'Story/Floor 13'; 'Story/Floor 14'; 'Story/Floor 15'; 'Story/Floor 16'; 'Story/Floor 17'; 'Story/Floor 18'; 'Story/Floor 19'; 'Story/Floor 20'};
            app.ParameterTable.ColumnEditable = [true true true];
            app.ParameterEdit2 = uieditfield(app.uipanel1, 'text');
            app.ParameterEdit2.Enable = 'off';
            app.ParameterEdit1 = uieditfield(app.uipanel1, 'numeric');
            app.ParameterEdit1.Limits = [1 Inf];
            app.ParameterEdit1.ValueDisplayFormat = '%.0f';
            app.ParameterEdit1.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_PredictorData_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_PredictorData_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end