classdef Module_Report_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        uipanel3             matlab.ui.container.Panel
        CB4                  matlab.ui.control.CheckBox
        CB3                  matlab.ui.control.CheckBox
        CB2                  matlab.ui.control.CheckBox
        CB1                  matlab.ui.control.CheckBox
        CB5                  matlab.ui.control.CheckBox
        ReportButton         matlab.ui.control.Button
        popup1               matlab.ui.control.DropDown
        popup2               matlab.ui.control.DropDown
        CB0                  matlab.ui.control.CheckBox
        UnitsButtonGroup     matlab.ui.container.ButtonGroup
        radio3               matlab.ui.control.RadioButton
        radio4               matlab.ui.control.RadioButton
        TargetIMButtonGroup  matlab.ui.container.ButtonGroup
        radio1               matlab.ui.control.RadioButton
        radio2               matlab.ui.control.RadioButton
        text11               matlab.ui.control.Label
        text10               matlab.ui.control.Label
        text14               matlab.ui.control.Label
        edit1                matlab.ui.control.NumericEditField
        edit2                matlab.ui.control.NumericEditField
        edit3                matlab.ui.control.NumericEditField
        text40               matlab.ui.control.Label
        edit4                matlab.ui.control.NumericEditField
        popup5               matlab.ui.control.DropDown
        popup4               matlab.ui.control.DropDown
        popup7               matlab.ui.control.DropDown
        SaveMatButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Report_OpeningFcn(app)
            global MainDirectory ProjectName ProjectPath RunMethodology
            clc;
            cd (ProjectPath)
            load(ProjectName,'Component_Option','Response_Option','TargetIM_Option','TargetIM','HazardDataStatus','RunMethodology','nRealization','nStripe','Timestatus');
            cd (MainDirectory)
            
            if TargetIM_Option==1
                app.edit1.Value=TargetIM(1,round(end/3));
            elseif TargetIM_Option==2
                app.edit1.Value=median(TargetIM);
            elseif TargetIM_Option==3
                app.edit1.Value=median(TargetIM);
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.text10.Enable='off';
                app.text11.Enable='off';
                app.radio1.Enable='off';
                app.radio2.Enable='off';
            end
            
            if RunMethodology==1
                app.SaveMatButton.Enable='off';
                if Response_Option ==2 || Response_Option ==3 || Response_Option ==4 || Response_Option ==6
                    app.CB1.Enable='off';
                    app.CB5.Enable='off';
                end
            end
            
            if Component_Option ~= 1
                app.popup2.Value='1';
                app.popup2.Enable='off';
                app.CB4.Enable='off';
                app.popup2.Items = {'by Component Type'};
                app.popup4.Items={'Cost'};
                app.popup5.Items={'Cost'};
                app.popup7.Items={'Cost'};
            end
            
            if HazardDataStatus ~=1
                app.CB5.Enable='off';
            end
            
            if RunMethodology ==2
                app.radio1.Enable='on';
                app.radio1.Text='Target Ri:';
                app.text14.Text='';
                app.edit1.Limits=[1 nRealization];
                app.edit1.Value=1;
                if nStripe>1; app.edit4.Limits=[1 nStripe]; else; app.edit4.Enable='off'; end
                app.edit1.ValueDisplayFormat='%.0f';
                app.edit1.Value=1;
                app.radio2.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.TargetIMButtonGroup.Title='Specify Target Realization and Stripe';
                app.CB1.Text='Repair         versus Realizations';
                app.CB5.Text='Annual Exceedance of Repair Cost';
                app.popup1.Visible='off';
                if Component_Option~=1
                    app.popup2.Enable='off';
                end
            else
                app.text40.Visible='off';
                app.edit4.Visible='off';
                app.popup7.Visible='off';
            end
            
            if Timestatus~=1 || RunMethodology==1
                app.popup4.Enable='off';
                app.popup5.Enable='off';
            end
                        
            app.radio1.Value=1;
            app.radio3.Value=1;
            
            TargetIMButtonGroupSelectionChanged(app)
            
        end

        % Button pushed function: ReportButton
        function ReportButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath RunMethodology
            clc;
            cd (ProjectPath)
            load(ProjectName)
            cd (MainDirectory)
            
            [Text]=Get_Project_Summary;
            
            ReportFilesPath = uigetdir(MainDirectory,'Select a Folder to Save the Report Files');
            if isempty(ReportFilesPath) || ischar(ReportFilesPath)==0
                return
            end
            
            if app.radio1.Value==1
                if RunMethodology==1
                    Sa_target=app.edit1.Value;
                    Stripe=1;
                    R_target=0;
                    nRealization=0;
                    if ismember(Sa_target,TargetIM)~=1; warndlg('Specified Target IM was not defined in the response data'); return; end
                else
                    Stripe=app.edit4.Value;
                    Sa_target=0;
                    R_target=app.edit1.Value;
                end
            else
                Exceedance=app.edit2.Value;
                Years=app.edit3.Value;
                MAFE_target=-log(1-Exceedance/100)/Years;
                Sa_target=interp1(HazardCurveData(:,2),HazardCurveData(:,1),MAFE_target);
            end
            
            UnitOption=app.radio3.Value;
            
            Report_Vulnerability_Curves=app.CB1.Value;
            VC_Option=app.popup1.Value;
            
            Report_Loss=app.CB2.Value;
            
            Report_Profile=app.CB3.Value;
            Profile_Option=str2double(app.popup2.Value);
            
            Report_Loss_Component=app.CB4.Value;
            
            Report_EAL_bar=app.CB5.Value;
            
            Consequence_Type1=str2double(app.popup4.Value);
            Consequence_Type2=str2double(app.popup5.Value);
            Consequence_Type3=str2double(app.popup7.Value);
            
            %%
            if app.CB0.Value==1
                cd(ReportFilesPath)
                xx=ProjectName;
                xx=xx(1:end-4);
                filename=['Summary of Project ',xx,'.txt'];
                fileX1 = fopen(filename,'wt');
                for i=1:size(Text,2)
                    fprintf(fileX1,'%s\n',char(Text{1,i}));
                end
                cd (MainDirectory)
            end
            
            %%
            if Report_Vulnerability_Curves==1
                if RunMethodology==1
            	   Report_VulnerabilityCurves(IMpoints,COLLAPSE_LOSSES_Per_IM,DEMOLITION_LOSSES_Per_IM,SC_REPAIR_COST_PerIM,NSC_SDR_REPAIR_COST_PerIM,NSC_ACC_REPAIR_COST_PerIM,MAF,VC_Option,UnitOption,Replacement_Cost,ReportFilesPath)
                else
                    if Consequence_Type3==1
                        Report_RepairCost_vs_Realizations(DEAGG_DATA,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,UnitOption,Replacement_Cost,nRealization,Stripe,Component_Option,ReportFilesPath)
                    else
                        Report_RepairTime_vs_Realization(DEAGG_DATA,C_Data,COMPDATA,N_Story,TimeModel,nRealization,Stripe,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,ReportFilesPath)
                    end
                end
            end
            
            %%
            if Report_Loss==1
                Report_LossBreakdown_by_Event(DEAGG_DATA,COLLAPSE_LOSSES_Per_IM,DEMOLITION_LOSSES_Per_IM,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,IMpoints,Sa_target,UnitOption,Replacement_Cost,N_Story,RunMethodology,R_target,nRealization,TargetIM,Stripe,Component_Option,ReportFilesPath)
            end
            %%
            if Report_Profile==1 && Profile_Option==1
                if RunMethodology==2;  STORY_REPAIR_COST_SC=0; STORY_REPAIR_COST_NSC_SDR=0; STORY_REPAIR_COST_NSC_ACC=0; end
                if Consequence_Type1==1
                    Report_RepairCostProfile_by_Type(DEAGG_DATA,STORY_REPAIR_COST_SC,STORY_REPAIR_COST_NSC_SDR,STORY_REPAIR_COST_NSC_ACC,N_Story,IMpoints,Sa_target,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe,Component_Option,ReportFilesPath)
                else
                    Report_RepairTimeProfile_by_Type(DEAGG_DATA,C_Data,COMPDATA,N_Story,TimeModel,R_target,TargetIM,Stripe,ReportFilesPath)
                end
            elseif Report_Profile==1 && Profile_Option==2
                if Consequence_Type1==1
                    Report_RepairCostProfile_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe,ReportFilesPath)
                else
                    Report_RepairTimeProfile_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,Sa_target,TimeModel,R_target,Stripe,ReportFilesPath)
                end
            end
            %%
            if Report_Loss_Component==1
                if Consequence_Type2==1
                    Report_RepairCostBreakdown_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe,ReportFilesPath)
                else
                    Report_RepairTimeBreakdown_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,TimeModel,R_target,TargetIM,Stripe,ReportFilesPath)
                end
            end
            %%
            if Report_EAL_bar ==1
                if RunMethodology==1
                    Report_EAL(EAL,Replacement_Cost,UnitOption,ReportFilesPath)
                else
                    Report_LAMBDA(DEAGG_DATA,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,nRealization,UnitOption,Replacement_Cost,IMpoints,MAF,TargetIM,Stripe,Component_Option,ReportFilesPath)
                end
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: TargetIMButtonGroup
        function TargetIMButtonGroupSelectionChanged(app, event)
            if app.radio1.Value==1
                app.edit1.Enable='on';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
            else
                app.edit1.Enable='off';
                app.edit2.Enable='on';
                app.edit3.Enable='on';
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            if app.CB1.Value ==1
                app.popup1.Enable='on';
            else
                app.popup1.Enable='off';
            end
        end

        % Value changed function: CB3
        function CB3ValueChanged(app, event)
            if app.CB3.Value ==1
                app.popup2.Enable='on';
            else
                app.popup2.Enable='off';
            end
        end

        % Button pushed function: SaveMatButton
        function SaveMatButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath RunMethodology
            cd (ProjectPath)
            load(ProjectName,'RunMethodology','DEAGG_DATA','REALIZATIONS','Component_Option','Placardstatus')
            cd (MainDirectory)
            clc;
            
            MatFilesPath = uigetdir(MainDirectory,'Select a Folder to Save the MAT File');
            if isempty(MatFilesPath) || ischar(MatFilesPath)==0
                return
            end
            
            [DEAGGREGATED_LOSS_DATA, REALIZATIONS_EDP_DATA, DEAGGREGATED_LOSS_DATA_Titles]=Get_Summarized_Data(RunMethodology,DEAGG_DATA,REALIZATIONS,Component_Option,Placardstatus);

            cd(MatFilesPath)
            save('Summary_Results_MAT_Files.mat','REALIZATIONS_EDP_DATA','DEAGGREGATED_LOSS_DATA','DEAGGREGATED_LOSS_DATA_Titles')
            cd(MainDirectory)
            
        end

        % Value changed function: edit1
        function edit1ValueChanged(app, event)
            global RunMethodology
            clc
            
            if RunMethodology==2
                app.edit1.Value=round(app.edit1.Value);
            end
            
        end

        % Value changed function: edit4
        function edit4ValueChanged(app, event)
            app.edit4.Value=round(app.edit4.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Report Options';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Report Options';
            app.CB4 = uicheckbox(app.uipanel3);
            app.CB4.Text = 'Repair         Breakdown by Component Name';
            app.CB3 = uicheckbox(app.uipanel3);
            app.CB3.ValueChangedFcn = createCallbackFcn(app, @CB3ValueChanged, true);
            app.CB3.Text = 'Repair         Profile';
            app.CB2 = uicheckbox(app.uipanel3);
            app.CB2.Text = 'Loss Breakdown by Event';
            app.CB1 = uicheckbox(app.uipanel3);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'Loss Vulnerability Curves';
            app.CB5 = uicheckbox(app.uipanel3);
            app.CB5.Text = 'Expected Annual Losses (EAL)';
            app.ReportButton = uibutton(app.uipanel3, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButton_Callback, true);
            app.ReportButton.Text = 'Generate Report Files';
            app.popup1 = uidropdown(app.uipanel3);
            app.popup1.Items = {'vs IM', 'vs MAFE'};
            app.popup1.Value = 'vs IM';
            app.popup2 = uidropdown(app.uipanel3);
            app.popup2.Items = {'by Component Type', 'by Component Name'};
            app.popup2.ItemsData = {'1', '2'};
            app.popup2.Value = '1';
            app.CB0 = uicheckbox(app.uipanel3);
            app.CB0.Text = 'Project Definition Summary';
            app.UnitsButtonGroup = uibuttongroup(app.uipanel3);
            app.UnitsButtonGroup.Title = 'Loss Units';
            app.radio3 = uiradiobutton(app.UnitsButtonGroup);
            app.radio3.Text = 'Absolute [M$]';
            app.radio3.Value = true;
            app.radio4 = uiradiobutton(app.UnitsButtonGroup);
            app.radio4.Text = 'Normalized [% Cost]';
            app.TargetIMButtonGroup = uibuttongroup(app.uipanel3);
            app.TargetIMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TargetIMButtonGroupSelectionChanged, true);
            app.TargetIMButtonGroup.Title = 'Specify Target IM/Hazard';
            app.radio1 = uiradiobutton(app.TargetIMButtonGroup);
            app.radio1.Text = 'Target IM:';
            app.radio1.Value = true;
            app.radio2 = uiradiobutton(app.TargetIMButtonGroup);
            app.radio2.Text = 'Hazard Level: ';
            app.text11 = uilabel(app.TargetIMButtonGroup);
            app.text11.Text = 'years';
            app.text10 = uilabel(app.TargetIMButtonGroup);
            app.text10.Text = '% in';
            app.text14 = uilabel(app.TargetIMButtonGroup);
            app.text14.Text = '[g]';
            app.edit1 = uieditfield(app.TargetIMButtonGroup, 'numeric');
            app.edit1.Limits = [0.0001 Inf];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.TargetIMButtonGroup, 'numeric');
            app.edit2.Limits = [0.01 100];
            app.edit2.Value = 10;
            app.edit3 = uieditfield(app.TargetIMButtonGroup, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 50;
            app.text40 = uilabel(app.TargetIMButtonGroup);
            app.text40.Text = 'Stripe:';
            app.edit4 = uieditfield(app.TargetIMButtonGroup, 'numeric');
            app.edit4.Limits = [1 10];
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4ValueChanged, true);
            app.edit4.Value = 1;
            app.popup5 = uidropdown(app.uipanel3);
            app.popup5.Items = {'Cost', 'Time'};
            app.popup5.ItemsData = {'1', '2'};
            app.popup5.Value = '1';
            app.popup4 = uidropdown(app.uipanel3);
            app.popup4.Items = {'Cost', 'Time'};
            app.popup4.ItemsData = {'1', '2'};
            app.popup4.Value = '1';
            app.popup7 = uidropdown(app.uipanel3);
            app.popup7.Items = {'Cost', 'Time'};
            app.popup7.ItemsData = {'1', '2'};
            app.popup7.Value = '1';
            app.SaveMatButton = uibutton(app.figure1, 'push');
            app.SaveMatButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMatButtonPushed, true);
            app.SaveMatButton.Text = 'Save Raw Data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Report_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Report_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end