classdef Module_Response_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1x           matlab.ui.Figure
        SelectButtonGroup  matlab.ui.container.ButtonGroup
        Image              matlab.ui.control.Image
        text3              matlab.ui.control.Label
        text18             matlab.ui.control.Label
        text9              matlab.ui.control.Label
        text10             matlab.ui.control.Label
        text6              matlab.ui.control.Label
        text13             matlab.ui.control.Label
        text11             matlab.ui.control.Label
        text12             matlab.ui.control.Label
        Image_2            matlab.ui.control.Image
        Image_3            matlab.ui.control.Image
        Image_4            matlab.ui.control.Image
        text9_2            matlab.ui.control.Label
        text10_2           matlab.ui.control.Label
        Image_5            matlab.ui.control.Image
        radiobutton1       matlab.ui.control.RadioButton
        radiobutton2       matlab.ui.control.RadioButton
        radiobutton3       matlab.ui.control.RadioButton
        radiobutton4       matlab.ui.control.RadioButton
        radiobutton5       matlab.ui.control.RadioButton
        radiobutton6       matlab.ui.control.RadioButton
        text14             matlab.ui.control.Label
        text15             matlab.ui.control.Label
        Image_6            matlab.ui.control.Image
        text18_2           matlab.ui.control.Label
        ContinueButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option')
            cd (MainDirectory)
            
            if ResponseDataStatus==1
                if     Response_Option==1; app.radiobutton1.Value=1;
                elseif Response_Option==2; app.radiobutton2.Value=1;
                elseif Response_Option==3; app.radiobutton3.Value=1;
                elseif Response_Option==4; app.radiobutton4.Value=1;
                elseif Response_Option==5; app.radiobutton5.Value=1;
                elseif Response_Option==6; app.radiobutton6.Value=1;
                end
            end
            
            SelectButtonGroupSelectionChanged(app)

        end

        % Button pushed function: ContinueButton
        function ContinueButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            if app.radiobutton1.Value ==1
                Response_OptionX=1;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option1_App(app.MainApp)
                closereq
            elseif app.radiobutton2.Value ==1
                Response_OptionX=2;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option2or3_App(app.MainApp)
                closereq
            elseif app.radiobutton3.Value ==1
                Response_OptionX=3;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option2or3_App(app.MainApp)
                closereq
            elseif app.radiobutton4.Value ==1
                Response_OptionX=4;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option4_App(app.MainApp)
                closereq
            elseif app.radiobutton5.Value ==1
                Response_OptionX=5;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option5_App(app.MainApp)
                closereq
            elseif app.radiobutton6.Value ==1
                Response_OptionX=6;
                cd (ProjectPath)
                save(ProjectName,'Response_OptionX','-append')
                pause(0.3)
                cd (MainDirectory)
                Module_Response_Option6_App(app.MainApp)
                closereq
            end
        end

        % Close request function: figure1x
        function figure1xCloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Selection changed function: SelectButtonGroup
        function SelectButtonGroupSelectionChanged(app, event)
            %X = app.SelectButtonGroup.SelectedObject;
            app.radiobutton1.FontColor='k';
            app.radiobutton2.FontColor='k';
            app.radiobutton3.FontColor='k';
            app.radiobutton4.FontColor='k';
            app.radiobutton5.FontColor='k';
            app.radiobutton6.FontColor='k';
            
            if     app.radiobutton1.Value==1; app.radiobutton1.FontColor='b';
            elseif app.radiobutton2.Value==1; app.radiobutton2.FontColor='b';
            elseif app.radiobutton3.Value==1; app.radiobutton3.FontColor='b';
            elseif app.radiobutton4.Value==1; app.radiobutton4.FontColor='b';
            elseif app.radiobutton5.Value==1; app.radiobutton5.FontColor='b';
            elseif app.radiobutton6.Value==1; app.radiobutton6.FontColor='b';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1x = uifigure('Visible', 'off');
            app.figure1x.Name = 'Response Data Options';
            app.figure1x.CloseRequestFcn = createCallbackFcn(app, @figure1xCloseRequest, true);
            app.SelectButtonGroup = uibuttongroup(app.figure1x);
            app.SelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectButtonGroupSelectionChanged, true);
            app.SelectButtonGroup.Title = 'Select an Option to Import/Generate Response Data';
            app.Image = uiimage(app.SelectButtonGroup);
            app.Image.ImageSource = 'NRHA1.png';
            app.text3 = uilabel(app.SelectButtonGroup);
            app.text3.Text = 'Import EDP data from multiple ground motions';
            app.text18 = uilabel(app.SelectButtonGroup);
            app.text18.Text = 'scaled at multiple intensities up to collapse';
            app.text9 = uilabel(app.SelectButtonGroup);
            app.text9.Text = 'Import EDP values from multiple ground motions';
            app.text10 = uilabel(app.SelectButtonGroup);
            app.text10.Text = 'scaled at single intensity';
            app.text6 = uilabel(app.SelectButtonGroup);
            app.text6.Text = 'Import EDP distribution parameters (medians';
            app.text13 = uilabel(app.SelectButtonGroup);
            app.text13.Text = 'standard deviations and correlations)';
            app.text11 = uilabel(app.SelectButtonGroup);
            app.text11.Text = 'Auto-Generate EDP data using FEMA P-58';
            app.text12 = uilabel(app.SelectButtonGroup);
            app.text12.Text = 'Simplified Analysis Method';
            app.Image_2 = uiimage(app.SelectButtonGroup);
            app.Image_2.ImageSource = 'NRHA2.png';
            app.Image_3 = uiimage(app.SelectButtonGroup);
            app.Image_3.ImageSource = 'NRHA3.png';
            app.Image_4 = uiimage(app.SelectButtonGroup);
            app.Image_4.ImageSource = 'NRHA6.png';
            app.text9_2 = uilabel(app.SelectButtonGroup);
            app.text9_2.Text = 'Import EDP values from multiple ground motions';
            app.text10_2 = uilabel(app.SelectButtonGroup);
            app.text10_2.Text = 'scaled at multiple intensities (max 5 stripes)';
            app.Image_5 = uiimage(app.SelectButtonGroup);
            app.Image_5.ImageSource = 'NRHA4.png';
            app.radiobutton1 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton1.Text = 'Data at Multiple Intensities up to Collapse';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton2.Text = 'Data at Single Intensity - Multiple GMs';
            app.radiobutton3 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton3.Text = 'Data at Single Intensity - Parameters';
            app.radiobutton4 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton4.Text = 'Data at Multiple Stripes';
            app.radiobutton5 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton5.Text = 'Data from a Non-linear MDoF Shear Model';
            app.radiobutton6 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton6.Text = 'FEMA P-58 Simplified Analysis Method';
            app.text14 = uilabel(app.SelectButtonGroup);
            app.text14.Text = 'Run a dynamic response-history analyses';
            app.text15 = uilabel(app.SelectButtonGroup);
            app.text15.Text = 'for a non-linear MDoF shear model in OpenSEES';
            app.Image_6 = uiimage(app.SelectButtonGroup);
            app.Image_6.ImageSource = 'NRHA5.png';
            app.text18_2 = uilabel(app.SelectButtonGroup);
            app.text18_2.Text = '(i.e. IDA data)';
            app.ContinueButton = uibutton(app.figure1x, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButton_Callback, true);
            app.ContinueButton.Text = 'Next';
            % Show the figure after all components are created
            app.figure1x.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Response_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1x)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1x)
        end
    end
end