classdef Module_Response_Option1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text3         matlab.ui.control.Label
        BrowseButton  matlab.ui.control.Button
        text4         matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
        uipanel2      matlab.ui.container.Panel
        checkbox5     matlab.ui.control.CheckBox
        checkbox6     matlab.ui.control.CheckBox
        checkbox7     matlab.ui.control.CheckBox
        checkbox8     matlab.ui.control.CheckBox
        checkbox11    matlab.ui.control.CheckBox
        checkbox12    matlab.ui.control.CheckBox
        checkbox13    matlab.ui.control.CheckBox
        checkbox14    matlab.ui.control.CheckBox
        checkbox15    matlab.ui.control.CheckBox
        checkbox16    matlab.ui.control.CheckBox
        checkbox17    matlab.ui.control.CheckBox
        checkbox18    matlab.ui.control.CheckBox
        checkbox19    matlab.ui.control.CheckBox
        checkbox20    matlab.ui.control.CheckBox
        checkbox21    matlab.ui.control.CheckBox
        checkbox1     matlab.ui.control.CheckBox
        checkbox2     matlab.ui.control.CheckBox
        PlotButton    matlab.ui.control.Button
        edit1         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp
        TipWindow
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_Option1_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';        

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'Component_Option','Component_Data')
            cd (MainDirectory)
            
            if Component_Option==1
                uniqueEDPs=unique(Component_Data(:,7));
                if ismember('RDR',     uniqueEDPs); app.checkbox5.Value=1;  end
                if ismember('VRD',     uniqueEDPs); app.checkbox6.Value=1;  end
                if ismember('LINK ROT',uniqueEDPs); app.checkbox7.Value=1;  end
                if ismember('BEAM ROT',uniqueEDPs); app.checkbox11.Value=1; end
                if ismember('COL ROT', uniqueEDPs); app.checkbox8.Value=1;  end
                if ismember('DWD',     uniqueEDPs); app.checkbox12.Value=1; end
                if ismember('PFV',     uniqueEDPs); app.checkbox20.Value=1; end
                if ismember('RD',      uniqueEDPs); app.checkbox13.Value=1; end
                if ismember('ED',      uniqueEDPs); app.checkbox21.Value=1; end
                if ismember('GENS1',   uniqueEDPs); app.checkbox14.Value=1; end
                if ismember('GENS2',   uniqueEDPs); app.checkbox15.Value=1; end
                if ismember('GENS3',   uniqueEDPs); app.checkbox16.Value=1; end
                if ismember('GENF1',   uniqueEDPs); app.checkbox17.Value=1; end
                if ismember('GENF2',   uniqueEDPs); app.checkbox18.Value=1; end
                if ismember('GENF3',   uniqueEDPs); app.checkbox19.Value=1; end
            end            
            
            
        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            ResponseDataFolderPath= uigetdir;
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            save(ProjectName,'ResponseDataFolderPath','-append');
            cd (MainDirectory)
            dirResult = dir(ResponseDataFolderPath);
            allDirs = dirResult([dirResult.isdir]);
            allSubDirs = allDirs(3:end);
            app.edit1.Value=size(allSubDirs,1);
            app.edit1.Limits=[1 size(allSubDirs,1)];
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'N_Story','ResponseDataFolderPath','Units');
            cd (MainDirectory)
            
            N_GM = app.edit1.Value;
            
            IDAFilename.SDR     = 'IDA SDR.txt';
            IDAFilename.PFA     = 'IDA PFA.txt';
            IDAFilename.RDR     = 'IDA RDR.txt';
            IDAFilename.VRD     = 'IDA VRD.txt';
            IDAFilename.LINKROT = 'IDA LINK ROT.txt';
            IDAFilename.COLROT  = 'IDA COL ROT.txt';
            IDAFilename.BEAMROT = 'IDA BEAM ROT.txt';
            IDAFilename.DWD     = 'IDA DWD.txt';
            IDAFilename.RD      = 'IDA RD.txt';
            IDAFilename.ED      = 'IDA ED.txt';
            IDAFilename.PFV     = 'IDA PFV.txt';
            IDAFilename.GENS1   = 'IDA GENS1.txt';
            IDAFilename.GENS1   = 'IDA GENS2.txt';
            IDAFilename.GENS2   = 'IDA GENS3.txt';
            IDAFilename.GENS3   = 'IDA GENF1.txt';
            IDAFilename.GENF2   = 'IDA GENF2.txt';
            IDAFilename.GENF3   = 'IDA GENF3.txt';

            Plot_SA_vs_RDR      = app.checkbox5.Value;
            Plot_SA_vs_VRD      = app.checkbox6.Value;
            Plot_SA_vs_LINKROT  = app.checkbox7.Value;
            Plot_SA_vs_COLROT   = app.checkbox8.Value;
            Plot_SA_vs_BEAMROT  = app.checkbox11.Value;
            Plot_SA_vs_DWD      = app.checkbox12.Value;
            Plot_SA_vs_RD       = app.checkbox13.Value;
            Plot_SA_vs_GENS1    = app.checkbox14.Value;
            Plot_SA_vs_GENS2    = app.checkbox15.Value;
            Plot_SA_vs_GENS3    = app.checkbox16.Value;
            Plot_SA_vs_GENF1    = app.checkbox17.Value;
            Plot_SA_vs_GENF2    = app.checkbox18.Value;
            Plot_SA_vs_GENF3    = app.checkbox19.Value;
            Plot_SA_vs_PFV      = app.checkbox20.Value;
            Plot_SA_vs_ED       = app.checkbox21.Value;
            
                                        Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.SDR,'SDR',Units)
                                        Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFA,'PFA',Units)            
            if Plot_SA_vs_RDR==1;       Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.RDR,'RDR',Units);         end
            if Plot_SA_vs_VRD==1;       Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.VRD,'VRD',Units);         end
            if Plot_SA_vs_LINKROT==1;   Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.LINKROT,'LINK ROT',Units);end
            if Plot_SA_vs_COLROT==1;    Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.COLROT,'COL ROT',Units);  end
            if Plot_SA_vs_BEAMROT==1;   Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.BEAMROT,'BEAM ROT',Units);end
            if Plot_SA_vs_DWD==1;       Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.DWD,'DWD',Units);         end
            if Plot_SA_vs_RD==1;        Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.RD,'RD',Units);           end
            if Plot_SA_vs_ED==1;        Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.ED,'ED',Units);           end
            if Plot_SA_vs_PFV==1;       Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFV,'PFV',Units);         end
            if Plot_SA_vs_GENS1==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS1,'GENS1',Units);     end
            if Plot_SA_vs_GENS2==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS2,'GENS2',Units);     end
            if Plot_SA_vs_GENS3==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS3,'GENS3',Units);     end
            if Plot_SA_vs_GENF1==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF1,'GENF1',Units);     end
            if Plot_SA_vs_GENF2==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF2,'GENF2',Units);     end
            if Plot_SA_vs_GENF3==1;     Plot_IM_vs_EDP (N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF3,'GENF3',Units);     end
            
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'ResponseDataFolderPath','N_Story','Units');
            cd (MainDirectory)
            
            str{1}='Importing EDP Data.';
            str{2}='Please wait...';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
            drawnow
            
            N_GM            = app.edit1.Value;
            
            IDAFilename.SDR     = 'IDA SDR.txt';
            IDAFilename.PFA     = 'IDA PFA.txt';
            IDAFilename.RDR     = 'IDA RDR.txt';
            IDAFilename.VRD     = 'IDA VRD.txt';
            IDAFilename.LINKROT = 'IDA LINK ROT.txt';
            IDAFilename.COLROT  = 'IDA COL ROT.txt';
            IDAFilename.BEAMROT = 'IDA BEAM ROT.txt';
            IDAFilename.DWD     = 'IDA DWD.txt';
            IDAFilename.RD      = 'IDA RD.txt';
            IDAFilename.ED      = 'IDA ED.txt';
            IDAFilename.PFV     = 'IDA PFV.txt';
            IDAFilename.GENS1   = 'IDA GENS1.txt';
            IDAFilename.GENS1   = 'IDA GENS2.txt';
            IDAFilename.GENS2   = 'IDA GENS3.txt';
            IDAFilename.GENS3   = 'IDA GENF1.txt';
            IDAFilename.GENF2   = 'IDA GENF2.txt';
            IDAFilename.GENF3   = 'IDA GENF3.txt';
            
            Data_Status.RDR      = app.checkbox5.Value;
            Data_Status.VRD      = app.checkbox6.Value;
            Data_Status.LINKROT  = app.checkbox7.Value;
            Data_Status.COLROT   = app.checkbox8.Value;
            Data_Status.BEAMROT  = app.checkbox11.Value;
            Data_Status.DWD      = app.checkbox12.Value;
            Data_Status.RD       = app.checkbox13.Value;
            Data_Status.GENS1    = app.checkbox14.Value;
            Data_Status.GENS2    = app.checkbox15.Value;
            Data_Status.GENS3    = app.checkbox16.Value;
            Data_Status.GENF1    = app.checkbox17.Value;
            Data_Status.GENF2    = app.checkbox18.Value;
            Data_Status.GENF3    = app.checkbox19.Value;
            Data_Status.PFV      = app.checkbox20.Value;
            Data_Status.ED       = app.checkbox21.Value;
            
            % DATA CHECKS BEFORE SAVING
            % Check if N_GM is specified correctly
            if isnan(N_GM)==1 || N_GM<=0
                errordlg('Please specify the number of ground-motion records!');
                return
            end
            
            % Check if the number of subfolder in the directory matches the number of
            % specified ground motion records
            S = dir(ResponseDataFolderPath);
            Nsubfolders=sum([S(~ismember({S.name},{'.','..'})).isdir]);
            if Nsubfolders<N_GM
                errordlg('The number of subfolders in the selected directory are less than the specified number of ground-motion records!');
                return
            end
            
            dirResult = dir(ResponseDataFolderPath);
            allDirs = dirResult([dirResult.isdir]);
            allSubDirs = allDirs(3:end);
            % Check file names in each subfolder
            cd(ResponseDataFolderPath)
            for i=1:N_GM
            
                thisDir = allSubDirs(i);
                thisDirName = thisDir.name;
                cd(num2str(thisDirName))
            
                if exist (IDAFilename.SDR)==0 || exist (IDAFilename.PFA)==0; errordlg(['The SDR or PFA data file is missing for ground motion record number ',num2str(i),'!']); return; end
                if Data_Status.RDR==1
                    if exist (IDAFilename.RDR)==0; errordlg(['The RDR data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.VRD==1
                    if exist (IDAFilename.VRD)==0; errordlg(['The VRD data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.LINKROT==1
                    if exist (IDAFilename.LINKROT)==0; errordlg(['The LINK ROT data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.COLROT==1
                    if exist (IDAFilename.COLROT)==0; errordlg(['The COL ROT data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.BEAMROT==1
                    if exist (IDAFilename.BEAMROT)==0; errordlg(['The BEAM ROT data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.DWD==1
                    if exist (IDAFilename.DWD)==0; errordlg(['The DWD data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.RD==1
                    if exist (IDAFilename.RD)==0; errordlg(['The RD data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENS1==1
                    if exist (IDAFilename.GENS1)==0; errordlg(['The GENS1 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENS2==1
                    if exist (IDAFilename.GENS2)==0; errordlg(['The GENS2 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENS3==1
                    if exist (IDAFilename.GENS3)==0; errordlg(['The GENS3 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENF1==1
                    if exist (IDAFilename.GENF1)==0; errordlg(['The GENF1 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENF2==1
                    if exist (IDAFilename.GENF2)==0; errordlg(['The GENF2 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.GENF3==1
                    if exist (IDAFilename.GENF3)==0; errordlg(['The GENF3 data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.PFV==1
                    if exist (IDAFilename.PFV)==0; errordlg(['The PFV data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                if Data_Status.ED==1
                    if exist (IDAFilename.ED)==0; errordlg(['The ED data file are missing for ground motion record number ',num2str(i),'!']); return; end
                end
                cd ..
            end
            cd (MainDirectory);
            
            
            str{1}='Checking EDP Data.';
            str{2}='Please wait...';
            app.TipWindow.textbox.Value=str;
            drawnow
            
            % Check EDP Data
                                        [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.SDR,'SDR');
                                        [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFA,'PFA');
            if Data_Status.RDR==1;      [errorflag]=Check_EDP_Data (1,      N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.RDR,'RDR');            end
            if Data_Status.VRD==1;      [errorflag]=Check_EDP_Data (1,      N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.VRD,'VRD');            end
            if Data_Status.LINKROT==1;  [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.LINKROT,'LINK ROT');   end
            if Data_Status.COLROT==1;   [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.COLROT,'COL ROT');     end
            if Data_Status.BEAMROT==1;  [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.BEAMROT,'BEAM ROT');   end
            if Data_Status.DWD==1;      [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.DWD,'DWD');            end
            if Data_Status.RD==1;       [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.RD,'RD');              end            
            if Data_Status.GENS1==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS1,'GENS1');        end
            if Data_Status.GENS2==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS2,'GENS2');        end
            if Data_Status.GENS3==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENS3,'GENS3');        end
            if Data_Status.GENF1==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF1,'GENF1');        end
            if Data_Status.GENF2==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF2,'GENF2');        end
            if Data_Status.GENF3==1;    [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.GENF3,'GENF3');        end
            if Data_Status.ED==1;       [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.ED,'ED');              end       
            if Data_Status.PFV==1;      [errorflag]=Check_EDP_Data (N_Story,N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFV,'PFV');            end
            
            if errorflag==1; return; end
            
            [MaxSDR]=Get_EDP_Range(N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.SDR);
            [MaxPFA]=Get_EDP_Range(N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFA);
            MaxVRD=0.0;
            if Data_Status.VRD==1
            [MaxVRD]=Get_EDP_Range(N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.VRD);
            end
            
            CollapseStatus=0;
            MedianCPS=10;
            SigmaCPS=0.001;
            CPS_Option=0;
    
            IMstatus        = 0;
            IMstart         = 0.001;
            IMincr          = 0.005;
            IMend           = 5.0;
            IMpoints        = IMstart:IMincr:IMend;
            nIMpoints       = size(IMpoints,2);
            TargetIMstart   = IMstart;
            TargetIMend     = IMend;
            TargetIM        = IMpoints;
            TargetIM_Option = 1;
                
            Response_Option     = 1;
            ResponseDataStatus  = 1;
            
            cd (ProjectPath)
            save(ProjectName,'ResponseDataStatus','Response_Option','Data_Status','N_GM','ResponseDataFolderPath','IDAFilename','-append')
            pause(1.0)
            save(ProjectName,'MaxSDR','MaxPFA','MaxVRD','CollapseStatus','MedianCPS','SigmaCPS','CPS_Option','IMstatus','IMstart','IMincr','IMend','IMpoints','nIMpoints','TargetIMstart','TargetIMend','TargetIM','TargetIM_Option','-append')
            cd (MainDirectory)
            
            figure1_CloseRequestFcn(app)
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option');
            cd (MainDirectory)
            
            if ResponseDataStatus==1            
                if Response_Option==1
                    app.MainApp.HazardButton.Enable='on';
                    app.MainApp.status4.BackgroundColor='g';
                    app.MainApp.status4.FontColor='k';
                    app.MainApp.status4.Text='IDA Data';
                    app.MainApp.status4b.BackgroundColor='g';
                    app.MainApp.status30.BackgroundColor=[0.65 0.65 0.65];
                else
                    app.MainApp.HazardButton.Enable='off';
                    app.MainApp.status4.BackgroundColor='g';
                    app.MainApp.status4.FontColor='k';
                    app.MainApp.status4.Text='Single Intensity';
                    app.MainApp.status4b.BackgroundColor='g';
                end
                app.MainApp.ComputeButton.Enable='on';
                app.MainApp.IMButton.Enable='on';
                app.MainApp.DemolitionButton.Enable='on';
                app.MainApp.CollapseButton.Enable='on';
            else
                app.MainApp.status4.BackgroundColor='r';
                app.MainApp.status4.FontColor='k';
                app.MainApp.status4.Text='status: not defined';
                app.MainApp.status4b.BackgroundColor='r';
                app.MainApp.HazardButton.Enable='off';
                app.MainApp.ComputeButton.Enable='off';
                app.MainApp.IMButton.Enable='off';
                app.MainApp.DemolitionButton.Enable='off';
                app.MainApp.CollapseButton.Enable='off';
            end
            app.MainApp.Image5.Visible='off';            
            delete(app.TipWindow)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Import IDA Response Data';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tooltip = 'Browse for folder containing EDP data';
            app.text3.Text = 'IDA Data Folder';
            app.BrowseButton = uibutton(app.uipanel1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tooltip = 'Browse for folder containing EDP data';
            app.BrowseButton.Text = 'browse';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tooltip = 'Specify number of ground motion records for which EDP data will be imported';
            app.text4.Text = 'No. of Ground Motions';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'EDP Data to Import';
            app.checkbox5 = uicheckbox(app.uipanel2);
            app.checkbox5.Tooltip = 'Residual Drift Ratio';
            app.checkbox5.Text = 'RDR';
            app.checkbox6 = uicheckbox(app.uipanel2);
            app.checkbox6.Tooltip = 'Vertical Residual Deforamtion';
            app.checkbox6.Text = 'VRD';
            app.checkbox7 = uicheckbox(app.uipanel2);
            app.checkbox7.Tooltip = 'Link Rotation';
            app.checkbox7.Text = 'LINK ROT';
            app.checkbox8 = uicheckbox(app.uipanel2);
            app.checkbox8.Tooltip = 'Column Rotation';
            app.checkbox8.Text = 'COL ROT';
            app.checkbox11 = uicheckbox(app.uipanel2);
            app.checkbox11.Tooltip = 'Beam Rotation';
            app.checkbox11.Text = 'BEAM ROT';
            app.checkbox12 = uicheckbox(app.uipanel2);
            app.checkbox12.Tooltip = {'Damageable Wall Drift'};
            app.checkbox12.Text = 'DWD';
            app.checkbox13 = uicheckbox(app.uipanel2);
            app.checkbox13.Tooltip = {'Racking Drift'};
            app.checkbox13.Text = 'RD';
            app.checkbox14 = uicheckbox(app.uipanel2);
            app.checkbox14.Tooltip = {'Generic Story-based EDP'};
            app.checkbox14.Text = 'GENS1';
            app.checkbox15 = uicheckbox(app.uipanel2);
            app.checkbox15.Tooltip = {'Generic Story-based EDP'};
            app.checkbox15.Text = 'GENS2';
            app.checkbox16 = uicheckbox(app.uipanel2);
            app.checkbox16.Tooltip = {'Generic Story-based EDP'};
            app.checkbox16.Text = 'GENS3';
            app.checkbox17 = uicheckbox(app.uipanel2);
            app.checkbox17.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox17.Text = 'GENF1';
            app.checkbox18 = uicheckbox(app.uipanel2);
            app.checkbox18.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox18.Text = 'GENF2';
            app.checkbox19 = uicheckbox(app.uipanel2);
            app.checkbox19.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox19.Text = 'GENF3';
            app.checkbox20 = uicheckbox(app.uipanel2);
            app.checkbox20.Tooltip = {'Peak Floor Velocity'};
            app.checkbox20.Text = 'PFV';
            app.checkbox21 = uicheckbox(app.uipanel2);
            app.checkbox21.Tooltip = {'Effective Drift'};
            app.checkbox21.Text = 'ED';
            app.checkbox1 = uicheckbox(app.uipanel2);
            app.checkbox1.Enable = 'off';
            app.checkbox1.Text = 'SDR';
            app.checkbox1.Value = true;
            app.checkbox2 = uicheckbox(app.uipanel2);
            app.checkbox2.Enable = 'off';
            app.checkbox2.Text = 'PFA';
            app.checkbox2.Value = true;
            app.PlotButton = uibutton(app.uipanel1, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Tooltip = {''};
            app.PlotButton.Text = 'Plot IDA Curves';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Value = 1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Response_Option1_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_Option1_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end