classdef Module_Response_Option2or3_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text3         matlab.ui.control.Label
        BrowseButton  matlab.ui.control.Button
        uipanel2      matlab.ui.container.Panel
        checkbox5     matlab.ui.control.CheckBox
        checkbox6     matlab.ui.control.CheckBox
        checkbox7     matlab.ui.control.CheckBox
        checkbox8     matlab.ui.control.CheckBox
        checkbox9     matlab.ui.control.CheckBox
        checkbox10    matlab.ui.control.CheckBox
        checkbox11    matlab.ui.control.CheckBox
        checkbox12    matlab.ui.control.CheckBox
        checkbox13    matlab.ui.control.CheckBox
        checkbox14    matlab.ui.control.CheckBox
        checkbox15    matlab.ui.control.CheckBox
        checkbox16    matlab.ui.control.CheckBox
        checkbox17    matlab.ui.control.CheckBox
        checkbox18    matlab.ui.control.CheckBox
        checkbox19    matlab.ui.control.CheckBox
        checkbox20    matlab.ui.control.CheckBox
        checkbox21    matlab.ui.control.CheckBox
        text9         matlab.ui.control.Label
        text12        matlab.ui.control.Label
        edit1         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
        SubmitButton  matlab.ui.control.Button
        PlotButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_Option2or3_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';            

            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'Response_OptionX','ResponseSA','N_GM','ResponseDataStatus','Component_Option','Component_Data')
            cd (MainDirectory)
            
            if Response_OptionX==3
                app.edit1.Enable='off';
            end
            
            if ResponseDataStatus==1
                app.edit1.Value=N_GM;
                app.edit2.Value=ResponseSA(1,1);                
            end
            
            if Component_Option==1
                uniqueEDPs=unique(Component_Data(:,7));
                if ismember('LINK ROT',uniqueEDPs); app.checkbox7.Value=1;  end
                if ismember('BEAM ROT',uniqueEDPs); app.checkbox11.Value=1; end
                if ismember('COL ROT', uniqueEDPs); app.checkbox8.Value=1;  end
                if ismember('DWD',     uniqueEDPs); app.checkbox12.Value=1; end
                if ismember('PFV',     uniqueEDPs); app.checkbox20.Value=1; end
                if ismember('RD',      uniqueEDPs); app.checkbox13.Value=1; end
                if ismember('ED',      uniqueEDPs); app.checkbox21.Value=1; end
                if ismember('GENS1',   uniqueEDPs); app.checkbox14.Value=1; end
                if ismember('GENS2',   uniqueEDPs); app.checkbox15.Value=1; end
                if ismember('GENS3',   uniqueEDPs); app.checkbox16.Value=1; end
                if ismember('GENF1',   uniqueEDPs); app.checkbox17.Value=1; end
                if ismember('GENF2',   uniqueEDPs); app.checkbox18.Value=1; end
                if ismember('GENF3',   uniqueEDPs); app.checkbox19.Value=1; end
            end

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            [NRHADataExcelFileName,NRHADataFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg the EDP data');
            if NRHADataFilePath ==0
                return
            end
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            save(ProjectName,'NRHADataExcelFileName','NRHADataFilePath','-append')
            
            cd (NRHADataFilePath)   
            Data =xlsread(NRHADataExcelFileName,'SDR','B3:EZ50');
            cd (MainDirectory)  
             app.edit1.Value=size(Data,1);
             
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'Response_OptionX','N_Story','NRHADataExcelFileName','NRHADataFilePath','Units','nStripe');
            cd (MainDirectory)
            
            N_GM=app.edit1.Value;
            
            Plot_RDR_Profile    = app.checkbox5.Value;
            Plot_VRD_Profile    = app.checkbox6.Value;
            Plot_LINKROT_Profile= app.checkbox7.Value;
            Plot_COLROT_Profile = app.checkbox8.Value;
            Plot_BEAMROT_Profile= app.checkbox11.Value;
            Plot_DWD_Profile    = app.checkbox12.Value;
            Plot_RD_Profile     = app.checkbox13.Value;
            Plot_GENS1_Profile  = app.checkbox14.Value;
            Plot_GENS2_Profile  = app.checkbox15.Value;
            Plot_GENS3_Profile  = app.checkbox16.Value;
            Plot_GENF1_Profile  = app.checkbox17.Value;
            Plot_GENF2_Profile  = app.checkbox18.Value;
            Plot_GENF3_Profile  = app.checkbox19.Value;
            Plot_PFV_Profile    = app.checkbox20.Value;
            Plot_ED_Profile     = app.checkbox21.Value;
            
                                        Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'SDR',Units,N_GM,nStripe)
                                        Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'PFA',Units,N_GM,nStripe)
            if Response_OptionX==2
            if Plot_RDR_Profile==1;     Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'RDR',Units,N_GM,nStripe); end
            if Plot_VRD_Profile==1;     Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'VRD',Units,N_GM,nStripe); end
            end            
            if Plot_LINKROT_Profile==1; Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'LINK ROT',Units,N_GM,nStripe); end
            if Plot_COLROT_Profile==1;  Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'COL ROT',Units,N_GM,nStripe); end            
            if Plot_BEAMROT_Profile==1; Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'BEAM ROT',Units,N_GM,nStripe); end
            if Plot_RD_Profile==1;      Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'RD',Units,N_GM,nStripe); end
            if Plot_DWD_Profile==1;     Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'DWD',Units,N_GM,nStripe); end
            if Plot_GENS1_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENS1',Units,N_GM,nStripe); end
            if Plot_GENS2_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENS2',Units,N_GM,nStripe); end         
            if Plot_GENS3_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENS3',Units,N_GM,nStripe); end
            if Plot_GENF1_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENF1',Units,N_GM,nStripe); end
            if Plot_GENF2_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENF2',Units,N_GM,nStripe); end
            if Plot_GENF3_Profile==1;   Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'GENF3',Units,N_GM,nStripe); end
            if Plot_PFV_Profile==1;     Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'PFV',Units,N_GM,nStripe); end
            if Plot_ED_Profile==1;      Plot_EDP_Profile (N_Story,MainDirectory,NRHADataFilePath,NRHADataExcelFileName,Response_OptionX,'ED',Units,N_GM,nStripe); end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'Response_OptionX','N_Story','NRHADataFilePath','NRHADataExcelFileName','IMpoints','nIMpoints')
            cd (MainDirectory)

            ResponseSA=app.edit2.Value;
            nStripe=1;
            
            EDP_Data.SDRcorr    = eye(N_Story);
            EDP_Data.PFAcorr    = eye(N_Story+1);
            EDP_Data.RDRcorr    = 1;
            EDP_Data.VRDcorr    = 1;
            EDP_Data.COLROTcorr = eye(N_Story);
            EDP_Data.LINKROTcorr= eye(N_Story+1);
            EDP_Data.BEAMROTcorr= eye(N_Story+1);
            EDP_Data.DWDcorr    = eye(N_Story);
            EDP_Data.RDcorr     = eye(N_Story);
            EDP_Data.GENS1corr  = eye(N_Story);
            EDP_Data.GENS2corr  = eye(N_Story);   
            EDP_Data.GENS3corr  = eye(N_Story);
            EDP_Data.GENF1corr  = eye(N_Story+1);   
            EDP_Data.GENF2corr  = eye(N_Story+1); 
            EDP_Data.GENF3corr  = eye(N_Story+1);
            EDP_Data.PFVcorr    = eye(N_Story+1);     
            EDP_Data.EDcorr     = eye(N_Story);
            
            Data_Status.SDR      = 1; 
            Data_Status.PFA      = 1;
            Data_Status.RDR      = app.checkbox5.Value;
            Data_Status.VRD      = app.checkbox6.Value;
            Data_Status.LINKROT  = app.checkbox7.Value;
            Data_Status.COLROT   = app.checkbox8.Value;
            Data_Status.BEAMROT  = app.checkbox11.Value;
            Data_Status.DWD      = app.checkbox12.Value;
            Data_Status.RD       = app.checkbox13.Value;
            Data_Status.ED       = app.checkbox21.Value;
            Data_Status.PFV      = app.checkbox20.Value;
            Data_Status.GENS1    = app.checkbox14.Value;
            Data_Status.GENS2    = app.checkbox15.Value;
            Data_Status.GENS3    = app.checkbox16.Value;
            Data_Status.GENF1    = app.checkbox17.Value;
            Data_Status.GENF2    = app.checkbox18.Value;
            Data_Status.GENF3    = app.checkbox19.Value;
            
            %%
            if Response_OptionX==2
            
                N_GM=app.edit1.Value;
            
                [EDP_Data,MaxSDR,MaxPFA,MaxVRD]=Get_EDP_Data_MultipleGMOption(NRHADataFilePath,NRHADataExcelFileName,Data_Status,N_GM,nStripe,EDP_Data);

                % Data Checks
                if N_GM < size(EDP_Data.SDR.S1,1) || N_GM ~= size(EDP_Data.PFA.S1,1)
                     warndlg('EXCEL sheet data size does not agree with the specified number of ground motions');
                end
                if N_GM > size(EDP_Data.SDR.S1,1) || N_GM ~= size(EDP_Data.PFA.S1,1)
                     errordlg('EXCEL sheet data size does not agree with the specified number of ground motions');
                     return
                end
                if N_Story ~= size(EDP_Data.SDR.S1,2) || N_Story ~= size(EDP_Data.PFA.S1,2)-1
                     errordlg('EXCEL sheet data size does not agree with number of stories/floors');
                     return
                end
            
            
                Response_Option=2;
                ResponseDataStatus=1;
            
                clear TargetIM
                if ismember(ResponseSA,IMpoints)==0
                    for i=2:nIMpoints
                        if IMpoints(1,i)<ResponseSA && IMpoints(1,i+1)>ResponseSA
                            if abs(IMpoints(1,i)-ResponseSA) < abs(IMpoints(1,i+1)-ResponseSA)
                                TargetIM=IMpoints(1,i);
                            else
                                TargetIM=IMpoints(1,i+1);
                            end
                            break
                        end
                    end
                end
                TargetIMstart   = TargetIM;
                TargetIMend     = TargetIM;
                TargetIM_Option = 3;
            
                cd (ProjectPath)
                save(ProjectName,'EDP_Data','Data_Status','MaxSDR','MaxPFA','MaxVRD', '-append')
                save(ProjectName,'ResponseDataStatus','Response_Option','N_GM','ResponseSA','nStripe','TargetIM','TargetIMstart','TargetIMend','TargetIM_Option','-append')
                pause(1.0)
                cd (MainDirectory)
            end
            
            %%
            if Response_OptionX==3
            
                try
                    [EDP_Data,MaxSDR,MaxPFA,MaxVRD]=Get_EDP_Data_ParametersOption(N_Story,NRHADataFilePath,NRHADataExcelFileName,Data_Status,EDP_Data);
                catch ME
                    cd(MainDirectory)
                    return
                end       
                Response_Option=3;
                ResponseDataStatus=1;
                
                clear TargetIM
                if ismember(ResponseSA,IMpoints)==0
                    for i=2:nIMpoints
                        if IMpoints(1,i)<ResponseSA && IMpoints(1,i+1)>ResponseSA
                            if abs(IMpoints(1,i)-ResponseSA) < abs(IMpoints(1,i+1)-ResponseSA)
                                TargetIM=IMpoints(1,i);
                            else
                                TargetIM=IMpoints(1,i+1);
                            end
                            break
                        end
                    end
                end
                TargetIMstart   = TargetIM;
                TargetIMend     = TargetIM;
                TargetIM_Option = 3;
            
                cd (ProjectPath)
                save(ProjectName,'EDP_Data','Data_Status','ResponseDataStatus','Response_Option','ResponseSA','nStripe','-append')
                save(ProjectName,'TargetIM','TargetIMstart','TargetIMend','TargetIM_Option','MaxSDR','MaxPFA','MaxVRD','-append')
                pause(1.0)
                cd (MainDirectory)
            
            end
            
            if ResponseDataStatus==1
                app.MainApp.status4.BackgroundColor='g';
                app.MainApp.status4.FontColor='k';
                app.MainApp.status4.Text='Single Intensity';
                app.MainApp.status4b.BackgroundColor='g';
                app.MainApp.HazardButton.Enable='on';
                app.MainApp.ComputeButton.Enable='on';
                app.MainApp.IMButton.Enable='on';
                app.MainApp.status10.BackgroundColor='g';
                app.MainApp.DemolitionButton.Enable='on';
                app.MainApp.CollapseButton.Enable='on';
                app.MainApp.status30.BackgroundColor=[0.65 0.65 0.65];
                CollapseStatus=0;
                MedianCPS=10;
                SigmaCPS=0.001;
                CPS_Option=0;
                cd (ProjectPath)
                save(ProjectName,'CollapseStatus','MedianCPS','SigmaCPS','CPS_Option','-append')
                cd (MainDirectory)
            else
                app.MainApp.status4.BackgroundColor='r';
                app.MainApp.status4.FontColor='k';
                app.MainApp.status4.Text='status: not defined';
                app.MainApp.status4b.BackgroundColor='r';
                app.MainApp.ComputeButton.Enable='off';
                app.MainApp.IMButton.Enable='on';
                app.MainApp.status10.BackgroundColor='g';
                app.MainApp.DemolitionButton.Enable='off';
                app.MainApp.CollapseButton.Enable='off';
            end
                        
            app.MainApp.Image5.Visible='off';            
            figure1_CloseRequestFcn(app, event)           

        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            app.MainApp.Image5.Visible='off';            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Import Response Data';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uipanel(app.figure1);
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'EXCEL file with EDP data';
            app.BrowseButton = uibutton(app.uipanel1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tooltip = 'Browse for Excel file containing the response data';
            app.BrowseButton.Text = 'browse';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'EDP Data to Import';
            app.checkbox5 = uicheckbox(app.uipanel2);
            app.checkbox5.Tooltip = 'Residual Drift Ratio';
            app.checkbox5.Text = 'RDR';
            app.checkbox6 = uicheckbox(app.uipanel2);
            app.checkbox6.Tooltip = 'Vertical Residual Deforamtion';
            app.checkbox6.Text = 'VRD';
            app.checkbox7 = uicheckbox(app.uipanel2);
            app.checkbox7.Tooltip = 'Link Rotation';
            app.checkbox7.Text = 'LINK ROT';
            app.checkbox8 = uicheckbox(app.uipanel2);
            app.checkbox8.Tooltip = 'Column Rotation';
            app.checkbox8.Text = 'COL ROT';
            app.checkbox9 = uicheckbox(app.uipanel2);
            app.checkbox9.Enable = 'off';
            app.checkbox9.Text = 'SDR';
            app.checkbox9.Value = true;
            app.checkbox10 = uicheckbox(app.uipanel2);
            app.checkbox10.Enable = 'off';
            app.checkbox10.Text = 'PFA';
            app.checkbox10.Value = true;
            app.checkbox11 = uicheckbox(app.uipanel2);
            app.checkbox11.Tooltip = 'Beam Rotation';
            app.checkbox11.Text = 'BEAM ROT';
            app.checkbox12 = uicheckbox(app.uipanel2);
            app.checkbox12.Tooltip = {'Damageable Wall Drift'};
            app.checkbox12.Text = 'DWD';
            app.checkbox13 = uicheckbox(app.uipanel2);
            app.checkbox13.Tooltip = {'Racking Drift'};
            app.checkbox13.Text = 'RD';
            app.checkbox14 = uicheckbox(app.uipanel2);
            app.checkbox14.Tooltip = {'Generic Story-based EDP'};
            app.checkbox14.Text = 'GENS1';
            app.checkbox15 = uicheckbox(app.uipanel2);
            app.checkbox15.Tooltip = {'Generic Story-based EDP'};
            app.checkbox15.Text = 'GENS2';
            app.checkbox16 = uicheckbox(app.uipanel2);
            app.checkbox16.Tooltip = {'Generic Story-based EDP'};
            app.checkbox16.Text = 'GENS3';
            app.checkbox17 = uicheckbox(app.uipanel2);
            app.checkbox17.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox17.Text = 'GENF1';
            app.checkbox18 = uicheckbox(app.uipanel2);
            app.checkbox18.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox18.Text = 'GENF2';
            app.checkbox19 = uicheckbox(app.uipanel2);
            app.checkbox19.Tooltip = {'Generic Floor-based EDP'};
            app.checkbox19.Text = 'GENF3';
            app.checkbox20 = uicheckbox(app.uipanel2);
            app.checkbox20.Tooltip = {'Peak Floor Velocity'};
            app.checkbox20.Text = 'PFV';
            app.checkbox21 = uicheckbox(app.uipanel2);
            app.checkbox21.Tooltip = {'Effective Drift'};
            app.checkbox21.Text = 'ED';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Text = 'No. of ground motions';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Text = 'Intensity level [g]';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [1 Inf];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [0.001 Inf];
            app.edit2.ValueDisplayFormat = '%.2f';
            app.edit2.Value = 1;
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.PlotButton = uibutton(app.figure1, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Text = 'Plot EDP Profiles';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Response_Option2or3_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_Option2or3_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end