classdef Module_Response_Option5_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        RunModelButton     matlab.ui.control.Button
        uipanel22          matlab.ui.container.Panel
        text84             matlab.ui.control.Label
        text79             matlab.ui.control.Label
        Lamp1              matlab.ui.control.Lamp
        BrowseButton       matlab.ui.control.Button
        ModelTypepopup     matlab.ui.control.DropDown
        uipanel22_2        matlab.ui.container.Panel
        BrowseGMButton     matlab.ui.control.Button
        edit6              matlab.ui.control.NumericEditField
        text80             matlab.ui.control.Label
        Lamp2              matlab.ui.control.Lamp
        text2_2            matlab.ui.control.Label
        uipanel2           matlab.ui.container.Panel
        popupmenu1         matlab.ui.control.DropDown
        text20             matlab.ui.control.Label
        text27             matlab.ui.control.Label
        edit4              matlab.ui.control.NumericEditField
        edit5              matlab.ui.control.NumericEditField
        text2              matlab.ui.control.Label
        edit2              matlab.ui.control.NumericEditField
        text6              matlab.ui.control.Label
        edit3              matlab.ui.control.NumericEditField
        text98_2           matlab.ui.control.Label
        text98             matlab.ui.control.Label
        text99             matlab.ui.control.Label
        ProgressBar        matlab.ui.control.EditField
        GetPeriodButton    matlab.ui.control.Button
        PlotEDPsButton     matlab.ui.control.Button
        Image0             matlab.ui.control.Image
        SavetoExcelButton  matlab.ui.control.Button
    end

    properties (Access = private)
        MainApp
        TipWindow
    end
    
    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_Option5_App_OpeningFcn(app, mainapp)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option','Option5Data')
            cd (MainDirectory)
            app.Image0.Visible='off';

            if ResponseDataStatus==1
                if Response_Option==5
                    %v2struct(Option5Data); % unpack Option5Data 
                    app.ModelTypepopup.Value=string(Option5Data.ModelType);
                    app.popupmenu1.Value=string(Option5Data.Option5_Type);
                    app.edit3.Value=Option5Data.T1;
                    app.edit6.Value=Option5Data.N_GM;
                    app.edit2.Value=Option5Data.zeta*100;
                    app.edit4.Value=Option5Data.SF;
                    app.edit5.Value=Option5Data.TargetSA;
                    app.text79.Text='EXPORT OK';
                    app.text80.Text='OK';
                    app.Lamp1.Color=[0 1 0];
                    app.Lamp2.Color=[0 1 0];
                    app.PlotEDPsButton.Enable='on';
                    app.SavetoExcelButton.Enable='on';
                end
            end
            
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';   
            popupmenu1ValueChanged(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'N_Story','Option5Data')
            cd (MainDirectory)
            v2struct(Option5Data); % unpack Option5Data 
            
            app.text79.Text='Please wait...';
            ModelType=str2double(app.ModelTypepopup.Value);
                        
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg component data');
            ExcelFileName=convertCharsToStrings(ExcelFileName);
            ExcelFilePath=convertCharsToStrings(ExcelFilePath);
            
            % Read Frame Cross-Section Data From Excel
            [HStory,Load,Mass,Parameters]=Import_ShearModel_Data_from_Excel(ModelType, ExcelFilePath ,ExcelFileName,N_Story);
            cd(MainDirectory)
            HBuilding=sum(HStory);

            Option5Data.N_Story=N_Story;
            Option5Data.ExcelFileName=ExcelFileName;
            Option5Data.ExcelFilePath=ExcelFilePath;
            Option5Data.HStory=HStory;
            Option5Data.Load=Load;
            Option5Data.Mass=Mass;
            Option5Data.Parameters=Parameters;
            Option5Data.HBuilding=HBuilding;

            cd (ProjectPath)
            save(ProjectName,'Option5Data','-append');
            cd (MainDirectory)
            
            app.text79.Text='Export OK';
            app.Lamp1.Color=[0 1 0];
        end

        % Button pushed function: RunModelButton
        function RunModelButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath Units
            clc;
            app.Image0.Visible='on';

            try
            if strcmp(app.text80.Text, 'OK')~=1
                errordlg('First, you must define the ground motion folder!');
                return
            end
            
            cd (ProjectPath)
            load(ProjectName,'Option5Data','Units','IMpoints')
            cd (MainDirectory)
            v2struct(Option5Data); % unpack Option5Data 

            ShowAnimation=0;
            
            ModelType    = str2double(app.ModelTypepopup.Value);
            Option5_Type = str2double(app.popupmenu1.Value);
            T1           = app.edit3.Value;
            N_GM         = app.edit6.Value;
            zeta         = app.edit2.Value/100;
            SF           = app.edit4.Value;
            TargetSA     = app.edit5.Value;
            NEigenModes  = min(3,N_Story);
            DampModeI    = 1;  
            DampModeJ    = 1;
                                    
            switch Option5_Type
                case 1; IDA=1; Dynamic_TargetSF=0; Dynamic_TargetSA=0; TargetIM_Option = 1;
                case 2; IDA=0; Dynamic_TargetSF=1; Dynamic_TargetSA=0; TargetIM_Option = 3;
                case 3; IDA=0; Dynamic_TargetSF=0; Dynamic_TargetSA=1; TargetIM_Option = 3;
            end

            if Units==1; g=398.2; else; g=9.81; end
            
            Option5Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,GMFolderPath,GMFolderName,ShowAnimation,T1,g,DampModeI,DampModeJ,ModelType,zeta,NEigenModes,IDA,Dynamic_TargetSF,Dynamic_TargetSA,SF,TargetSA,Option5_Type,N_GM); % re-pack with new data
    
            if IDA==0
                clear TargetIM
                nIMpoints=length(IMpoints);
                if ismember(TargetSA,IMpoints)==0
                    for i=2:nIMpoints
                        if IMpoints(1,i)<TargetSA && IMpoints(1,i+1)>TargetSA
                            if abs(IMpoints(1,i)-TargetSA) < abs(IMpoints(1,i+1)-TargetSA)
                                TargetIM=IMpoints(1,i);
                            else
                                TargetIM=IMpoints(1,i+1);
                            end
                            break
                        end
                    end
                end
                TargetIMstart   = TargetIM;
                TargetIMend     = TargetIM;
            else
                IMstart=0.001;
                IMincr=0.005;
                IMend=5.0;
                IMpoints=IMstart:IMincr:IMend;
                TargetIMstart   = IMstart;
                TargetIMend     = IMend;
                TargetIM        = IMpoints;
                TargetIM_Option = 1;
            end
            
            
            % Save MRF Data to .mat File
            cd(ProjectPath)
            save (ProjectName,'Option5Data','Option5_Type','TargetIMstart','TargetIMend','TargetIM','TargetIM_Option','-append');
            cd(MainDirectory)
            
            tic
            
            MDoF_CREATOR_EIGEN (5)
            ! OpenSEES.exe Temp.tcl
            fileID = fopen('Periods.txt','r');
            A = fscanf(fileID,'%f');
            T1_computed = A(1,1);
            
            app.text98.Text=['Computed T1 is ',num2str(round(T1_computed*100)/100),'sec'];
    	    app.text98.FontColor='y';
            drawnow
            
            app.PlotEDPsButton.Enable='off';
            app.SavetoExcelButton.Enable='off';
                
            fclose(fileID);
            MDoF_CREATOR_MAIN()
            MDoF_Run_OpenSEES(app)
            
            if toc>60; ETime=round(toc*10/60)/10; str=' min'; else;  ETime=round(toc); str=' sec'; end   
            app.text99.Text=['DONE - Run Time: ', num2str(ETime), str];
    	    app.text98.FontColor='g';
            app.text99.FontColor='g';

            Response_Option=5;
            ResponseDataStatus=1;
            
            CollapseStatus=0;
            MedianCPS=10;
            SigmaCPS=0.001;
            CPS_Option=0;
            
            Data_Status.RDR=1;      Data_Status.VRD=0;      Data_Status.ED=0;   
            Data_Status.DWD=0;      Data_Status.RD=0;       Data_Status.COLROT=0; 
            Data_Status.BEAMROT=0;  Data_Status.LINKROT=0;  Data_Status.PFV=0; 
            Data_Status.GENS1=0;    Data_Status.GENS2=0;    Data_Status.GENS3=0;
            Data_Status.GENF1=0;    Data_Status.GENF2=0;    Data_Status.GENF3=0;
            
            cd(ProjectPath)
            save (ProjectName,'Response_Option','ResponseDataStatus','Option5Data','Data_Status','CollapseStatus','MedianCPS','SigmaCPS','CPS_Option','-append');
            cd(MainDirectory)
 

            if Option5_Type==1
                
                IDAFilename.SDR     = 'IDA SDR.txt';
                IDAFilename.PFA     = 'IDA PFA.txt';
                IDAFilename.RDR     = 'IDA RDR.txt';
                ResponseDataFolderPath=[MainDirectory,'\Results'];                
                
                [MaxSDR]=Get_EDP_Range(N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.SDR);
                [MaxPFA]=Get_EDP_Range(N_GM,MainDirectory,ResponseDataFolderPath,IDAFilename.PFA);
                MaxVRD=0.0;
    
                cd (ProjectPath)
                save(ProjectName,'IDAFilename','MaxSDR','MaxPFA','MaxVRD','ResponseDataFolderPath','N_GM','-append')
                cd (MainDirectory)
                
            
            else
                app.PlotEDPsButton.Enable='on';
                app.SavetoExcelButton.Enable='on';
                try
                    cd (ProjectPath)
                    save(ProjectName,'N_GM','-append')
                    cd (MainDirectory)
                    rmdir('Results','s')
                    app.PlotEDPsButton.Enable='on';

                catch ME

  
                end
                
            end
            app.Image0.Visible='off';
            catch ME
            app.Image0.Visible='off';
            end
                      
        end

        % Button pushed function: BrowseGMButton
        function BrowseGMButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc
            
            if strcmp(app.text79.Text,'Export OK') ~= 1 
                errordlg('First, you must define the system properties!');
                return
            end
               
            cd (ProjectPath)
            load(ProjectName,'Option5Data')
            v2struct(Option5Data); % unpack Option5Data 
            cd (MainDirectory)  
            
            GMFolderPath= uigetdir;
            [~,GMFolderName] = fileparts(GMFolderPath);

            cd (GMFolderPath)
            % If the file "GM Data.txt" exits
            if exist ('AllGMinfo.txt')~=0
                 delimiter = char(9); %tab delimeter
                 fid = fopen('AllGMinfo.txt','rt');
                 tLines = fgets(fid);
                 numCols = numel(strfind(tLines,delimiter)) + 1;
                 fclose(fid);
                % if the file has 2 columns
                if numCols == 2; inputtype = 2;
                else;            inputtype = 1;
                end
            else
                d=errordlg('The file "AllGMinfo.txt" is not found in the selected folder!','Error','modal');
                waitfor(d);
                delete(app.TipWindow)
                return
            end
            GM_Data=importdata('AllGMinfo.txt'); % Read the file data
            N_GM=size(GM_Data,1);            
            app.edit6.Value=N_GM;
            
            cd (ProjectPath)
            Option5Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,GMFolderPath,GMFolderName); % re-pack with new data
            save (ProjectName,'Option5Data','-append')
            cd (MainDirectory)  
            
            app.text80.Text='OK';
            app.Lamp2.Color=[0 1 0];

        end

        % Value changed function: popupmenu1
        function popupmenu1ValueChanged(app, event)
            value = str2double(app.popupmenu1.Value);
            if     value==1; app.edit4.Enable='off'; app.edit5.Enable='off';                
            elseif value==2; app.edit4.Enable='on';  app.edit5.Enable='off';
            elseif value==3; app.edit4.Enable='off'; app.edit5.Enable='on';               
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option')
            cd (MainDirectory)

            if ResponseDataStatus==1
                if Response_Option==5
            app.MainApp.status4.BackgroundColor='g';
            app.MainApp.status4.FontColor='k';
            app.MainApp.status4.Text='MDoF Model';
            app.MainApp.status4b.BackgroundColor='g';
            app.MainApp.HazardButton.Enable='on';
            app.MainApp.ComputeButton.Enable='on';
            app.MainApp.IMButton.Enable='on';
            app.MainApp.status10.BackgroundColor='g';
            app.MainApp.DemolitionButton.Enable='on';
            app.MainApp.CollapseButton.Enable='on';
            app.MainApp.status30.BackgroundColor=[0.65 0.65 0.65];
                end
            end
            
            app.MainApp.Image5.Visible='off';  
            delete(app)
        end

        % Button pushed function: GetPeriodButton
        function GetPeriodButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            app.text98.Text=['Building OpenSEES Model...'];  app.text98.FontColor='y';   drawnow
            app.text99.Text=[' '];                           app.text98.FontColor='y';   drawnow
            
            cd (ProjectPath)
            load(ProjectName,'Option5Data')
            cd (MainDirectory)
            v2struct(Option5Data); % unpack Option5Data 
            
            ModelType = str2double(app.ModelTypepopup.Value);
            
            Option5Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,GMFolderPath,GMFolderName,ModelType); % re-pack with new data

            % Save MRF Data to .mat File
            cd(ProjectPath)
            save (ProjectName,'Option5Data','-append');
            pause(1)
            cd(MainDirectory)
            
            app.text98.Text=['Running Eigen Analysis...'];  app.text98.FontColor='y';   drawnow

            tic
            MDoF_CREATOR_EIGEN (5)
            ! OpenSEES.exe Temp.tcl
            fileID = fopen('Periods.txt','r');
            A = fscanf(fileID,'%f');
            fclose(fileID);
            T1_computed = A(1,1);
            app.text98.Text=['Computed T1 is ',num2str(round(T1_computed*100)/100),'sec']; app.text98.FontColor='g'; drawnow
    
            delete ('Periods.txt')
            delete ('Temp.tcl')
        end

        % Button pushed function: PlotEDPsButton
        function PlotEDPsButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            Plot_EDP_Data_Option5(ProjectPath, ProjectName, MainDirectory)
        end

        % Button pushed function: SavetoExcelButton
        function SavetoExcelButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'EDP_Data','N_GM','N_Story')
            cd (MainDirectory)
            clc;
            
            [FileName, FilePath] = uiputfile('*.xlsx','Select the location and specify the Excel filename');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
                        
            Write_EDP_Data_2Excel_Option5(N_GM,N_Story, EDP_Data, MainDirectory, FilePath ,FileName);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MDoF Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.RunModelButton = uibutton(app.figure1, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButton_Callback, true);
            app.RunModelButton.Text = 'Run Model';
            app.uipanel22 = uipanel(app.figure1);
            app.uipanel22.Title = 'System Properties';
            app.text84 = uilabel(app.uipanel22);
            app.text84.Text = 'Import Excel file';
            app.text79 = uilabel(app.uipanel22);
            app.text79.Text = '';
            app.Lamp1 = uilamp(app.uipanel22);
            app.BrowseButton = uibutton(app.uipanel22, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Text = 'browse';
            app.ModelTypepopup = uidropdown(app.uipanel22);
            app.ModelTypepopup.Items = {'Elastic', 'Bilinear', 'IMK Bilin', 'IMK Peak-Oriented'};
            app.ModelTypepopup.ItemsData = {'1', '2', '3', '4'};
            app.ModelTypepopup.Value = '1';
            app.uipanel22_2 = uipanel(app.figure1);
            app.uipanel22_2.Title = 'Ground Motion Records';
            app.BrowseGMButton = uibutton(app.uipanel22_2, 'push');
            app.BrowseGMButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseGMButtonPushed, true);
            app.BrowseGMButton.Text = 'browse';
            app.edit6 = uieditfield(app.uipanel22_2, 'numeric');
            app.edit6.Limits = [1 1000];
            app.edit6.ValueDisplayFormat = '%.0f';
            app.edit6.Value = 1;
            app.text80 = uilabel(app.uipanel22_2);
            app.text80.Text = '';
            app.Lamp2 = uilamp(app.uipanel22_2);
            app.text2_2 = uilabel(app.uipanel22_2);
            app.text2_2.Text = '#GM';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Analysis Type';
            app.popupmenu1 = uidropdown(app.uipanel2);
            app.popupmenu1.Items = {'Mutliple IMs: IDA', 'Single IM: Target SF', 'Single IM: Target IM'};
            app.popupmenu1.ItemsData = {'1', '2', '3'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1ValueChanged, true);
            app.popupmenu1.Value = '1';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Text = 'Scale Factor';
            app.text27 = uilabel(app.uipanel2);
            app.text27.Text = 'Target Sa(T) [g]';
            app.edit4 = uieditfield(app.uipanel2, 'numeric');
            app.edit4.Limits = [0 50];
            app.edit4.ValueDisplayFormat = '%.2f';
            app.edit4.Value = 1;
            app.edit5 = uieditfield(app.uipanel2, 'numeric');
            app.edit5.Limits = [0 10];
            app.edit5.ValueDisplayFormat = '%.2f';
            app.edit5.Value = 1;
            app.text2 = uilabel(app.uipanel2);
            app.text2.Text = 'Damping ζ [%]';
            app.edit2 = uieditfield(app.uipanel2, 'numeric');
            app.edit2.Limits = [0 10];
            app.edit2.ValueDisplayFormat = '%.2f';
            app.edit2.Value = 1;
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'Scale Period [sec]';
            app.edit3 = uieditfield(app.uipanel2, 'numeric');
            app.edit3.Limits = [0 10];
            app.edit3.ValueDisplayFormat = '%.2f';
            app.edit3.Value = 1;
            app.text98_2 = uilabel(app.figure1);
            app.text98_2.Text = '';
            app.text98 = uilabel(app.figure1);
            app.text98.Text = '';
            app.text99 = uilabel(app.figure1);
            app.text99.Text = '';
            app.ProgressBar = uieditfield(app.figure1, 'text');
            app.GetPeriodButton = uibutton(app.figure1, 'push');
            app.GetPeriodButton.ButtonPushedFcn = createCallbackFcn(app, @GetPeriodButtonPushed, true);
            app.GetPeriodButton.Text = 'Get Period';
            app.PlotEDPsButton = uibutton(app.figure1, 'push');
            app.PlotEDPsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEDPsButtonPushed, true);
            app.PlotEDPsButton.Enable = 'off';
            app.PlotEDPsButton.Text = 'Plot EDPs';
            app.Image0 = uiimage(app.figure1);
            app.Image0.Visible = 'off';
            app.Image0.ImageSource = 'Transparent.png';
            app.SavetoExcelButton = uibutton(app.figure1, 'push');
            app.SavetoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoExcelButtonPushed, true);
            app.SavetoExcelButton.Enable = 'off';
            app.SavetoExcelButton.Text = 'Save to Excel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Response_Option5_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_Option5_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end