classdef Module_Response_Option6_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        RunButton              matlab.ui.control.Button
        uipanel22              matlab.ui.container.Panel
        BrowseButton           matlab.ui.control.Button
        text84                 matlab.ui.control.Label
        text79                 matlab.ui.control.Label
        Lamp1                  matlab.ui.control.Lamp
        SystemPropertiesPanel  matlab.ui.container.Panel
        SiteClasspopup         matlab.ui.control.DropDown
        FrameTypepopup         matlab.ui.control.DropDown
        text2                  matlab.ui.control.Label
        edit2                  matlab.ui.control.NumericEditField
        text4                  matlab.ui.control.Label
        edit3                  matlab.ui.control.NumericEditField
        text6                  matlab.ui.control.Label
        edit4                  matlab.ui.control.NumericEditField
        text6_2                matlab.ui.control.Label
        text6_3                matlab.ui.control.Label
        text4_2                matlab.ui.control.Label
        edit5                  matlab.ui.control.NumericEditField
        GMPEpopup              matlab.ui.control.DropDown
        text6_4                matlab.ui.control.Label
        text2_2                matlab.ui.control.Label
        edit6                  matlab.ui.control.NumericEditField
        EstimateVy1            matlab.ui.control.Button
        text4_3                matlab.ui.control.Label
        edit7                  matlab.ui.control.NumericEditField
        GetPeriodButton        matlab.ui.control.Button
        text98_1               matlab.ui.control.Label
        text98                 matlab.ui.control.Label
        PlotEDPsButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_Option6_App_OpeningFcn(app, mainapp)
            global MainDirectory ProjectName ProjectPath Units
            clc;
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option','Option6Data')
            cd (MainDirectory)
            
            if ResponseDataStatus==1
                if Response_Option==6
                    %v2struct(Option6Data); % unpack Option5Data 
                    app.FrameTypepopup.Value=string(Option6Data.FrameType);
                    app.SiteClasspopup.Value=string(Option6Data.SiteClass);
                    app.edit2.Value=Option6Data.Vy1;
                    app.edit3.Value=Option6Data.SaT1;
                    app.edit4.Value=Option6Data.T1;
                    app.edit5.Value=Option6Data.PGA;
                    app.edit6.Value=Option6Data.SDRy*100;
                    app.Lamp1.Color=[0 1 0];
                    app.text79.Text='EXPORT OK';
                    app.PlotEDPsButton.Enable='on';
                end
            end
            
            if Units==2
              app.text2.Text='Vy [kN]';  
            end
            
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';   

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'N_Story');
            cd (MainDirectory)
            
            try
                
            app.text79.Text='Please wait...';
            ModelType=1;
                        
            [ExcelFileName,ExcelFilePath] = uigetfile('*.xlsx','Select the .xlsx file containg component data');
            ExcelFileName=convertCharsToStrings(ExcelFileName);
            ExcelFilePath=convertCharsToStrings(ExcelFilePath);
            
            % Read Frame Cross-Section Data From Excel
            cd(ExcelFilePath)
            [HStory,Load,Mass,Parameters]=Import_ShearModel_Data_from_Excel(ModelType,ExcelFileName,N_Story);
            HBuilding=sum(HStory);

            Option6Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,ModelType);

            cd (ProjectPath)
            save(ProjectName,'Option6Data','-append');
            cd (MainDirectory)
            
            app.text79.Text='EXPORT OK';
            app.Lamp1.Color=[0 1 0];
            
            catch ME
                app.text79.Text='Error!';
                errordlg('Error importing EXCEL file! Check that story-height, load, mass and stiffness are defined at each story/floor!','Importing Error');
                cd (MainDirectory)
            end
        end

        % Button pushed function: RunButton
        function RunButton_Callback(app, event)
            app.text98.Text=['Please wait...']; app.text98.FontColor='y'; drawnow

            global MainDirectory ProjectName ProjectPath
            clc;
            cd(ProjectPath)
            load(ProjectName,'Option6Data','IMpoints','N_Story','Units')
            cd(MainDirectory)
            v2struct(Option6Data); % unpack Option6Data 

            if strcmp(app.text79.Text, 'EXPORT OK')~=1
                errordlg('Please browse first for the Excel file with building properties!');
                return
            end

            ShowAnimation=0;
                        
            SiteClass = str2double(app.SiteClasspopup.Value);
            FrameType = str2double(app.FrameTypepopup.Value);
            Vy1       = app.edit2.Value;
            SaT1      = app.edit3.Value;
            Sa_1sec   = app.edit7.Value;
            T1        = app.edit4.Value;
            PGA       = app.edit5.Value;
            SDRy      = app.edit6.Value/100;
            GMPEloc   = str2double(app.GMPEpopup.Value);
            
            [Fx,S,hx,W,W1]=FEMAP58_Get_Fx_Distribution(N_Story,HStory,SaT1,Load,Vy1,T1,SiteClass,FrameType);
            
            Option6Data.Fx=Fx;
            Option6Data.ShowAnimation=ShowAnimation;
            Option6Data.SiteClass=SiteClass;
            Option6Data.GMPEloc=GMPEloc;
            Option6Data.FrameType=FrameType;
            Option6Data.T1=T1;
            Option6Data.PGA=PGA;
            Option6Data.Vy1=Vy1;
            Option6Data.SaT1=SaT1;
            Option6Data.Sa_1sec=Sa_1sec;
            Option6Data.SDRy=SDRy;
            Option6Data.W=W;
            Option6Data.W1=W1;
            Option6Data.ExcelFileName=ExcelFileName;
            Option6Data.ExcelFilePath=ExcelFilePath;
            
            % Save MRF Data to .mat File
            cd(ProjectPath)
            save (ProjectName,'Fx','Option6Data', '-append');
            pause(1)
            cd(MainDirectory)
            
            MDoF_CREATOR_ELASTIC ()
            ! OpenSEES.exe Option6.tcl
            
            delete('Option6.tcl');
            
            % Read SDR Data            
            cd ('Results');
            for i=1:N_Story
            	FilenameX1=strcat('SDR',num2str(i),'.out');
            	evalc(strcat('SDR(1,i)= max(importdata(''',FilenameX1,'''))'));
            end
            for i=2:N_Story+1
            	FilenameX1=strcat('Disp',num2str(i),'.out');
            	evalc(strcat('DISP(1,i)= max(importdata(''',FilenameX1,'''))'));
            end
            cd (MainDirectory);
            try
                rmdir('Results','s')
            catch ME
                
            end
            [EDP_Data]=FEMAP58_EDP_Correction(PGA,FrameType,N_Story,T1,hx,HBuilding,S,SDRy,GMPEloc,SDR,DISP,Units,Sa_1sec,W,W1,Vy1);

            ResponseDataStatus=1;
            Response_Option=6;
            nStripe=1;
            
            clear TargetIM
            ResponseSA=SaT1;
            nIMpoints=length(IMpoints);
            if ismember(ResponseSA,IMpoints)==0
                for i=2:nIMpoints
                    if IMpoints(1,i)<ResponseSA && IMpoints(1,i+1)>ResponseSA
                        if abs(IMpoints(1,i)-ResponseSA) < abs(IMpoints(1,i+1)-ResponseSA)
                            TargetIM=IMpoints(1,i);
                        else
                            TargetIM=IMpoints(1,i+1);
                        end
                        break
                    end
                end
            end
            TargetIMstart   = TargetIM;
            TargetIMend     = TargetIM;
            TargetIM_Option = 3;
            
            CollapseStatus=0;
            MedianCPS=10;
            SigmaCPS=0.001;
            CPS_Option=0;      
            
            Data_Status.RDR=1;      Data_Status.VRD=0;      Data_Status.ED=0;   
            Data_Status.DWD=0;      Data_Status.RD=0;       Data_Status.COLROT=0; 
            Data_Status.BEAMROT=0;  Data_Status.LINKROT=0;  Data_Status.PFV=1; 
            Data_Status.GENS1=0;    Data_Status.GENS2=0;    Data_Status.GENS3=0;
            Data_Status.GENF1=0;    Data_Status.GENF2=0;    Data_Status.GENF3=0;
            
            cd (ProjectPath)
            save(ProjectName,'EDP_Data','Data_Status','ResponseDataStatus','Response_Option','Option6Data','nStripe','ResponseSA','TargetIMstart','TargetIMend','TargetIM_Option','TargetIM','CollapseStatus','MedianCPS','SigmaCPS','CPS_Option','SaT1','-append')
            cd (MainDirectory)
            
            app.text98.Text=['EDPs generation is DONE']; app.text98.FontColor='g'; drawnow

            app.PlotEDPsButton.Enable='on'; 
                
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'ResponseDataStatus','Response_Option')
            cd (MainDirectory)
            
            if ResponseDataStatus==1
                if Response_Option==6            
                    app.MainApp.status4.BackgroundColor='g';
                    app.MainApp.status4.FontColor='k';
                    app.MainApp.status4.Text='FEMA P-58 SAM';
                    app.MainApp.status4b.BackgroundColor='g';
                    app.MainApp.HazardButton.Enable='on';
                    app.MainApp.ComputeButton.Enable='on';
                    app.MainApp.IMButton.Enable='on';
                    app.MainApp.status10.BackgroundColor='g';
                    app.MainApp.DemolitionButton.Enable='on';
                    app.MainApp.CollapseButton.Enable='on';
                    app.MainApp.status30.BackgroundColor=[0.65 0.65 0.65];
                end
            end
            
            app.MainApp.Image5.Visible='off';  
            delete(app)            
        end

        % Button pushed function: GetPeriodButton
        function GetPeriodButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            
            app.text98.Text=['Building OpenSEES Model...'];  app.text98.FontColor='y';   drawnow
            
            cd (ProjectPath)
            load(ProjectName,'Option6Data')
            cd (MainDirectory)
            v2struct(Option6Data); % unpack Option5Data 
            
            ModelType=1;
            
            Option6Data=v2struct(N_Story,ExcelFileName,ExcelFilePath,HStory, Load,Mass,Parameters,HBuilding,ModelType); % re-pack with new data

            % Save MRF Data to .mat File
            cd(ProjectPath)
            save (ProjectName,'Option6Data','-append');
            cd(MainDirectory)
            
            app.text98.Text=['Running Eigen Analysis...'];  app.text98.FontColor='y';   drawnow

            tic
            MDoF_CREATOR_EIGEN (6)
            ! OpenSEES.exe Temp.tcl
            fileID = fopen('Periods.txt','r');
            A = fscanf(fileID,'%f');
            fclose(fileID);
            T1_computed = A(1,1);
            app.text98.Text=['Computed T1 is ',num2str(round(T1_computed*100)/100),'sec']; app.text98.FontColor='g'; drawnow

            delete ('Periods.txt')
            delete ('Temp.tcl')
        end

        % Button pushed function: PlotEDPsButton
        function PlotEDPsButtonPushed(app, event)
            global ProjectPath ProjectName MainDirectory
            cd(ProjectPath)
            load (ProjectName,'N_Story','EDP_Data','Option6Data','Units');
            cd(MainDirectory)
            
            Plot_EDP_Profile_Option6 (N_Story,EDP_Data,Option6Data,Units)            
        end

        % Button pushed function: EstimateVy1
        function EstimateVy1ButtonPushed(app, event)
            
            global MainDirectory ProjectName ProjectPath
            clc;
            cd(ProjectPath)
            load(ProjectName,'Option6Data')
            cd(MainDirectory)
            v2struct(Option6Data); % unpack Option6Data 
            
            if exist('Load')~=1; errordlg('You must import the Excel file first!'); return; end 
            
            waitfor(Module_Option6_RMF_App);
            
            cd(ProjectPath)
            load(ProjectName,'Option6Data')
            cd(MainDirectory)
            v2struct(Option6Data); % unpack Option6Data 
            
            W         = sum(Load);
            SaT1      = app.edit3.Value;

            % Judgment-based Vy1 computation 
            Vy1_min=1.55*SaT1*W/(Option6Data.R/Option6Data.I);  
            Vy1_max=Option6Data.Omega*SaT1*W/(Option6Data.R/Option6Data.I);
            Vy1=(Vy1_min+Vy1_max)/2;

            app.edit2.Value=Vy1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'FEMA P-58 SAM';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.RunButton = uibutton(app.figure1, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButton_Callback, true);
            app.RunButton.Text = 'Run';
            app.uipanel22 = uipanel(app.figure1);
            app.uipanel22.Title = 'Load/Mass/Stiffness';
            app.BrowseButton = uibutton(app.uipanel22, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Text = 'browse';
            app.text84 = uilabel(app.uipanel22);
            app.text84.Text = 'Import Excel file';
            app.text79 = uilabel(app.uipanel22);
            app.text79.Text = '';
            app.Lamp1 = uilamp(app.uipanel22);
            app.SystemPropertiesPanel = uipanel(app.figure1);
            app.SystemPropertiesPanel.Title = 'System Properties';
            app.SiteClasspopup = uidropdown(app.SystemPropertiesPanel);
            app.SiteClasspopup.Items = {'A', 'B', 'C', 'D', 'E'};
            app.SiteClasspopup.ItemsData = {'1', '2', '3', '4', '5'};
            app.SiteClasspopup.Value = '1';
            app.FrameTypepopup = uidropdown(app.SystemPropertiesPanel);
            app.FrameTypepopup.Items = {'EBF', 'SCBF', 'BRBF', 'MF', 'Wall'};
            app.FrameTypepopup.ItemsData = {'1', '2', '3', '4', '5'};
            app.FrameTypepopup.Value = '1';
            app.text2 = uilabel(app.SystemPropertiesPanel);
            app.text2.Text = 'Vy [kip]';
            app.edit2 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit2.Limits = [0 10000];
            app.edit2.ValueDisplayFormat = '%.2f';
            app.edit2.Tooltip = {'Story yield strength'};
            app.edit2.Value = 318;
            app.text4 = uilabel(app.SystemPropertiesPanel);
            app.text4.Text = 'Sa(T₁) [g]';
            app.edit3 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit3.Limits = [0 10];
            app.edit3.ValueDisplayFormat = '%.2f';
            app.edit3.Tooltip = {'Spectral acceleration at T1'};
            app.edit3.Value = 1;
            app.text6 = uilabel(app.SystemPropertiesPanel);
            app.text6.Text = 'T₁ [sec]';
            app.edit4 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit4.Limits = [0 10];
            app.edit4.ValueDisplayFormat = '%.2f';
            app.edit4.Tooltip = {'First mode period'};
            app.edit4.Value = 0.13;
            app.text6_2 = uilabel(app.SystemPropertiesPanel);
            app.text6_2.Text = 'Soil class';
            app.text6_3 = uilabel(app.SystemPropertiesPanel);
            app.text6_3.Text = 'System type';
            app.text4_2 = uilabel(app.SystemPropertiesPanel);
            app.text4_2.Text = 'PGA [g]';
            app.edit5 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit5.Limits = [0 10];
            app.edit5.ValueDisplayFormat = '%.2f';
            app.edit5.Tooltip = {'Peak ground acceleration'};
            app.edit5.Value = 0.4;
            app.GMPEpopup = uidropdown(app.SystemPropertiesPanel);
            app.GMPEpopup.Items = {'WNA', 'CEUS', 'PNW'};
            app.GMPEpopup.ItemsData = {'1', '2', '3', '4', '5'};
            app.GMPEpopup.Tooltip = {'Location for GMPE [see FEMA P-58 Vol 1]'};
            app.GMPEpopup.Value = '1';
            app.text6_4 = uilabel(app.SystemPropertiesPanel);
            app.text6_4.Text = 'GMPE';
            app.text2_2 = uilabel(app.SystemPropertiesPanel);
            app.text2_2.Text = 'SDRᵧ [% rad]';
            app.edit6 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit6.Limits = [0 5];
            app.edit6.ValueDisplayFormat = '%.2f';
            app.edit6.Tooltip = {'Yield story drift ratio'};
            app.edit6.Value = 0.5;
            app.EstimateVy1 = uibutton(app.SystemPropertiesPanel, 'push');
            app.EstimateVy1.ButtonPushedFcn = createCallbackFcn(app, @EstimateVy1ButtonPushed, true);
            app.EstimateVy1.Tooltip = {'estimate Vy using FEMA P58 judgment method'};
            app.EstimateVy1.Text = 'estimate';
            app.text4_3 = uilabel(app.SystemPropertiesPanel);
            app.text4_3.Text = 'Sa(1s) [g]';
            app.edit7 = uieditfield(app.SystemPropertiesPanel, 'numeric');
            app.edit7.Limits = [0 10];
            app.edit7.ValueDisplayFormat = '%.2f';
            app.edit7.Tooltip = {'Spectral acceleration at 1 second'};
            app.edit7.Value = 1;
            app.GetPeriodButton = uibutton(app.figure1, 'push');
            app.GetPeriodButton.ButtonPushedFcn = createCallbackFcn(app, @GetPeriodButtonPushed, true);
            app.GetPeriodButton.Text = 'Get Period';
            app.text98_1 = uilabel(app.figure1);
            app.text98_1.Text = '';
            app.text98 = uilabel(app.figure1);
            app.text98.Text = '';
            app.PlotEDPsButton = uibutton(app.figure1, 'push');
            app.PlotEDPsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEDPsButtonPushed, true);
            app.PlotEDPsButton.Enable = 'off';
            app.PlotEDPsButton.Text = 'Plot EDPs';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Response_Option6_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_Option6_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end