classdef Module_Run_Options_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1x           matlab.ui.Figure
        SelectButtonGroup  matlab.ui.container.ButtonGroup
        radiobutton1       matlab.ui.control.RadioButton
        radiobutton2       matlab.ui.control.RadioButton
        Image_2            matlab.ui.control.Image
        Option2_tip        matlab.ui.control.Button
        Option1_tip        matlab.ui.control.Button
        Image_3            matlab.ui.control.Image
        text10             matlab.ui.control.Label
        edit1              matlab.ui.control.NumericEditField
        text9_3            matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        text11             matlab.ui.control.Label
        edit2              matlab.ui.control.NumericEditField
        checkbox1          matlab.ui.control.CheckBox
        RunButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
        TipWindow
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Response_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';

            global MainDirectory ProjectName ProjectPath RunMethodology
            clc;
            cd (ProjectPath)
            load(ProjectName,'Response_Option','Option5_Type','Option5Data','Option6Data')
            cd (MainDirectory)
            
            if Response_Option==5; app.edit1.Limits=[Option5Data.N_GM+1 5000]; end
            if Response_Option==6; app.edit1.Limits=[1 5000]; end
         
            if Response_Option~=5; Option5_Type=0; end

            if Response_Option==1 || Option5_Type==1
                app.radiobutton2.Enable='off';
                app.edit1.Enable='off'; 
                app.edit2.Enable='off';
                app.text10.Enable='off';
                app.text11.Enable='off';
                app.checkbox1.Enable='off';
            end

            RunMethodology=0;

        end

        % Button pushed function: RunButton
        function RunButton_Callback(app, event)
            global MainDirectory ProjectName ProjectPath RunMethodology
            clc;
            cd (ProjectPath)
            load(ProjectName,'FRAGDATA')
            cd (MainDirectory)
            
             TargetVal=str2double(app.DropDown.Value);
             stdAdd=app.edit2.Value;
             
             if TargetVal==1
                 FRAGDATA.DS_Cost=FRAGDATA.DS_Costmean;
                 FRAGDATA.DS_Time=FRAGDATA.DS_Timemean;
             elseif TargetVal==2
                 FRAGDATA.DS_Cost=FRAGDATA.DS_CostP50;
                 FRAGDATA.DS_Time=FRAGDATA.DS_TimeP50;
             elseif TargetVal==3
                 FRAGDATA.DS_Cost=FRAGDATA.DS_CostP90;
                 FRAGDATA.DS_Time=FRAGDATA.DS_TimeP90;
             elseif TargetVal==4
                  FRAGDATA.DS_Cost=FRAGDATA.DS_CostP10;
                 FRAGDATA.DS_Time=FRAGDATA.DS_TimeP10;
             end
             
            if app.radiobutton1.Value ==1
                RunMethodology=1;
                nRealization=1;
            elseif app.radiobutton2.Value ==1
                RunMethodology=2;
                nRealization=app.edit1.Value;
            end
            R_target=1;
            COLLAPSE_LOSSES_Per_Ri=0;
            DEMOLITION_LOSSES_Per_Ri=0;
                 
            if app.checkbox1.Value ==1
                Placardstatus=1;
            else
                Placardstatus=0;
            end
               
            cd (ProjectPath)
            save(ProjectName,'RunMethodology','nRealization','stdAdd','R_target','COLLAPSE_LOSSES_Per_Ri','DEMOLITION_LOSSES_Per_Ri','TargetVal','FRAGDATA','Placardstatus','-append')
            cd (MainDirectory)
            closereq
        end

        % Close request function: figure1x
        function figure1xCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: Option1_tip
        function Option1_tipPushed(app, event)
            str{1}='This option utilizes the loss assessment methodology developed by Moehle & Deierlein (2004) as part of the Pacific Earthquake Engineering Reseach (PEER) center program. The methodology essentially computes losses by employing the triple integral of the consequence functions, damage fragility functions and the structural response, based on the total probability theorem.';
            str{2}=' ';
            str{3}='Yang, T. Y., Moehle, J., Stojadinovic, B., and Der Kiureghian, A. (2009). "Seismic performance evaluation of facilities: Methodology and implementation." Journal of Structural Engineering, 135(10), 1146-1154';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: Option2_tip
        function Option2_tipPushed(app, event)
            str{1}='This option utilizes the loss assessment methodology developed by Yang et al (2009), that was then adopted in FEMA P-58. The methodology essentially computes losses by employing the Monte Carlo approach to generate a large number of structural response realizations that are then used to query structural damage based on random variable generation.';
            str{2}=' ';
            str{3}='Moehle, J. & Deierlein, G. G. (2004)- "A Framework Methodology for Performance-Based Earthqauke Engineering". The 13th World Conference on Earthquake Engineering, Vancouver, Canada';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1x = uifigure('Visible', 'off');
            app.figure1x.Name = 'Loss Methodology Options';
            app.figure1x.CloseRequestFcn = createCallbackFcn(app, @figure1xCloseRequest, true);
            app.SelectButtonGroup = uibuttongroup(app.figure1x);
            app.SelectButtonGroup.Title = 'Select a Methodology Option for Loss Assessment';
            app.radiobutton1 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton1.Text = 'PEER Methodology';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.SelectButtonGroup);
            app.radiobutton2.Text = 'FEMA P-58 Methodology';
            app.Image_2 = uiimage(app.SelectButtonGroup);
            app.Image_2.ImageSource = 'RunOption2.png';
            app.Option2_tip = uibutton(app.SelectButtonGroup, 'push');
            app.Option2_tip.ButtonPushedFcn = createCallbackFcn(app, @Option2_tipPushed, true);
            app.Option2_tip.Text = '?';
            app.Option1_tip = uibutton(app.SelectButtonGroup, 'push');
            app.Option1_tip.ButtonPushedFcn = createCallbackFcn(app, @Option1_tipPushed, true);
            app.Option1_tip.Text = '?';
            app.Image_3 = uiimage(app.SelectButtonGroup);
            app.Image_3.ImageSource = 'RunOption1.png';
            app.text10 = uilabel(app.SelectButtonGroup);
            app.text10.Tooltip = {'Number of simulated structural responses as part of the Monte Carlo scheme'};
            app.text10.Text = 'No. of Realizations';
            app.edit1 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit1.Limits = [1 5000];
            app.edit1.ValueDisplayFormat = '%.0f';
            app.edit1.Tooltip = {'Number of simulated structural responses as part of the Monte Carlo scheme'};
            app.edit1.Value = 1000;
            app.text9_3 = uilabel(app.SelectButtonGroup);
            app.text9_3.Text = 'Cost Values';
            app.DropDown = uidropdown(app.SelectButtonGroup);
            app.DropDown.Items = {'Mean', 'Median', '90th percentile', '10th percentile'};
            app.DropDown.ItemsData = {'1', '2', '3', '4'};
            app.DropDown.Value = '1';
            app.text11 = uilabel(app.SelectButtonGroup);
            app.text11.Tooltip = {'Additional uncertainty coefficient to account for modeling uncertainty'};
            app.text11.Text = 'EDP Uncertainty +';
            app.edit2 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit2.Limits = [0 1];
            app.edit2.ValueDisplayFormat = '%5.2f';
            app.edit2.Tooltip = {'Additional uncertainty coefficient to account for modeling uncertainty'};
            app.checkbox1 = uicheckbox(app.SelectButtonGroup);
            app.checkbox1.Tooltip = {'Note that Unsafe Placard estimation can take few minutes in computation time'};
            app.checkbox1.Text = 'Check for Unsafe Placard';
            app.RunButton = uibutton(app.figure1x, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButton_Callback, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.figure1x.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Run_Options_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1x)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Response_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1x)
        end
    end
end