classdef Module_SummaryPlot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        LossIMplot     matlab.ui.control.UIAxes
        PieChart       matlab.ui.control.UIAxes
        LossesProfile  matlab.ui.control.UIAxes
        EALplot        matlab.ui.control.UIAxes
        uipanel1       matlab.ui.container.Panel
        slider         matlab.ui.control.Slider
        SavePlotData   matlab.ui.control.Button
        text3          matlab.ui.control.Label
        text4          matlab.ui.control.Label
        edit2          matlab.ui.control.NumericEditField
        checkbox1      matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function Write2File(app,FileNameX,Xdata,Ydata)
            global MainDirectory ProjectPath

            cd (ProjectPath)
            fileX = fopen(FileNameX,'wt');
            
            fprintf(fileX,'%s\t%s\t\n','X-data    ','Y-data   ');
            fprintf(fileX,'%s\n','-------------------------------------------------------------------------------------------------');    
            
            for i=1:size(Xdata,1)
                for j=1:size(Xdata,2)
                    fprintf(fileX,'%f\t',  Xdata(i,j));
                end  
                for j=1:size(Ydata,2)
                    fprintf(fileX,'%f\t',  Ydata(i,j));
                end  
                fprintf(fileX,'\n');
            end
     
            fclose(fileX);
            cd (MainDirectory)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SummaryPlot_OpeningFcn(app)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName,'Response_Option','TargetIM_Option','TargetIM','Option5_Type')
            cd (MainDirectory)
            
            % Clear Figures
            app.LossIMplot.cla;
            app.PieChart.cla; 
            app.LossesProfile.cla;
            app.EALplot.cla;
                        
            if TargetIM_Option~=1
                app.slider.Value=median(TargetIM);
                app.edit2.Value=median(TargetIM);
                if TargetIM_Option==3
                    app.slider.Enable='off';
                end
            end
            
            if TargetIM_Option==1
                app.slider.Limits=[min(TargetIM) max(TargetIM)];
                yy=0:max(0.1,round((max(TargetIM)-min(TargetIM))/10*10)/10):max(TargetIM);
                yy(1)=min(TargetIM);
                app.slider.MajorTicks=yy;
            elseif TargetIM_Option==2
                app.slider.Limits=[TargetIMstart TargetIMend];
                app.slider.MajorTicks=TargetIMstart:0.1:TargetIMend;
             end
                        
            if Response_Option ==1
                Sa_target=app.slider.Value;
                app.edit2.Value=Sa_target;
            elseif Response_Option ==5 && Option5_Type==1
                Sa_target=app.slider.Value;
                app.edit2.Value=Sa_target;                
            else
                app.slider.Enable='off';
                app.edit2.Enable='off';
                app.EALplot.Enable='off';
                app.LossIMplot.Enable='off';
            end
            
            slider_Callback(app)

        end

        % Button pushed function: SavePlotData
        function SavePlotData_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName)
            cd (MainDirectory)
            
            Sa_target=app.slider.Value;
            
            for i=1:length(IMpoints)-1
                if Sa_target>=IMpoints(1,i) && Sa_target<IMpoints(1,i+1)
                    indexSa1=i;
                    break
                end
            end
                
            if app.checkbox1.Value==1
                NormVal=Replacement_Cost/10^6/100;
                str='Loss [% \itCost\rm]';
                str2='EAL [% \itCost\rm/year]';
            else
                NormVal=1.0;
                str='Loss [M$]';
                str2='EAL [M$/year]';
            end
            
            %% Plot Vulnearability Curve
            X=IMpoints;
            Y=[(COLLAPSE_LOSSES_Per_IM+DEMOLITION_LOSSES_Per_IM+SC_REPAIR_COST_PerIM+NSC_SDR_REPAIR_COST_PerIM+NSC_ACC_REPAIR_COST_PerIM) COLLAPSE_LOSSES_Per_IM DEMOLITION_LOSSES_Per_IM (SC_REPAIR_COST_PerIM+NSC_SDR_REPAIR_COST_PerIM+NSC_ACC_REPAIR_COST_PerIM)]/10^6/NormVal;
            Write2File(app,'Figure 1 - Vulnerability Curves.txt',X',Y)

            %% Plot Loss Breakdown at Specific IM
            X=1:5;
            Y = [COLLAPSE_LOSSES_Per_IM(indexSa1,1) DEMOLITION_LOSSES_Per_IM(indexSa1,1) SC_REPAIR_COST_PerIM(indexSa1,1) NSC_SDR_REPAIR_COST_PerIM(indexSa1,1) NSC_ACC_REPAIR_COST_PerIM(indexSa1,1)]/10^6/NormVal;
            Write2File(app,'Figure 2 - Loss Breakdown.txt',X',Y')

            %% Plot Losses Profile
            Y=1:N_Story+1;
            X=[STORY_REPAIR_COST_SC(indexSa1,:)' STORY_REPAIR_COST_NSC_SDR(indexSa1,:)'  STORY_REPAIR_COST_NSC_ACC(indexSa1,:)'] /10^6/NormVal;
            Write2File(app,'Figure 3 - Loss Profile.txt',X,Y')

            %% Plot EAL bar chart
            if HazardDataStatus ==1 && ResponseDataStatus == 1 && Response_Option == 1
                X=1:5;
                Y=EAL/10^6/NormVal;
                Write2File(app,'Figure 4 - EAL.txt',X',Y')
            end
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            slider_Callback(app)
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            Sa_targetX=app.edit2.Value;
            app.slider.Value=Sa_targetX;
            slider_Callback(app)
        end

        % Value changed function: slider
        function slider_Callback(app, event)
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load (ProjectName)
            cd (MainDirectory)
            
            app.LossIMplot.cla; 
            app.PieChart.cla; 
            app.LossesProfile.cla; 
            app.EALplot.cla; 
            
            Sa_target=app.slider.Value;
            app.edit2.Value=Sa_target;
            
            if app.checkbox1.Value==1
                NormVal=Replacement_Cost/10^6/100;
                str='Loss [% \itCost\rm]';
                str3='Repair Cost [% \itCost\rm]';
                str2='EAL [% \itCost\rm/year]';
            else
                NormVal=1.0;
                str='Loss [M$]';
                str3='Repair Cost [M$]';
                str2='EAL [M$/year]';
            end
            %% Plot Vulnearability Curve
            plot(app.LossIMplot,IMpoints, (COLLAPSE_LOSSES_Per_IM+DEMOLITION_LOSSES_Per_IM+SC_REPAIR_COST_PerIM+NSC_SDR_REPAIR_COST_PerIM+NSC_ACC_REPAIR_COST_PerIM) /10^6/NormVal,'-k','linewidth',2);
            app.LossIMplot.XLabel.String= 'IM [g]';
            app.LossIMplot.YLabel.String=str;
            app.LossIMplot.XLim=[0.0 TargetIMend-TargetIMend/10];
            Max=ceil(max(([max(COLLAPSE_LOSSES_Per_IM/10^6) max(DEMOLITION_LOSSES_Per_IM/10^6) max(SC_REPAIR_COST_PerIM/10^6+NSC_SDR_REPAIR_COST_PerIM/10^6+NSC_ACC_REPAIR_COST_PerIM/10^6)])));
            app.LossIMplot.YLim=[0.0 ceil(Max/NormVal)];
            app.LossIMplot.Box='on';
            app.LossIMplot.XGrid='on';
            app.LossIMplot.YGrid='on';
            hold(app.LossIMplot,"on");
            plot(app.LossIMplot,IMpoints,COLLAPSE_LOSSES_Per_IM/10^6/NormVal,'--b','linewidth',2);
            plot(app.LossIMplot,IMpoints, DEMOLITION_LOSSES_Per_IM  /10^6/NormVal,'-.r','linewidth',2);
            plot(app.LossIMplot,IMpoints, (SC_REPAIR_COST_PerIM+NSC_SDR_REPAIR_COST_PerIM+NSC_ACC_REPAIR_COST_PerIM) /10^6/NormVal,':g','linewidth',2);
            %plot(app.LossIMplot,IMpoints, NSC_SDR_REPAIR_COST_PerIM/10^6/NormVal,':g','linewidth',2);
            %plot(app.LossIMplot,IMpoints, NSC_ACC_REPAIR_COST_PerIM/10^6/NormVal,'-','color',[1 0.7 0],'linewidth',2);
            plot(app.LossIMplot,[Sa_target Sa_target],[0 ceil(Max/NormVal)],'--b');
            legend (app.LossIMplot,'Total','Collapse','Demolition','Repair');
            app.LossIMplot.Legend.FontName='Euclid';
            app.LossIMplot.Legend.FontSize=14;
            app.LossIMplot.Legend.Location='NorthWest';
            app.LossIMplot.FontName='Euclid';
            app.LossIMplot.FontSize=18;
            
            %% Plot Losses at Specific IM
            % Pie Chart Option
            app.PieChart.cla;
%             if app.Radio2.Value == 1
%                 for i=1:length(IMpoints)-1
%                     if Sa_target>=IMpoints(1,i) && Sa_target<IMpoints(1,i+1)
%                         indexSa1=i;
%                         X = [COLLAPSE_LOSSES_Per_IM(i,1) DEMOLITION_LOSSES_Per_IM(i,1) SC_REPAIR_COST_PerIM(i,1) NSC_SDR_REPAIR_COST_PerIM(i,1) NSC_ACC_REPAIR_COST_PerIM(i,1)];
%                         break
%                     end
%                 end
%                 X(X==0)=0.001;
%                 h=pie(app.PieChart,X);
%                 labels = {'Collapse','Demolition','SC','NSC-SDR','NSC-ACC'};
%                 app.PieChart.FontName='Euclid';
%                 app.PieChart.FontSize=18;
%                 colormap(app.PieChart,[0 0 0;      %// black
%                                       0 0 1;      %// blue
%                                       1 0 0;      %// red
%                                       0 1 0;      %// green
%                                       1 0.7 0])     %// yellow
%                 legend(app.PieChart,labels);
%                 app.PieChart.Legend.Location='eastoutside';
%                 app.PieChart.Legend.Orientation='vertical';
%                 app.PieChart.Legend.FontSize=12;
%             else
                % Bar Chart Option
                app.PieChart.cla;
            
                for i=1:length(IMpoints)-1
                if Sa_target>=IMpoints(1,i) && Sa_target<IMpoints(1,i+1)
                    indexSa1=i;
                    X = [COLLAPSE_LOSSES_Per_IM(i,1) DEMOLITION_LOSSES_Per_IM(i,1) SC_REPAIR_COST_PerIM(i,1) NSC_SDR_REPAIR_COST_PerIM(i,1) NSC_ACC_REPAIR_COST_PerIM(i,1)];
                    break
                end
                end
                bar(app.PieChart,1,X(1,1)/10^6/NormVal,'barwidth',1,'facecolor','k');
                app.PieChart.Box='on';
                app.PieChart.XGrid='on';
                app.PieChart.YGrid='on';
                hold(app.PieChart,"on");
                bar(app.PieChart,2,X(1,2)/10^6/NormVal,'barwidth',1,'facecolor','b');
                bar(app.PieChart,3,X(1,3)/10^6/NormVal,'barwidth',1,'facecolor','r');
                bar(app.PieChart,4,X(1,4)/10^6/NormVal,'barwidth',1,'facecolor','g');
                bar(app.PieChart,5,X(1,5)/10^6/NormVal,'barwidth',1,'facecolor',[1 0.7 0]);
                app.PieChart.YLabel.String=str;
                app.PieChart.YLim=[0.0 ceil(1.1*max(X)/10^6)/NormVal];
                app.PieChart.XLim=[0 6];
                app.PieChart.XTick=[1 2 3 4 5];
                app.PieChart.FontName='Euclid';
                app.PieChart.FontSize=17;
                labels = {'Collapse','Demolition','SC','NSC-SDR','NSC-ACC'};
                app.PieChart.XTickLabel =labels;
                rotateXLabels(app.PieChart, 45)
%             end
                title(app.PieChart,[num2str(round(sum(X/10^6)*100)/100),'M$ Total Loss at IM=', num2str(round(Sa_target*100)/100),'g'], 'fontname', 'Courier', 'fontsize',14);
            
            %% Plot Losses Profile
            app.LossesProfile.cla
            Elevation=1:N_Story+1;
            plot(app.LossesProfile,STORY_REPAIR_COST_SC(indexSa1,:)/10^6/NormVal,Elevation,'-or','linewidth',2);
            app.LossesProfile.Box='on';
            app.LossesProfile.XGrid='on';
            app.LossesProfile.YGrid='on';
            hold(app.LossesProfile,"on");
            plot(app.LossesProfile,STORY_REPAIR_COST_NSC_SDR(indexSa1,:)/10^6/NormVal,Elevation,'--*g','linewidth',2);
            plot(app.LossesProfile,STORY_REPAIR_COST_NSC_ACC(indexSa1,:)/10^6/NormVal,Elevation,'--*','linewidth',2,'Color',[1.0 0.7 0.0]);
            app.LossesProfile.XLabel.String=str3;
            app.LossesProfile.YLabel.String='Story/Floor';
            Max1=max(max(STORY_REPAIR_COST_SC(indexSa1,:)));
            Max2=max(max(STORY_REPAIR_COST_NSC_SDR(indexSa1,:)));
            Max3=max(max(STORY_REPAIR_COST_NSC_ACC(indexSa1,:)));
            MaxLosses=ceil(1.5*max([Max1;Max2;Max3])*15/10^6)/10;
            app.LossesProfile.XLim=[0.0 max(MaxLosses/NormVal,0.01)];
            app.LossesProfile.YLim=[1 N_Story+1];
            app.LossesProfile.YTick=Elevation;
            app.LossesProfile.FontName='Euclid';
            app.LossesProfile.FontSize=17;
            legend (app.LossesProfile,'SC','NSC-SDR','NSC-ACC');
            app.LossesProfile.Legend.FontName= 'Euclid';
            app.LossesProfile.Legend.FontSize=14;
            app.LossesProfile.Legend.Location='northeast';
            
            
            %% Plot EAL bar chart
            if HazardDataStatus ==1 && ResponseDataStatus == 1 && Response_Option == 1 %&& TargetIM_Option==1
                bar(app.EALplot,1,EAL(1,1)/10^6/NormVal,'barwidth',1,'facecolor','k');
                app.EALplot.Box='on';
                app.EALplot.XGrid='on';
                app.EALplot.YGrid='on';
                hold(app.EALplot,"on");
                bar(app.EALplot,2,EAL(1,2)/10^6/NormVal,'barwidth',1,'facecolor','b');
                bar(app.EALplot,3,EAL(1,3)/10^6/NormVal,'barwidth',1,'facecolor','r');
                bar(app.EALplot,app.EALplot,4,EAL(1,4)/10^6/NormVal,'barwidth',1,'facecolor','g');
                bar(app.EALplot,5,EAL(1,5)/10^6/NormVal,'barwidth',1,'facecolor',[1 0.7 0]);
                app.EALplot.FontName= 'Euclid';
                app.EALplot.FontSize=18;
                app.EALplot.YLabel.String=str2;
                app.EALplot.XLim=[0 6];
                app.EALplot.XTick=[1 2 3 4 5];
                labels = {'Collapse','Demolition','SC','NSC-SDR','NSC-ACC'};
                app.EALplot.XTickLabel=labels;
                rotateXLabels(app.EALplot, 45);
            else
                xx{1}='Hazard is not defined';
                xx{2}='EAL is not computed';
                app.EALplot.Box='on';
                app.EALplot.XGrid='on';
                app.EALplot.YGrid='on';
                hold(app.EALplot,"on");
                app.EALplot.YLabel.String=str2;
                app.EALplot.XLim=[0 6];
                app.EALplot.XTick=[1 2 3 4 5];
                labels = {'Collapse','Demolition','SC','NSC-SDR','NSC-ACC'};
                app.EALplot.XTickLabel=labels;
                rotateXLabels(app.EALplot, 45);
                text(app.EALplot,0.15, 0.5,xx, 'fontname', 'Courier', 'fontsize',16, 'fontweight', 'bold');
                app.EALplot.FontName= 'Euclid';
                app.EALplot.FontSize=18;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function UnitsButtonGroupSelectionChanged(app, event)
            slider_Callback(app)                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Summary Loss Plots';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.LossIMplot = uiaxes(app.figure1);
            app.LossIMplot.XLim = [0 5];
            app.LossIMplot.YLim = [0 14];
            app.PieChart = uiaxes(app.figure1);
            app.PieChart.XLim = [0 6];
            app.PieChart.YLim = [0 1];
            app.PieChart.XTick = [1 2 3 4 5];
            app.PieChart.XTickLabel = {'Collapse'; 'Demolition'; 'SC'; 'NSC-SDR'; 'NSC-ACC'};
            app.LossesProfile = uiaxes(app.figure1);
            app.LossesProfile.XLim = [0 0.7];
            app.LossesProfile.YLim = [0.5 4.5];
            app.LossesProfile.YTick = [1 2 3 4];
            app.EALplot = uiaxes(app.figure1);
            app.EALplot.XLim = [0 6];
            app.uipanel1 = uipanel(app.figure1);
            app.slider = uislider(app.uipanel1);
            app.slider.Limits = [0.001 4.996];
            app.slider.MajorTicks = [];
            app.slider.ValueChangedFcn = createCallbackFcn(app, @slider_Callback, true);
            app.slider.MinorTicks = [];
            app.slider.Value = 0.5;
            app.SavePlotData = uibutton(app.uipanel1, 'push');
            app.SavePlotData.ButtonPushedFcn = createCallbackFcn(app, @SavePlotData_Callback, true);
            app.SavePlotData.Text = 'Save Plot Data';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = '[g]';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Target IM:';
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [0.0001 Inf];
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 1;
            app.checkbox1 = uicheckbox(app.figure1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'normalize';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SummaryPlot_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_SummaryPlot_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end