classdef Module_SummaryPlot_FEMAP58_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LossDistributionTab        matlab.ui.container.Tab
        checkbox2                  matlab.ui.control.CheckBox
        checkbox1                  matlab.ui.control.CheckBox
        CostDistribution           matlab.ui.control.UIAxes
        CostProfileType            matlab.ui.control.UIAxes
        CostBreakdown              matlab.ui.control.UIAxes
        CostvsRealization          matlab.ui.control.UIAxes
        RepairCostDsitributionTab  matlab.ui.container.Tab
        text92                     matlab.ui.control.Label
        text91                     matlab.ui.control.Label
        text90                     matlab.ui.control.Label
        text88                     matlab.ui.control.Label
        text89                     matlab.ui.control.Label
        CostProfileName            matlab.ui.control.UIAxes
        CostBar                    matlab.ui.control.UIAxes
        MAFEplot                   matlab.ui.control.UIAxes
        RepairTimeDistributionTab  matlab.ui.container.Tab
        checkbox3                  matlab.ui.control.CheckBox
        TimeDistribution           matlab.ui.control.UIAxes
        TimevsRealization          matlab.ui.control.UIAxes
        TimeProfile                matlab.ui.control.UIAxes
        TimeBar                    matlab.ui.control.UIAxes
        EDPDistributionTab         matlab.ui.container.Tab
        EditField                  matlab.ui.control.EditField
        Storypopup                 matlab.ui.control.DropDown
        text8_2                    matlab.ui.control.Label
        EDPpopup                   matlab.ui.control.DropDown
        text8                      matlab.ui.control.Label
        checkbox2_2                matlab.ui.control.CheckBox
        EDPDistribution            matlab.ui.control.UIAxes
        EDPvsRealization           matlab.ui.control.UIAxes
        StripeKnob                 matlab.ui.control.DiscreteKnob
        StripeLabel                matlab.ui.control.Label
        uipanel1                   matlab.ui.container.Panel
        Spinner                    matlab.ui.control.Spinner
        SavePlotDataButton         matlab.ui.control.Button
        text4                      matlab.ui.control.Label
        slider                     matlab.ui.control.Slider
    end

    
    methods (Access = private)
        
        function SavePlotData(app)
            global  ProjectName ProjectPath
            clc;
            load (strcat(ProjectPath,ProjectName))
            
            Stripe=app.StripeKnob.Value;
            R_target=round(app.slider.Value);
            app.edit2.Value=R_target;
            story=app.Storypopup.Value;
            
            if app.checkbox1.Value==1
                NormVal=Replacement_Cost/10^6/100;
            else
                NormVal=10^6;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% PRE CALCULATIONS
            [CollapseCount, DemolitionCount, PlacardCount, InjuryCount, CasualtyCount, Realization_Filtered, REPAIR_COST_Per_Ri_Filtered, STORY_REPAIR_COST_SC_Per_Ri, STORY_REPAIR_COST_NSC_SDR_Per_Ri, STORY_REPAIR_COST_NSC_ACC_Per_Ri, MCAC, lambda, AreaMCAC]=Get_Repair_Cost_Per_Ri(Stripe);
            
            if Component_Option==1
                [Realization_Filtered, REPAIR_TIME_Per_Ri_Filtered]=Get_Repair_Time_Per_Ri(TimeModel,DEAGG_DATA, CIDs, COMPDATA,nRealization, COLLAPSE_LOSSES_Per_Ri, DEMOLITION_LOSSES_Per_Ri);
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Repair Loss vs Realization
            PlotData.D1_X=Realization_Filtered;
            PlotData.D1_Y=REPAIR_COST_Per_Ri_Filtered/NormVal;
            Write2File('Figure 1 - Repair Cost vs Realization.txt',PlotData.D1_X,PlotData.D1_Y)
            
            %% Losses Breakdown at Specific Realization
            X = [COLLAPSE_LOSSES_Per_Ri(R_target,Stripe) DEMOLITION_LOSSES_Per_Ri(R_target,Stripe) sum(STORY_REPAIR_COST_SC_Per_Ri(R_target,:)) sum(STORY_REPAIR_COST_NSC_SDR_Per_Ri(R_target,:)) sum(STORY_REPAIR_COST_NSC_ACC_Per_Ri(R_target,:))];
            PlotData.D2_X=[1 2 3 4 5]';
            PlotData.D2_Y=X'/NormVal;
            Write2File('Figure 2 - Cost Breakdown.txt',PlotData.D2_X,PlotData.D2_Y)
            
            %% Losses Profile
            Elevation=1:N_Story+1;
            PlotData.D3_X=[STORY_REPAIR_COST_SC_Per_Ri(R_target,:); STORY_REPAIR_COST_NSC_SDR_Per_Ri(R_target,:); STORY_REPAIR_COST_NSC_ACC_Per_Ri(R_target,:)]'/NormVal;
            PlotData.D3_Y=Elevation';
            Write2File('Figure 3 - Loss Profile.txt',PlotData.D3_X,PlotData.D3_Y)
            
            %% Repair Cost Distribution
            if app.checkbox2.Value==1
                X=0.0:(max(REPAIR_COST_Per_Ri_Filtered)-min(REPAIR_COST_Per_Ri_Filtered))/50:max(REPAIR_COST_Per_Ri_Filtered)*1.1;
                Y=normcdf(X,mean(REPAIR_COST_Per_Ri_Filtered),std(REPAIR_COST_Per_Ri_Filtered));
                PlotData.D4_X=X'/NormVal;
                PlotData.D4_Y=Y;
                Write2File('Figure 4 - CDF Cost.txt',PlotData.D4_X,PlotData.D4_Y)
            else
                h=histogram(REPAIR_COST_Per_Ri_Filtered/NormVal,10);
                PlotData.D4_X=h.BinWidth/2:h.BinWidth/2:10*h.BinWidth/2;
                PlotData.D4_X=PlotData.D4_X';
                PlotData.D4_Y=h.Values';
                Write2File('Figure 4 - Histogram Cost.txt',PlotData.D4_X,PlotData.D4_Y)
            end
            
            %% Annual Rate of Exceeding the repair cost
            if HazardDataStatus ==1
                PlotData.D5_X=MCAC'/NormVal;
                PlotData.D5_Y=lambda';
                Write2File('Figure 5 - Annual Rate of Exceedance vs Cost.txt',PlotData.D5_X,PlotData.D5_Y)
            end
            
            %% EDP vs Realization
            if app.EDPpopup.Value==1
                EDPtype='SDR';
            else
                EDPtype='PFA';
            end
            if Response_Option==4
                evalc(['EDP=REALIZATIONS.',EDPtype,'_S',num2str(Stripe),'(:,story)']);
            else
                evalc(['EDP=REALIZATIONS.',EDPtype,'(:,story)']);
            end
            PlotData.D6_X=1:nRealization;
            PlotData.D6_X=PlotData.D6_X';
            PlotData.D6_Y=EDP;
            Write2File('Figure 6 - EDP vs Realizations.txt',PlotData.D6_X,PlotData.D6_Y)
            
            %% EDP PDF Distribution
            if app.checkbox2_2.Value==1
                X=0.0:(max(EDP)-min(EDP))/50:max(EDP)*1.1;
                Y=logncdf(X,log(median(EDP)),std(log(EDP)));
                PlotData.D7_X=X';
                PlotData.D7_Y=Y;
                Write2File('Figure 7 - CDF EDP.txt',PlotData.D7_X,PlotData.D7_Y)
            else
                h=histogram(EDP,10);
                PlotData.D7_X=h.BinWidth/2:h.BinWidth/2:10*h.BinWidth/2;
                PlotData.D7_X=PlotData.D7_X';
                PlotData.D7_Y=h.Values';
                Write2File('Figure 7 - Histogram EDP.txt',PlotData.D7_X,PlotData.D7_Y)
            end
            
            %% TIME PLOTS
            if Component_Option==1
                %% Repair Time vs Realization
                PlotData.D8_X=Realization_Filtered;
                PlotData.D8_Y=REPAIR_TIME_Per_Ri_Filtered;
                Write2File('Figure 8 - Repair Time vs Realization.txt',PlotData.D8_X,PlotData.D8_Y)
                
                %% Repair Time Distribution
                if app.checkbox3.Value==1
                    X=0.0:(max(REPAIR_TIME_Per_Ri_Filtered)-min(REPAIR_TIME_Per_Ri_Filtered))/50:max(REPAIR_TIME_Per_Ri_Filtered)*1.1;
                    Y=normcdf(X,mean(REPAIR_TIME_Per_Ri_Filtered),std(REPAIR_TIME_Per_Ri_Filtered));
                    PlotData.D9_X=X';
                    PlotData.D9_Y=Y;
                    Write2File('Figure 9 - CDF Time.txt',PlotData.D9_X,PlotData.D9_Y)
                else
                    h=histogram(REPAIR_TIME_Per_Ri_Filtered,10);
                    PlotData.D9_X=h.BinWidth/2:h.BinWidth/2:10*h.BinWidth/2;
                    PlotData.D9_X=PlotData.D9_X';
                    PlotData.D9_Y=h.Values';
                    Write2File('Figure 9 - Histogram Time.txt',PlotData.D9_X,PlotData.D9_Y)
                end
                
                %% Repair Time Breakdown
                [Cost_Loss_Per_Component, Clabel]=Get_Repair_Cost_Breakdown_by_Component(Stripe,R_target,COMPDATA,DEAGG_DATA,CIDs);
                PlotData.D10_X=Clabel;
                PlotData.D10_Y=Cost_Loss_Per_Component;
                Write2File('Figure 10 - Repair Time Breakdown by Component.txt',PlotData.D10_X,PlotData.D10_Y)
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SummaryPlot_OpeningFcn(app)
            global ProjectName ProjectPath
            clc;
            load (strcat(ProjectPath,ProjectName))
            
            % Clear Figures
            cla(app.CostvsRealization);
            cla(app.CostBreakdown);
            cla(app.CostProfileType);
            cla(app.CostDistribution);
            
            app.slider.Limits=[1 nRealization];
            xx3=0:min(100,round(nRealization/10)):nRealization; xx3(1,1)=1;
            app.slider.MajorTicks=xx3;
            app.slider.Value=ceil(nRealization/2);
            app.Spinner.Value=ceil(nRealization/2);
            app.Spinner.Limits=[1 nRealization];
            
            if Response_Option==4
                app.StripeKnob.Value='1';
                app.StripeKnob.ItemsData=1:nStripe;
                app.StripeKnob.Enable='on';
            else
                app.StripeKnob.Value='1';
                app.StripeKnob.ItemsData=1:3;
                app.StripeKnob.Enable='off';
            end
            
            S{1}='SDR';
            S{2}='PFA';
            if Data_Status.RDR==1; S{end+1}='RDR'; end
            if Data_Status.VRD==1; S{end+1}='VRD'; end
            app.EDPpopup.Items=S;
            app.EDPpopup.ItemsData=1:length(S);
            app.EDPpopup.Value=1;
            EDPpopupValueChanged(app)
            
        end

        % Callback function
        function SaveFigButton_Callback(app, event)
            global MainDirectory ProjectPath
            clc;
            
            cd (ProjectPath)
            export_fig 'Loss_SummaryPlot.png' -nocrop
            cd (MainDirectory)
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            slider_Callback(app)
        end

        % Callback function
        function edit2_Callback(app, event)
            R_targetX=app.Spinner.Value;
            app.slider.Value=R_targetX;
            slider_Callback(app)
        end

        % Value changed function: slider
        function slider_Callback(app, event)
            global ProjectName ProjectPath
            load (strcat(ProjectPath,ProjectName));
            
            Stripe   = app.StripeKnob.Value;
            R_target = round(app.slider.Value);
            app.Spinner.Value = R_target;
            
            if app.checkbox1.Value==1
                NormVal=Replacement_Cost/100;
                str='Cost [% \itCost\rm]';
                str3='Repair Cost [% \itCost\rm]';
                str2='EAL [% \itCost\rm/year]';
            else
                NormVal=10^6;
                str='Cost [M$]';
                str3='Repair Cost [M$]';
                str2='EAL [M$/year]';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% PRE CALCULATIONS %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            [CIDs, Elevation, CollapseCount, DemolitionCount, PlacardCount, InjuryCount, CasualtyCount, Realization_Filtered, REPAIR_COST_Per_Ri, REPAIR_COST_Per_Ri_Filtered, STORY_REPAIR_COST_SC_Per_Ri, STORY_REPAIR_COST_NSC_SDR_Per_Ri, STORY_REPAIR_COST_NSC_ACC_Per_Ri, MCAC, lambda, AreaMCAC]=Get_Repair_Cost_Per_Ri(Stripe);
            
            if Placardstatus==1 && Component_Option==1
                app.text90.Text=['Unsafe Placard probability = ',num2str(round(PlacardCount/size(DEAGG_DATA,1)/nStripe*100*10)/10),'%'];
            else
                app.text90.Text=['Unsafe Placard probability = --'];
            end
            
            if Casualtystatus==1 && Component_Option==1
                app.text91.Text=['Number of Injuries = ',num2str(round(InjuryCount/size(DEAGG_DATA,1)/nStripe*100))];
                app.text92.Text=['Number of Causalties = ',num2str(round(CasualtyCount/size(DEAGG_DATA,1)/nStripe*100))];
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            cla(app.CostvsRealization);
            cla(app.CostBreakdown);
            cla(app.CostProfileType);
            cla(app.CostDistribution);
            
            app.text88.Text=['Demolition probability = ',num2str(DemolitionCount/nRealization*100),'%'];
            app.text89.Text=['Collapse probability = ',num2str(CollapseCount/nRealization*100),'%'];
            
            %% Plot Repair Loss vs Realization
            plot(app.CostvsRealization,Realization_Filtered,REPAIR_COST_Per_Ri_Filtered/NormVal,'ok','MarkerFaceColor',[0.9 0.9 0.9],'MarkerEdgeColor',[0.5 0.5 0.5],'MarkerSize',4);
            app.CostvsRealization.Box='on';
            app.CostvsRealization.XGrid='on';
            app.CostvsRealization.YGrid='on';
            hold(app.CostvsRealization,"on");
            plot(app.CostvsRealization,[0 nRealization],[mean(REPAIR_COST_Per_Ri_Filtered)/NormVal   mean(REPAIR_COST_Per_Ri_Filtered)/NormVal],'--r','linewidth',2);
            plot(app.CostvsRealization,[0 nRealization],[median(REPAIR_COST_Per_Ri_Filtered)/NormVal median(REPAIR_COST_Per_Ri_Filtered)/NormVal],'--b','linewidth',2);
            scatter(app.CostvsRealization,R_target,REPAIR_COST_Per_Ri(R_target,1)/NormVal,'ok','MarkerFaceColor','r');
            app.CostvsRealization.XLabel.String= 'Realization Number';
            app.CostvsRealization.YLabel.String=str3;
            app.CostvsRealization.XLim=[1 nRealization];
            Max=ceil(1.1*max(REPAIR_COST_Per_Ri_Filtered/NormVal));
            app.CostvsRealization.YLim=[0.0 max(Max,0.01)];
            app.CostvsRealization.FontName='Euclid';
            app.CostvsRealization.FontSize=18;
            legend (app.CostvsRealization,'Realization data','mean','median');
            app.CostvsRealization.Legend.FontName='Euclid';
            app.CostvsRealization.Legend.FontSize=10;
            app.CostvsRealization.Legend.Location='NorthWest';
            
            
            %% Plot Losses Breakdown at Specific Realization
            cla(app.CostBreakdown);
            X = [COLLAPSE_LOSSES_Per_Ri(R_target,Stripe) DEMOLITION_LOSSES_Per_Ri(R_target,Stripe) sum(STORY_REPAIR_COST_SC_Per_Ri(R_target,:)) sum(STORY_REPAIR_COST_NSC_SDR_Per_Ri(R_target,:)) sum(STORY_REPAIR_COST_NSC_ACC_Per_Ri(R_target,:))];
            bar(app.CostBreakdown,1,X(1,1)/NormVal,'barwidth',1,'facecolor','k');
            app.CostBreakdown.Box='on';
            app.CostBreakdown.XGrid='on';
            app.CostBreakdown.YGrid='on';
            hold(app.CostBreakdown,"on");
            bar(app.CostBreakdown,2,X(1,2)/NormVal,'barwidth',1,'facecolor','b');
            bar(app.CostBreakdown,3,X(1,3)/NormVal,'barwidth',1,'facecolor','r');
            bar(app.CostBreakdown,4,X(1,4)/NormVal,'barwidth',1,'facecolor','g');
            bar(app.CostBreakdown,5,X(1,5)/NormVal,'barwidth',1,'facecolor',[1 0.7 0]);
            app.CostBreakdown.YLabel.String=str;
            app.CostBreakdown.YLim=[0.0 max(ceil(1.1*max([max(X);0.001]))/NormVal,0.01)];
            app.CostBreakdown.XLim=[0 6];
            app.CostBreakdown.XTick=[1 2 3 4 5];
            app.CostBreakdown.FontName='Euclid';
            app.CostBreakdown.FontSize=18;
            labels = {'Collapse','Demolition','SC','NSC-SDR','NSC-ACC'};
            app.CostBreakdown.XTickLabel =labels;
            rotateXLabels(app.CostBreakdown, 45);
            if app.checkbox1.Value==1
                title(app.CostBreakdown,[num2str(round(sum(X)/NormVal*100)/100),'% Total Loss at Stripe ',num2str(Stripe),' Ri #', num2str(R_target)], 'fontname', 'Courier', 'fontsize',16);
            else
                title(app.CostBreakdown,[num2str(round(sum(X)/NormVal*100)/100),'M$ Total Loss at Stripe ',num2str(Stripe),' Ri #', num2str(R_target)], 'fontname', 'Courier', 'fontsize',16);
            end
            %% Plot Losses Profile
            cla(app.CostProfileType);
            plot(app.CostProfileType,STORY_REPAIR_COST_SC_Per_Ri(R_target,:)/NormVal,Elevation,'-or','linewidth',2);
            app.CostProfileType.Box='on';
            app.CostProfileType.XGrid='on';
            app.CostProfileType.YGrid='on';
            hold(app.CostProfileType,"on");
            plot(app.CostProfileType,STORY_REPAIR_COST_NSC_SDR_Per_Ri(R_target,:)/NormVal,Elevation,'--*g','linewidth',2);
            plot(app.CostProfileType,STORY_REPAIR_COST_NSC_ACC_Per_Ri(R_target,:)/NormVal,Elevation,'--*','linewidth',2,'Color',[1.0 0.7 0.0]);
            app.CostProfileType.XLabel.String=str3;
            app.CostProfileType.YLabel.String='Story/Floor';
            Max1=max(max(STORY_REPAIR_COST_SC_Per_Ri(R_target,:)));
            Max2=max(max(STORY_REPAIR_COST_NSC_SDR_Per_Ri(R_target,:)));
            Max3=max(max(STORY_REPAIR_COST_NSC_ACC_Per_Ri(R_target,:)));
            MaxLosses=ceil(1.5*max([Max1;Max2;Max3])*15)/10;
            app.CostProfileType.XLim=[0.0 max(0.01,MaxLosses/NormVal)];
            app.CostProfileType.YLim=[1 N_Story+1];
            app.CostProfileType.YTick=Elevation;
            app.CostProfileType.FontName='Euclid';
            app.CostProfileType.FontSize=18;
            legend (app.CostProfileType,'SC','NSC-SDR','NSC-ACC');
            app.CostProfileType.Legend.FontName= 'Euclid';
            app.CostProfileType.Legend.FontSize=14;
            app.CostProfileType.Legend.Location='northeast';
            
            
            %% Plot Repair Cost Distribution
            cla(app.CostDistribution);
            app.CostDistribution.Box='on';
            app.CostDistribution.XGrid='on';
            app.CostDistribution.YGrid='on';
            app.CostDistribution.XLabel.String=str3;
            if app.checkbox2.Value==1
                X=0.0:(max(REPAIR_COST_Per_Ri_Filtered)-min(REPAIR_COST_Per_Ri_Filtered))/50:max(REPAIR_COST_Per_Ri_Filtered)*1.1;
                Y=normcdf(X,mean(REPAIR_COST_Per_Ri_Filtered),std(REPAIR_COST_Per_Ri_Filtered));
                plot(app.CostDistribution,X/NormVal,Y,'-k','linewidth',2);
                scatter(app.CostDistribution,mean(REPAIR_COST_Per_Ri_Filtered)/NormVal,0.5,'ok','MarkerFaceColor','r');
                xx{1}=['mean=', num2str(round(mean(REPAIR_COST_Per_Ri_Filtered)/NormVal*100)/100)];
                xx{2}=['Std=',num2str(round(std(REPAIR_COST_Per_Ri_Filtered)/NormVal*100)/100)];
                text(app.CostDistribution,1.1*mean(REPAIR_COST_Per_Ri_Filtered)/NormVal,0.5,xx, 'fontname', 'Courier', 'fontsize',14);
                app.CostDistribution.YLabel.String='P(cost \leq Repair Cost)';
                app.CostDistribution.YLim=[0 1];
                app.CostDistribution.XLim=[0 max(X/NormVal)];
            else
                X=REPAIR_COST_Per_Ri_Filtered/NormVal;
                h=histogram(app.CostDistribution,X,10);%,'facecolor',[0.65 0.65 0.65]);
                app.CostDistribution.YLim=[0.0 max(1.1*max(h.Values),0.01)];
                app.CostDistribution.YLabel.String='Frequency';
                plot(app.CostDistribution,[mean(X) mean(X)],[0.0 1.1*max(h.Values)],'--r','linewidth',2);
                app.CostDistribution.XLim=[0 max(1.1*max(X),0.001)];
            end
            app.CostDistribution.FontName= 'Euclid';
            app.CostDistribution.FontSize=18;
            
            if HazardDataStatus ==1
                %% Plot Annual Rate of Exceeding the repair cost
                cla(app.MAFEplot);
                plot(app.MAFEplot,MCAC/NormVal,lambda,'-k','linewidth',2);
                app.MAFEplot.YLim=[0.0 max(max(lambda),0.00001)];
                app.MAFEplot.XLim=[0.0 max(max(MCAC/NormVal),0.001)];
                app.MAFEplot.YLabel.String='\lambda(cost \geq Cost)';
                app.MAFEplot.XLabel.String=str3;
                xx2{1}=['Mean cumulative annual '];
                xx2{2}=['repair cost=',num2str(round(AreaMCAC)),'$'];
                text(app.MAFEplot,0.3*max(MCAC)/NormVal,0.8*max(lambda),xx2, 'fontname', 'Courier', 'fontsize',14, 'fontweight','bold');
                MCAC=[0;MCAC'];lambda=[0;lambda'];
                pgon = polyshape(MCAC/NormVal,lambda);
                plot(app.MAFEplot,pgon,'FaceColor','y')
                app.MAFEplot.FontName='Euclid';
                app.MAFEplot.FontSize=18;
            else
                cla(app.MAFEplot);
                xx{1}='Hazard is not defined. Annual';
                xx{2}='frequncy of exceedence is not computed';
                app.MAFEplot.FontName= 'Euclid';
                app.MAFEplot.FontSize=14;
                app.MAFEplot.Box='on';
                app.MAFEplot.XGrid='on';
                app.MAFEplot.YGrid='on';
                hold(app.MAFEplot,"on");
                text(app.MAFEplot,0.05, 0.5,xx, 'fontname', 'Courier', 'fontsize',14,'fontweight','bold');
            end
            
            
            %% Plot EDP vs Realization
            cla(app.EDPvsRealization);
            EDPtype=app.EDPpopup.Items{app.EDPpopup.Value};
            if strcmp(EDPtype,'SDR')
                labelx='SDR [rad]';
            elseif strcmp(EDPtype,'RDR')
                labelx='RDR [rad]';
            elseif strcmp(EDPtype,'VRD') && Units==1
                labelx='VRD [in]';
            elseif strcmp(EDPtype,'VRD') && Units==2
                labelx='VRD [mm]';
            elseif strcmp(EDPtype,'PFA') || strcmp(EDPtype,'PGA')
                labelx='PFA [g]';
            else
                labelx='Rotation [rad]';
            end
            story=app.Storypopup.Value;
            if Response_Option==4
                evalc(['EDP=REALIZATIONS.',EDPtype,'_S',num2str(Stripe),'(:,story)']);
            else
                evalc(['EDP=REALIZATIONS.',EDPtype,'(:,story)']);
            end
            plot(app.EDPvsRealization,1:nRealization,EDP,'ok','MarkerFaceColor',[0.9 0.9 0.9],'MarkerEdgeColor',[0.5 0.5 0.5],'MarkerSize',4);
            app.EDPvsRealization.Box='on';
            app.EDPvsRealization.XGrid='on';
            app.EDPvsRealization.YGrid='on';
            hold(app.EDPvsRealization,"on");
            plot(app.EDPvsRealization,[0 nRealization],[exp(mean(log(EDP))) exp(mean(log(EDP)))],'--r','linewidth',2);
            scatter(app.EDPvsRealization,R_target,EDP(R_target,1),'ok','MarkerFaceColor','r');
            app.EDPvsRealization.XLabel.String= 'Realization Number';
            app.EDPvsRealization.YLabel.String=labelx;
            app.EDPvsRealization.XLim=[1 nRealization];
            Max=ceil(1.1*max(EDP)*1000)/1000;
            app.EDPvsRealization.YLim=[0 max(Max,0.01)];
            app.EDPvsRealization.FontName='Euclid';
            app.EDPvsRealization.FontSize=18;
            legend (app.EDPvsRealization,'Realization data','\it\mu\rm_{ln(EDP)}');
            app.EDPvsRealization.Legend.FontName='Euclid';
            app.EDPvsRealization.Legend.FontSize=10;
            app.EDPvsRealization.Legend.Location='NorthWest';
            
            %% Plot EDP PDF Distribution
            cla(app.EDPDistribution);
            app.EDPDistribution.Box='on';
            app.EDPDistribution.XGrid='on';
            app.EDPDistribution.YGrid='on';
            app.EDPDistribution.XLabel.String=labelx;
            if app.checkbox2_2.Value==1
                X=0.0:(max(EDP)-min(EDP))/50:max(EDP)*1.1;
                Y=logncdf(X,log(median(EDP)),std(log(EDP)));
                plot(app.EDPDistribution,X,Y,'-k','linewidth',2);
                scatter(app.EDPDistribution,median(EDP),0.5,'ok','MarkerFaceColor','r');
                xx{1}=['median=',num2str(round(median(EDP)*1000)/1000)];
                xx{2}=['beta=',num2str(round(std(log(EDP)*1000))/1000)];
                text(app.EDPDistribution,1.1*mean(EDP),0.5,xx, 'fontname', 'Courier', 'fontsize',14);
                app.EDPDistribution.YLabel.String='P(cost \leq Cost)';
                app.EDPDistribution.YLim=[0 1];
            else
                h=histogram(app.EDPDistribution,EDP,10);%,'barwidth',1,'facecolor',[0.65 0.65 0.65]);
                app.EDPDistribution.YLim=[0.0 1.1*max(h.Values)];
                app.EDPDistribution.YLabel.String='No. of Occurrences';
                plot(app.EDPDistribution,[exp(mean(log(EDP))) exp(mean(log(EDP)))],[0.0 1.1*max(h.Values)],'--r','linewidth',2);
            end
            app.EDPDistribution.FontName= 'Euclid';
            app.EDPDistribution.FontSize=18;
            
            if Component_Option==1
                
                [Realization_Filtered, REPAIR_TIME_Per_Ri, REPAIR_TIME_Per_Ri_Filtered]=Get_Repair_Time_Per_Ri(Stripe, N_Story, TimeModel,DEAGG_DATA, CIDs, COMPDATA,nRealization, COLLAPSE_LOSSES_Per_Ri, DEMOLITION_LOSSES_Per_Ri);
                
                % Time Profile Plot
                cla(app.TimevsRealization);
                clo(app.TimevsRealization);
                app.TimevsRealization.Box='on';
                app.TimevsRealization.XGrid='on';
                app.TimevsRealization.YGrid='on';
                hold(app.TimevsRealization,"on");
                plot(app.TimevsRealization,Realization_Filtered,REPAIR_TIME_Per_Ri_Filtered,'ok','MarkerFaceColor',[0.9 0.9 0.9],'MarkerEdgeColor',[0.5 0.5 0.5],'MarkerSize',4);
                plot(app.TimevsRealization,[0 nRealization],[mean(REPAIR_TIME_Per_Ri_Filtered) mean(REPAIR_TIME_Per_Ri_Filtered)],'--r','linewidth',2);
                plot(app.TimevsRealization,[0 nRealization],[median(REPAIR_TIME_Per_Ri_Filtered) median(REPAIR_TIME_Per_Ri_Filtered)],'--b','linewidth',2);
                scatter(app.TimevsRealization,R_target,REPAIR_TIME_Per_Ri(R_target,1),'ok','MarkerFaceColor','r');
                app.TimevsRealization.XLabel.String= 'Realization Number';
                app.TimevsRealization.YLabel.String='Repair Time [days]';
                app.TimevsRealization.XLim=[1 nRealization];
                Max=ceil(1.1*max(REPAIR_TIME_Per_Ri_Filtered));
                app.TimevsRealization.YLim=[0.0 max(ceil(Max),0.01)];
                app.TimevsRealization.FontName='Euclid';
                app.TimevsRealization.FontSize=18;
                legend (app.TimevsRealization,'Realization data','mean','median');
                app.TimevsRealization.Legend.FontName='Euclid';
                app.TimevsRealization.Legend.FontSize=10;
                app.TimevsRealization.Legend.Location='NorthWest';
                
                %% Plot Repair Time Distribution
                cla(app.TimeDistribution);
                app.TimeDistribution.Box='on';
                app.TimeDistribution.XGrid='on';
                app.TimeDistribution.YGrid='on';
                app.TimeDistribution.XLabel.String='Repair Time [days]';
                if app.checkbox3.Value==1
                    X=0.0:(max(REPAIR_TIME_Per_Ri_Filtered)-min(REPAIR_TIME_Per_Ri_Filtered))/50:max(REPAIR_TIME_Per_Ri_Filtered)*1.1;
                    Y=normcdf(X,mean(REPAIR_TIME_Per_Ri_Filtered),std(REPAIR_TIME_Per_Ri_Filtered));
                    plot(app.TimeDistribution,X,Y,'-k','linewidth',2);
                    scatter(app.TimeDistribution,mean(REPAIR_TIME_Per_Ri_Filtered),0.5,'ok','MarkerFaceColor','r');
                    xx1{1}=['mean=', num2str(round(mean(REPAIR_TIME_Per_Ri_Filtered)*10)/10)];
                    xx1{2}=['Std=',num2str(round(std(REPAIR_TIME_Per_Ri_Filtered)*10)/10)];
                    text(app.TimeDistribution,1.1*mean(REPAIR_TIME_Per_Ri_Filtered),0.5,xx1, 'fontname', 'Courier', 'fontsize',14);
                    app.TimeDistribution.YLabel.String='P(time \leq Repair Time)';
                    app.TimeDistribution.YLim=[0 1];
                    app.TimeDistribution.XLim=[0 max(0.01,1.1*max(X))];
                else
                    X=REPAIR_TIME_Per_Ri_Filtered;
                    h=histogram(app.TimeDistribution,X,10);%,'facecolor',[0.65 0.65 0.65]);
                    app.TimeDistribution.YLim=[0.0 max(1.1*max(h.Values),0.01)];
                    app.TimeDistribution.YLabel.String='Frequency';
                    plot(app.TimeDistribution,[mean(X) mean(X)],[0.0 1.1*max(h.Values)],'--r','linewidth',2);
                    app.TimeDistribution.XLim=[0 max(0.01,1.1*max(X))];
                end
                app.TimeDistribution.FontName= 'Euclid';
                app.TimeDistribution.FontSize=18;
                
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
             if COLLAPSE_LOSSES_Per_Ri(R_target,Stripe)~=0 || DEMOLITION_LOSSES_Per_Ri(R_target,Stripe)~=0 || Component_Option~=1 || REPAIR_COST_Per_Ri(R_target,1)==0
                cla(app.CostBar); text(app.CostBar,0.3,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                cla(app.CostProfileName); text(app.CostProfileName,0.2,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                app.CostBar.XLim=[0.0 0.9]; app.CostBar.YLim=[0.0 1];
                app.CostProfileName.XLim=[0.0 1]; app.CostProfileName.YLim=[0.0 1];
                cla(app.TimeBar); text(app.TimeBar,0.3,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                cla(app.TimeProfile);  text(app.TimeProfile,0.2,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                cla(app.TimeDistribution); text(app.TimeDistribution,0.2,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                cla(app.TimevsRealization); text(app.TimevsRealization,0.2,0.5,'Plot Not Applicable', 'fontname', 'Courier', 'fontsize',16);
                app.TimeBar.XLim=[0.0 0.9]; app.TimeBar.YLim=[0 1];
                app.TimeDistribution.XLim=[0 1]; app.TimeDistribution.YLim=[0 1];
                app.TimevsRealization.XLim=[0 1]; app.TimevsRealization.YLim=[0 1];
                app.TimeProfile.XLim=[0 1]; app.TimeProfile.YLim=[0 1];
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [Cost_Loss_Per_Component, Clabel]=Get_Repair_Cost_Breakdown_by_Component(Stripe,R_target,COMPDATA,DEAGG_DATA,CIDs);
            %% Plot Cost Breakdown by Component
            cla(app.CostBar);
            clo(app.CostBar);
            app.CostBar.Box='on';
            app.CostBar.XGrid='off';
            app.CostBar.YGrid='on';
            %[Sorted_DEAGG_STORY_LOSS_AllStory_Norm,Indx] = sort(DEAGG_STORY_LOSS_AllStory_Norm,'descend');
            bar(app.CostBar,Cost_Loss_Per_Component(:,1)/NormVal);
            app.CostBar.YLim=[0.0 max(0.0001,(max(Cost_Loss_Per_Component)/NormVal*1.2))];
            app.CostBar.YLabel.String=str3;
            app.CostBar.XLim=[0 size(Cost_Loss_Per_Component,1)+1];
            app.CostBar.XTick=1:size(Cost_Loss_Per_Component,1);
            app.CostBar.XTickLabel=Clabel;
            app.CostBar.XTickLabelRotation=45;
            app.CostBar.FontName= 'Euclid';
            app.CostBar.FontSize=18;
            
            [Cost_Loss_Per_Component_Per_Story, Clabel]=Get_Repair_Cost_Profile_by_Component(Stripe,R_target,COMPDATA,DEAGG_DATA,N_Story,CIDs);
            %% Plot Cost Profile by Component
            cla(app.CostProfileName);
            clo(app.CostProfileName);
            app.CostProfileName.Box='on';
            app.CostProfileName.XGrid='on';
            app.CostProfileName.YGrid='on';
            hold(app.CostProfileName,"on");
            for i=1:size(Clabel,1)  % Loop over all SC
                plot(app.CostProfileName,Cost_Loss_Per_Component_Per_Story(i,:)/NormVal,Elevation,'-o','linewidth',1.5);
            end
            app.CostProfileName.XLim=[0.0 max(0.0001,(max(max(Cost_Loss_Per_Component_Per_Story))/NormVal*1.2))];
            app.CostProfileName.YLim=[1 N_Story+1];
            app.CostProfileName.YLabel.String='Story/Floor';
            app.CostProfileName.XLabel.String=str3;
            app.CostProfileName.YTick=Elevation;
            app.CostProfileName.FontName= 'Euclid';
            app.CostProfileName.FontSize=18;
            legend (app.CostProfileName,Clabel);
            app.CostProfileName.Legend.FontName='Euclid';
            app.CostProfileName.Legend.FontSize=10;
            app.CostProfileName.Legend.Location='northeast';
            
            if Component_Option~=1
                return
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Repair Time Plots
            [Time_Loss_Per_Component,Time_Loss_Per_Component_Per_Story]=Get_Repair_Time_Per_Component(Stripe,TimeModel,R_target,COMPDATA,DEAGG_DATA,N_Story,CIDs);
            
            % Bar Plot
            cla(app.TimeBar);
            clo(app.TimeBar);
            app.TimeBar.Box='on';
            app.TimeBar.XGrid='off';
            app.TimeBar.YGrid='on';
            hold(app.TimeBar,"on");
            MaxTime=max(Time_Loss_Per_Component);
            bar(app.TimeBar,Time_Loss_Per_Component(:,1));
            app.TimeBar.YLim=[0.0  max(0.01,MaxTime*1.2)];
            app.TimeBar.YLabel.String='Repair Time [days]';
            app.TimeBar.XTick=1:size(Time_Loss_Per_Component_Per_Story,1);
            app.TimeBar.XLim=[0 size(Time_Loss_Per_Component_Per_Story,1)+1];
            app.TimeBar.XTickLabel=Clabel;
            app.TimeBar.XTickLabelRotation=45;
            app.TimeBar.FontName= 'Euclid';
            app.TimeBar.FontSize=18;
            
            % Time Profile Plot
            cla(app.TimeProfile);
            clo(app.TimeProfile);
            app.TimeProfile.Box='on';
            app.TimeProfile.XGrid='on';
            app.TimeProfile.YGrid='on';
            hold(app.TimeProfile,"on");
            MaxTime=max(max(Time_Loss_Per_Component_Per_Story));
            for i=1:size(Clabel,1)
                plot(app.TimeProfile,Time_Loss_Per_Component_Per_Story(i,:),Elevation,'-o','linewidth',1.5);
            end
            app.TimeProfile.XLim=[0.0 max(MaxTime*1.1,0.01)];
            app.TimeProfile.YLim=[1 N_Story+1];
            app.TimeProfile.YLabel.String='Story/Floor';
            app.TimeProfile.XLabel.String='Repair Time [days]';
            app.TimeProfile.YTick=Elevation;
            app.TimeProfile.FontName= 'Euclid';
            app.TimeProfile.FontSize=18;
            legend (app.TimeProfile,Clabel);
            app.TimeProfile.Legend.FontName='Euclid';
            app.TimeProfile.Legend.FontSize=10;
            app.TimeProfile.Legend.Location='northeast';
            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end

        % Value changed function: checkbox2
        function checkbox2ValueChanged(app, event)
            slider_Callback(app)
        end

        % Callback function
        function UnitsButtonGroupSelectionChanged(app, event)
            slider_Callback(app)
        end

        % Value changed function: StripeKnob
        function StripeKnobValueChanged(app, event)
            slider_Callback(app)
        end

        % Value changed function: checkbox2_2
        function checkbox2_2ValueChanged(app, event)
            slider_Callback(app)
        end

        % Value changed function: EDPpopup
        function EDPpopupValueChanged(app, event)
            global  ProjectName ProjectPath
            clc;
            load (strcat(ProjectPath,ProjectName),'N_Story')
            
            EDPtype=app.EDPpopup.Items{app.EDPpopup.Value};
            
            if strcmp(EDPtype,'SDR') || strcmp(EDPtype,'DWD') || strcmp(EDPtype,'RD') || strcmp(EDPtype,'COL ROT')
                n=N_Story;
            elseif strcmp(EDPtype,'RDR') || strcmp(EDPtype,'VRD')
                n=1;
            elseif strcmp(EDPtype,'PFA')
                n=N_Story+1;
            end
            
            if n>1
                S{1}='1 st';
                for i=2:n
                    if i==2; S{i}=[num2str(i),'st']; end
                    if i==3; S{i}=[num2str(i),'nd']; end
                    if i> 3; S{i}=[num2str(i),'th']; end
                end
            else
                S{1}='--';
            end
            app.Storypopup.Items=S;
            app.Storypopup.ItemsData=1:n;
            app.Storypopup.Value=1;
            
            slider_Callback(app)
        end

        % Value changed function: Storypopup
        function StorypopupValueChanged(app, event)
            slider_Callback(app)
        end

        % Button pushed function: SavePlotDataButton
        function SavePlotDataButtonPushed(app, event)
            SavePlotData(app)
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            X=app.Spinner.Value;
            app.slider.Value=X;
            slider_Callback(app)
        end

        % Value changed function: checkbox3
        function checkbox3ValueChanged(app, event)
            slider_Callback(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Summary Loss Plots';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.slider = uislider(app.uipanel1);
            app.slider.Limits = [1 1000000];
            app.slider.MajorTicks = [];
            app.slider.ValueChangedFcn = createCallbackFcn(app, @slider_Callback, true);
            app.slider.MinorTicks = [];
            app.slider.Value = 1;
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Realization:';
            app.SavePlotDataButton = uibutton(app.uipanel1, 'push');
            app.SavePlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotDataButtonPushed, true);
            app.SavePlotDataButton.Text = 'Save Plot Data';
            app.Spinner = uispinner(app.uipanel1);
            app.Spinner.Limits = [1 10000];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.StripeLabel = uilabel(app.figure1);
            app.StripeLabel.Text = 'Stripe:';
            app.StripeKnob = uiknob(app.figure1, 'discrete');
            app.StripeKnob.Items = {'1', '2', '3'};
            app.StripeKnob.ItemsData = {'1', '2', '3'};
            app.StripeKnob.ValueChangedFcn = createCallbackFcn(app, @StripeKnobValueChanged, true);
            app.StripeKnob.Value = '1';
            app.TabGroup = uitabgroup(app.figure1);
            app.LossDistributionTab = uitab(app.TabGroup);
            app.LossDistributionTab.Title = 'Loss Distribution';
            app.CostvsRealization = uiaxes(app.LossDistributionTab);
            app.CostvsRealization.XLim = [0 5];
            app.CostvsRealization.YLim = [0 14];
            app.CostBreakdown = uiaxes(app.LossDistributionTab);
            app.CostBreakdown.XLim = [0 6];
            app.CostBreakdown.YLim = [0 1];
            app.CostProfileType = uiaxes(app.LossDistributionTab);
            app.CostProfileType.XLim = [0 0.7];
            app.CostProfileType.YLim = [0.5 4.5];
            app.CostProfileType.YTick = [1 2 3 4];
            app.CostDistribution = uiaxes(app.LossDistributionTab);
            app.checkbox1 = uicheckbox(app.LossDistributionTab);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'normalize';
            app.checkbox2 = uicheckbox(app.LossDistributionTab);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2ValueChanged, true);
            app.checkbox2.Text = 'CDF';
            app.RepairCostDsitributionTab = uitab(app.TabGroup);
            app.RepairCostDsitributionTab.Title = 'Repair Cost Dsitribution';
            app.MAFEplot = uiaxes(app.RepairCostDsitributionTab);
            app.CostBar = uiaxes(app.RepairCostDsitributionTab);
            app.CostProfileName = uiaxes(app.RepairCostDsitributionTab);
            app.text89 = uilabel(app.RepairCostDsitributionTab);
            app.text89.Text = 'Collapse probability =';
            app.text88 = uilabel(app.RepairCostDsitributionTab);
            app.text88.Text = 'Demolition probability =';
            app.text90 = uilabel(app.RepairCostDsitributionTab);
            app.text90.Text = 'Unsafe Placard probability =';
            app.text91 = uilabel(app.RepairCostDsitributionTab);
            app.text91.Text = 'Number of Injuries =';
            app.text92 = uilabel(app.RepairCostDsitributionTab);
            app.text92.Text = 'Number of Casualties =';
            app.RepairTimeDistributionTab = uitab(app.TabGroup);
            app.RepairTimeDistributionTab.Title = 'Repair Time Distribution';
            app.TimeBar = uiaxes(app.RepairTimeDistributionTab);
            app.TimeProfile = uiaxes(app.RepairTimeDistributionTab);
            app.TimevsRealization = uiaxes(app.RepairTimeDistributionTab);
            app.TimevsRealization.XLim = [0 5];
            app.TimevsRealization.YLim = [0 14];
            app.TimeDistribution = uiaxes(app.RepairTimeDistributionTab);
            app.checkbox3 = uicheckbox(app.RepairTimeDistributionTab);
            app.checkbox3.ValueChangedFcn = createCallbackFcn(app, @checkbox3ValueChanged, true);
            app.checkbox3.Text = 'CDF';
            app.EDPDistributionTab = uitab(app.TabGroup);
            app.EDPDistributionTab.Title = 'EDP Distribution';
            app.EDPvsRealization = uiaxes(app.EDPDistributionTab);
            app.EDPvsRealization.XLim = [0 5];
            app.EDPvsRealization.YLim = [0 14];
            app.EDPDistribution = uiaxes(app.EDPDistributionTab);
            app.checkbox2_2 = uicheckbox(app.EDPDistributionTab);
            app.checkbox2_2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_2ValueChanged, true);
            app.checkbox2_2.Text = 'CDF';
            app.text8 = uilabel(app.EDPDistributionTab);
            app.text8.Text = 'EDP';
            app.EDPpopup = uidropdown(app.EDPDistributionTab);
            app.EDPpopup.Items = {'SDR', 'PFA', 'RDR', 'LINK ROT', 'BEAM ROT', 'COL ROT', 'DWD', 'RD'};
            app.EDPpopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.EDPpopup.ValueChangedFcn = createCallbackFcn(app, @EDPpopupValueChanged, true);
            app.EDPpopup.Value = '1';
            app.text8_2 = uilabel(app.EDPDistributionTab);
            app.text8_2.Text = 'Story/Floor';
            app.Storypopup = uidropdown(app.EDPDistributionTab);
            app.Storypopup.Items = {'---', 'SDR', 'RDR', 'PFA', 'PGA', 'LINK ROT', 'BEAM ROT', 'COL ROT', 'DWD', 'RD'};
            app.Storypopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.Storypopup.ValueChangedFcn = createCallbackFcn(app, @StorypopupValueChanged, true);
            app.Storypopup.Value = '1';
            app.EditField = uieditfield(app.EDPDistributionTab, 'text');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SummaryPlot_FEMAP58_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_SummaryPlot_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end