classdef Module_Time_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel2      matlab.ui.container.Panel
        uitable1      matlab.ui.control.Table
        SpecifyinseperaterowstheIDsofparallelyrepairedcomponentsLabel  matlab.ui.control.Label
        text8         matlab.ui.control.Label
        Schemepopup   matlab.ui.control.DropDown
        FloorCB       matlab.ui.control.CheckBox
        CompCB        matlab.ui.control.CheckBox
        SubmitButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Time_App_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
        
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'TimeModel','Timestatus','C_Data')
            cd (MainDirectory)

            CIDs = unique(C_Data(:,1));
            nComponents=length(CIDs);  
            CIDs=sort(CIDs);
            
            if Timestatus==1
                app.Schemepopup.Value=string(TimeModel.Scheme);
                app.CompCB.Value=TimeModel.SchemeSameComp;
                app.FloorCB.Value=TimeModel.SchemeFloor;
                app.uitable1.Data=TimeModel.TimeMatrix;
            else
                SchemepopupValueChanged(app);
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'C_Data')
            cd (MainDirectory)
            CIDs = unique(C_Data(:,1));
            
            TimeModel.Scheme = str2double(app.Schemepopup.Value);
            TimeModel.TimeMatrix = app.uitable1.Data;
            TimeModel.SchemeSameComp = app.CompCB.Value;
            TimeModel.SchemeFloor = app.FloorCB.Value;

            % Data Checks
            C_ID_Matrix = nonzeros(TimeModel.TimeMatrix);
            UniqueCIDs = unique(C_ID_Matrix);
            if length(UniqueCIDs)~=length(C_ID_Matrix)
                errordlg('The table should include  the IDs of all the components defined in the project!')
                return
            end
            
            for i=1:length(UniqueCIDs)
                if isempty(find(CIDs==UniqueCIDs(i)))
                    errordlg('The table includes IDs of components that are not defined in the project!')
                    return                    
                end
            end
            
%             uniqueVals = unique( TimeModel.TimeMatrix );
%             uniqueVals(uniqueVals==0)=[];
%             valCount = hist( TimeModel.TimeMatrix , uniqueVals )';
            
            Timestatus=1;
            
            % Check data
            cd (ProjectPath)
            save(ProjectName,'TimeModel','Timestatus','-append')
            cd (MainDirectory)
            figure1CloseRequest(app);
        end

        % Value changed function: Schemepopup
        function SchemepopupValueChanged(app, event)
            global MainDirectory ProjectName ProjectPath
            cd (ProjectPath)
            load(ProjectName,'C_Data')
            cd (MainDirectory)

            CIDs = unique(C_Data(:,1));
            nComponents=length(CIDs);  
            CIDs=sort(CIDs);
            xx=zeros(nComponents,nComponents);

            x = str2double(app.Schemepopup.Value);
            if x==1
                xx(:,1)=CIDs;
                app.uitable1.Data=xx;
            elseif x==2
                xx(1,:)=CIDs;
                app.uitable1.Data=xx;  
            elseif x==3
                app.uitable1.Data=xx;                  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Repair Time Scheme';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Repair Time Scheme';
            app.uitable1 = uitable(app.uipanel2);
            app.uitable1.ColumnEditable = true;
            app.SpecifyinseperaterowstheIDsofparallelyrepairedcomponentsLabel = uilabel(app.uipanel2);
            app.SpecifyinseperaterowstheIDsofparallelyrepairedcomponentsLabel.Text = {'Specify in seperate rows the IDs of components '; 'that will be repaired simultaneously'};
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'Repair Time Scheme:';
            app.Schemepopup = uidropdown(app.uipanel2);
            app.Schemepopup.Items = {'Sequential', 'Simultaneous - All Components', 'Simultaneous - Component Groups'};
            app.Schemepopup.ItemsData = {'1', '2', '3'};
            app.Schemepopup.ValueChangedFcn = createCallbackFcn(app, @SchemepopupValueChanged, true);
            app.Schemepopup.Value = '1';
            app.FloorCB = uicheckbox(app.uipanel2);
            app.FloorCB.Text = {' Repairs will be conducted at different'; ' stories/floors simultanously'};
            app.CompCB = uicheckbox(app.uipanel2);
            app.CompCB.Text = {' Units of the same component at a given'; ' story/floor will be repaired in parallel'};
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Time_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Time_App_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end