classdef Module_Visualize_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uipanel3           matlab.ui.container.Panel
        popup7             matlab.ui.control.DropDown
        text11_2           matlab.ui.control.Label
        popup5             matlab.ui.control.DropDown
        popup4             matlab.ui.control.DropDown
        ButtonGroup_2      matlab.ui.container.ButtonGroup
        radio8             matlab.ui.control.RadioButton
        radio7             matlab.ui.control.RadioButton
        ButtonGroup        matlab.ui.container.ButtonGroup
        radio6             matlab.ui.control.RadioButton
        radio5             matlab.ui.control.RadioButton
        SelectButtonGroup  matlab.ui.container.ButtonGroup
        edit4              matlab.ui.control.NumericEditField
        text40             matlab.ui.control.Label
        edit3              matlab.ui.control.NumericEditField
        edit2              matlab.ui.control.NumericEditField
        edit1              matlab.ui.control.NumericEditField
        text14             matlab.ui.control.Label
        text10             matlab.ui.control.Label
        text11             matlab.ui.control.Label
        radio2             matlab.ui.control.RadioButton
        radio1             matlab.ui.control.RadioButton
        UnitsButtonGroup   matlab.ui.container.ButtonGroup
        radio4             matlab.ui.control.RadioButton
        radio3             matlab.ui.control.RadioButton
        Image_6            matlab.ui.control.Image
        Image_5            matlab.ui.control.Image
        Image_4            matlab.ui.control.Image
        Image_3            matlab.ui.control.Image
        Image_2            matlab.ui.control.Image
        Image              matlab.ui.control.Image
        popup3             matlab.ui.control.DropDown
        popup2             matlab.ui.control.DropDown
        popup1             matlab.ui.control.DropDown
        PlotButton         matlab.ui.control.Button
        CB6                matlab.ui.control.CheckBox
        CB5                matlab.ui.control.CheckBox
        CB1                matlab.ui.control.CheckBox
        CB2                matlab.ui.control.CheckBox
        CB3                matlab.ui.control.CheckBox
        CB4                matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Visualize_OpeningFcn(app)
            global  ProjectName ProjectPath RunMethodology
            clc;
            load(strcat(ProjectPath,ProjectName),'Component_Option','Response_Option','Option5_Type','TargetIM_Option','TargetIM','HazardDataStatus','RunMethodology','nRealization','nStripe','Timestatus');
            
            if TargetIM_Option==1
                app.edit1.Value=TargetIM(1,round(end/3));
            elseif TargetIM_Option==2
                app.edit1.Value=median(TargetIM);
            elseif TargetIM_Option==3
                app.edit1.Value=median(TargetIM);
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
                app.radio1.Enable='off';
                app.radio2.Enable='off';
                app.text10.Enable='off';
                app.text11.Enable='off';
                app.CB6.Enable='off';
            end
            
            if RunMethodology==1
                if Response_Option ==2 || Response_Option ==3 || Response_Option ==4  || Response_Option ==6
                    app.edit1.Enable='off';
                    app.CB1.Enable='off';
                    app.CB5.Enable='off';
                    app.CB6.Enable='off';
                    app.popup3.Enable='off';
                    app.popup1.Enable='off';
                end
                if Response_Option ==5 && Option5_Type~=1
                    app.edit1.Enable='off';
                    app.CB1.Enable='off';
                    app.CB5.Enable='off';
                    app.CB6.Enable='off';
                    app.popup3.Enable='off';
                    app.popup1.Enable='off';
                end
                app.popup7.Visible='off';
            end


            if Component_Option == 2 || Component_Option == 3 
                app.popup2.Value='1';
                app.popup2.Enable='off';
                app.popup2.Items = {'by Component Type'};
                app.CB4.Enable='off';
                app.popup3.Value='1';
                app.popup3.Enable='off';
                app.popup4.Items={'Cost'};
                app.popup7.Items={'Cost'};
            end
            
            if HazardDataStatus ~=1
                app.CB5.Enable='off';
                app.popup1.Enable='off';
                app.radio2.Value=0;
            end
            
            if RunMethodology ==2
                app.radio1.Enable='on';
                app.radio1.Text='Target Ri:';
                app.text14.Text='';
                app.edit1.Limits=[1 nRealization];
                app.edit1.Value=1;
                if Response_Option==4
                    app.edit4.Limits=[1 nStripe];
                else
                    app.text40.Enable='off';            
                    app.edit4.Enable='off';            
                end
                app.edit1.ValueDisplayFormat='%5.0f';
                app.CB6.Enable='on';
                app.SelectButtonGroup.Title='Target Realization and Stripe';
                app.Image.ImageSource='LossRealization.png';
                app.Image_5.ImageSource='Visulaize_LAMBDA.png';
                app.Image_6.ImageSource='Visulaize_SummaryMC.png';
                app.popup3.Visible='off';
                app.popup1.Visible='off';
                app.CB1.Text='          vs Realization';
                app.CB5.Text='Annual Exceedance of Repair Cost';
                app.CB1.Enable='on';
            else
                app.text40.Visible='off';
                app.edit4.Visible='off';
            end
            
            if Timestatus~=1 || RunMethodology==1
                app.popup4.Enable='off';
                app.popup5.Enable='off';
            end

                        
            app.radio1.Value=1;            
            app.radio3.Enable='on';
            
            app.radio5.Enable='off';
            app.radio6.Enable='off';
            app.radio7.Enable='off';
            app.radio8.Enable='off';

            SelectButtonGroupSelectionChanged(app)
        end

        % Value changed function: CB2
        function CB2_Callback(app, event)
            if app.CB2.Value==1
                app.radio5.Enable='on';
                app.radio6.Enable='on';
                app.radio5.Value=1;
                app.radio6.Value=0;
            else
                app.radio5.Enable='off';
                app.radio6.Enable='off';
                app.radio5.Value=0;
                app.radio6.Value=0;
            end
        end

        % Value changed function: CB4
        function CB4_Callback(app, event)
            if app.CB4.Value==1
                app.radio7.Enable='on';
                app.radio8.Enable='on';
                app.radio7.Value=1;
                app.radio8.Value=0;
            else
                app.radio7.Enable='off';
                app.radio8.Enable='off';
                app.radio7.Value=0;
                app.radio8.Value=0;
            end
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            global  ProjectName ProjectPath RunMethodology
            clc;
            load(strcat(ProjectPath,ProjectName))
            
            if app.radio1.Value==1
                if RunMethodology==1    
                    Sa_target=app.edit1.Value;
                    Stripe=1;
                    R_target=0;
                    nRealization=0;
                    if ismember(Sa_target,TargetIM)~=1; 
                        warndlg('Specified Target IM was not defined in the response data. IM is modified to nearest value; try to re-plot now'); 
                        [minval, indx]=min(abs(TargetIM-Sa_target));
                        Sa_target=TargetIM(indx);
                        app.edit1.Value=Sa_target;
                        return; 
                    end 
                else
                    Stripe=app.edit4.Value;
                    Sa_target=0;
                    R_target=app.edit1.Value;                
                end
            else
                Exceedance=app.edit2.Value;
                Years=app.edit3.Value;
                MAFE_target=-log(1-Exceedance/100)/Years;
                Sa_target=interp1(HazardCurveData(:,2),HazardCurveData(:,1),MAFE_target);
            end
            
            Plot_Profile=app.CB3.Value;
            Profile_Option=str2double(app.popup2.Value);
            
            Plot_Loss_Component=app.CB4.Value;
            Plot_Loss=app.CB2.Value;
            
            Plot_Vulnerability_Curves=app.CB1.Value;
            VC_Option=str2double(app.popup1.Value);
            VC_Type=str2double(app.popup3.Value);
            
            Consequence_Type1=str2double(app.popup4.Value);
            Consequence_Type2=str2double(app.popup5.Value);
            Consequence_Type3=str2double(app.popup7.Value);

            Plot_EAL_bar=app.CB5.Value;
            Plot_Summary=app.CB6.Value;
            
            Loss_BarChart=app.radio5.Value;
            Loss_PieChart=app.radio6.Value;
            BarChart=app.radio7.Value;
            UnitOption=app.radio3.Value;
            
            if exist('MAF')==0
               MAF=0;
            end
            %%
            if Plot_Vulnerability_Curves==1
                if RunMethodology==1
                    if VC_Type==1
                        Plot_VulnerabilityCurves_Event(IMpoints,COLLAPSE_LOSSES_Per_IM,DEMOLITION_LOSSES_Per_IM,SC_REPAIR_COST_PerIM,NSC_SDR_REPAIR_COST_PerIM,NSC_ACC_REPAIR_COST_PerIM,MAF,VC_Option,UnitOption,Replacement_Cost)
                    else
                        Plot_VulnerabilityCurves_Component(IMpoints,DEAGG_DATA,MAF,VC_Option,UnitOption,Replacement_Cost,C_Data,COMPDATA,N_Story)
                    end
                else
                    if Consequence_Type3==1
                        Plot_RepairCost_vs_Realization (DEAGG_DATA,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,UnitOption,Replacement_Cost,nRealization,Stripe,Component_Option)
                    else
                        Plot_RepairTime_vs_Realization (DEAGG_DATA,C_Data,COMPDATA,N_Story,TimeModel,nRealization,Stripe,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri)          
                    end
                end
            end
            %%
            if Plot_Loss==1
                Plot_LossBreakdown_by_Event(DEAGG_DATA,COLLAPSE_LOSSES_Per_IM,DEMOLITION_LOSSES_Per_IM,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,IMpoints,Sa_target,Loss_BarChart,Loss_PieChart,UnitOption,Replacement_Cost,N_Story,RunMethodology,R_target,nRealization,TargetIM,Stripe,Component_Option)
            end
            %%
            if Plot_Profile==1 && Profile_Option==1
                if RunMethodology==2;  STORY_REPAIR_COST_SC=0; STORY_REPAIR_COST_NSC_SDR=0; STORY_REPAIR_COST_NSC_ACC=0; end
                if Consequence_Type1==1
                    Plot_RepairCostProfile_by_Type(DEAGG_DATA,STORY_REPAIR_COST_SC,STORY_REPAIR_COST_NSC_SDR,STORY_REPAIR_COST_NSC_ACC,N_Story,IMpoints,Sa_target,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe,Component_Option)
                else
                    Plot_RepairTimeProfile_by_Type(DEAGG_DATA,C_Data,COMPDATA,N_Story,TimeModel,R_target,TargetIM,Stripe)                                    
                end
            elseif Plot_Profile==1 && Profile_Option==2
                if Consequence_Type1==1
                    Plot_RepairCostProfile_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe)
                else
                    Plot_RepairTimeProfile_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,TimeModel,R_target,TargetIM,Stripe)                    
                end
            end
            
            %%
            if Plot_Loss_Component==1
                if Consequence_Type2==1
                    Plot_RepairCostBreakdown_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,BarChart,UnitOption,Replacement_Cost,RunMethodology,R_target,nRealization,TargetIM,Stripe)
                else
                    Plot_RepairTimeBreakdown_by_Component(DEAGG_DATA,C_Data,COMPDATA,N_Story,IMpoints,Sa_target,BarChart,TimeModel,RunMethodology,R_target,TargetIM,Stripe)
                end
            end
            
            %%
            if Plot_EAL_bar ==1
                if RunMethodology==1
                    Plot_EAL(EAL,UnitOption,Replacement_Cost)
                else
                    Plot_LAMBDA(DEAGG_DATA,COLLAPSE_LOSSES_Per_Ri,DEMOLITION_LOSSES_Per_Ri,nRealization,UnitOption,Replacement_Cost,IMpoints,MAF,TargetIM,Stripe,Component_Option)
                end
            end
            
            %%
            if Plot_Summary ==1
                if RunMethodology==1
                    Module_SummaryPlot_PEER_App
                else
                    Module_SummaryPlot_FEMAP58_App
                end
            end
        end

        % Value changed function: CB3
        function CB3ValueChanged(app, event)
            if app.CB3.Value==1
                app.popup2.Enable='on';
            else
                app.popup2.Enable='off';
            end            
        end

        % Selection changed function: SelectButtonGroup
        function SelectButtonGroupSelectionChanged(app, event)
            if app.radio1.Value==1
                app.edit1.Enable='on';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
            else
                app.edit1.Enable='off';
                app.edit2.Enable='on';
                app.edit3.Enable='on';    
            end
        end

        % Value changed function: CB1
        function CB1ValueChanged(app, event)
            global  ProjectName ProjectPath RunMethodology
            clc;
            load(strcat(ProjectPath,ProjectName),'HazardDataStatus')

            if RunMethodology==1
                if app.CB1.Value==1
                    if HazardDataStatus==1; app.popup1.Enable='on'; end
                    app.popup3.Enable='on';
                else
                    app.popup1.Enable='off';
                    app.popup3.Enable='off';
                end 
            end
        end

        % Value changed function: edit1
        function edit1ValueChanged(app, event)
            global RunMethodology
            clc;

            if RunMethodology==2
                app.edit1.Value=round(app.edit1.Value);
            end            
        end

        % Value changed function: popup4
        function popup4ValueChanged(app, event)
            value = app.popup4.Value;
            if value==2
                app.popup2.Value='2';
                app.popup2.Enable='off';
            else
                app.popup2.Value='1';
                app.popup2.Enable='on';                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visualization Options';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Visualization Options';
            app.CB4 = uicheckbox(app.uipanel3);
            app.CB4.ValueChangedFcn = createCallbackFcn(app, @CB4_Callback, true);
            app.CB4.Text = 'Repair      ';
            app.CB3 = uicheckbox(app.uipanel3);
            app.CB3.ValueChangedFcn = createCallbackFcn(app, @CB3ValueChanged, true);
            app.CB3.Text = 'Repair           Profile';
            app.CB2 = uicheckbox(app.uipanel3);
            app.CB2.ValueChangedFcn = createCallbackFcn(app, @CB2_Callback, true);
            app.CB2.Text = 'Loss Breakdown by Event';
            app.CB1 = uicheckbox(app.uipanel3);
            app.CB1.ValueChangedFcn = createCallbackFcn(app, @CB1ValueChanged, true);
            app.CB1.Text = 'Vulnerability Curves';
            app.CB5 = uicheckbox(app.uipanel3);
            app.CB5.Text = 'Expected Annual Losses (EAL)';
            app.CB6 = uicheckbox(app.uipanel3);
            app.CB6.Text = 'Summary Plots';
            app.PlotButton = uibutton(app.uipanel3, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Text = 'Plot';
            app.popup1 = uidropdown(app.uipanel3);
            app.popup1.Items = {'vs IM', 'vs MAFE'};
            app.popup1.ItemsData = {'1', '2'};
            app.popup1.Value = '1';
            app.popup2 = uidropdown(app.uipanel3);
            app.popup2.Items = {'by Component Type', 'by Component Name'};
            app.popup2.ItemsData = {'1', '2'};
            app.popup2.Value = '1';
            app.popup3 = uidropdown(app.uipanel3);
            app.popup3.Items = {'by Event', 'by Component'};
            app.popup3.ItemsData = {'1', '2'};
            app.popup3.Value = '1';
            app.Image = uiimage(app.uipanel3);
            app.Image.ImageSource = 'Visulaize_VC.png';
            app.Image_2 = uiimage(app.uipanel3);
            app.Image_2.ImageSource = 'Visulaize_LossBreakdown.png';
            app.Image_3 = uiimage(app.uipanel3);
            app.Image_3.ImageSource = 'Visulaize_Profile.png';
            app.Image_4 = uiimage(app.uipanel3);
            app.Image_4.ImageSource = 'Visulaize_PieBreakdown.png';
            app.Image_5 = uiimage(app.uipanel3);
            app.Image_5.ImageSource = 'Visulaize_EAL.png';
            app.Image_6 = uiimage(app.uipanel3);
            app.Image_6.ImageSource = 'Visulaize_Summary.png';
            app.UnitsButtonGroup = uibuttongroup(app.uipanel3);
            app.UnitsButtonGroup.Title = 'Loss Units';
            app.radio3 = uiradiobutton(app.UnitsButtonGroup);
            app.radio3.Text = 'Absolute [M$]';
            app.radio3.Value = true;
            app.radio4 = uiradiobutton(app.UnitsButtonGroup);
            app.radio4.Text = 'Normalized [% Cost]';
            app.SelectButtonGroup = uibuttongroup(app.uipanel3);
            app.SelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectButtonGroupSelectionChanged, true);
            app.SelectButtonGroup.Title = 'Specify Target IM/Hazard';
            app.radio1 = uiradiobutton(app.SelectButtonGroup);
            app.radio1.Text = 'Target IM:';
            app.radio1.Value = true;
            app.radio2 = uiradiobutton(app.SelectButtonGroup);
            app.radio2.Text = 'Hazard Level: ';
            app.text11 = uilabel(app.SelectButtonGroup);
            app.text11.Text = 'years';
            app.text10 = uilabel(app.SelectButtonGroup);
            app.text10.Text = '% in';
            app.text14 = uilabel(app.SelectButtonGroup);
            app.text14.Text = '[g]';
            app.edit1 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit1.Limits = [0.0001 Inf];
            app.edit1.ValueDisplayFormat = '%5.2f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1ValueChanged, true);
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit2.Limits = [0.01 100];
            app.edit2.Value = 10;
            app.edit3 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit3.Limits = [1 Inf];
            app.edit3.ValueDisplayFormat = '%.0f';
            app.edit3.Value = 50;
            app.text40 = uilabel(app.SelectButtonGroup);
            app.text40.Text = 'Stripe:';
            app.edit4 = uieditfield(app.SelectButtonGroup, 'numeric');
            app.edit4.Limits = [1 10];
            app.edit4.ValueDisplayFormat = '%.0f';
            app.edit4.Value = 1;
            app.ButtonGroup = uibuttongroup(app.uipanel3);
            app.radio5 = uiradiobutton(app.ButtonGroup);
            app.radio5.Text = 'Bar Chart';
            app.radio5.Value = true;
            app.radio6 = uiradiobutton(app.ButtonGroup);
            app.radio6.Text = 'Pie Chart';
            app.ButtonGroup_2 = uibuttongroup(app.uipanel3);
            app.radio7 = uiradiobutton(app.ButtonGroup_2);
            app.radio7.Text = 'Bar Chart';
            app.radio7.Value = true;
            app.radio8 = uiradiobutton(app.ButtonGroup_2);
            app.radio8.Text = 'Pie Chart';
            app.popup4 = uidropdown(app.uipanel3);
            app.popup4.Items = {'Cost', 'Time'};
            app.popup4.ItemsData = {'1', '2'};
            app.popup4.ValueChangedFcn = createCallbackFcn(app, @popup4ValueChanged, true);
            app.popup4.Value = '1';
            app.popup5 = uidropdown(app.uipanel3);
            app.popup5.Items = {'Cost', 'Time'};
            app.popup5.ItemsData = {'1', '2'};
            app.popup5.Value = '1';
            app.text11_2 = uilabel(app.uipanel3);
            app.text11_2.Text = 'Breakdown by Component Name';
            app.popup7 = uidropdown(app.uipanel3);
            app.popup7.Items = {'Cost', 'Time'};
            app.popup7.ItemsData = {'1', '2'};
            app.popup7.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Visualize_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Visualize_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end